classdef winSignalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Document                      matlab.ui.container.GridLayout
        play_axesToolbar              matlab.ui.container.GridLayout
        axesTool_DataTip              matlab.ui.control.Image
        axesTool_Pan                  matlab.ui.control.Image
        axesTool_RestoreView          matlab.ui.control.Image
        play_PlotPanel                matlab.ui.container.Panel
        play_PlotLabel                matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        tableInfoNRows                matlab.ui.control.Label
        tableInfoMetadata             matlab.ui.control.Label
        toolGrid                      matlab.ui.container.GridLayout
        tool_AppInfo                  matlab.ui.control.Image
        tool_ShowExceptionGlobalList  matlab.ui.control.Button
        jsBackDoor                    matlab.ui.control.HTML
        tool_simulationButton         matlab.ui.control.Button
        tool_ExportJSON               matlab.ui.control.Button
        tool_LayoutLeft               matlab.ui.control.Image
        ControlGrid                   matlab.ui.container.GridLayout
        ControlMenu                   matlab.ui.container.GridLayout
        menuBtn1Grid                  matlab.ui.container.GridLayout
        menuBtn1Icon                  matlab.ui.control.Image
        menuBtn1Label                 matlab.ui.control.Label
        menuUnderline                 matlab.ui.control.Image
        EditableParametersPanel       matlab.ui.container.Panel
        EditableParametersGrid        matlab.ui.container.GridLayout
        AdditionalDescription         matlab.ui.control.TextArea
        AdditionalDescriptionLabel    matlab.ui.control.Label
        RiskLevel                     matlab.ui.control.DropDown
        RiskLevelLabel                matlab.ui.control.Label
        Compliance                    matlab.ui.control.DropDown
        ComplianceLabel               matlab.ui.control.Label
        Type                          matlab.ui.control.DropDown
        TypeLabel                     matlab.ui.control.Label
        StationID                     matlab.ui.control.EditField
        StationIDLabel                matlab.ui.control.Label
        Regulatory                    matlab.ui.control.DropDown
        RegulatoryLabel               matlab.ui.control.Label
        DelAnnotation                 matlab.ui.control.Image
        EditableParametersLabel       matlab.ui.control.Label
        selectedEmissionPanel         matlab.ui.container.Panel
        selectedEmissionGrid          matlab.ui.container.GridLayout
        selectedEmissionInfo          matlab.ui.control.HTML
        selectedEmissionLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        %-------------------------------------------------------------------------%
        CallingApp
        General
        rootFolder

        timerObj_startup

        specData
        projectData
        tempBandObj

        UIAxes1
        restoreView = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj_startup = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj_startup)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj_startup)
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app)

                % Criação do eixo e leitura dos dados...
                startup_AppProperties(app)
                startup_Axes(app)
                renderProjectDataOnScreen(app)                

                % Libera screen... 
                if ~strcmp(app.CallingApp.executionMode, 'webApp')
                    appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
                end
                drawnow

                pause(.100)
                focus(app.UITable)
            end
        end

        %------------------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end

        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                   'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                       '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                       '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                   'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

            ccTools.compCustomizationV2(app.jsBackDoor, app.AdditionalDescription, 'textAlign', 'justify')
            ccTools.compCustomizationV2(app.jsBackDoor, app.play_axesToolbar, 'backgroundColor', 'transparent')
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            app.tempBandObj = class.Band('appAnalise:SIGNALANALYSIS', app);
        end

        %-------------------------------------------------------------------------%
        function startup_Axes(app)
            % Axes creation:
            hParent     = tiledlayout(app.play_PlotPanel, 1, 1, "Padding", "compact", "TileSpacing", "loose");
            app.UIAxes1 = plot.axes.Creation(hParent, 'Cartesian');

            % Axes fixed labels:
            xlabel(app.UIAxes1, 'Frequência (MHz)')
            ylabel(app.UIAxes1, 'Nível (dB)')

            % Axes interactions:
            plot.axes.Interactivity.DefaultCreation(app.UIAxes1, [dataTipInteraction, rulerPanInteraction, regionZoomInteraction])
            % plot.axes.Interactivity.ToolbarCreation(app.UIAxes1, {'pan', 'restoreview'})
            % plot.axes.Interactivity.CustomCallbacks(app.UIAxes1, [], {'pan', 'restoreview'}, app)
        end

        %-------------------------------------------------------------------------%
        function layout_Regulatory(app)
            switch app.Regulatory.Value
                case 'Licenciada'
                    set(app.Type, 'Value', 'Fundamental', 'Enable', 0)
                    app.TypeLabel.Enable   = 0;    
                    set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')

                case {'Não licenciada', 'Não passível de licenciamento'}    
                    app.Type.Enable      = 1;
                    app.TypeLabel.Enable = 1;
                    app.StationID.Value  = '-1';
    
                    switch app.Regulatory.Value
                        case 'Não licenciada'
                            app.Compliance.Items = {'Sim'};                            
                        case 'Não passível de licenciamento'
                            set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')
                    end
            end
        end

        %-------------------------------------------------------------------------%
        function layout_Compliance(app)
            if strcmp(app.Compliance.Value, 'Não')
                set(app.RiskLevel,      'Enable', 0, 'Items', {'-'})
                set(app.RiskLevelLabel, 'Enable', 0)
            else
                set(app.RiskLevel,      'Enable', 1, 'Items', {'Baixo', 'Médio', 'Alto'})
                set(app.RiskLevelLabel, 'Enable', 1)
            end
        end

        %-------------------------------------------------------------------------%
        function layout_TableLabel(app)
            app.tableInfoMetadata.Text = sprintf(['Exibindo emissões relacionadas aos fluxos espectrais a processar' ...
                                                  '<p style="color: #808080; font-size:10px;">Fluxos a processar: ' ...
                                                  '%s</p>'], strjoin({findobj(app.CallingApp.report_Tree.Children).Text}, ', '));
        end

        %-------------------------------------------------------------------------%
        function layout_TableStyle(app)
            removeStyle(app.UITable)
                
            idxEditedPeaks    = [];
            idxIrregularPeaks = [];
            if ~isempty(app.projectData.exceptionList)
                for ii = 1:height(app.projectData.exceptionList)
                    idxEditedPeaks = [idxEditedPeaks; intersect(find(strcmp(app.projectData.peaksTable.Tag,  app.projectData.exceptionList.Tag(ii))), ...
                                                                find(abs(app.projectData.peaksTable.Frequency - app.projectData.exceptionList.Frequency(ii)) <= class.Constants.floatDiffTolerance))];

                    if ~strcmp(app.projectData.exceptionList.Regulatory, 'Licenciada')
                        idxIrregularPeaks = [idxIrregularPeaks; ii];
                    end
                end
                
                if ~isempty(idxEditedPeaks)
                    listOfCells1 = [idxEditedPeaks, ones(numel(idxEditedPeaks), 1)];
                    addStyle(app.UITable, uistyle('Icon', 'Edit_18Gray.png',  'IconAlignment', 'leftmargin'), 'cell', listOfCells1) 
                end
            end

            idxIrregularPeaks = sort([idxIrregularPeaks; find(~strcmp(app.projectData.peaksTable.Regulatory, 'Licenciada'))]);
            if ~isempty(idxIrregularPeaks)
                listOfCells2 = [idxIrregularPeaks, repmat(2, numel(idxIrregularPeaks), 1)];
                addStyle(app.UITable, uistyle('BackgroundColor', '#c94756', 'FontColor', 'white'), 'cell', listOfCells2) 
            end
        end


        %-------------------------------------------------------------------------%
        function userDescription = UserDescriptionParser(app)
            userDescription = strtrim(app.AdditionalDescription.Value);
            userDescription(cellfun(@(x) isempty(x), userDescription)) = [];
            if isempty(userDescription)
                userDescription = '';
            end
        end
        
        %-------------------------------------------------------------------------%
        function FillComponents(app)   
            idxPrjPeaks     = app.UITable.Selection;
            idxPrjException = exceptionListIndex(app);
            idxThread       = specDataIndex(app, idxPrjPeaks);

            [htmlContent, ...
             emissionTag, ...
             userDescription] = fcn.htmlCode_EmissionInfo(app.specData, idxThread, app.projectData, idxPrjPeaks, idxPrjException);

            app.selectedEmissionInfo.HTMLSource = htmlContent;
            set(app.AdditionalDescription, 'Value', userDescription, 'UserData', userDescription)

            % Panel 2
            if isempty(idxPrjException)
                app.DelAnnotation.Visible = 0;
                app.Regulatory.Value      = app.projectData.peaksTable.Regulatory{idxPrjPeaks};
                layout_Regulatory(app)
                app.StationID.Value       = num2str(app.projectData.peaksTable.Station(idxPrjPeaks));                
                app.Type.Value            = app.projectData.peaksTable.Type{idxPrjPeaks};
                app.Compliance.Value      = app.projectData.peaksTable.Irregular{idxPrjPeaks};
                layout_Compliance(app)
                app.RiskLevel.Value       = app.projectData.peaksTable.RiskLevel{idxPrjPeaks};
                
            else
                app.DelAnnotation.Visible = 1;
                app.Regulatory.Value      = app.projectData.exceptionList.Regulatory(idxPrjException);
                layout_Regulatory(app)
                app.StationID.Value       = num2str(app.projectData.exceptionList.Station(idxPrjException));
                app.Type.Value            = app.projectData.exceptionList.Type(idxPrjException);
                app.Compliance.Value      = app.projectData.exceptionList.Irregular(idxPrjException);
                layout_Compliance(app)
                app.RiskLevel.Value       = app.projectData.exceptionList.RiskLevel(idxPrjException);
            end

            % Plot
            CreatePlot(app, emissionTag)
        end

        %-------------------------------------------------------------------------%
        function CreatePlot(app, emissionTag)
            idxPrjPeaks   = app.UITable.Selection;            
            [idxThread, ...
             idxEmission] = specDataIndex(app, idxPrjPeaks);

            cla(app.UIAxes1)
            if ~isempty(idxThread) && ~isempty(idxEmission)
                app.play_PlotLabel.Text = [app.projectData.peaksTable.Tag{idxPrjPeaks} ' @ ' emissionTag];

                axesLimits = update(app.tempBandObj, idxThread, idxPrjPeaks);
                app.restoreView = struct('ID', 'app.UIAxes1', 'xLim', axesLimits.xLim, 'yLim', axesLimits.yLevelLim, 'cLim', 'auto');
                set(app.UIAxes1, 'XLim', app.restoreView.xLim, 'YLim', app.restoreView.yLim)
                ylabel(app.UIAxes1, sprintf('Nível (%s)', app.tempBandObj.LevelUnit))

                % (a) MinHold, Average, e MaxHold
                for plotTag = ["MinHold", "Average", "MaxHold"]
                    eval(sprintf('hLine = plot.draw2D.OrdinaryLine(app.UIAxes1, app.tempBandObj, idxThread, "%s");', plotTag))
                    plot.datatip.Template(hLine, "Frequency+Level", app.tempBandObj.LevelUnit)
                end

                % (b) ROI
                plot.draw2D.rectangularROI(app.UIAxes1, app.tempBandObj, idxThread, 'ROI', idxEmission, {'EdgeAlpha', 0, 'InteractionsAllowed', 'none'})

            else
                app.play_PlotLabel.Text = '';
                msgWarning = 'Não identificado o fluxo de dados relacionado à emissão...';
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
            end
        end

        %-------------------------------------------------------------------------%
        function AddException(app, stationInfo)
            idxPrjPeaks     = app.UITable.Selection;
            idxPrjException = exceptionListIndex(app);
            [idxThread, ...
             idxEmission]   = specDataIndex(app, idxPrjPeaks);

            if ~strcmp(app.projectData.peaksTable.Regulatory{idxPrjPeaks}, app.Regulatory.Value)  || ...
               app.projectData.peaksTable.Station(idxPrjPeaks) ~= str2double(app.StationID.Value) || ...
               ~strcmp(app.projectData.peaksTable.Type{idxPrjPeaks},      app.Type.Value)         || ...
               ~strcmp(app.projectData.peaksTable.Irregular{idxPrjPeaks}, app.Compliance.Value)   || ...
               ~strcmp(app.projectData.peaksTable.RiskLevel{idxPrjPeaks}, app.RiskLevel.Value)    || ...
               ~isequal(app.AdditionalDescription.Value, app.AdditionalDescription.UserData)
    
                % Modificação direta de app.StationID.Value ocasionará num 
                % registro relacionado a uma estação "Licenciada" ou "Não 
                % licenciada" (extraída de base aeronáutica). Neste caso, 
                % "stationInfo" é uma estrutura não vazia.

                % Caso a modificação tenha sido em outro campo, a alteração
                % somente terá efeito se o campo "Informações complementares"
                % estiver preenchido!                
                if ~isempty(stationInfo)
                    switch stationInfo.Service 
                        case -1
                            newRegulatory = 'Não licenciada';
                            newCompliance = 'Sim';
                            newRiskLevel  = 'Baixo';
                        otherwise
                            newRegulatory = 'Licenciada';
                            newCompliance = 'Não';
                            newRiskLevel  = '-';
                    end

                    exceptionValue = {app.projectData.peaksTable.Tag{idxPrjPeaks},       ...
                                      app.projectData.peaksTable.Frequency(idxPrjPeaks), ...
                                      'Fundamental',                                     ...
                                      newRegulatory,                                     ...
                                      stationInfo.Service,                               ...
                                      stationInfo.Station,                               ...
                                      stationInfo.Description,                           ...
                                      sprintf('%.1f', stationInfo.Distance),             ...
                                      newCompliance,                                     ...
                                      newRiskLevel};
    
                else
                    exceptionValue = {app.projectData.peaksTable.Tag{idxPrjPeaks},       ...
                                      app.projectData.peaksTable.Frequency(idxPrjPeaks), ...
                                      app.Type.Value,                                    ...
                                      app.Regulatory.Value,                              ...
                                      [], [], [], [],                                    ...
                                      app.Compliance.Value,                              ...
                                      app.RiskLevel.Value};
    
                    userDescription = strjoin(app.AdditionalDescription.Value);

                    switch app.Regulatory.Value
                        case 'Licenciada'
                            if isempty(idxPrjException)
                                exceptionValue(5:8) = {app.projectData.peaksTable.Service(idxPrjPeaks),     ...
                                                       app.projectData.peaksTable.Station(idxPrjPeaks),     ...
                                                       app.projectData.peaksTable.Description{idxPrjPeaks}, ...
                                                       app.projectData.peaksTable.Distance{idxPrjPeaks}};
                            else
                                exceptionValue(5:8) = {app.projectData.exceptionList.Service(idxPrjException),     ...
                                                       app.projectData.exceptionList.Station(idxPrjException),     ...
                                                       app.projectData.exceptionList.Description{idxPrjException}, ...
                                                       app.projectData.exceptionList.Distance{idxPrjException}};
                            end

                        otherwise
                            exceptionValue(5:8) = {-1, -1, '[EXC]', '-'};
                    end

                    % O campo "Informações complementares" atualmente é armazenado em
                    % app.specData(idx).UserData.Emissions.Detection. Abaixo a sua 
                    % atualização, caso aplicável.
                    if ~isempty(idxThread) && ~isempty(idxEmission)
                        if ~isequal(userDescription, app.AdditionalDescription.UserData)
                            detectionInfo = jsondecode(app.specData(idxThread).UserData.Emissions.Detection{idxEmission});
                            detectionInfo.Description = app.AdditionalDescription.Value;
    
                            app.specData(idxThread).UserData.Emissions.Detection{idxEmission} = jsonencode(detectionInfo, 'ConvertInfAndNaN', false);
                            app.projectData.peaksTable.Detection{idxPrjPeaks}                 = app.specData(idxThread).UserData.Emissions.Detection{idxEmission};
                        end
                    end
                end

                if isempty(idxPrjException)
                    app.projectData.exceptionList(end+1,:)           = exceptionValue;
                else
                    app.projectData.exceptionList(idxPrjException,:) = exceptionValue;
                end

                layout_TableStyle(app)            
                FillComponents(app)
            end
        end

        %-------------------------------------------------------------------------%
        function [idxThread, idxEmission] = specDataIndex(app, idxPrjPeaks)
            idxThread   = [];
            idxEmission = [];

            for ii = 1:numel(app.specData)
                Tag = sprintf('%s\n%.3f - %.3f MHz', app.specData(ii).Receiver,                  ...
                                                     app.specData(ii).MetaData.FreqStart / 1e+6, ...
                                                     app.specData(ii).MetaData.FreqStop  / 1e+6);
                if strcmp(Tag, app.projectData.peaksTable.Tag{idxPrjPeaks})
                    idxThread   = ii;
                    idxEmission = find(app.specData(idxThread).UserData.Emissions.Index == app.projectData.peaksTable.Index(idxPrjPeaks), 1);
                    break
                end
            end
        end

        %-------------------------------------------------------------------------%
        function idxPrjException = exceptionListIndex(app)
            idxPrjPeaks     = app.UITable.Selection;
            idxPrjException = intersect(find(strcmp(app.projectData.exceptionList.Tag,  app.projectData.peaksTable.Tag(idxPrjPeaks))), ...
                                        find(abs(app.projectData.exceptionList.Frequency - app.projectData.peaksTable.Frequency(idxPrjPeaks)) <= class.Constants.floatDiffTolerance));

            if ~isempty(idxPrjException)
                idxPrjException = idxPrjException(1);
            end
        end
    end


    methods (Access = public)
        %-------------------------------------------------------------------------%
        function renderProjectDataOnScreen(app)
            if isempty(app.projectData.peaksTable)
                closeFcn(app)
                return
            end

            % !! PONTO DE EVOLUÇÃO !!
            % Converter para string, controlando o número de casas decimais, 
            % apenas na geração do relatório. De resto, manter como numérico 
            % para garantir coerência nas operações com a tabela (como a 
            % filtragem).
            tempData           = app.projectData.peaksTable(:, {'Frequency', 'Truncated', 'BW', 'minLevel', 'meanLevel', 'maxLevel', 'meanOCC', 'maxOCC', 'Tag'});
            tempData.BW        = int32(tempData.BW);
            tempData.minLevel  = str2double(tempData.minLevel);
            tempData.meanLevel = str2double(tempData.meanLevel);
            tempData.maxLevel  = str2double(tempData.maxLevel);
            tempData.meanOCC   = str2double(tempData.meanOCC);
            tempData.maxOCC    = str2double(tempData.maxOCC);
            % !! PONTO DE EVOLUÇÃO !!

            layout_TableLabel(app)
            set(app.tableInfoNRows, 'Text', sprintf('# %d', height(tempData)), 'Visible', 1)
            set(app.UITable, 'Data', tempData, 'Selection', 1)
            layout_TableStyle(app)
            FillComponents(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp  = mainapp;
            app.General     = mainapp.General;
            app.rootFolder  = mainapp.rootFolder;
            app.specData    = mainapp.specData;
            app.projectData = mainapp.projectData;

            appUtil.winPosition(app.UIFigure)            
            jsBackDoor_Initialization(app)
            startup_timerCreation(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWinFunction(app, 'closeFcn')
            app.CallingApp.auxiliarWin1 = [];
            delete(app)
            
        end

        % Button pushed function: tool_ShowExceptionGlobalList
        function tool_ShowExceptionGlobalListPushed(app, event)
                        
            ExceptionGlobalList = app.CallingApp.channelObj.Exception;

            if isempty(ExceptionGlobalList)
                msg = 'Não identificada emissão na lista global de exceções contida no arquivo "ChannelLib.json".';

            else
                msg = [];
                for ii = 1:height(ExceptionGlobalList)
                    msg = sprintf('%s\n%s', msg, sprintf('<b>%.3f MHz</b>: "%s"', ExceptionGlobalList.FreqCenter(ii), ExceptionGlobalList.Description{ii}));
                end
            end
            appUtil.modalWindow(app.UIFigure, 'warning', msg);

        end

        % Button pushed function: tool_ExportJSON
        function tool_ExportJSONPushed(app, event)
            
            report.Controller(app, 'auxApp.winSignalAnalysis')

        end

        % Button pushed function: tool_simulationButton
        function tool_simulationButtonPushed(app, event)
            
            idxPrjPeaks     = app.UITable.Selection;
            idxPrjException = exceptionListIndex(app);

            if isempty(idxPrjException)
                txStationNumber = app.projectData.peaksTable.Station(idxPrjPeaks);
            else
                txStationNumber = app.projectData.exceptionList.Station(idxPrjException);
            end

            if txStationNumber == -1
                msg = 'Funcionalidade aplicável apenas para estações licenciadas...';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                
                return
            end

            if ~isempty(app.CallingApp.auxiliarWin3)
                msgQuestion   = ['O módulo de consulta ao RFDataHub está aberto e, por essa razão, caso se opte ' ...
                                 'por continuar, todas as inserções de dados inseridas no módulo de consulta ao ' ...
                                 'RFDataHub serão perdidas.<br><br>Deseja continuar?'];
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                if userSelection == "Não"
                    return
                end
            end

            request = struct('Type',           'TerrainProfilePlot',                                  ...
                             'rxLatitude',      double(app.projectData.peaksTable.Latitude(idxPrjPeaks)),  ...
                             'rxLongitude',     double(app.projectData.peaksTable.Longitude(idxPrjPeaks)), ...
                             'txStationNumber', txStationNumber);

            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');
            app.CallingApp.auxiliarWinFunction(app, 'tool_simulationButtonPushed', request)
            delete(d)

        end

        % Selection changed function: UITable
        function UITableSelectionValueChanged(app, event)

            if isempty(event.Selection)
                app.UITable.Selection = event.PreviousSelection;
            else
                FillComponents(app)
            end
            
        end

        % Value changed function: StationID
        function StationIDValueChanged(app, event)
            
            try
                app.StationID.Value = strtrim(app.StationID.Value);
                if isempty(regexp(app.StationID.Value, '^\#?\d+$', 'once'))
                    error(['Esse campo deve ser preenchido com o número da estação ou a ID do registro no RFDataHub. ' ...
                           'O seu formato é numérico, com a ressalva de que quando inserido a ID do registro '         ...
                           'deve ser colocado o caractere "#" à frente do número. Por exemplo: 123456 ou #123456.'])
                end

                global RFDataHub
                
                idxPrjPeaks   = app.UITable.Selection;
                latitudeNode  = app.projectData.peaksTable.Latitude(idxPrjPeaks);
                longitudeNode = app.projectData.peaksTable.Longitude(idxPrjPeaks);
                stationInfo   = class.RFDataHub.query(RFDataHub, app.StationID.Value, latitudeNode, longitudeNode);

                dataStruct(1) = struct('group', 'INDICAÇÃO AUTOMÁTICA',                                                                          ...
                                        'value', struct('Regulatory',  app.projectData.peaksTable.Regulatory{idxPrjPeaks},                     ...
                                                        'Frequency',   sprintf('%.3f MHz', app.projectData.peaksTable.Frequency(idxPrjPeaks))));
                
                if app.projectData.peaksTable.Regulatory{idxPrjPeaks} == "Licenciada"
                    dataStruct(1).value.Description = app.projectData.peaksTable.Description{idxPrjPeaks};
                    dataStruct(1).value.Distance    = sprintf('%s km', app.projectData.peaksTable.Distance{idxPrjPeaks});
                end

                switch stationInfo.Service
                    case -1
                        emissionRegulatory = '<font style="color: #c94756;">Não Licenciada</font>';
                    otherwise
                        emissionRegulatory = 'Licenciada';
                end
                dataStruct(2)  = struct('group', 'MANUAL',                                                       ...
                                        'value', struct('Regulatory',  emissionRegulatory,                       ...
                                                        'Frequency',   sprintf('%s MHz', stationInfo.Frequency), ...
                                                        'Description', stationInfo.Description,                  ...
                                                        'Distance',    sprintf('%.1f km', stationInfo.Distance)));

                msgQuestion    = sprintf('%s<br><font style="font-size: 12px;">Confirma edição?<font>', textFormatGUI.struct2PrettyPrintList(dataStruct));
                userSelection  = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                switch userSelection
                    case 'Sim'
                        AddException(app, stationInfo)
                    case 'Não'
                        app.StationID.Value = num2str(app.projectData.peaksTable.Station(idxPrjPeaks));
                end

            catch ME
                app.StationID.Value = num2str(app.projectData.peaksTable.Station(idxPrjPeaks));                
                appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
            end
            
        end

        % Value changed function: Regulatory
        function RegulatoryValueChanged(app, event)
            
            switch event.Value
                case 'Licenciada'
                    app.Regulatory.Value = event.PreviousValue;
    
                    msgWarning = ['A alteração da situção de uma emissão para "Licenciada" deve ser feita diretamente no ' ...
                                  'campo "Estação / ID", inserindo o número da estação ou a ID do registro no RFDataHub. ' ...
                                  'O seu formato é numérico, com a ressalva de que quando inserido a ID do registro '      ...
                                  'deve ser colocado o caractere "#" à frente do número. Por exemplo: 123456 ou #123456.'];
                    appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                    return

                otherwise % case {'Não licenciada', 'Não passível de licenciamento'}
                    userDescription = UserDescriptionParser(app);
                    if isempty(userDescription)
                        app.Regulatory.Value = event.PreviousValue;
    
                        msgWarning = ['O campo "Informações complementares" não pode ficar vazio para registros ' ...
                                      'relacionados a uma estação "Não licenciada" ou "Não passível de licenciamento".'];
                        appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                        return
                    end
            end

            layout_Regulatory(app)
            OthersParametersValueChanged(app, struct('Source', app.Compliance))
            
        end

        % Value changed function: AdditionalDescription, Compliance, 
        % ...and 2 other components
        function OthersParametersValueChanged(app, event)
            
            switch event.Source
                case app.Compliance
                    layout_Compliance(app)

                case {app.Type, app.RiskLevel}
                    % Algo?! Acho que não. Apenas atualização do registro
                    % da exceção...

                case app.AdditionalDescription
                    userDescription = UserDescriptionParser(app);
                    if isempty(userDescription) && ~strcmp(app.Regulatory.Value, 'Licenciada')
                        app.AdditionalDescription.Value = event.PreviousValue;
    
                        msgWarning = ['O campo "Informações complementares" não pode ficar vazio para registros ' ...
                                      'relacionados a uma estação "Não licenciada" ou "Não passível de licenciamento".'];
                        appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                        return
                    end

                    app.AdditionalDescription.Value = userDescription;
            end

            AddException(app, [])

        end

        % Image clicked function: DelAnnotation
        function DeleteException(app, event)
            
            idxPrjException = exceptionListIndex(app);
            app.projectData.exceptionList(idxPrjException,:) = [];

            layout_TableStyle(app)            
            FillComponents(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ControlGrid = uigridlayout(app.GridLayout);
            app.selectedEmissionLabel = uilabel(app.ControlGrid);
            app.selectedEmissionLabel.Text = 'EMISSÃO SELECIONADA';
            app.selectedEmissionPanel = uipanel(app.ControlGrid);
            app.selectedEmissionGrid = uigridlayout(app.selectedEmissionPanel);
            app.selectedEmissionInfo = uihtml(app.selectedEmissionGrid);
            app.selectedEmissionInfo.HTMLSource = ' ';
            app.EditableParametersLabel = uilabel(app.ControlGrid);
            app.EditableParametersLabel.Text = 'PARÂMETROS EDITÁVEIS';
            app.DelAnnotation = uiimage(app.ControlGrid);
            app.DelAnnotation.ImageClickedFcn = createCallbackFcn(app, @DeleteException, true);
            app.DelAnnotation.Tooltip = {'Retorna à classificação automática'};
            app.DelAnnotation.ImageSource = 'Refresh_18.png';
            app.EditableParametersPanel = uipanel(app.ControlGrid);
            app.EditableParametersGrid = uigridlayout(app.EditableParametersPanel);
            app.RegulatoryLabel = uilabel(app.EditableParametersGrid);
            app.RegulatoryLabel.Text = 'Situação:';
            app.Regulatory = uidropdown(app.EditableParametersGrid);
            app.Regulatory.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            app.Regulatory.ValueChangedFcn = createCallbackFcn(app, @RegulatoryValueChanged, true);
            app.Regulatory.Value = 'Não passível de licenciamento';
            app.StationIDLabel = uilabel(app.EditableParametersGrid);
            app.StationIDLabel.Text = 'Estação / ID:';
            app.StationID = uieditfield(app.EditableParametersGrid, 'text');
            app.StationID.ValueChangedFcn = createCallbackFcn(app, @StationIDValueChanged, true);
            app.TypeLabel = uilabel(app.EditableParametersGrid);
            app.TypeLabel.Text = {'Tipo de '; 'emissão:'};
            app.Type = uidropdown(app.EditableParametersGrid);
            app.Type.Items = {'Fundamental', 'Harmônico de fundamental', 'Produto de intermodulação', 'Espúrio', 'Não identificado', 'Não se manifestou', 'Pendente identificação'};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @OthersParametersValueChanged, true);
            app.Type.Value = 'Pendente identificação';
            app.ComplianceLabel = uilabel(app.EditableParametersGrid);
            app.ComplianceLabel.Text = {'Indício de'; 'irregularidade?'};
            app.Compliance = uidropdown(app.EditableParametersGrid);
            app.Compliance.Items = {'Não', 'Sim'};
            app.Compliance.ValueChangedFcn = createCallbackFcn(app, @OthersParametersValueChanged, true);
            app.Compliance.Value = 'Não';
            app.RiskLevelLabel = uilabel(app.EditableParametersGrid);
            app.RiskLevelLabel.Text = 'Potencial lesivo:';
            app.RiskLevel = uidropdown(app.EditableParametersGrid);
            app.RiskLevel.Items = {'-', 'Baixo', 'Médio', 'Alto'};
            app.RiskLevel.ValueChangedFcn = createCallbackFcn(app, @OthersParametersValueChanged, true);
            app.RiskLevel.Value = '-';
            app.AdditionalDescriptionLabel = uilabel(app.EditableParametersGrid);
            app.AdditionalDescriptionLabel.Text = 'Informações complementares:';
            app.AdditionalDescription = uitextarea(app.EditableParametersGrid);
            app.AdditionalDescription.ValueChangedFcn = createCallbackFcn(app, @OthersParametersValueChanged, true);
            app.ControlMenu = uigridlayout(app.ControlGrid);
            app.menuUnderline = uiimage(app.ControlMenu);
            app.menuUnderline.ImageSource = 'LineH.png';
            app.menuBtn1Grid = uigridlayout(app.ControlMenu);
            app.menuBtn1Label = uilabel(app.menuBtn1Grid);
            app.menuBtn1Label.Text = 'Análise de emissões';
            app.menuBtn1Icon = uiimage(app.menuBtn1Grid);
            app.menuBtn1Icon.Tag = '1';
            app.menuBtn1Icon.ImageSource = 'exceptionList_18.png';
            app.toolGrid = uigridlayout(app.GridLayout);
            app.tool_LayoutLeft = uiimage(app.toolGrid);
            app.tool_LayoutLeft.ImageSource = 'ArrowLeft_32.png';
            app.tool_ExportJSON = uibutton(app.toolGrid, 'push');
            app.tool_ExportJSON.ButtonPushedFcn = createCallbackFcn(app, @tool_ExportJSONPushed, true);
            app.tool_ExportJSON.Icon = 'Export_24.png';
            app.tool_ExportJSON.Tooltip = {'Exportar lista de emissões'};
            app.tool_ExportJSON.Text = '';
            app.tool_simulationButton = uibutton(app.toolGrid, 'push');
            app.tool_simulationButton.ButtonPushedFcn = createCallbackFcn(app, @tool_simulationButtonPushed, true);
            app.tool_simulationButton.Icon = 'Publish_HTML_16.png';
            app.tool_simulationButton.Tooltip = {'Enlace'};
            app.tool_simulationButton.Text = '';
            app.jsBackDoor = uihtml(app.toolGrid);
            app.tool_ShowExceptionGlobalList = uibutton(app.toolGrid, 'push');
            app.tool_ShowExceptionGlobalList.ButtonPushedFcn = createCallbackFcn(app, @tool_ShowExceptionGlobalListPushed, true);
            app.tool_ShowExceptionGlobalList.Icon = 'Info_32.png';
            app.tool_ShowExceptionGlobalList.Tooltip = {'Lista global de exceções'};
            app.tool_ShowExceptionGlobalList.Text = '';
            app.tool_AppInfo = uiimage(app.toolGrid);
            app.tool_AppInfo.ImageSource = 'Dots_32.png';
            app.Document = uigridlayout(app.GridLayout);
            app.tableInfoMetadata = uilabel(app.Document);
            app.tableInfoMetadata.Interpreter = 'html';
            app.tableInfoMetadata.Text = {'Exibindo emissões relacionadas aos fluxos espectrais a processar'; '<p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p><p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p>'};
            app.tableInfoNRows = uilabel(app.Document);
            app.tableInfoNRows.Visible = 'off';
            app.tableInfoNRows.Text = '# 0 ';
            app.UITable = uitable(app.Document);
            app.UITable.ColumnName = {'FREQUÊNCIA (MHz)'; 'FREQUÊNCIA CANAL (MHz)'; 'LARGURA OCUPADA (kHz)'; 'NÍVEL MÍNIMO (dB)'; 'NÍVEL MÉDIO (dB)'; 'NÍVEL MÁXIMO (dB)'; 'OCUPAÇÃO MÉDIA (%)'; 'OCUPAÇÃO MÁXIMA (%)'; 'TAG'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionValueChanged, true);
            app.UITable.Multiselect = 'off';
            app.play_PlotLabel = uilabel(app.Document);
            app.play_PlotLabel.Text = '';
            app.play_PlotPanel = uipanel(app.Document);
            app.play_axesToolbar = uigridlayout(app.Document);
            app.axesTool_RestoreView = uiimage(app.play_axesToolbar);
            app.axesTool_RestoreView.Tooltip = {'RestoreView'};
            app.axesTool_RestoreView.ImageSource = 'Home_32White.png';
            app.axesTool_Pan = uiimage(app.play_axesToolbar);
            app.axesTool_Pan.Tooltip = {'Pan'};
            app.axesTool_Pan.ImageSource = 'Pan_32White.png';
            app.axesTool_DataTip = uiimage(app.play_axesToolbar);
            app.axesTool_DataTip.Tooltip = {'DataTip'};
            app.axesTool_DataTip.ImageSource = 'DataTip_22White.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSignalAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end