classdef winAppAnalise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        home_mainGrid                   matlab.ui.container.GridLayout
        home_Title                      matlab.ui.control.Label
        home_GIF                        matlab.ui.control.Image
        menu_Panel                      matlab.ui.container.Panel
        menu_Grid                       matlab.ui.container.GridLayout
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        AppInfo                         matlab.ui.control.Image
        FigurePosition                  matlab.ui.control.Image
        RFDataHubModule                 matlab.ui.control.Image
        jsBackDoor                      matlab.ui.control.HTML
        menu_Button6                    matlab.ui.control.StateButton
        menu_Button5                    matlab.ui.control.StateButton
        menu_Button4                    matlab.ui.control.StateButton
        menu_Button3                    matlab.ui.control.StateButton
        menu_Button2                    matlab.ui.control.StateButton
        menu_Button1                    matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_File                       matlab.ui.container.Tab
        file_Grid                       matlab.ui.container.GridLayout
        file_panelGrid                  matlab.ui.container.GridLayout
        file_FilteringTree              matlab.ui.container.Tree
        file_FilteringAdd               matlab.ui.control.Image
        file_FilteringType3_Description  matlab.ui.control.EditField
        file_FilteringType2_ID          matlab.ui.control.DropDown
        file_FilteringType1_Frequency   matlab.ui.control.DropDown
        file_FilteringTypePanel         matlab.ui.container.ButtonGroup
        file_FilteringType3             matlab.ui.control.RadioButton
        file_FilteringType2             matlab.ui.control.RadioButton
        file_FilteringType1             matlab.ui.control.RadioButton
        file_FilteringLabel             matlab.ui.control.Label
        file_MetadataPanel              matlab.ui.container.Panel
        file_MetadataGrid               matlab.ui.container.GridLayout
        file_Metadata                   matlab.ui.control.HTML
        file_MetadataLabel              matlab.ui.control.Label
        file_docGrid                    matlab.ui.container.GridLayout
        file_Tree                       matlab.ui.container.Tree
        file_TreeLabel                  matlab.ui.control.Label
        file_TitleGrid                  matlab.ui.container.GridLayout
        file_Title                      matlab.ui.control.Label
        file_TitleIcon                  matlab.ui.control.Image
        file_toolGrid                   matlab.ui.container.GridLayout
        file_SpecReadButton             matlab.ui.control.Button
        file_OpenFileButton             matlab.ui.control.Image
        Tab2_Playback                   matlab.ui.container.Tab
        play_Grid                       matlab.ui.container.GridLayout
        play_toolGrid                   matlab.ui.container.GridLayout
        report_FiscalizaAutoFillImage   matlab.ui.control.Image
        report_FiscalizaUpdateImage     matlab.ui.control.Image
        report_Button3                  matlab.ui.control.Image
        report_Button2                  matlab.ui.control.Image
        report_Button1                  matlab.ui.control.Image
        play_PlaybackLoop               matlab.ui.control.Image
        play_PlaybackPlay               matlab.ui.control.Image
        play_Timestamp                  matlab.ui.control.Label
        misc_LayoutRight                matlab.ui.control.Image
        misc_LayoutLeft                 matlab.ui.control.Image
        play_Waterfall                  matlab.ui.control.StateButton
        play_Occupancy                  matlab.ui.control.StateButton
        play_Persistance                matlab.ui.control.StateButton
        play_MaxHold                    matlab.ui.control.StateButton
        play_Average                    matlab.ui.control.StateButton
        play_MinHold                    matlab.ui.control.StateButton
        play_PlaybackSlider             matlab.ui.control.Slider
        play_ControlsGrid               matlab.ui.container.GridLayout
        misc_ControlsTab1Info           matlab.ui.container.GridLayout
        misc_Panel1                     matlab.ui.container.Panel
        misc_Grid1                      matlab.ui.container.GridLayout
        misc_SaveLabel_2                matlab.ui.control.Label
        play_DeleteAll                  matlab.ui.control.Button
        misc_ImportLabel                matlab.ui.control.Label
        misc_Import                     matlab.ui.control.Button
        misc_ExportLabel                matlab.ui.control.Label
        misc_Export                     matlab.ui.control.Button
        misc_Serarator                  matlab.ui.control.Image
        misc_DelLabel                   matlab.ui.control.Label
        misc_Del                        matlab.ui.control.Button
        misc_MergeLabel                 matlab.ui.control.Label
        misc_Merge                      matlab.ui.control.Button
        misc_DuplicateLabel             matlab.ui.control.Label
        misc_Duplicate                  matlab.ui.control.Button
        misc_SaveLabel                  matlab.ui.control.Label
        misc_Save                       matlab.ui.control.Button
        misc_Label1                     matlab.ui.control.Label
        misc_Index                      matlab.ui.control.EditField
        misc_IndexLabel                 matlab.ui.control.Label
        misc_ControlsTab1Grid           matlab.ui.container.GridLayout
        misc_ControlsTab1Image          matlab.ui.control.Image
        misc_ControlsTab1Label          matlab.ui.control.Label
        edit_ControlsTab1Info           matlab.ui.container.GridLayout
        edit_gpsLabel                   matlab.ui.control.Label
        edit_unitPanel                  matlab.ui.container.Panel
        misc_Edit_unitGrid              matlab.ui.container.GridLayout
        misc_Edit_kFactorTree           matlab.ui.container.Tree
        misc_Edit_kFactorAdd            matlab.ui.control.Image
        misc_Edit_kFactor               matlab.ui.control.DropDown
        misc_Edit_kFactorLabel          matlab.ui.control.Label
        misc_Edit_unitRaw               matlab.ui.control.EditField
        misc_Edit_unitRawLabel          matlab.ui.control.Label
        edit_unitRefresh                matlab.ui.control.Image
        edit_unitLabel                  matlab.ui.control.Label
        edit_gpsPanel                   matlab.ui.container.Panel
        edit_gpsGrid                    matlab.ui.container.GridLayout
        edit_gpsCityPanel               matlab.ui.container.Panel
        edit_gpsCityGrid                matlab.ui.container.GridLayout
        edit_gpsCityButton              matlab.ui.control.Button
        edit_gpsCity                    matlab.ui.control.EditField
        edit_gpsCityLabel               matlab.ui.control.Label
        edit_gpsCitySource              matlab.ui.control.EditField
        edit_gpsCitySourceLabel         matlab.ui.control.Label
        edit_gpsLocationLabel2          matlab.ui.control.Label
        edit_gpsLocationPanel           matlab.ui.container.Panel
        edit_gpsLocationGrid            matlab.ui.container.GridLayout
        edit_gpsButton                  matlab.ui.control.Button
        edit_gpsLongitude               matlab.ui.control.NumericEditField
        edit_gpsLongitudeLabel          matlab.ui.control.Label
        edit_gpsLatitude                matlab.ui.control.NumericEditField
        edit_gpsLatitudeLabel           matlab.ui.control.Label
        edit_gpsSource                  matlab.ui.control.EditField
        edit_gpsSourceLabel             matlab.ui.control.Label
        edit_gpsLocationLabel1          matlab.ui.control.Label
        edit_gpsRefresh                 matlab.ui.control.Image
        edit_ControlsTab1Grid           matlab.ui.container.GridLayout
        edit_ControlsTab1Image          matlab.ui.control.Image
        edit_ControlsTab1Label          matlab.ui.control.Label
        report_ControlsTab3Info         matlab.ui.container.GridLayout
        report_FiscalizaPanel           matlab.ui.container.Panel
        report_FiscalizaGrid            matlab.ui.container.GridLayout
        report_FiscalizaIcon            matlab.ui.control.Image
        report_FiscalizaRefresh         matlab.ui.control.Image
        report_Fiscaliza_PanelLabel     matlab.ui.control.Label
        report_ControlsTab3Grid         matlab.ui.container.GridLayout
        report_ControlsTab3Image        matlab.ui.control.Image
        report_ControlsTab3Label        matlab.ui.control.Label
        report_ControlsTab2Info         matlab.ui.container.GridLayout
        report_ClassificationLabel      matlab.ui.control.Label
        report_occLabel                 matlab.ui.control.Label
        report_ClassificationPanel      matlab.ui.container.Panel
        report_ClassificationGrid       matlab.ui.container.GridLayout
        report_ClassificationParameters  matlab.ui.control.TextArea
        report_ClassificationButton     matlab.ui.control.Button
        report_ClassificationAlgorithm  matlab.ui.control.EditField
        report_ClassificationAlgorithmLabel  matlab.ui.control.Label
        report_DetectionPanel           matlab.ui.container.Panel
        report_DetectionGrid            matlab.ui.container.GridLayout
        report_DetectionManualMode      matlab.ui.control.CheckBox
        report_DetectionBands           matlab.ui.control.TextArea
        report_DetectionButton          matlab.ui.control.Button
        report_DetectionAlgorithm       matlab.ui.control.EditField
        report_DetectionAlgorithmLabel  matlab.ui.control.Label
        report_DetectionBandsLabel      matlab.ui.control.Label
        report_DetectionParameters      matlab.ui.control.TextArea
        report_DetectionLabel           matlab.ui.control.Label
        report_occPanel                 matlab.ui.container.Panel
        report_occGrid                  matlab.ui.container.GridLayout
        report_occButton                matlab.ui.control.Button
        report_occParameters            matlab.ui.control.TextArea
        report_occMethod                matlab.ui.control.EditField
        report_occMethodLabel           matlab.ui.control.Label
        report_ControlsTab2Grid         matlab.ui.container.GridLayout
        report_ControlsTab2Image        matlab.ui.control.Image
        report_ControlsTab2Label        matlab.ui.control.Label
        report_ControlsTab1Info         matlab.ui.container.GridLayout
        report_ProjectPanel             matlab.ui.control.Label
        report_ProjectWarn              matlab.ui.control.Image
        report_addButton                matlab.ui.control.Image
        report_TreeLabel                matlab.ui.control.Label
        report_TimeStampLabel           matlab.ui.control.Label
        report_GeneralPanel             matlab.ui.container.Panel
        report_GeneralGrid              matlab.ui.container.GridLayout
        report_NewProject               matlab.ui.control.Button
        report_ProjectFilename          matlab.ui.control.TextArea
        report_ReportLabel              matlab.ui.control.Label
        report_ReportPanel              matlab.ui.container.Panel
        report_ReportGrid               matlab.ui.container.GridLayout
        report_VersionLabel             matlab.ui.control.Label
        report_Version                  matlab.ui.control.DropDown
        report_AddProjectAttachment     matlab.ui.control.Button
        report_TypeLabel                matlab.ui.control.Label
        report_Type                     matlab.ui.control.DropDown
        report_Issue                    matlab.ui.control.NumericEditField
        report_IssueLabel               matlab.ui.control.Label
        report_SaveProject              matlab.ui.control.Button
        report_ProjectName              matlab.ui.control.EditField
        report_TimeStampPanel           matlab.ui.container.Panel
        report_TimeStampGrid            matlab.ui.container.GridLayout
        report_Mark2                    matlab.ui.control.Label
        report_Mark1                    matlab.ui.control.Label
        report_Spinner4                 matlab.ui.control.Spinner
        report_Spinner3                 matlab.ui.control.Spinner
        report_DatePicker2              matlab.ui.control.DatePicker
        report_Spinner2                 matlab.ui.control.Spinner
        report_Spinner1                 matlab.ui.control.Spinner
        report_DatePicker1              matlab.ui.control.DatePicker
        report_Tree                     matlab.ui.container.CheckBoxTree
        report_ControlsTab1Grid         matlab.ui.container.GridLayout
        report_ControlsTab1Image        matlab.ui.control.Image
        report_ControlsTab1Label        matlab.ui.control.Label
        play_ControlsTab3Info           matlab.ui.container.GridLayout
        play_FindPeaks_Description      matlab.ui.control.EditField
        play_FindPeaks_DescriptionLabel  matlab.ui.control.Label
        play_FindPeaks_PeakBW           matlab.ui.control.NumericEditField
        play_FindPeaks_PeakBWLabel      matlab.ui.control.Label
        play_FindPeaks_PeakCF           matlab.ui.control.NumericEditField
        play_FindPeaks_PeakCFLabel      matlab.ui.control.Label
        play_FindPeaks_Tree             matlab.ui.container.Tree
        play_FindPeaks_add              matlab.ui.control.Image
        play_FindPeaks_ExternalFilePanel  matlab.ui.container.Panel
        play_FindPeaks_ExternalFileGrid  matlab.ui.container.GridLayout
        play_FindPeaks_ExternalFile     matlab.ui.control.DropDown
        play_FindPeaks_ExternalFileLabel  matlab.ui.control.Label
        play_FindPeaks_ParametersPanel  matlab.ui.container.Panel
        play_FindPeaks_ParametersGrid   matlab.ui.container.GridLayout
        play_FindPeaks_Algorithm        matlab.ui.control.DropDown
        play_FindPeaks_AlgorithmLabel   matlab.ui.control.Label
        play_FindPeaks_MaxHoldPanel     matlab.ui.container.Panel
        play_FindPeaks_MaxHoldGrid      matlab.ui.container.GridLayout
        play_FindPeaks_maxOCC           matlab.ui.control.Spinner
        play_FindPeaks_maxOCCLabel      matlab.ui.control.Label
        play_FindPeaks_meanOCC          matlab.ui.control.Spinner
        play_FindPeaks_meanOCCLabel     matlab.ui.control.Label
        play_FindPeaks_Prominence2      matlab.ui.control.Spinner
        play_FindPeaks_Prominence2Label  matlab.ui.control.Label
        play_FindPeaks_MeanPanel        matlab.ui.container.Panel
        play_FindPeaks_MeanGrid         matlab.ui.container.GridLayout
        play_FindPeaks_Prominence1      matlab.ui.control.Spinner
        play_FindPeaks_Prominence1Label  matlab.ui.control.Label
        play_FindPeaks_BW               matlab.ui.control.Spinner
        play_FindPeaks_BWLabel          matlab.ui.control.Label
        play_FindPeaks_distance         matlab.ui.control.Spinner
        play_FindPeaks_distanceLabel    matlab.ui.control.Label
        play_FindPeaks_Class            matlab.ui.control.DropDown
        play_FindPeaks_ClassLabel       matlab.ui.control.Label
        play_FindPeaks_prominence       matlab.ui.control.Spinner
        play_FindPeaks_prominenceLabel  matlab.ui.control.Label
        play_FindPeaks_THR              matlab.ui.control.Spinner
        play_FindPeaks_THRLabel         matlab.ui.control.Label
        play_FindPeaks_Numbers          matlab.ui.control.Spinner
        play_FindPeaks_NumbersLabel     matlab.ui.control.Label
        play_FindPeaks_Trace            matlab.ui.control.DropDown
        play_FindPeaks_TraceLabel       matlab.ui.control.Label
        play_FindPeaks_RadioGroup       matlab.ui.container.ButtonGroup
        play_FindPeaks_File             matlab.ui.control.RadioButton
        play_FindPeaks_DataTips         matlab.ui.control.RadioButton
        play_FindPeaks_ROI              matlab.ui.control.RadioButton
        play_FindPeaks_auto             matlab.ui.control.RadioButton
        play_FindPeaks_Label            matlab.ui.control.Label
        play_ControlsTab3Grid           matlab.ui.container.GridLayout
        play_ControlsTab3Image          matlab.ui.control.Image
        play_ControlsTab3Label          matlab.ui.control.Label
        play_ControlsTab2Info           matlab.ui.container.GridLayout
        play_Channel_Tree               matlab.ui.container.Tree
        play_BandLimits_Tree            matlab.ui.container.Tree
        play_BandLimits_add             matlab.ui.control.Image
        play_BandLimits_Panel           matlab.ui.container.Panel
        play_BandLimits_Grid            matlab.ui.container.GridLayout
        play_BandLimits_xLim2           matlab.ui.control.NumericEditField
        play_BandLimits_xLabel          matlab.ui.control.Label
        play_BandLimits_xLim1           matlab.ui.control.NumericEditField
        play_BandLimitsGrid             matlab.ui.container.GridLayout
        play_BandLimits_Label           matlab.ui.control.Label
        play_BandLimits_Status          matlab.ui.control.CheckBox
        play_Channel_add                matlab.ui.control.Image
        play_Channel_Panel              matlab.ui.container.Panel
        play_Channel_Grid               matlab.ui.container.GridLayout
        play_Channel_Sample             matlab.ui.control.Label
        play_Channel_BW                 matlab.ui.control.NumericEditField
        play_Channel_BWLabel            matlab.ui.control.Label
        play_Channel_StepWidth          matlab.ui.control.NumericEditField
        play_Channel_StepWidthLabel     matlab.ui.control.Label
        play_Channel_Class              matlab.ui.control.DropDown
        play_Channel_ClassLabel         matlab.ui.control.Label
        play_Channel_nChannel           matlab.ui.control.NumericEditField
        play_Channel_nChannelLabel      matlab.ui.control.Label
        play_Channel_FirstChannel       matlab.ui.control.NumericEditField
        play_Channel_FirstChannelLabel  matlab.ui.control.Label
        play_Channel_nChannels          matlab.ui.control.NumericEditField
        play_Channel_nChannelsLabel     matlab.ui.control.Label
        play_Channel_List               matlab.ui.control.DropDown
        play_Channel_ListUpdate         matlab.ui.control.Image
        play_Channel_ListLabel          matlab.ui.control.Label
        play_Channel_RadioGroup         matlab.ui.container.ButtonGroup
        play_Channel_Single             matlab.ui.control.RadioButton
        play_Channel_Multiples          matlab.ui.control.RadioButton
        play_Channel_ReferenceList      matlab.ui.control.RadioButton
        play_Channel_Label              matlab.ui.control.Label
        play_ControlsTab2Grid           matlab.ui.container.GridLayout
        play_ControlsTab2Image          matlab.ui.control.Image
        play_ControlsTab2Label          matlab.ui.control.Label
        play_ControlsTab1Info           matlab.ui.container.GridLayout
        play_Customization              matlab.ui.control.CheckBox
        play_Waterfall_Panel            matlab.ui.container.Panel
        play_WaterFallGrid              matlab.ui.container.GridLayout
        play_Waterfall_cLim_Mode        matlab.ui.control.Image
        play_Waterfall_cLim_Grid2       matlab.ui.container.GridLayout
        play_Waterfall_cLim_Separation  matlab.ui.control.Label
        play_Waterfall_cLim2            matlab.ui.control.Spinner
        play_Waterfall_cLim1            matlab.ui.control.Spinner
        play_Waterfall_cLim_Label       matlab.ui.control.Label
        play_Waterfall_Interpolation    matlab.ui.control.DropDown
        play_Waterfall_Interpolation_Label  matlab.ui.control.Label
        play_Waterfall_Colormap         matlab.ui.control.DropDown
        play_Waterfall_ColormapLabel    matlab.ui.control.Label
        play_Waterfall_Timestamp        matlab.ui.control.DropDown
        play_Waterfall_Timestamp_Label  matlab.ui.control.Label
        play_Waterfall_Decimation       matlab.ui.control.DropDown
        play_Waterfall_DecimationGrid   matlab.ui.container.GridLayout
        play_Waterfall_DecimationValue  matlab.ui.control.Label
        play_Waterfall_Decimation_Label  matlab.ui.control.Label
        play_OCC_Panel                  matlab.ui.container.Panel
        play_OCCGrid                    matlab.ui.container.GridLayout
        play_OCC_noisePanel             matlab.ui.container.Panel
        play_OCC_noiseGrid              matlab.ui.container.GridLayout
        play_OCC_noiseUsefulSamples     matlab.ui.control.Spinner
        play_OCC_noiseUsefulSamplesLabel  matlab.ui.control.Label
        play_OCC_noiseTrashSamples      matlab.ui.control.Spinner
        play_OCC_noiseTrashSamplesLabel  matlab.ui.control.Label
        play_OCC_noiseFcn               matlab.ui.control.DropDown
        play_OCC_noiseFcnLabel          matlab.ui.control.Label
        play_OCC_noiseLabel             matlab.ui.control.Label
        play_OCC_ceilFactor             matlab.ui.control.DropDown
        play_OCC_ceilFactorLabel        matlab.ui.control.Label
        play_OCC_Offset                 matlab.ui.control.Spinner
        play_OCC_OffsetLabel            matlab.ui.control.Label
        play_OCC_THRCaptured            matlab.ui.control.DropDown
        play_OCC_THR                    matlab.ui.control.Spinner
        play_OCC_THRLabel               matlab.ui.control.Label
        play_OCC_Orientation            matlab.ui.control.DropDown
        play_OCC_OrientationLabel       matlab.ui.control.Label
        play_OCC_IntegrationTimeCaptured  matlab.ui.control.NumericEditField
        play_OCC_IntegrationTime        matlab.ui.control.DropDown
        play_OCC_IntegrationTimeLabel   matlab.ui.control.Label
        play_OCC_Method                 matlab.ui.control.DropDown
        play_OCC_MethodLabel            matlab.ui.control.Label
        play_Persistance_Panel          matlab.ui.container.Panel
        play_PersistanceGrid            matlab.ui.container.GridLayout
        play_Persistance_cLim_Mode      matlab.ui.control.Image
        play_Persistance_cLim_Grid2     matlab.ui.container.GridLayout
        play_Persistance_cLim_Separation  matlab.ui.control.Label
        play_Persistance_cLim2          matlab.ui.control.Spinner
        play_Persistance_cLim1          matlab.ui.control.Spinner
        play_Persistance_cLim_Label     matlab.ui.control.Label
        play_Persistance_Transparency   matlab.ui.control.Spinner
        play_Persistance_TransparencyLabel  matlab.ui.control.Label
        play_Persistance_Colormap       matlab.ui.control.DropDown
        play_Persistance_ColormapLabel  matlab.ui.control.Label
        play_Persistance_Samples        matlab.ui.control.DropDown
        play_Persistance_SamplesLabel   matlab.ui.control.Label
        play_Persistance_Interpolation  matlab.ui.control.DropDown
        play_Persistance_InterpolationLabel  matlab.ui.control.Label
        play_ControlsPanel              matlab.ui.container.ButtonGroup
        play_RadioButton_Waterfall      matlab.ui.control.RadioButton
        play_RadioButton_Occupancy      matlab.ui.control.RadioButton
        play_RadioButton_Persistance    matlab.ui.control.RadioButton
        play_ControlPanelLabel          matlab.ui.control.Label
        play_GeneralPanel               matlab.ui.container.Panel
        play_OthersGrid                 matlab.ui.container.GridLayout
        play_LimitsPanel                matlab.ui.container.Panel
        play_LimitsGrid                 matlab.ui.container.GridLayout
        play_Limits_yLim2               matlab.ui.control.Spinner
        play_Limits_yLimLabel           matlab.ui.control.Label
        play_Limits_yLim1               matlab.ui.control.Spinner
        play_Limits_xLim2               matlab.ui.control.Spinner
        play_Limits_xLimLabel           matlab.ui.control.Label
        play_Limits_xLim1               matlab.ui.control.Spinner
        play_LimitsPanelLabel           matlab.ui.control.Label
        play_TraceIntegration           matlab.ui.control.DropDown
        play_TraceIntegrationLabel      matlab.ui.control.Label
        play_MinPlotTime                matlab.ui.control.Spinner
        play_MinPlotTimeLabel           matlab.ui.control.Label
        play_LineVisibility             matlab.ui.control.DropDown
        play_LineVisibilityLabel        matlab.ui.control.Label
        play_LayoutRatio                matlab.ui.control.DropDown
        play_LayoutRatioLabel           matlab.ui.control.Label
        play_GeneralPanelLabel          matlab.ui.control.Label
        play_ControlsTab1Grid           matlab.ui.container.GridLayout
        play_ControlsTab1Image          matlab.ui.control.Image
        play_ControlsTab1Label          matlab.ui.control.Label
        play_PlotPanel                  matlab.ui.container.Panel
        play_TreeGrid                   matlab.ui.container.GridLayout
        play_MetadataPanel              matlab.ui.container.Panel
        play_MetadataGrid               matlab.ui.container.GridLayout
        play_Metadata                   matlab.ui.control.HTML
        play_Tree                       matlab.ui.container.Tree
        play_TreeLabelGrid              matlab.ui.container.GridLayout
        play_TreeLayout                 matlab.ui.control.Image
        play_Lock                       matlab.ui.control.Image
        play_TreeLabel                  matlab.ui.control.Label
        play_TreeTitleGrid              matlab.ui.container.GridLayout
        play_TreeTitle                  matlab.ui.control.Label
        play_TreeTitleImage             matlab.ui.control.Image
        Tab3_Config                     matlab.ui.container.Tab
        config_mainGrid                 matlab.ui.container.GridLayout
        config_Option3Grid              matlab.ui.container.GridLayout
        config_DefaultIssueValuesPanel  matlab.ui.container.Panel
        config_DefaultIssueValuesGrid   matlab.ui.container.GridLayout
        config_ServicoInspecao          matlab.ui.container.CheckBoxTree
        config_ServicoInspecaoLabel     matlab.ui.control.Label
        config_MotivoLAI                matlab.ui.container.CheckBoxTree
        config_MotivoLAILabel           matlab.ui.control.Label
        config_TipoPLAI                 matlab.ui.control.DropDown
        config_TipoPLAILabel            matlab.ui.control.Label
        config_GerarPLAI                matlab.ui.control.DropDown
        config_GerarPLAILabel           matlab.ui.control.Label
        config_CadastroSTEL             matlab.ui.control.DropDown
        config_CadastroSTELLabel        matlab.ui.control.Label
        config_DefaultIssueValuesLock   matlab.ui.control.Image
        config_DefaultIssueValuesLabel  matlab.ui.control.Label
        config_FiscalizaVersion         matlab.ui.container.ButtonGroup
        config_FiscalizaHM              matlab.ui.control.RadioButton
        config_FiscalizaPD              matlab.ui.control.RadioButton
        config_FiscalizaVersionLabel    matlab.ui.control.Label
        config_Option2Grid              matlab.ui.container.GridLayout
        config_SelectedTableColumns     matlab.ui.container.CheckBoxTree
        config_SelectedTableColumnsLabel  matlab.ui.control.Label
        config_MiscelaneousPanel2       matlab.ui.container.Panel
        config_MiscelaneousGrid2        matlab.ui.container.GridLayout
        config_WordCloudColumn          matlab.ui.control.DropDown
        config_WordCloudColumnLabel     matlab.ui.control.Label
        config_WordCloudAlgorithm       matlab.ui.control.DropDown
        config_WordCloudAlgorithmLabel  matlab.ui.control.Label
        config_MiscelaneousLabel2       matlab.ui.control.Label
        config_MiscelaneousPanel1       matlab.ui.container.Panel
        config_MiscelaneousGrid1        matlab.ui.container.GridLayout
        config_nMinWords                matlab.ui.control.DropDown
        config_nMinWordsLabel           matlab.ui.control.Label
        config_nMinCharacters           matlab.ui.control.Spinner
        config_nMinCharactersLabel      matlab.ui.control.Label
        config_MiscelaneousLabel1       matlab.ui.control.Label
        config_SearchModePanel          matlab.ui.container.ButtonGroup
        config_SearchModeListOfWords    matlab.ui.control.RadioButton
        config_SearchModeTokenSuggestion  matlab.ui.control.RadioButton
        config_SearchModeLabel          matlab.ui.control.Label
        config_Option1Grid              matlab.ui.container.GridLayout
        config_FolderMapPanel           matlab.ui.container.Panel
        config_FolderMapGrid            matlab.ui.container.GridLayout
        config_Folder_userPathButton    matlab.ui.control.Image
        config_Folder_userPath          matlab.ui.control.DropDown
        config_Folder_userPathLabel     matlab.ui.control.Label
        config_Folder_pythonPathButton  matlab.ui.control.Image
        config_Folder_pythonPath        matlab.ui.control.EditField
        config_Folder_pythonPathLabel   matlab.ui.control.Label
        config_Folder_DataHubPOSTButton  matlab.ui.control.Image
        config_Folder_DataHubPOST       matlab.ui.control.EditField
        config_Folder_DataHubPOSTLabel  matlab.ui.control.Label
        config_Folder_DataHubGETButton  matlab.ui.control.Image
        config_Folder_DataHubGET        matlab.ui.control.EditField
        config_Folder_DataHubGETLabel   matlab.ui.control.Label
        config_FolderMapLabel           matlab.ui.control.Label
        config_Control                  matlab.ui.container.GridLayout
        config_ButtonGroup              matlab.ui.container.ButtonGroup
        config_Option_Folder            matlab.ui.control.RadioButton
        config_Option_Fiscaliza         matlab.ui.control.RadioButton
        config_Option_Search            matlab.ui.control.RadioButton
        config_Label                    matlab.ui.control.Label
        config_ControlMenu              matlab.ui.container.GridLayout
        config_menuBtn1Grid             matlab.ui.container.GridLayout
        config_menuBtn1Icon             matlab.ui.control.Image
        config_menuBtn1Label            matlab.ui.control.Label
        config_menuUnderline            matlab.ui.control.Image
        file_ContextMenu_Tree1          matlab.ui.container.ContextMenu
        file_ContextMenu_delTree1Node   matlab.ui.container.Menu
        file_ContextMenu_Tree2          matlab.ui.container.ContextMenu
        file_ContextMenu_delTree2Node   matlab.ui.container.Menu
        play_FindPeaks_ContextMenu      matlab.ui.container.ContextMenu
        play_FindPeaks_ContextMenu_Drive  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_search  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_edit  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_analog  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_digital  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_del  matlab.ui.container.Menu
        play_Channel_ContextMenu        matlab.ui.container.ContextMenu
        play_Channel_ContextMenu_del    matlab.ui.container.Menu
        play_Channel_ContextMenu_add    matlab.ui.container.Menu
        report_ContextMenu              matlab.ui.container.ContextMenu
        report_ContextMenu_del          matlab.ui.container.Menu
        report_ContextMenu_ExternalFiles  matlab.ui.container.Menu
        play_BandLimits_ContextMenu     matlab.ui.container.ContextMenu
        play_BandLimits_ContextMenu_del  matlab.ui.container.Menu
        edit_ContextMenu                matlab.ui.container.ContextMenu
        edit_ContextMenu_del            matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        General 
        rootFolder

        % Essa propriedade registra o tipo de execução da aplicação, podendo
        % ser: 'built-in', 'desktopApp' ou 'webApp'.
        executionMode        

        % A função do timer é executada uma única vez após a renderização
        % da figura. Dessa forma, deixa-se o MATLAB criar os componentes
        % essenciais da GUI - normalmente constantes em createComponents -,
        % já mostrando a GUI para o usuário.
        timerObj_startup

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag = {true, ...
                          true, ...
                          true};

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        %-----------------------------------------------------------------%
        % ## PECULIARIDADES DESTE APP ##
        %-----------------------------------------------------------------%

        channelObj
        metaData      = class.metaData.empty
        specData      = []
        projData      = struct('image', {''}, 'table', {[]})

        plotFlag      = 0
        plotLayout    = 1
        timeIndex     = 1

        %-----------------------------------------------------------------%
        % ## PLOT HANDLES ##
        %-----------------------------------------------------------------%
        axes1
        axes2
        axes3
        
        Band          = class.Band
        restoreView   = {[0,1], [0,1], [0,1], [0,1]}

        line_ClrWrite                                                       % ClearWrite (main trace)
        line_MinHold
        line_Average
        line_MaxHold
        obj_Persistance                                                     % Persistance Object
    
        mkr_ROI                                                             % Selected marker roi
        mkr_Label                                                           % Markers labels
        
        line_OCC                                                            % OCC roi/line
        line_OCCLabel                                                       % OCC roi/line label ("THR")
            
        img_WaterFall
        line_WaterFallTime                                                  % Waterfall timestamp

        %-----------------------------------------------------------------%
        % ## REPORT ##
        %-----------------------------------------------------------------%
        peaksTable    = table('Size', [0, 23],                                                                                                                                                                                                         ...
                              'VariableTypes', {'cell', 'single', 'single', 'uint16', 'double', 'single', 'double', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'int16', 'int32', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell'}, ...
                              'VariableNames', {'Tag', 'Latitude', 'Longitude', 'Index', 'Frequency', 'Truncated', 'BW', 'minLevel', 'meanLevel', 'maxLevel', 'meanOCC', 'maxOCC', 'Type', 'Regulatory', 'Service', 'Station', 'Description', 'Distance', 'Irregular', 'RiskLevel', 'occMethod', 'Detection', 'Classification'});
        exceptionList = table('Size', [0, 10],                                                                                       ...
                              'VariableTypes', {'cell', 'double', 'cell', 'cell', 'int16', 'int32', 'cell', 'cell', 'cell', 'cell'}, ...
                              'VariableNames', {'Tag', 'Frequency', 'Type', 'Regulatory', 'Service', 'Station', 'Description', 'Distance', 'Irregular', 'RiskLevel'});
        
        %-----------------------------------------------------------------%
        % ## AUXILIAR APPS HANDLES ##
        %-----------------------------------------------------------------%
        auxiliarWin1                                                        % winSignalAnalysis
        auxiliarWin2                                                        % winDriveTest
        auxiliarWin3                                                        % winRFDataHub
    end
    
    
    methods (Access = public)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            app.timerObj_startup = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj_startup)
        end


        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj_startup)

                % PATH SEARCH
                appName   = class.Constants.appName;
                MFilePath = fileparts(mfilename('fullpath'));
                
                app.rootFolder    = appUtil.RootFolder(appName, MFilePath);
                app.executionMode = appUtil.ExecutionMode(app.UIFigure);
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app, 0)

                % Leitura de arquivos externos ("GeneralSettings.json", por
                % exemplo). E, posteriormente, atualização de propriedades de alguns 
                % dos componentes da GUI.
                startup_ReadFiles(app)
                startup_LayoutComponents(app)
                
                % Salva na propriedade "UserData" as opções de ícone e o índice 
                % da aba, simplificando os ajustes decorrentes de uma alteração...
                app.menu_Button1.UserData = struct('iconOptions', {{'OpenFile_32White.png', 'OpenFile_32Yellow.png'}}, 'tabGroup', 1);
                app.menu_Button2.UserData = struct('iconOptions', {{'Playback_32White.png', 'Playback_32Yellow.png'}}, 'tabGroup', 2);
                app.menu_Button3.UserData = struct('iconOptions', {{'Report_32White.png',   'Report_32Yellow.png'}},   'tabGroup', 2);
                app.menu_Button4.UserData = struct('iconOptions', {{'Edit_32White.png',     'Edit_32Yellow.png'}},     'tabGroup', 2);
                app.menu_Button5.UserData = struct('iconOptions', {{'Misc_32White.png',     'Misc_32Yellow.png'}},     'tabGroup', 2);
                app.menu_Button6.UserData = struct('iconOptions', {{'Settings_36White.png', 'Settings_36Yellow.png'}}, 'tabGroup', 3);

                % Configura versão do sistema FISCALIZA...
                switch app.General.fiscaliza.systemVersion
                    case 'PROD'
                        app.config_FiscalizaPD.Value = 1;
                    case 'HOM'
                        app.config_FiscalizaHM.Value = 1;
                end
                config_FiscalizaModeLayout(app)
                config_FiscalizaDefaultValues(app)

                % Inicia módulo de operação paralelo...
                % parpoolCheck()

                % Libera screen... 
                % Inicialmente, diminui opacidade da imagem de inicialização, 
                % até exclui-la (no listener JS).
                menu_mainButtonPushed(app, struct('Source', app.menu_Button1, 'PreviousValue', false))
                sendEventToHTMLSource(app.jsBackDoor, 'turningBackgroundColorInvisible', struct('componentName', 'app.home_mainGrid', 'componentDataTag', struct(app.home_mainGrid).Controller.ViewModel.Id));

                app.menu_Button1.Enable    = 1;
                app.RFDataHubModule.Enable = 1;
                app.AppInfo.Enable         = 1;

                if strcmp(app.executionMode, 'webApp')
                    % Força a exclusão da tela de progresso da inicialização de um 
                    % webapp, caso aplicável.
                    sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");

                else
                    app.FigurePosition.Enable = 1;
                    appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
                end
                drawnow
            end
        end


        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            t = tiledlayout(app.play_PlotPanel, 4, 1, "Padding", "tight", "TileSpacing", "tight");

            % app.axes1
            app.axes1 = uiaxes(t);
            app.axes1.Layout.Tile = 1;
            app.axes1.Layout.TileSpan = [2 1];

            set(app.axes1, Color=[0 0 0], FontName='Helvetica', FontSize=9, FontSmoothing='on',     ...
                                          XGrid='on', XMinorGrid='on', YGrid='on', YMinorGrid='on', ...
                                          GridAlpha=.25, GridColor=[.94,.94,.94], MinorGridAlpha=.2, MinorGridColor=[.94,.94,.94], ...
                                          Interactions=[], XTickLabel={}, ColorScale='log')
            ylabel(app.axes1, 'Nível (dB)')

            % app.axes2
            app.axes2 = uiaxes(t);
            app.axes2.Layout.Tile = 3;

            set(app.axes2, Color=[0 0 0], FontName='Helvetica', FontSize=9, FontSmoothing='on',     ...
                                          XGrid='on', XMinorGrid='on', YGrid='on', YMinorGrid='on', ...
                                          GridAlpha=.25, GridColor=[.94,.94,.94], MinorGridAlpha=.2, MinorGridColor=[.94,.94,.94], ...
                                          Interactions=[], XTickLabel={}, Toolbar=[])
            ylabel(app.axes2, 'Ocupação (%)')


            % app.axes3
            app.axes3 = uiaxes(t);
            app.axes3.Layout.Tile = 4;

            set(app.axes3, Color=[.98,.98,.98], FontName='Helvetica', FontSize=9, FontSmoothing='on', ...
                                                Interactions=[], Box='on', Toolbar=[])
            xlabel(app.axes3, 'Frequência (MHz)')
            ylabel(app.axes3, 'Amostras')

            % Others aspects...
            hold(app.axes1, 'on')
            hold(app.axes2, 'on')
            hold(app.axes3, 'on')

            plot_Colormap_axes1(app)
            plot_Colormap_axes3(app)

            startup_AxesInteractions(app)

            addlistener(app.axes1, 'XLim', 'PostSet', @app.plot_xLimitsUpdate);
            addlistener(app.axes1, 'YLim', 'PostSet', @app.plot_yLimitsUpdate);

            linkaxes([app.axes1, app.axes2, app.axes3], 'x')

            plot_axesLayout(app)
        end


        %-----------------------------------------------------------------%
        function startup_AxesInteractions(app)
            % Toolbar
            plotFcn.axesInteraction.CartesianToolbar(app.axes1, {'pan', 'restoreview'});

            % Default interactions
            plotFcn.axesInteraction.CartesianDefaultInteractions([app.axes1, app.axes2, app.axes3], [dataTipInteraction, regionZoomInteraction])

            % Interactions callbacks
            plotFcn.axesInteraction.InteractionsCallbacks({'Pan'}, [app.axes1, app.axes2, app.axes3], [])
            plotFcn.axesInteraction.InteractionsCallbacks({'Restore View'}, app.axes1, app)
        end


        %-----------------------------------------------------------------%
        function startup_ReadFiles(app)
            % app.General
            [app.General, msgWarning] = appUtil.generalSettingsLoad(class.Constants.appName, app.rootFolder);
            if ~isempty(msgWarning)
                appUtil.modalWindow(app.UIFigure, 'error', msgWarning);
            end
        
            if isempty(app.General.Merge.Distance)
                app.General.Merge.Distance = Inf;
            end
        
            if isempty(app.General.Integration.Trace)
                app.General.Integration.Trace = Inf;
            end
        
            app.General.AppVersion = fcn.startup_Versions("Startup", app.rootFolder);
            app.General.Models     = struct2table(jsondecode(fileread(fullfile(app.rootFolder, 'Template', 'html_General.cfg'))));
            app.General.Report     = '';
        end


        %-----------------------------------------------------------------%
        function startup_LayoutComponents(app)
            if strcmp(app.executionMode, 'webApp')
                % Webapps não suporta uigetdir, então o mapeamento das pastas
                % POST/GET deve ser feito em arquivo externo de configuração...
                app.config_Option_Folder.Enable = 0;
            else
                userPaths = appUtil.UserPaths(app.General.fileFolder.userPath);
                set(app.config_Folder_userPath, 'Items', userPaths, 'Value', userPaths{end})
                app.General.fileFolder.userPath = userPaths{end};
            end
        
            % FOLDER: pythonPath
            pythonPath = app.General.fileFolder.pythonPath;
            if isfile(pythonPath)
                pyenv('Version', pythonPath);
                app.config_Folder_pythonPath.Value = pythonPath;
            else
                pyEnv = pyenv;
                app.config_Folder_pythonPath.Value = pyEnv.Executable;
            end

            % Others...
            try
                eval(sprintf('opengl %s', app.General.openGL))
            catch
            end

            startup_AxesCreation(app)
            
            app.channelObj = class.ChannelLib(app.rootFolder);
            channelList = {};
            for ii = 1:height(app.channelObj.Channel)
                channelList{end+1} = sprintf('%d: %.3f - %.3f MHz (%s)', ii, app.channelObj.Channel.Band{ii}(1), ...
                                                                             app.channelObj.Channel.Band{ii}(2),     ...
                                                                             app.channelObj.Channel.Name{ii});
            end
            app.play_Channel_List.Items = channelList';
            play_Channel_RadioGroupSelectionChanged(app)
            
            app.play_FindPeaks_Class.Items = app.channelObj.FindPeaks.Name;
            play_FindPeaks_ClassValueChanged(app)

            play_FindPeaks_RadioGroupSelectionChanged(app)
            play_FindPeaks_AlgorithmValueChanged(app)            
            
            app.play_Persistance_Colormap.Value = app.General.Plot.Persistance.Colormap;
            app.play_Waterfall_Colormap.Value   = app.General.Plot.Waterfall.Colormap;
            app.report_Type.Items               = app.General.Models.Name;

            measCalibration = jsondecode(fileread(fullfile(app.rootFolder, 'Settings', 'measCalibration.json')));
            app.misc_Edit_kFactor.Items = {measCalibration.Name};

            if ismember(num2str(app.General.Integration.Trace), app.play_TraceIntegration.Items)
                app.play_TraceIntegration.Value = num2str(app.General.Integration.Trace);
            else
                app.General.Integration.Trace = Inf;
            end

            app.play_RadioButton_Persistance.Value = 1;
            play_ControlsPanelSelectionChanged(app)

            set(findobj(app.play_PersistanceGrid, '-not', {'Type', 'uilabel', '-or', 'Type', 'uigrid', '-or', 'Type', 'uipanel'}), Enable=0)
          % set(findobj(app.play_OCCGrid,         '-not', {'Type', 'uilabel', '-or', 'Type', 'uigrid', '-or', 'Type', 'uipanel'}), Enable=0)
            set(findobj(app.play_WaterFallGrid,   '-not', {'Type', 'uilabel', '-or', 'Type', 'uigrid', '-or', 'Type', 'uipanel'}), Enable=0)
        end


        %-----------------------------------------------------------------%
        % ## SWITCH MODE OPERATION ##
        %-----------------------------------------------------------------%
        function menu_LayoutControl(app, tabIndex)
            switch tabIndex
                case 1
                    % ...

                case 2
                    if app.menu_Button2.Value
                        app.play_Tree.SelectedNodes     = app.play_PlotPanel.UserData;                            
                        app.play_Tree.Multiselect       = "off";
                        app.play_ControlsGrid.RowHeight = {22,'1x',22,0,22,0,0,0,0,0,0,0,0,0,0,0};
                        set(findobj(app.play_toolGrid, 'Tag', 'REPORT'), Enable=0)

                    elseif app.menu_Button3.Value
                        app.play_Tree.Multiselect       = "on";
                        app.play_ControlsGrid.RowHeight = {0,0,0,0,0,0,22,'1x',22,0,22,0,0,0,0,0};
                        set(findobj(app.play_toolGrid, 'Tag', 'REPORT'), Enable=1)

                    elseif app.menu_Button4.Value
                        app.play_Tree.Multiselect       = "on";
                        app.play_ControlsGrid.RowHeight = {0,0,0,0,0,0,0,0,0,0,0,0,22,'1x',0,0};
                        set(findobj(app.play_toolGrid, 'Tag', 'REPORT'), Enable=0)

                    elseif app.menu_Button5.Value
                        app.play_Tree.Multiselect       = "on";
                        app.play_ControlsGrid.RowHeight = {0,0,0,0,0,0,0,0,0,0,0,0,0,0,22,'1x'};
                        set(findobj(app.play_toolGrid, 'Tag', 'REPORT'), Enable=0)
                    end
                    play_TreeSelectionChanged(app)

                case 3
                    % ...
            end
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource           = ccTools.fcn.jsBackDoorHTMLSource('external');
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_Listener(app, evt);
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Listener(app, event)
            switch event.HTMLEventName
                case 'credentialDialog'
                    report_FiscalizaConnect(app, event.HTMLEventData, 'OpenConnection')

                case 'BackgroundColorTurnedInvisible'
                    eval(sprintf('delete(%s)', event.HTMLEventData))
            end
            drawnow
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            % O menu gráfico controla, programaticamente, qual das abas de
            % app.TabGroup estará visível. 

            % Lembrando que o MATLAB renderiza em tela apenas as abas visíveis.
            % Por isso as customizações de abas e suas subabas somente é possível 
            % após a renderização da aba.

            switch tabIndex
                case 0 % STARTUP
                    app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        'body',                           ...
                                                                                           'classAttributes', ['--tabButton-border-color: #fff;' ...
                                                                                                               '--tabContainer-border-color: #fff;']));

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                           'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                               '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                               '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);'        ...
                                                                                                               '--mw-backgroundColor-tab: #fff;']));

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                           'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

                case 1 % FILE
                    if any(app.jsBackDoorFlag{tabIndex})
                        app.jsBackDoorFlag{tabIndex} = false;
                        % ...
                    end

                case 2 % PLAYBACK+REPORT+MISC
                    if any(app.jsBackDoorFlag{tabIndex})
                        app.jsBackDoorFlag{tabIndex} = false;
                        % ...
                    end

                case 3 % CONFIG
                    if app.jsBackDoorFlag{tabIndex}
                        app.jsBackDoorFlag{tabIndex} = false;
                        % ...
                    end
            end
        end


        %-----------------------------------------------------------------%
        function DeleteAll(app)
            app.projData = struct('image', {''}, 'table', {[]});
            report_ProjectIcon(app)

            app.peaksTable(:,:)    = [];
            app.exceptionList(:,:) = [];

            app.report_ProjectWarn.Visible   = 0;
            app.report_ProjectName.Value     = 'Projeto 001';
            app.report_ProjectFilename.Value = '';
            app.report_Version.Value         = 'Preliminar';
            app.report_Issue.Value           = -1;

            DeleteFigures(app)
            file_DataReaderError(app)
            file_specReadButtonVisibility(app)
        end


        %-----------------------------------------------------------------%
        function DeleteFigures(app)
            for ii = 1:3
                eval(sprintf('delete(app.auxiliarWin%d)', ii))
                eval(sprintf('app.auxiliarWin%d = [];', ii))
            end

            DeleteTempFigures(app, 'all')

            app.play_Tree.Enable = 1;
            set(app.play_Lock, 'Enable', 1, 'ImageSource', 'lockOpen_32.png')
        end


        %-----------------------------------------------------------------%
        function DeleteTempFigures(app, FigureVisibility)
            switch FigureVisibility
                case 'all'; h = findall(groot, 'Type', 'Figure', 'Tag', 'ReportGenerator');
                otherwise;  h = findall(groot, 'Type', 'Figure', 'Tag', 'ReportGenerator', 'Visible', 0);
            end

            if ~isempty(h)
                delete(h)
            end
        end
        

        %-----------------------------------------------------------------%
        % ## Modo "ARQUIVO(S)" ##
        %-----------------------------------------------------------------%
        function file_TreeBuilding(app)

            if ~isempty(app.file_Tree.Children)
                delete(app.file_Tree.Children)
            end

            if ~isempty(app.metaData)
                file_FilterOptions(app)
                file_FilterCheck(app)

                filteredNodes = [];

                for ii = 1:numel(app.metaData)
                    [~, fileName, fileExt] = fileparts(app.metaData(ii).File);
                    
                    fileNode = uitreenode(app.file_Tree, 'Text',        [fileName fileExt],                         ...
                                                         'NodeData',    struct('level', 1, 'idx1', ii, 'idx2', []), ...
                                                         'ContextMenu', app.file_ContextMenu_Tree1);

                    receiverRawList = {app.metaData(ii).Data.Receiver};
                    [receiverList, ~, receiverIndex] = unique(receiverRawList);

                    if isscalar(receiverList) && isscalar(app.metaData(ii).Data)
                        fileNode.NodeData.idx2 = 1;
                    end
                    
                    for jj = 1:numel(receiverList)
                        idx = find(receiverIndex == jj)';

                        receiverNode = uitreenode(fileNode, 'Text',        fcn.treeReceiverName(receiverList{jj}),         ...
                                                            'NodeData',    struct('level', 2, 'idx1', ii, 'idx2', idx),    ...
                                                            'Icon',        fcn.treeNodeIcon('Receiver', receiverList{jj}), ...
                                                            'ContextMenu', app.file_ContextMenu_Tree1);
                        
                        for kk = idx
                            if ismember(app.metaData(ii).Data(kk).MetaData.DataType, class.Constants.specDataTypes)
                                nodeTextNote = '';
                                nodeIcon     = fcn.treeNodeIcon('DataType', 'SpectralData');

                            elseif ismember(app.metaData(ii).Data(kk).MetaData.DataType, class.Constants.occDataTypes)
                                nodeTextNote = ' (Ocupação)';
                                nodeIcon     = fcn.treeNodeIcon('DataType', 'Occupancy');

                            else
                                error('winAppAnalise:UnexpectedDataType', 'Unexpeted data type %d', app.metaData(ii).Data(kk).MetaData.DataType)
                            end

                            dataNode = uitreenode(receiverNode, 'Text',        sprintf('ID %d: %.3f - %.3f MHz%s', app.metaData(ii).Data(kk).RelatedFiles.ID(1),                       ...
                                                                                                                   app.metaData(ii).Data(kk).MetaData.FreqStart .* 1e-6,               ...
                                                                                                                   app.metaData(ii).Data(kk).MetaData.FreqStop .* 1e-6, nodeTextNote), ...
                                                                'NodeData',    struct('level', 3, 'idx1', ii, 'idx2', kk),                                                             ...
                                                                'Icon',        nodeIcon,                                                                                               ...
                                                                'ContextMenu', app.file_ContextMenu_Tree1);

                            if ~app.metaData(ii).Data(kk).Enable
                                dataNode.Icon = '';
                                filteredNodes = [filteredNodes, dataNode];
                            end
                        end
                    end
                end

                if ~isempty(filteredNodes)
                    addStyle(app.file_Tree, uistyle('FontColor', [.65,.65,.65]), 'node', filteredNodes)
                end

                app.file_Tree.SelectedNodes = app.file_Tree.Children(1);
                file_TreeSelectionChanged(app)

            else
                app.file_Metadata.HTMLSource              = ' ';
                app.file_FilteringType1_Frequency.Items   = {};
                app.file_FilteringType2_ID.Items          = {};
                app.file_FilteringType3_Description.Value = '';
                
            end
            file_specReadButtonVisibility(app)
        end


        %-----------------------------------------------------------------%
        function file_FilterOptions(app)
            bandList = table('Size', [0,3], ...
                             'VariableTypes', {'double', 'double', 'string'}, ...
                             'VariableNames', {'FreqStart', 'FreqStop', 'Band'});
            IDList   = [];
            for ii = 1:numel(app.metaData)
                for jj = 1:numel(app.metaData(ii).Data)

                    FreqStart = app.metaData(ii).Data(jj).MetaData.FreqStart;
                    FreqStop  = app.metaData(ii).Data(jj).MetaData.FreqStop;

                    bandList(end+1,:) = {FreqStart, FreqStop, sprintf('%.3f - %.3f MHz', FreqStart/1e+6, FreqStop/1e+6)};
                    IDList(end+1,1)   = app.metaData(ii).Data(jj).RelatedFiles.ID(1);
                end
            end
            bandList = sortrows(bandList, {'FreqStart', 'FreqStop'});

            app.file_FilteringType1_Frequency.Items = unique(bandList.Band, "rows", "stable");
            app.file_FilteringType2_ID.Items        = unique(string(sort(IDList)), "rows", "stable");
        end


        %-----------------------------------------------------------------%
        function file_FilterCheck(app)
            hFilter = allchild(app.file_FilteringTree);

            if isempty(hFilter)
                for ii = 1:numel(app.metaData)
                    for jj = 1:numel(app.metaData(ii).Data)
                        app.metaData(ii).Data(jj).Enable = true;
                    end
                end

            else
                filterTextList = strjoin({hFilter.Text}, '\n');                
                filterParser   = struct2table(regexp(filterTextList, '(?<Type>(FREQUÊNCIA|ID|DESCRIÇÃO))[:] (?<Sentence>.*)', 'names', 'dotexceptnewline'));
                if ~iscell(filterParser.Sentence)
                    filterParser.Sentence = {filterParser.Sentence};
                end

                filterSentence_Frequency   = filterParser.Sentence(filterParser.Type == "FREQUÊNCIA");
                filterSentence_ID          = str2double(filterParser.Sentence(filterParser.Type == "ID"));
                filterSentence_Description = filterParser.Sentence(filterParser.Type == "DESCRIÇÃO");

                for ii = 1:numel(app.metaData)
                    for jj = 1:numel(app.metaData(ii).Data)
                        app.metaData(ii).Data(jj).Enable = false;

                        % FREQUÊNCIA
                        if ~isempty(filterSentence_Frequency)
                            dataSentence = sprintf('%.3f - %.3f MHz', app.metaData(ii).Data(jj).MetaData.FreqStart .* 1e-6, ...
                                                                      app.metaData(ii).Data(jj).MetaData.FreqStop  .* 1e-6);
                            if ismember(dataSentence, filterSentence_Frequency)
                                app.metaData(ii).Data(jj).Enable = true;
                                continue
                            end
                        end

                        % ID
                        if ~isempty(filterSentence_ID)
                            dataSentence = app.metaData(ii).Data(jj).RelatedFiles.ID(1);

                            if ismember(dataSentence, filterSentence_ID)
                                app.metaData(ii).Data(jj).Enable = true;
                                continue
                            end
                        end

                        % DESCRIÇÃO
                        if ~isempty(filterSentence_Description)
                            dataSentence = app.metaData(ii).Data(jj).RelatedFiles.Description{1};

                            if any(cellfun(@(x) contains(dataSentence, x, "IgnoreCase", true), replace(filterSentence_Description, '"', '')))
                                app.metaData(ii).Data(jj).Enable = true;
                                continue
                            end
                        end
                    end
                end
            end
        end


        %-----------------------------------------------------------------%
        function file_DataReaderError(app)
            if ~isempty(app.specData)
                delete(app.specData)
            end
            app.specData = [];

            set(findobj(app.menu_Grid, 'Type', 'uistatebutton'), 'Enable', 0)
            set(app.menu_Button1, 'Value', 1, 'Enable', 1)
            menu_mainButtonPushed(app, struct('Source', app.menu_Button1, 'PreviousValue', false)) 
        end

        %-----------------------------------------------------------------%
        function file_specReadButtonVisibility(app)
            if ~isempty(app.metaData)
                app.file_SpecReadButton.Visible = 1;
            else
                app.file_SpecReadButton.Visible = 0;
            end
        end


        %-----------------------------------------------------------------%
        % ## Modo "PLAYBACK/REPORT" ##
        %-----------------------------------------------------------------%
        function play_TreeBuilding(app)
            % LIMPEZA
            if ~isempty(app.play_Tree.Children)
                delete(app.play_Tree.Children)
            end
            app.play_Metadata.HTMLSource = ' ';

            % CONSTRUÇÃO            
            receiverRawList = {app.specData.Receiver};
            [receiverList, ~, receiverIndex] = unique(receiverRawList);

            for ii = 1:numel(receiverList)
                idx1 = find(receiverIndex == ii)';

                receiverNode = uitreenode(app.play_Tree, 'Text',     fcn.treeReceiverName(receiverList{ii}), ...
                                                         'NodeData', idx1,                                   ...
                                                         'Icon',     fcn.treeNodeIcon('Receiver', receiverList{ii}));
                                
                for jj = idx1
                    specNodeText = sprintf('ID %d: %.3f - %.3f MHz', app.specData(jj).RelatedFiles.ID(1),        ...
                                                                     app.specData(jj).MetaData.FreqStart / 1e+6, ...
                                                                     app.specData(jj).MetaData.FreqStop  / 1e+6);
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.occDataTypes)
                        specNodeText = [specNodeText ' (Ocupação)'];
                    end

                    specNode = uitreenode(receiverNode, 'Text', specNodeText, 'NodeData', jj);

                    % TEMPO DE OBSERVAÇÃO
                    uitreenode(specNode, 'Text', sprintf('%s - %s', datestr(app.specData(jj).Data{1}(1),   'dd/mm/yyyy HH:MM:SS'),  ...
                                                                    datestr(app.specData(jj).Data{1}(end), 'dd/mm/yyyy HH:MM:SS')), ...
                                         'NodeData', jj);
                        
                    % GPS
                    if ~app.specData(jj).GPS.Status
                        uitreenode(specNode, 'Text',     'GPS', ...
                                             'NodeData', jj,    ...
                                             'Icon',     'Warn_18.png');
                    else
                        gpsNode = uitreenode(specNode, 'Text', sprintf('%.6f, %.6f (%s)', app.specData(jj).GPS.Latitude,  ...
                                                                                          app.specData(jj).GPS.Longitude, ...
                                                                                          app.specData(jj).GPS.Location), ...
                                                       'NodeData', jj);
                        if app.specData(jj).GPS.Status == -1
                            gpsNode.Icon = 'modeManual_32.png';
                        end
                    end

                    % OCC
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.specDataTypes)
                        if ~isempty(app.specData(jj).UserData.occMethod.RelatedIndex)
                            occNode = uitreenode(specNode, 'Text',     'Fluxo(s) de ocupação', ...
                                                           'NodeData', jj);
                            
                            idx2 = app.specData(jj).UserData.occMethod.RelatedIndex;
                            for kk = idx2
                                occChildThreadNode = uitreenode(occNode, 'Text', sprintf('Threshold: %d %s (ID %d)', app.specData(kk).MetaData.Threshold,  ...
                                                                                                                     app.specData(kk).MetaData.LevelUnit,  ...
                                                                                                                     app.specData(kk).RelatedFiles.ID(1)), ...
                                                                         'NodeData', jj);

                                if app.specData(jj).UserData.occMethod.SelectedIndex == kk
                                    occChildThreadNode.Icon = 'modeSelected_32.png';
                                end                     
                            end

                            occChildPlaybackNode = uitreenode(occNode, 'Text', 'Ocupação a ser aferida', 'NodeData', jj);
                            if isempty(app.specData(jj).UserData.occMethod.SelectedIndex)
                                occChildPlaybackNode.Icon = 'modeSelected_32.png';
                            end
                        end
                    end
                end
            end
            layoutFcn.treeNodeStyle(app)

            for ii = 1:numel(app.play_Tree.Children)
                expand(app.play_Tree.Children(ii));
            end
        end


        %-----------------------------------------------------------------%
        function play_TreeRebuilding(app, SelectedNodesTextList, hComp)

            % Evidencia células que tiveram os seus valores editados...
            set(hComp, BackgroundColor='#bfbfbf')
            drawnow
        
            % Desenha novamente a árvore, deixando selecionados os mesmos fluxos espectrais
            % que estavam selecionados quando da edição das suas informações de GPS.
            play_TreeBuilding(app)
            drawnow

            hTreeNodes     = findobj(app.play_Tree, '-not', 'Type', 'uitree');
            hTreeNodeText  = arrayfun(@(x) x.Text, hTreeNodes, "UniformOutput", false);
            hTreeNodeIndex = [];
            for ii = 1:numel(SelectedNodesTextList)
                hTreeNodeIndex = [hTreeNodeIndex, find(strcmp(hTreeNodeText, SelectedNodesTextList{ii}), 1)];
            end
            
            if isempty(hTreeNodeIndex)
                app.play_Tree.SelectedNodes = app.play_Tree.Children(1).Children(1);
            else
                app.play_Tree.SelectedNodes = hTreeNodes(hTreeNodeIndex);
            end
            report_TreeBuilding(app)
        
            % Retira evidência às supracitadas células.
            set(hComp, BackgroundColor='white') 
        end


        %-----------------------------------------------------------------%
        function generation = play_findGenerationTreeNode(app, treeNode)
            referenceNode = treeNode;
            generation    = 0;
            while true
                if referenceNode.Parent ~= app.play_Tree
                    referenceNode = referenceNode.Parent;
                    generation = generation+1;
                else
                    break
                end
            end
        end


        %-----------------------------------------------------------------%
        function play_TreeCollapseStyle(app)
            
            collapse(app.play_Tree, 'all')
            for ii = 1:numel(app.play_Tree.Children)
                expand(app.play_Tree.Children(ii))
            end

            generation = play_findGenerationTreeNode(app, app.play_Tree.SelectedNodes(1));
            for ii = 1:numel(app.play_Tree.SelectedNodes)
                switch generation
                    case 0
                        expand(app.play_Tree.SelectedNodes(ii), 'all')
                    case 1
                        expand(app.play_Tree.SelectedNodes(ii), 'all')
                    case 2
                        expand(app.play_Tree.SelectedNodes(ii).Parent, 'all')
                    case 3
                        expand(app.play_Tree.SelectedNodes(ii).Parent.Parent, 'all')
                end
            end

            focus(app.play_Tree)
            
        end


        %-----------------------------------------------------------------%
        function occInfo = play_OCCInfo(app)
            occInfo = struct('Method',                  app.play_OCC_Method.Value,                      ...
                             'IntegrationTime',         str2double(app.play_OCC_IntegrationTime.Value), ...
                             'IntegrationTimeCaptured', app.play_OCC_IntegrationTimeCaptured.Value,     ...
                             'THR',                     app.play_OCC_THR.Value,                         ...
                             'THRCaptured',             str2double(app.play_OCC_THRCaptured.Value),     ...
                             'Offset',                  app.play_OCC_Offset.Value,                      ...
                             'ceilFactor',              app.play_OCC_ceilFactor.Value,                  ...
                             'noiseFcn',                app.play_OCC_noiseFcn.Value,                    ...
                             'noiseTrashSamples',       app.play_OCC_noiseTrashSamples.Value/100,       ...
                             'noiseUsefulSamples',      app.play_OCC_noiseUsefulSamples.Value/100);

            switch occInfo.Method
                case 'Linear fixo (COLETA)'; occInfo = rmfield(occInfo, {'IntegrationTime',         'THR',         'Offset', 'ceilFactor', 'noiseFcn', 'noiseTrashSamples', 'noiseUsefulSamples'});
                case 'Linear fixo';          occInfo = rmfield(occInfo, {'IntegrationTimeCaptured', 'THRCaptured', 'Offset', 'ceilFactor', 'noiseFcn', 'noiseTrashSamples', 'noiseUsefulSamples'});
                case 'Linear adaptativo';    occInfo = rmfield(occInfo, {'IntegrationTimeCaptured', 'THR', 'THRCaptured', 'ceilFactor'});
                case 'Envoltória do ruído';  occInfo = rmfield(occInfo, {'IntegrationTimeCaptured', 'THR', 'THRCaptured'});
            end
        end


        %-----------------------------------------------------------------%
        function occIndex = play_OCCIndex(app, idx, srcFcn)

            switch srcFcn
                case 'PLAYBACK/REPORT'
                    if isempty(app.specData(idx).UserData.reportOCC)
                        occInfo = play_OCCInfo(app);
                    else
                        occInfo = app.specData(idx).UserData.reportOCC;
                    end
                    layoutFcn.occStartup(app, idx)

                case 'PLAYBACK'
                    occInfo = play_OCCInfo(app);

                case 'REPORT'
                    occInfo = app.specData(idx).UserData.reportOCC;
            end
            
            occIndex = find(cellfun(@(x) isequal(x, occInfo), {app.specData(idx).UserData.occCache.Info}));
            
            if isempty(occIndex)
                occIndex = numel(app.specData(idx).UserData.occCache)+1;
                occTHR   = class.OCC.Threshold(app.specData(idx), occInfo);

                switch occInfo.Method
                    case 'Linear fixo (COLETA)'
                        occData = app.specData(app.specData(idx).UserData.occMethod.SelectedIndex).Data;

                    otherwise
                        app.specData(idx).UserData.occMethod.SelectedIndex = [];
                        occData = class.OCC.Analysis(app.specData(idx), occInfo, occTHR);
                end

                app.specData(idx).UserData.occCache(occIndex) = struct('Info',  occInfo, ...
                                                                       'THR',   occTHR,  ...
                                                                       'Data', {occData});
            end

            app.specData(idx).UserData.occMethod.CacheIndex   = occIndex;
        end


        %-----------------------------------------------------------------%
        function selectedTreeNode = play_OCCSelectedTreeNode(app, idx)
            selectedTreeNode = [];
            for ii = 1:numel(app.play_Tree.Children)
                for jj = 1:numel(app.play_Tree.Children(ii).Children)
                    if app.play_Tree.Children(ii).Children(jj).NodeData == idx
                        selectedTreeNode = app.play_Tree.Children(ii).Children(jj);
                        break
                    end
                end
            end
        end


        %-----------------------------------------------------------------%
        function play_OCCSelectedTreeNodeIconUpdate(app, idx1)

            selectedTreeNode = play_OCCSelectedTreeNode(app, idx1);

            switch app.play_OCC_Method.Value
                case 'Linear fixo (COLETA)'
                    idx2 = find(strcmp(app.play_OCC_THRCaptured.Items, app.play_OCC_THRCaptured.Value), 1);
                    if isempty(app.specData(idx1).UserData.occMethod.SelectedIndex) || ...
                            (app.specData(idx1).UserData.occMethod.SelectedIndex ~= app.specData(idx1).UserData.occMethod.RelatedIndex(idx2))
                        app.specData(idx1).UserData.occMethod.SelectedIndex = app.specData(idx1).UserData.occMethod.RelatedIndex(idx2);
                    end                    

                otherwise
                    if ~isempty(app.specData(idx1).UserData.occMethod.RelatedIndex)
                        app.specData(idx1).UserData.occMethod.SelectedIndex = [];
                        idx2 = numel(selectedTreeNode.Children(end).Children);
                    end
            end
   
            if exist('idx2', 'var')
                set(selectedTreeNode.Children(end).Children, Icon='')
                selectedTreeNode.Children(end).Children(idx2).Icon = 'modeSelected_32.png';
            end
        end


        %-----------------------------------------------------------------%
        function play_OCCNewPlot(app)

            [idx1, ~, LevelUnit] = plot_updateLine(app);

            % Layout
            play_OCCSelectedTreeNodeIconUpdate(app, idx1)
            if strcmp(app.play_OCC_Method.Value, 'Linear fixo (COLETA)')
                idx2 = app.specData(idx1).UserData.occMethod.SelectedIndex;

                app.play_OCC_THRCaptured.Value = num2str(app.specData(idx2).MetaData.Threshold);
                app.play_OCC_IntegrationTimeCaptured.Value = mean(app.specData(idx2).RelatedFiles.RevisitTime)/60;
            end
            layoutFcn.occVisibility(app, LevelUnit)
            
            % Ao trocar qualquer um dos parâmetros relacionados aos métodos
            % de ocupação embarcados no appAnalise, afere-se, novamente, a
            % ocupação por bin. Isso, contudo, somente ocorre se estiver
            % habilitado o botão de ocupação, deixando visível o resultado
            % no app.axes2.

            % Ao inserir um fluxo de espectro para compor o relatório, será
            % definido um método de aferição de ocupação, caso ainda não
            % feita nenhuma simulação. Neste caso, a alteração dos parâmetros 
            % também irão refletir no algoritmo que será aplicado para fins
            % de geração do relatório.

            if app.play_Occupancy.Value
                % Aferição da ocupação (armazenada no campo "occCache" da 
                % propriedade "UserData" de app.specData).
                occIndex = play_OCCIndex(app, idx1, 'PLAYBACK');

                % Avalia se o fluxo que está sendo alterado foi incluído no
                % modo relatório e este é exatamente o fluxo selecionado.
                if app.specData(idx1).UserData.reportFlag
                    app.specData(idx1).UserData.reportOCC = app.specData(idx1).UserData.occCache(occIndex).Info;

                    idx2 = [];
                    if ~isempty(app.play_Tree.SelectedNodes)
                        idx2 = unique([app.play_Tree.SelectedNodes.NodeData]);
                    end
    
                    report_Algorithms(app, idx2)
                end
    
                % Plot
                
                plotFcn.OCC(app, idx1, 'Creation', LevelUnit, occIndex)
            end
        end


        %-----------------------------------------------------------------%
        function play_Channel_TreeBuilding(app, idx)
            
            if ~isempty(app.play_Channel_Tree.Children)
                delete(app.play_Channel_Tree.Children)
            end

            channelLibIndex = app.specData(idx).UserData.channelLibIndex;
            if ~isempty(channelLibIndex)
                for ii = channelLibIndex'
                    uitreenode(app.play_Channel_Tree, 'Text', sprintf('%.3f - %.3f MHz (%s)',             ...
                                                                      app.channelObj.Channel.Band{ii}(1), ...
                                                                      app.channelObj.Channel.Band{ii}(2), ...
                                                                      app.channelObj.Channel.Name{ii}),   ...
                                                      'NodeData', ii, 'ContextMenu', app.play_Channel_ContextMenu);
                end
            end

            channelManual = app.specData(idx).UserData.channelManual;
            manualNodes   = [];
            for ii = 1:height(channelManual)
                if channelManual.FirstChannel(ii) == channelManual.LastChannel(ii)
                    treeText = sprintf('%.3f MHz', channelManual.FirstChannel(ii));
                else
                    treeText = sprintf('%.3f - %.3f MHz', channelManual.FirstChannel(ii), channelManual.LastChannel(ii));
                end

                manualNodes = [manualNodes, uitreenode(app.play_Channel_Tree, 'Text', treeText,               ...
                                                                              'NodeData', -1, 'UserData', ii, ...
                                                                              'ContextMenu', app.play_Channel_ContextMenu)];
            end

            if ~isempty(manualNodes)
                addStyle(app.play_Channel_Tree, uistyle('FontColor', '#c94756'), 'node', manualNodes)
            end
        end


        %-----------------------------------------------------------------%
        function play_ChannelListSample(app, FreqList)

            switch numel(FreqList)
                case 0
                    app.play_Channel_Sample.Text = '';
                case {1,2,3,4}
                    app.play_Channel_Sample.Text = strjoin(string(FreqList) + " MHz", ', ');
                otherwise
                    app.play_Channel_Sample.Text = strjoin(string(FreqList(1:2)) + " MHz", ', ') + " ... " + strjoin(string(FreqList(end-1:end)) + " MHz", ', ');
            end
        end


        %-----------------------------------------------------------------%
        function play_ChannelAddValidations(app, idx)

            if app.play_Channel_ReferenceList.Value
                chIndex    = str2double(extractBefore(app.play_Channel_List.Value, ':'));
                BandLimits = app.channelObj.Channel.Band{chIndex};

                Flag = play_ChannelAdd_BandValidation(app, idx, BandLimits);
                if Flag == 1
                    app.specData(idx).UserData.channelLibIndex = unique([app.specData(idx).UserData.channelLibIndex; chIndex]);
                end

            else % 'Faixa de frequência' | 'Canal'
                BandLimits = [app.play_Channel_FirstChannel.Value - app.play_Channel_BW.Value/2000, ...
                              app.play_Channel_nChannel.Value     + app.play_Channel_BW.Value/2000];

                Flag = play_ChannelAdd_BandValidation(app, idx, BandLimits);
                if Flag == 1
                    newChannelID = sprintf('%.3f - %.3f MHz (StepWidth: %.1f kHz; BW: %.1f kHz)', app.play_Channel_FirstChannel.Value, ...
                                                                                                  app.play_Channel_nChannel.Value,     ...
                                                                                                  app.play_Channel_StepWidth.Value,    ...
                                                                                                  app.play_Channel_BW.Value);
                    newChannelFlag = any(strcmp(app.specData(idx).UserData.channelManual.Name, newChannelID));
                    if ~newChannelFlag
                        app.specData(idx).UserData.channelManual(end+1,:) = {newChannelID,                            ...
                                                                             {BandLimits},                            ...
                                                                             app.play_Channel_FirstChannel.Value,     ...
                                                                             app.play_Channel_nChannel.Value,         ...
                                                                             app.play_Channel_StepWidth.Value / 1000, ...
                                                                             app.play_Channel_BW.Value        / 1000, ...
                                                                             {}, 'Inclusão manual', app.play_Channel_Class.Value};
                    else
                        Flag = -4;
                    end
                end
            end
        
            switch Flag
                case  1; play_Channel_TreeBuilding(app,  idx)
                case -1; appUtil.modalWindow(app.UIFigure, 'warning', 'A frequência central do n-ésimo canal deve ser superior à do 1º canal.');
                case -2; appUtil.modalWindow(app.UIFigure, 'warning', 'O passo da varredura não pode ser menor ou igual a 0 Hertz.');
                case -3; appUtil.modalWindow(app.UIFigure, 'warning', 'Fluxo de espectro e canalização não se sobrepõem.');
                case -4; appUtil.modalWindow(app.UIFigure, 'warning', 'Canalização já incluída.');
            end
        end


        %-----------------------------------------------------------------%
        function Flag = play_ChannelAdd_BandValidation(app, idx, BandLimits)

            FreqStart  = app.specData(idx).MetaData.FreqStart/1e+6;
            FreqStop   = app.specData(idx).MetaData.FreqStop /1e+6;
            
            Flag = 1;            
            if app.play_Channel_Multiples.Value
                if ~issorted(BandLimits) || (app.play_Channel_nChannel.Value < app.play_Channel_FirstChannel.Value)                                                                             
                    Flag = -1;
                elseif app.play_Channel_StepWidth.Value <= 0
                    Flag = -2;
                elseif (BandLimits(2) <= FreqStart) || (BandLimits(1) >= FreqStop)
                    Flag = -3;
                end
            else
                if (BandLimits(2) <= FreqStart) || (BandLimits(1) >= FreqStop)
                    Flag = -3;
                end
            end
        end


        %-----------------------------------------------------------------%
        function play_BandLimits_updateEmissions(app, idx, newIndex, updatePlotFlag)

            % Analisa se as emissões incluídas/editadas pertencem às subfaixas 
            % sob análise. 
            %
            % Trigger:
            % (a) Inclusão automática de emissões. 
            %     Callback "play_FindPeaks_addEmission(app, event)"
            % (b) Edição manual da frequência central da emissão selecionada.
            %     Callback "play_FindPeaks_editEmission(app, event)"
            % (c) Ajuste do ROI, diretamente no eixo.
            %     Função auxiliar à "plotFcn.clrWrite" ("mkrLineROI")

            arguments
                app
                idx
                newIndex
                updatePlotFlag = true
            end

            fcn.Detection_BandLimits(app.specData(idx))

            if updatePlotFlag
                selectedEmission = find(app.specData(idx).UserData.Emissions.Index == newIndex(1), 1);
                if isempty(selectedEmission)
                    if ~isempty(app.specData(idx).UserData.Emissions)
                        selectedEmission = 1;
                    else
                        delete(app.mkr_ROI)
                        app.mkr_ROI = [];
                    end
                end
                plotFcn.clrWrite(app, idx, 'PeakValueChanged', selectedEmission)
            end
        end


        %-----------------------------------------------------------------%
        function play_BandLimits_Layout(app, idx)

            if app.play_BandLimits_Status.Value
                app.specData(idx).UserData.bandLimitsStatus = true;
                
                set(app.play_BandLimits_Grid.Children, Enable=1)
                app.play_BandLimits_add.Enable  = 1;
                app.play_BandLimits_Tree.Enable = 1;

            else
                app.specData(idx).UserData.bandLimitsStatus = false;

                set(findobj(app.play_BandLimits_Grid, 'Type', 'uinumericeditfield'), Enable=0)
                app.play_BandLimits_add.Enable  = 0;
                app.play_BandLimits_Tree.Enable = 0;
            end
        end


        %-----------------------------------------------------------------%
        function play_BandLimits_TreeBuilding(app, idx)
            
            if ~isempty(app.play_BandLimits_Tree.Children)
                delete(app.play_BandLimits_Tree.Children)
            end

            bandLimitsTable = app.specData(idx).UserData.bandLimitsTable;
            for ii = 1:height(bandLimitsTable)
                uitreenode(app.play_BandLimits_Tree, 'Text', sprintf('%.3f - %.3f MHz', bandLimitsTable.FreqStart(ii), bandLimitsTable.FreqStop(ii)), ...
                                                     'NodeData', ii, 'ContextMenu', app.play_BandLimits_ContextMenu);
            end
        end


        %-----------------------------------------------------------------%
        function play_EmissionList(app, idx, selectedEmission)

            if ~isempty(app.play_FindPeaks_Tree.Children)
                delete(app.play_FindPeaks_Tree.Children)
            end

            if ~isempty(app.specData(idx).UserData.Emissions)
                for ii = 1:height(app.specData(idx).UserData.Emissions)
                    if app.specData(idx).UserData.Emissions.isTruncated(ii); Icon = 'signalTruncated_32.png';
                    else;                                                    Icon = 'signalUntruncated_32.png';
                    end

                    if isempty(app.specData(idx).UserData.Emissions.UserData{ii}); DriveTest = '';
                    else;                                                          DriveTest = ' (DT)';
                    end

                    uitreenode(app.play_FindPeaks_Tree, 'Text', sprintf('%d: %.3f MHz ⌂ %.3f kHz%s', ii, app.specData(idx).UserData.Emissions{ii,2}, app.specData(idx).UserData.Emissions{ii,3}, DriveTest), ...
                                                        'NodeData', ii, 'Icon', Icon, 'ContextMenu', app.play_FindPeaks_ContextMenu);
                end

                app.play_FindPeaks_Tree.SelectedNodes = app.play_FindPeaks_Tree.Children(selectedEmission);
            end
            play_FindPeaks_TreeSelectionChanged(app)
        end


        %-----------------------------------------------------------------%
        function play_UpdatePeaksTable(app, idx)
            if ismember(idx, find(arrayfun(@(x) x.UserData.reportFlag, app.specData)))
                report.Controller(app, 'playback.AddEditOrDeleteEmission')
            end
        end


        %-----------------------------------------------------------------%
        % ## PLOT ##
        %-----------------------------------------------------------------%
        function plot_axesLayout(app)

            if app.play_Occupancy.Value && app.play_Waterfall.Value
                app.play_LayoutRatio.Items = {'2:1:1', '1:2:1', '1:1:2'};
                
                xlabel(app.axes1, '')
                app.axes1.XTickLabel = {};
                
                xlabel(app.axes2, '')
                app.axes2.XTickLabel = {};

            elseif app.play_Occupancy.Value
                app.play_LayoutRatio.Items = {'3:1:0', '1:1:0', '1:3:0'};
                
                xlabel(app.axes1, '')
                app.axes1.XTickLabel = {};

                xlabel(app.axes2, 'Frequência (MHz)')
                app.axes2.XTickLabelMode = 'auto';

            elseif app.play_Waterfall.Value
                app.play_LayoutRatio.Items = {'3:0:1', '1:0:1', '1:0:3'};
                
                xlabel(app.axes1, '')
                app.axes1.XTickLabel = {};

            else
                app.play_LayoutRatio.Items = {'1:0:0'};
                
                xlabel(app.axes1, 'Frequência (MHz)')
                app.axes1.XTickLabelMode = 'auto';
            end

            plot_axesPosition(app)
            play_LineVisibilityValueChanged(app)
        end


        %-----------------------------------------------------------------%
        function plot_axesPosition(app)
            tiledSpan = str2double(strsplit(app.play_LayoutRatio.Value, ':'));
            tiledSpan = (4/sum(tiledSpan)) * tiledSpan;

            tiledPos = 1;
            for ii = 1:3
                if tiledSpan(ii) == 0
                    eval(sprintf('set(app.axes%d,          Visible=0)', ii))
                    eval(sprintf('set(app.axes%d.Children, Visible=0)', ii))
                    eval(sprintf('set(app.axes%d.Toolbar,  Visible=0)', ii))

                else
                    eval(sprintf('set(app.axes%d,          Visible=1)', ii))
                    eval(sprintf('set(app.axes%d.Children, Visible=1)', ii))
                    eval(sprintf('set(app.axes%d.Toolbar,  Visible=1)', ii))

                    eval(sprintf('app.axes%d.Layout.Tile = tiledPos;', ii))
                    eval(sprintf('app.axes%d.Layout.TileSpan = [tiledSpan(ii) 1];', ii))
                end

                tiledPos = tiledPos+tiledSpan(ii);
            end
        end


        %-----------------------------------------------------------------%
        function plot_Colormap_axes1(app)
            colormap(app.axes1, app.play_Persistance_Colormap.Value);
            app.axes1.Colormap(1,:) = [0,0,0];
        end


        %-----------------------------------------------------------------%
        function plot_Colormap_axes3(app)
            colormap(app.axes3, app.play_Waterfall_Colormap.Value);
            app.axes3.Colormap(1,:) = [0,0,0];
        end


        %-----------------------------------------------------------------%
        function plot_xLimitsUpdate(app, src, evt)

            app.play_Limits_xLim1.Value = round(app.axes1.XLim(1), 3);
            app.play_Limits_xLim2.Value = round(app.axes1.XLim(2), 3);

        end
                
        
        %-----------------------------------------------------------------%
        function plot_yLimitsUpdate(app, src, evt)

            app.play_Limits_yLim1.Value = round(double(app.axes1.YLim(1)), 1);
            app.play_Limits_yLim2.Value = round(double(app.axes1.YLim(2)), 1);

        end


        %-----------------------------------------------------------------%
        function [idx, nSweeps] = plot_updateIndex(app)
            idx = app.play_Tree.SelectedNodes.NodeData;
            nSweeps = numel(app.specData(idx).Data{1});
            
            plotFcn.StartUp(app, idx)
            plotFcn.Draw(app, idx)
          % plot_axesLayout(app)

            app.plotFlag = 1;
        end


        %-----------------------------------------------------------------%
        function [idx, newArray, LevelUnit] = plot_updateLine(app)
            idx = [];
            if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                idx = app.play_PlotPanel.UserData.NodeData;
            end

            newArray  = app.specData(idx).Data{2}(:,app.timeIndex);
            LevelUnit = app.specData(idx).MetaData.LevelUnit;
        end


        %-----------------------------------------------------------------%
        function plot_resetLines(app)
            if ~isempty(app.line_MinHold)
                plot_deleteLine(app, 'MinHold')
                play_ControlButtonPushed(app, struct('Source', app.play_MinHold))
            end

            if ~isempty(app.line_Average)
                plot_deleteLine(app, 'Average')
                play_ControlButtonPushed(app, struct('Source', app.play_Average))
            end

            if ~isempty(app.line_MaxHold)
                plot_deleteLine(app, 'MaxHold')
                play_ControlButtonPushed(app, struct('Source', app.play_MaxHold))
            end
        end


        %-----------------------------------------------------------------%
        function plot_deleteLine(app, Tag)
            switch Tag
                case 'MinHold'
                    delete(app.line_MinHold)
                    app.line_MinHold = [];

                case 'Average'
                    delete(app.line_Average)
                    app.line_Average = [];

                case 'MaxHold'
                    delete(app.line_MaxHold)
                    app.line_MaxHold = [];
            end
        end


        %-----------------------------------------------------------------%
        function plot_MainLoop(app, idx, nSweeps)

            while app.timeIndex <= nSweeps
                % A variável app.plotFlag pode assumir os valores -1 | 0 | 1.
                % - -1: alteração de fluxo espectral
                % -  0: finalização do plot
                % -  1: atualização do plot
                switch app.plotFlag
                    case -1; [idx, nSweeps] = plot_updateIndex(app);
                    case  0; return
                end
                sweepTic = tic;
                
                plotFcn.Draw(app, idx)
                app.play_Timestamp.Text       = sprintf('%d de %d\n%s', app.timeIndex, nSweeps, app.specData(idx).Data{1}(app.timeIndex));
                app.play_PlaybackSlider.Value = 100 * app.timeIndex/nSweeps;
                plot_DriveTestUpdate(app)
                
                pause(max(app.play_MinPlotTime.Value/1000-toc(sweepTic), .001))
                
                % Reload Flag
                if app.timeIndex == nSweeps
                    if app.play_PlaybackLoop.Tag == "loop"
                        app.timeIndex = 1;
                    else
                        break
                    end
                else
                    app.timeIndex = app.timeIndex+1;
                end                
            end
            
            play_PlaybackToolbarButtonCallback(app, struct('Source', app.play_PlaybackPlay))
        end

        %-----------------------------------------------------------------%
        % ## Modo "RELATÓRIO" ##
        %-----------------------------------------------------------------%
        function report_TreeBuilding(app)
            
            if ~isempty(app.report_Tree.Children)
                delete(app.report_Tree.Children);
            end
            
            idx1 = find(arrayfun(@(x) x.UserData.reportFlag, app.specData));

            % Atualizando a árvore principal de fluxos de dados, destacando
            % os fluxos incluídos para análise (no modo RELATÓRIO).
            layoutFcn.treeNodeStyle(app)

            % E, posteriormente, ajusta os elementos do painel do modo
            % RELATÓRIO.
            if isempty(idx1)
                app.report_Button2.Enable = 0;
                app.report_Button3.Enable = 0;
                report_TimeStampFilter(app, 'Off', [])
                
                app.peaksTable(:,:)       = [];
                
            else
                app.report_Button2.Enable = 1;
                app.report_Button3.Enable = 1;
                report_TimeStampFilter(app, 'On', idx1)

                report.Controller(app, 'report.AddOrDeleteThread')
                
                [receiverList, ~, ic] = unique({app.specData(idx1).Receiver});                
                for ii = 1:numel(receiverList)
                    idx2 = find(ic == ii)';
                    Category = uitreenode(app.report_Tree, 'Text', receiverList{ii},                               ...
                                                           'NodeData', idx1(idx2),                                 ...
                                                           'Icon', fcn.treeNodeIcon('Receiver', receiverList{ii}), ...
                                                           'ContextMenu', app.report_ContextMenu);
                    
                    for jj = idx2
                        idx3 = idx1(jj);
                        Node = uitreenode(Category, 'Text', sprintf('ID %d: %.3f - %.3f MHz%s', app.specData(idx3).RelatedFiles.ID(1),         ...
                                                                                                app.specData(idx3).MetaData.FreqStart / 1e+6,  ...
                                                                                                app.specData(idx3).MetaData.FreqStop  / 1e+6), ...
                                                    'NodeData', idx3, 'ContextMenu', app.report_ContextMenu);

                        if ~isempty(app.specData(idx3).UserData.reportAttachments.image) && ~isempty(app.specData(idx3).UserData.reportAttachments.table.Source)
                            Node.Icon = 'attach2_32.png';
                        elseif ~isempty(app.specData(idx3).UserData.reportAttachments.image) || ~isempty(app.specData(idx3).UserData.reportAttachments.table.Source)
                            Node.Icon = 'attach1_32.png';
                        end
                    end
                end
                expand(app.report_Tree, 'all')
            end

            play_TreeSelectionChanged(app)
        end
        

        %-----------------------------------------------------------------%
        function report_TimeStampFilter(app, Flag, idx)
            
            switch Flag
                case 'Off'
                    set(app.report_TimeStampGrid.Children, Enable=0)
                    
                    app.report_DatePicker1.Value = NaT;
                    app.report_DatePicker2.Value = NaT;
                    
                    app.report_Spinner1.Value    = 0;
                    app.report_Spinner2.Value    = 0;
                    app.report_Spinner3.Value    = 23;
                    app.report_Spinner4.Value    = 59;
                    
                case 'On'                    
                    set(app.report_TimeStampGrid.Children, Enable=1)

                    BeginTime = min(arrayfun(@(x) x.Data{1}(1),   app.specData(idx)));
                    EndTime   = max(arrayfun(@(x) x.Data{1}(end), app.specData(idx)));
                    layoutFcn.datetimeComponents(app, BeginTime, EndTime)
            end
        end


        %-----------------------------------------------------------------%
        function report_Algorithms(app, idx)

            if isscalar(idx) && app.specData(idx).UserData.reportFlag
                app.report_occMethod.Value                = app.specData(idx).UserData.reportOCC.Method;
                app.report_occParameters.Value            = jsonencode(rmfield(app.specData(idx).UserData.reportOCC, 'Method'));

                if app.specData(idx).UserData.bandLimitsStatus && height(app.specData(idx).UserData.bandLimitsTable)
                    app.report_DetectionBands.Value       = jsonencode(app.specData(idx).UserData.bandLimitsTable);
                else
                    app.report_DetectionBands.Value       = sprintf('%.3f - %.3f MHz', app.specData(idx).MetaData.FreqStart/1e+6, ...
                                                                                       app.specData(idx).MetaData.FreqStop /1e+6);
                end

                set(app.report_DetectionManualMode, Value=app.specData(idx).UserData.reportDetection.ManualMode, Enable=1)
                report_DetectionManualModeValueChanged(app)

                app.report_ClassificationAlgorithm.Value  = app.specData(idx).UserData.reportClassification.Algorithm;
                app.report_ClassificationParameters.Value = jsonencode(app.specData(idx).UserData.reportClassification.Parameters);

                app.report_occButton.Enable               = 1;
                app.report_DetectionButton.Enable         = 1;
                app.report_ClassificationButton.Enable    = 1;   

            else
                app.report_occMethod.Value                = '';
                app.report_occParameters.Value            = '';
                app.report_DetectionBands.Value           = '';
                app.report_DetectionAlgorithm.Value       = '';
                app.report_DetectionParameters.Value      = '';
                app.report_ClassificationAlgorithm.Value  = '';
                app.report_ClassificationParameters.Value = '';

                app.report_occButton.Enable               = 0;
                app.report_DetectionButton.Enable         = 0;
                app.report_ClassificationButton.Enable    = 0;

                set(app.report_DetectionManualMode, Value=0, Enable=0)                 
            end            
        end


        %-----------------------------------------------------------------%
        function report_LayoutPlaybackOnly(app)

            if app.report_DetectionManualMode.Value
                set(findall(groot, 'Parent', app.report_DetectionGrid, 'Tag', 'Detection'), 'Enable', 0)
            else
                set(findall(groot, 'Parent', app.report_DetectionGrid, 'Tag', 'Detection'), 'Enable', 1)
            end
        end


        %-----------------------------------------------------------------%
        function report_ProjectIcon(app)

            NN = numel(app.projData.image) + numel(app.projData.table);

            switch NN
                case 0;    app.report_AddProjectAttachment.Icon = 'attach0_32.png';
                case 1;    app.report_AddProjectAttachment.Icon = 'attach1_32.png';
                case 2;    app.report_AddProjectAttachment.Icon = 'attach2_32.png';
                otherwise; app.report_AddProjectAttachment.Icon = 'attachN_32.png';
            end
        end


        %-----------------------------------------------------------------%
        % EDIT
        %-----------------------------------------------------------------%
        function SelectedNodesTextList = edit_SelectedNodesText(app)
            SelectedNodesTextList = {};
            for ii = 1:numel(app.play_Tree.SelectedNodes)
                generation = play_findGenerationTreeNode(app, app.play_Tree.SelectedNodes(ii));

                switch generation
                    case 0
                        NN = numel(app.play_Tree.SelectedNodes(ii).Children);
                        SelectedNodesTextList(end+1:end+NN) = {app.play_Tree.SelectedNodes(ii).Text};
                    case 1
                        SelectedNodesTextList{end+1} = app.play_Tree.SelectedNodes(ii).Text;
                    case 2
                        SelectedNodesTextList{end+1} = app.play_Tree.SelectedNodes(ii).Parent.Text;
                    case 3
                        SelectedNodesTextList{end+1} = app.play_Tree.SelectedNodes(ii).Parent.Parent.Text;
                end

            end
            SelectedNodesTextList = unique(SelectedNodesTextList);
        end


        %-----------------------------------------------------------------%
        function idx = edit_gpsFindIndex(app)

            % Identifica os fluxos espectrais selecionados, verificando se 
            % o local de monitoração que gerou a informação é único.
            idx = unique([app.play_Tree.SelectedNodes.NodeData]);

            if ~isempty(idx)
                gpsArray = arrayfun(@(x) x.GPS, app.specData(idx));
                for ii = numel(gpsArray):-1:2
                    if isequal(gpsArray(ii), gpsArray(1))
                        gpsArray(ii) = [];
                    end
                end

                if numel(gpsArray) > 1
                    idx = [];                
                end
            end
        end


        %-----------------------------------------------------------------%
        function edit_gpsLayout(app, clickEffect)
            idx = edit_gpsFindIndex(app);
            SelectedNodesTextList = edit_SelectedNodesText(app);
            
            if ~isempty(idx)    
                % gpsPanel
                switch app.specData(idx(1)).GPS.Status
                    case -1;   app.edit_gpsSource.Value = 'manual';
                    otherwise; app.edit_gpsSource.Value = 'auto';
                end
                app.edit_gpsLatitude.Value    = app.specData(idx(1)).GPS.Latitude;
                app.edit_gpsLongitude.Value   = app.specData(idx(1)).GPS.Longitude;                
    
                % gpsCityPanel
                app.edit_gpsCitySource.Value  = app.specData(idx(1)).GPS.LocationSource;
                app.edit_gpsCity.Value        = app.specData(idx(1)).GPS.Location;

                app.edit_gpsButton.Enable     = 1;
                app.edit_gpsCityButton.Enable = 1;

                switch clickEffect
                    case 'City'
                        hComp = [app.edit_gpsCitySource, ...
                                 app.edit_gpsCity];
                        play_TreeRebuilding(app, SelectedNodesTextList, hComp)

                    case 'City+Latitude+Longitude'
                        hComp = [app.edit_gpsSource,     ...
                                 app.edit_gpsLatitude,   ...
                                 app.edit_gpsLongitude,  ...
                                 app.edit_gpsCitySource, ...
                                 app.edit_gpsCity];
                        play_TreeRebuilding(app, SelectedNodesTextList, hComp)

                    case 'Latitude+Longitude'
                        hComp = [app.edit_gpsSource,     ...
                                 app.edit_gpsLatitude,   ...
                                 app.edit_gpsLongitude];
                        play_TreeRebuilding(app, SelectedNodesTextList, hComp)
                end                
            else
                app.edit_gpsSource.Value      = '';
                app.edit_gpsLatitude.Value    = -1;
                app.edit_gpsLongitude.Value   = -1;
                app.edit_gpsCitySource.Value  = '';
                app.edit_gpsCity.Value        = '';

                app.edit_gpsButton.Enable     = 0;
                app.edit_gpsCityButton.Enable = 0;
            end

            if ismember('manual', {app.edit_gpsSource.Value, app.edit_gpsCitySource.Value})
                app.edit_gpsRefresh.Enable = 1;
            else
                app.edit_gpsRefresh.Enable = 0;
            end

            set(findobj(app.edit_gpsLocationGrid, '-not', {'Type', 'uilabel', '-or', 'Type', 'uibutton', '-or', 'Type', 'uigridlayout'}), Editable=0)
            set(findobj(app.edit_gpsCityGrid,     '-not', {'Type', 'uilabel', '-or', 'Type', 'uibutton', '-or', 'Type', 'uigridlayout'}), Editable=0)
        end


        %-----------------------------------------------------------------%
        function edit_gpsUpdate(app, idx, Latitude, Longitude)
            app.specData(idx).GPS.Status        = -1;
            app.specData(idx).GPS.Count         = 1;
            app.specData(idx).GPS.Latitude      = Latitude;
            app.specData(idx).GPS.Longitude     = Longitude;
            app.specData(idx).GPS.Latitude_std  = 0;
            app.specData(idx).GPS.Longitude_std = 0;
            app.specData(idx).GPS.stdRange      = [100 100 100];
        end


        %-----------------------------------------------------------------%
        function edit_unitLevelLayout(app)
            if ~isempty(app.misc_Edit_kFactorTree.Children)
                delete(app.misc_Edit_kFactorTree.Children)
            end

            idx = unique([app.play_Tree.SelectedNodes.NodeData]);

            if numel(idx) == 1
                measCalibration = app.specData(idx).UserData.measCalibration;
                if ~isempty(measCalibration)
                    app.misc_Edit_unitRaw.Value = measCalibration.oldUnitLevel{1};

                    for ii = 1:height(measCalibration)
                        uitreenode(app.misc_Edit_kFactorTree, 'Text', sprintf('%s → %s: "%s"', measCalibration.oldUnitLevel{ii}, ...
                                                                                               measCalibration.newUnitLevel{ii}, ...
                                                                                               measCalibration.Name{ii}),        ...
                                                              'NodeData', ii, 'ContextMenu', app.edit_ContextMenu);
                    end
                else
                    app.misc_Edit_unitRaw.Value = app.specData(idx).MetaData.LevelUnit;
                end
                app.misc_Edit_kFactorAdd.Enable = 1;
            else
                app.misc_Edit_unitRaw.Value     = '';
                app.misc_Edit_kFactorAdd.Enable = 0;
            end

            if ~isempty(app.misc_Edit_kFactorTree.Children)
                app.edit_unitRefresh.Enable = 1;
            else
                app.edit_unitRefresh.Enable = 0;
            end
        end


        %-----------------------------------------------------------------%
        function edit_unitLevelCorrection(app, OperationType, idx1, idx2, Name)
            try
                fcn.measCalibration(app, OperationType, idx1, idx2, Name)
            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', ME.message);
            end
        end


        %-----------------------------------------------------------------%
        % MISC
        %-----------------------------------------------------------------%
        function selection = misc_checkIfAuxiliarAppIsOpen(app)
            selection = 'Sim';

            if ~isempty(app.auxiliarWin1) || ~isempty(app.auxiliarWin2)
                msg = 'Alguns dos módulos auxiliares serão fechados pois estão relacionados à informação espectral lida anteriormente. Deseja continuar?';
                selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'Sim', 'Não'}, 'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');

                if strcmp(selection, 'Sim')
                    cellfun(@(x) delete(x), {app.auxiliarWin1, app.auxiliarWin2})
                    app.auxiliarWin1 = [];
                    app.auxiliarWin2 = [];
                end
            end
        end


        %-----------------------------------------------------------------%
        function misc_OperationLayout(app, idx)
            if ~isempty(idx)
                app.misc_Index.Value = strjoin(string(idx), ', ');
            else
                app.misc_Index.Value = '';
            end
        end


        %-----------------------------------------------------------------%
        function misc_SaveSpectralData(app, idx)
            defaultFilename = class.Constants.DefaultFileName(app.config_Folder_userPath.Value, 'SpectralData', -1);
            nameFormatMap   = {'*.mat',    'appAnalise (*.mat)'; ...
                               '*.bin',    'Logger (*.bin)';     ...
                               '*.sm1809', 'SM1809 (*.sm1809)'};

            [fileName, filePath, fileIndex] = uiputfile(nameFormatMap, '', defaultFilename);
            figure(app.UIFigure)

            % As mensagens de erro apresentadas a seguir já explicitam as
            % limitações dos formatos "CRFS Bin" e "SM1809". O formato  "MAT",
            % por outro lado, não possui limitação.
            switch fileIndex
                case 0
                    return

                case 2 % CRFS Bin v. 5
                    receiverList = unique({app.specData(idx).Receiver});
                    if (numel(receiverList) > 1) || ~contains(receiverList, 'RFeye', 'IgnoreCase', true)
                        msg = 'O formato de arquivo CRFS Bin não possibilita o armazenamento de dados gerados por mais de um sensor, ou por um sensor que não seja um RFeye.';
                    end

                case 3 % SM1809
                    receiverList = unique({app.specData(idx).Receiver});
                    if (numel(receiverList) > 1)
                        msg = 'O formato de arquivo SM1809 não possibilita o armazenamento de dados gerados por mais de um sensor.';
                    elseif any(ismember(arrayfun(@(x) x.MetaData.DataType, app.specData(idx)), class.Constants.occDataTypes))
                        msg = 'O formato de arquivo SM1809 não possibilita o armazenamento de fluxos de ocupação.';
                    end
            end

            if exist('msg', 'var')
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                return
            end

            fileName = fullfile(filePath, fileName);
            switch fileIndex
                case 1; fileWriter.MAT(fileName, 'SpectralData', app.specData(idx));
                case 2; fileWriter.CRFSBin(fileName, app.specData(idx));
                case 3; fileWriter.SM1809(fileName, app.specData(idx));
            end
        end


        %-----------------------------------------------------------------%
        function misc_ExportUserData(app, idx)
            defaultFilename = class.Constants.DefaultFileName(app.config_Folder_userPath.Value, 'UserData', -1);
            nameFormatMap   = {'*.mat', 'appAnalise (*.mat)'};

            [fileName, filePath, fileIndex] = uiputfile(nameFormatMap, '', defaultFilename);
            figure(app.UIFigure)

            if ~fileIndex
                return
            end
            
            prjInfo  = struct('exceptionList', app.exceptionList);
            fileName = fullfile(filePath, fileName);
            fileWriter.MAT(fileName, 'UserData', app.specData(idx), prjInfo);
        end


        %-----------------------------------------------------------------%
        function misc_ImportUserData(app)
            [fileName, filePath] = uigetfile({'*.mat', 'appAnalise (*.mat)'}, '', app.General.fileFolder.lastVisited);
            figure(app.UIFigure)

            if isequal(fileName, 0)
                return
            end

            fileName = fullfile(filePath, fileName);
            fileReader.MAT_UserData(app, fileName)
        end


        %-----------------------------------------------------------------%
        function config_FiscalizaModeLayout(app)
            selectedButton = app.config_FiscalizaVersion.SelectedObject;
            switch selectedButton
                case app.config_FiscalizaPD
                    app.report_ControlsTab3Label.Text = 'API FISCALIZA';
                    app.General.fiscaliza.systemVersion = 'PROD';

                case app.config_FiscalizaHM
                    app.report_ControlsTab3Label.Text = 'API FISCALIZA HOMOLOGAÇÃO';
                    app.General.fiscaliza.systemVersion = 'HOM';
            end
        end


        %-----------------------------------------------------------------%
        function config_FiscalizaDefaultValues(app)
            set(app.config_CadastroSTEL, 'Items', app.General.fiscaliza.defaultValues.entidade_com_cadastro_stel.options, ...
                                         'Value', app.General.fiscaliza.defaultValues.entidade_com_cadastro_stel.value)

            set(app.config_GerarPLAI,    'Items', app.General.fiscaliza.defaultValues.gerar_plai.options, ...
                                         'Value', app.General.fiscaliza.defaultValues.gerar_plai.value)

            set(app.config_TipoPLAI,     'Items', app.General.fiscaliza.defaultValues.tipo_do_processo_plai.options, ...
                                         'Value', app.General.fiscaliza.defaultValues.tipo_do_processo_plai.value)

            % app.config_MotivoLAI
            config_FiscalizaFillTree(app, 'config_MotivoLAI',       'motivo_de_lai')
            config_FiscalizaFillTree(app, 'config_ServicoInspecao', 'servicos_da_inspecao')
        end


        %-----------------------------------------------------------------%
        function config_FiscalizaFillTree(app, componentName, fieldName)
            fieldOptions = app.General.fiscaliza.defaultValues.(fieldName).options;
            fieldValue   = app.General.fiscaliza.defaultValues.(fieldName).value;
            for ii = 1:numel(fieldOptions)
                childNode = uitreenode(app.(componentName), 'Text', fieldOptions{ii});
                if ismember(fieldOptions{ii}, fieldValue)
                    app.(componentName).CheckedNodes = [app.(componentName).CheckedNodes; childNode];
                end
            end
        end


        %-----------------------------------------------------------------%
        % AUXILIAR FUNCTIONS TO OTHERS APPS OR EXTERNAL FUNCTIONS
        %-----------------------------------------------------------------%
        function auxiliarWinFunction(app, CallingApp, varargin)
            figureFocusFlag = true;

            switch class(CallingApp)
                case 'winAppAnalise'
                    switch varargin{1}
                        case 'plotFcn.StartUp'; play_Channel_TreeSelectionChanged(app)
                        case 'fcn.clickEffect'; play_TreeCollapseStyle(app)
                    end

                case 'auxApp.winAddFiles'
                    switch varargin{1}
                        case 'Projeto'
                            report_ProjectIcon(app)        
                        case 'Fluxo de dados'
                            report_TreeBuilding(app)
                            app.report_Tree.SelectedNodes = app.report_Tree.Children(1).Children(1);
                    end

                case {'auxApp.winClassification', 'auxApp.winDetection'}
                    idx = varargin{1};
                    report_Algorithms(app, idx)
                    report_SaveWarn(app)

                case 'auxApp.winSignalAnalysis'
                    switch varargin{1}
                        case 'closeFcn'
                            report_SaveWarn(app)

                        case 'tool_simulationButtonPushed'
                            if isempty(app.auxiliarWin3)
                                menu_OpenExternalApp_OthersButtons(app, struct('Source', app.RFDataHubModule))
                                pause(2)
                            end
                            externalRequest(app.auxiliarWin3, varargin{2})
                            figureFocusFlag = false;
                    end

                case 'auxApp.winDriveTest'
                    switch varargin{1}
                        case 'emissionTreeUpdate'
                            idx1 = varargin{2}(1);
                            idx2 = varargin{2}(2);
                            
                            if isequal(idx1, app.play_PlotPanel.UserData.NodeData)
                                plotFcn.clrWrite(app, idx1, 'PeakValueChanged', idx2)
                            end

                        case 'lockMainTree'
                            app.play_Tree.SelectedNodes = varargin{2};
                            play_TreeSelectionChanged(app)
                    end

                otherwise
                    % OUTROS APPS AUXILIARES
            end

            if figureFocusFlag
                figure(app.UIFigure)
            end
        end


        %-----------------------------------------------------------------%
        function plot_DriveTestUpdate(app)
            if ~app.play_Lock.Enable
                try
                    app.auxiliarWin2.TimeStampUpdatePosition(app.timeIndex)
                catch
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            try
                % WARNING MESSAGES
                appUtil.disablingWarningMessages()
                
                % GUI STARTUP
                app.home_Title.Text = sprintf('appAnalise<font style="font-size:12px;"> v. %s</font>', class.Constants.appVersion);

                % !! APAGAR ISSO DEPOIS !!
                app.home_mainGrid.Layout.Row = [2 3];
                app.TabGroup.Visible = 0;
                app.TabGroup.Layout.Row = [1, 3];
                app.UIFigure.Position(4) = 660;
                % !! APAGAR ISSO DEPOIS !!

                appUtil.winPosition(app.UIFigure)
                
                config_ButtonGroupSelectionChanged(app)

                jsBackDoor_Initialization(app)
                startup_timerCreation(app)

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            % RUNNING PROJECT CHECK
            if ~isempty(char(app.report_ProjectFilename.Value)) && app.report_ProjectWarn.Visible
                msg = sprintf('O projeto aberto - registrado no arquivo <b>"%s"</b> - foi alterado.\n\nDeseja descartar essas alterações? Caso não, favor salvá-las no modo RELATÓRIO.', char(app.report_ProjectFilename.Value));
                selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'Sim', 'Não'}, 'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');

                if strcmp(selection, 'Não')
                    return
                end
            end

            % DELETE TEMP FILES
            delete(fullfile(app.rootFolder, 'Temp', '~Report*.html'))
            delete(fullfile(app.rootFolder, 'Temp', '~Image*.*'))

            delete(fullfile(app.config_Folder_userPath.Value, '~Report*.html'))
            delete(fullfile(app.config_Folder_userPath.Value, '~Image*.*'))

            % DELETE APPS
            DeleteFigures(app)

            % TIMER
            h = timerfindall;
            if ~isempty(h)
                stop(h); delete(h); clear h
            end

            % PROGRESS DIALOG
            delete(app.progressDialog)

            % MATLAB RUNTIME
            % Ao fechar um webapp, o MATLAB WebServer demora uns 10 segundos para
            % fechar o Runtime que suportava a sessão do webapp. Dessa forma, a 
            % liberação do recurso, que ocorre com a inicialização de uma nova 
            % sessão do Runtime, fica comprometida.
            appUtil.killingMATLABRuntime(app.executionMode)

            delete(app)
            
        end

        % Value changed function: menu_Button1, menu_Button2, 
        % ...and 4 other components
        function menu_mainButtonPushed(app, event)

            clickedButton = event.Source;

            if event.PreviousValue
                clickedButton.Value = true;
                return
            end

            if ~app.TabGroup.Visible
                app.TabGroup.Visible = 1;
            end

            tabIndex      = clickedButton.UserData.tabGroup;
          % tabIndex      = str2double(clickedButton.Tag);

            nonClickedButtons = findobj(app.menu_Grid, 'Type', 'uistatebutton', '-not', 'Tag', clickedButton.Tag);
            arrayfun(@(x) set(x, 'Value', 0, 'Icon', x.UserData.iconOptions{1}), nonClickedButtons)
            set(clickedButton, 'Icon', clickedButton.UserData.iconOptions{2})
            
            app.TabGroup.SelectedTab = app.TabGroup.Children(tabIndex);
            drawnow

            % A customização somente pode tem efeito se os componentes
            % já tiverem sido renderizados no HTML. Por essa razão, inclui-se 
            % um drawnow após a mudança da aba.
            jsBackDoor_Customizations(app, tabIndex)
            menu_LayoutControl(app, tabIndex)
            
        end

        % Image clicked function: RFDataHubModule
        function menu_OpenExternalApp_OthersButtons(app, event)
            
            if isempty(app.auxiliarWin3)
                msg = ['O RFDataHub é uma ETL de dados de estações de telecomunicações composta por '       ...
                       'registros extraídos de bases de dados do MOSAICO, STEL, SRD, ICAO, AISWEB, GEOAIS ' ...
                       'e REDEMET.<br><br>Deseja abrir o módulo de consulta ao RFDataHub?'];
                selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'Sim', 'Não'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if strcmp(selection, 'Não')
                    return
                end

                app.progressDialog.Visible = 'visible';
                app.auxiliarWin3 = auxApp.winRFDataHub(app);
                app.progressDialog.Visible = 'hidden';

            else
                figure(app.auxiliarWin3.UIFigure)
            end

        end

        % Image clicked function: AppInfo, FigurePosition
        function FigurePositionImageClicked(app, event)

            switch event.Source
                case app.FigurePosition
                    app.UIFigure.Position(3:4) = class.Constants.windowSize;
                    appUtil.winPosition(app.UIFigure)

                case app.AppInfo
                    if isempty(app.AppInfo.Tag)
                        app.progressDialog.Visible = 'visible';
                        app.AppInfo.Tag = fcn.htmlCode_appInfo(app.General, app.rootFolder, app.executionMode);
                        app.progressDialog.Visible = 'hidden';
                    end

                    msgInfo = app.AppInfo.Tag;
                    appUtil.modalWindow(app.UIFigure, 'info', msgInfo);
            end

            focus(app.jsBackDoor)

        end

        % Image clicked function: file_OpenFileButton
        function file_ButtonPushed_OpenFile(app, event)

            [fileName, filePath] = uigetfile({'*.bin;*.dbm;*.mat', 'Binários (*.bin,*.dbm,*.mat)'; ...
                                              '*.csv;*.sm1809',    'Textuais (*.csv,*.sm1809)'},   ...
                                              '', app.General.fileFolder.lastVisited, 'MultiSelect', 'on');
            figure(app.UIFigure)
            
            if isequal(fileName, 0)
                return
            elseif ~iscell(fileName)
                fileName = {fileName};
            end

            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', ' ');
            
            RepeteadFiles = {};
            for ii = 1:numel(fileName)
                d.Message = sprintf('<p style="font-size:12px;">Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d</p>', fileName{ii}, ii, numel(fileName));
                
                fileFullPath  = fullfile(filePath, fileName{ii});
                [~,~,fileExt] = fileparts(fileFullPath);

                relatedFiles = RelatedFiles(app.metaData);

                switch lower(fileExt)
                    case {'.bin', '.dbm', '.sm1809', '.csv'}
                        if ~any(contains(relatedFiles, fileName(ii)))
                            idx = numel(app.metaData)+1;
                            
                            app.metaData(idx).File = fileFullPath;
                            app.metaData(idx).Type = 'Spectral data';
                        else
                            RepeteadFiles(end+1) = fileName(ii);
                            continue
                        end
                        
                    case '.mat'
                        lastwarn('')
                        load(fileFullPath, '-mat', 'prj_Type', 'prj_RelatedFiles')
                        [~, warnID] = lastwarn;
                        
                        % Validações...
                        if strcmp(warnID, 'MATLAB:load:variableNotFound')
                            msg = sprintf('O arquivo indicado a seguir não possui a variável "prj_Type", não sendo, portanto, um arquivo gerado pelo appAnalise ou appColeta.\n• %s', fileName{ii});
                            appUtil.modalWindow(app.UIFigure, 'warning', msg);                                                        
                            continue
                            
                        elseif ismember(fileFullPath, {app.metaData.File})
                            msg = sprintf('O arquivo indicado a seguir já tinha sido lido.\n• %s', fileName{ii});
                            appUtil.modalWindow(app.UIFigure, 'warning', msg);                            
                            continue
                            
                        elseif any(contains(relatedFiles, prj_RelatedFiles))
                            msg = sprintf(['O arquivo indicado a seguir não será lido por já ter sido lido ao menos um arquivo relacionado ao ' ...
                                           'projeto appAnalise.\n• %s\n\nArquivo(s) relacionado(s) ao projeto appAnalise já lido(s):\n%s'],   ...
                                           fileName{ii}, strjoin(cellfun(@(x) sprintf('• %s', x), relatedFiles(contains(relatedFiles, prj_RelatedFiles)), 'UniformOutput', false), '\n'));
                            appUtil.modalWindow(app.UIFigure, 'warning', msg);                            
                            continue

                        elseif ~isempty(app.metaData) && strcmp(prj_Type, 'Project data') && ismember('Project data', {app.metaData.Type})
                            msg = sprintf('O arquivo indicado a seguir não será lido porque já foram lidos os metadados de outro projeto appAnalise.\n• %s', fileName{ii});
                            appUtil.modalWindow(app.UIFigure, 'warning', msg);                            
                            continue
                            
                        else
                            idx = numel(app.metaData)+1;
                            
                            app.metaData(idx).File = fileFullPath;
                            app.metaData(idx).Type = char(prj_Type);
                        end
                end
                
                try
                    Read_MetaData(app.metaData(idx))

                catch ME
                    appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));

                    switch ME.identifier
                        case {'metaData:Read:NotImplementedReader', 'metaData:Read:EmptySpectralData'}
                            app.metaData(idx) = [];
                            ErrorFlag = 0;

                        otherwise
                            app.metaData = class.metaData.empty;
                            ErrorFlag = 1;
                    end
                    fclose all;

                    if ErrorFlag
                        break
                    end
                end
            end
            
            if ~isempty(RepeteadFiles)
                msg = 'Os metadados do(s) arquivo(s) indicado(s) a seguir já tinham sido lidos.';
                
                for jj = 1:numel(RepeteadFiles)
                    msg = sprintf('%s\n• %s', msg, RepeteadFiles{jj});
                end
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
            end

            file_TreeBuilding(app)
            focus(app.file_Tree)

            % Atualiza pasta...
            app.General.fileFolder.lastVisited = filePath;
            appUtil.generalSettingsSave(class.Constants.appName, app.rootFolder, app.General, {'AppVersion', 'Models', 'Report'})

        end

        % Button pushed function: file_SpecReadButton
        function file_ButtonPushed_SpecRead(app, event)
            
            % Inicialmente, limpa eventuais informações de um projeto antigo, 
            % no modo REPORT. Essas informações serão preenchidas, caso esteja 
            % sendo lido um arquivo MAT (de projeto), em "fcn.Read_specData".
            app.report_ProjectName.Value     = 'Projeto 001';
            app.report_ProjectFilename.Value = '';
            app.report_Issue.Value           = -1;

            % Verificar se há ao menos um fluxo a ser lido...
            flag = false;
            for ii = 1:numel(app.metaData)
                for jj = 1:numel(app.metaData(ii).Data)
                    if app.metaData(ii).Data(jj).Enable
                        flag = true;
                        break
                    end
                end
            end

            if ~flag
                appUtil.modalWindow(app.UIFigure, 'warning', 'Não há nenhum fluxo de informação a ser lido...');
                return
            end

            % Verifica se algum dos citados módulos auxiliares abaixo estão aberto...
            % - auxiliarWin1: winSignalAnalysis
            % - auxiliarWin2: winDriveTest
            if strcmp(misc_checkIfAuxiliarAppIsOpen(app), 'Não')
                return
            end

            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', ' ');
            
            try
                class.specData.read(app, d)
            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));

                file_DataReaderError(app)
                return
            end

            % Constroi a árvore de fluxos espectrais, deixando selecionado
            % o primeiro dos fluxos. E constroi a árvore de fluxos espectrais
            % que eventualmente foram incluídos em um projeto.
            play_TreeBuilding(app)
            app.play_Tree.SelectedNodes = app.play_Tree.Children(1).Children(1);
            report_TreeBuilding(app)

            % Habilita botões do menu principal - PLAYBACK, REPORT e MISC -
            % abrindo programaticamente o modo PLAYBACK.
            set(findobj(app.menu_Grid, 'Type', 'uistatebutton'), 'Enable', 1)
            app.menu_Button2.Value = 1;
            menu_mainButtonPushed(app, struct('Source', app.menu_Button2, 'PreviousValue', false))

            app.file_SpecReadButton.Visible = 0;

        end

        % Selection changed function: file_Tree
        function file_TreeSelectionChanged(app, event)
            
            if ~isempty(app.metaData)
                % Caso seja selecionado apenas um nó, apresenta-se os
                % metadados relacionados, além de habilitar os botões do 
                % toolbar.
                if isscalar(app.file_Tree.SelectedNodes)
                    collapse(app.file_Tree)
                    idx = app.file_Tree.SelectedNodes.NodeData.idx1;
                    expand(app.file_Tree.Children(idx), 'all')

                    fileSummary = fcn.SelectedNodeSummary(app, 'app.metaData', {app.file_Tree.SelectedNodes.NodeData.idx1, app.file_Tree.SelectedNodes.NodeData.idx2});
                    app.file_Metadata.HTMLSource = textFormatGUI.struct2PrettyPrintList(fileSummary, 'delete');
                else
                    app.file_Metadata.HTMLSource = ' ';
                end

            else
                % Desabilita o painel de metadados...
                app.file_Metadata.HTMLSource   = ' ';
            end
            
        end

        % Selection changed function: file_FilteringTypePanel
        function file_FilteringTypeChanged(app, event)

            switch app.file_FilteringTypePanel.SelectedObject
                case app.file_FilteringType1; app.file_panelGrid.RowHeight(6:8) = {22,0,0};
                case app.file_FilteringType2; app.file_panelGrid.RowHeight(6:8) = {0,22,0};
                case app.file_FilteringType3; app.file_panelGrid.RowHeight(6:8) = {0,0,22};
            end

        end

        % Image clicked function: file_FilteringAdd
        function file_FilteringAddClicked(app, event)
            
            h = allchild(app.file_FilteringTree);

            switch app.file_FilteringTypePanel.SelectedObject
                case app.file_FilteringType1                                % Faixa de frequência
                    if isempty(app.file_FilteringType1_Frequency.Value)
                        return
                    end
                    newFilterText = sprintf('FREQUÊNCIA: %s', app.file_FilteringType1_Frequency.Value);
                    
                case app.file_FilteringType2                                % ID
                    if isempty(app.file_FilteringType2_ID.Value)
                        return
                    end
                    newFilterText = sprintf('ID: %s', app.file_FilteringType2_ID.Value);
                    
                case app.file_FilteringType3                                % Descrição
                    app.file_FilteringType3_Description.Value = upper(strtrim(app.file_FilteringType3_Description.Value));
                    
                    if isempty(app.file_FilteringType3_Description.Value)
                        return
                    else
                        newFilterText = sprintf('DESCRIÇÃO: "%s"', app.file_FilteringType3_Description.Value);
                    end
            end

            if ~isempty(h) && ismember(newFilterText, {h.Text})
                return
            end

            uitreenode(app.file_FilteringTree, 'Text',        newFilterText, ...
                                               'ContextMenu', app.file_ContextMenu_Tree2);
            file_TreeBuilding(app)

        end

        % Menu selected function: file_ContextMenu_delTree1Node
        function file_ContextMenu_delTree1NodeSelected(app, event)
            
            idxTable = table('Size', [0, 3], 'VariableTypes', {'double', 'double', 'cell'}, 'VariableNames', {'level', 'idx1', 'idx2'});
            for ii = 1:numel(app.file_Tree.SelectedNodes)
                idx = find(idxTable.idx1 == app.file_Tree.SelectedNodes(ii).NodeData.idx1, 1);
                if isempty(idx)
                    idxTable(end+1,:)   = {app.file_Tree.SelectedNodes(ii).NodeData.level, app.file_Tree.SelectedNodes(ii).NodeData.idx1, {app.file_Tree.SelectedNodes(ii).NodeData.idx2}};
                else
                    idxTable(idx,[1,3]) = {min([idxTable{idx,1}, app.file_Tree.SelectedNodes(ii).NodeData.level]), {unique([cell2mat(idxTable{idx,3}), app.file_Tree.SelectedNodes(ii).NodeData.idx2])}};
                end
            end

            idxTable = sortrows(idxTable, 'idx1');
            for kk = height(idxTable):-1:1
                idx1 = idxTable.idx1(kk);
                idx2 = idxTable.idx2{kk};

                switch idxTable.level(kk)
                    case 1
                        app.metaData(idx1) = [];

                    otherwise
                        if isequal(idxTable.idx2{kk}, 1:numel(app.metaData(idx1).Data))
                            app.metaData(idx1) = [];
                        else
                            app.metaData(idx1).Data(idx2)    = [];
                            app.metaData(idx1).Samples(idx2) = [];
                            app.metaData(idx1).Memory        = EstimatedMemory(app.metaData, idx1);
                        end
                end
            end
            
            file_TreeBuilding(app)

        end

        % Menu selected function: file_ContextMenu_delTree2Node
        function file_ContextMenu_delTree2NodeSelected(app, event)
            
            if ~isempty(app.file_FilteringTree.SelectedNodes)
                delete(app.file_FilteringTree.SelectedNodes)
                file_TreeBuilding(app)
            end

        end

        % Image clicked function: play_ControlsTab1Image, 
        % ...and 5 other components
        function play_TabGroupVisibility(app, event)

            switch event.Source
                case app.play_ControlsTab1Image;   app.play_ControlsGrid.RowHeight(2:2:16) = {'1x',0,0,0,0,0,0,0};
                case app.play_ControlsTab2Image;   app.play_ControlsGrid.RowHeight(2:2:16) = {0,'1x',0,0,0,0,0,0};
                case app.play_ControlsTab3Image;   app.play_ControlsGrid.RowHeight(2:2:16) = {0,0,'1x',0,0,0,0,0};
                case app.report_ControlsTab1Image; app.play_ControlsGrid.RowHeight(2:2:16) = {0,0,0,'1x',0,0,0,0};
                case app.report_ControlsTab2Image; app.play_ControlsGrid.RowHeight(2:2:16) = {0,0,0,0,'1x',0,0,0};
                case app.report_ControlsTab3Image; app.play_ControlsGrid.RowHeight(2:2:16) = {0,0,0,0,0,'1x',0,0};
            end

        end

        % Image clicked function: misc_LayoutLeft, misc_LayoutRight, 
        % ...and 2 other components
        function play_PanelsVisibility(app, event)
            
            switch event.Source
                case app.play_TreeLayout
                    if app.play_TreeGrid.RowHeight{4}
                        app.play_TreeGrid.RowHeight{4} = 0;
                    else
                        app.play_TreeGrid.RowHeight{4} = '1x';
                    end

                case app.play_Lock
                    if app.play_Tree.Enable
                        app.play_Tree.Enable         = 0;
                        app.play_Lock.ImageSource    = 'lockClose_32.png';
                    else
                        app.play_Tree.Enable         = 1;
                        app.play_Lock.ImageSource    = 'lockOpen_32.png';
                    end

                case app.misc_LayoutLeft
                    if app.play_Grid.ColumnWidth{1}
                        app.play_Grid.ColumnWidth{1} = 0;
                        app.misc_LayoutLeft.ImageSource = 'ArrowRight_32.png';
                    else
                        app.play_Grid.ColumnWidth{1} = 325;
                        app.misc_LayoutLeft.ImageSource = 'ArrowLeft_32.png';
                    end

                case app.misc_LayoutRight
                    if app.play_Grid.ColumnWidth{3}
                        app.play_Grid.ColumnWidth{3} = 0;
                        app.misc_LayoutRight.ImageSource = 'ArrowLeft_32.png';
                    else
                        app.play_Grid.ColumnWidth{3} = 325;
                        app.misc_LayoutRight.ImageSource = 'ArrowRight_32.png';
                    end
            end

            if app.play_Tree.Enable
                focus(app.play_Tree)
            else
                focus(app.play_PlaybackPlay)
            end

        end

        % Selection changed function: play_Tree
        function play_TreeSelectionChanged(app, event)
            
            idx = [];
            if ~isempty(app.play_Tree.SelectedNodes)
                idx = unique([app.play_Tree.SelectedNodes.NodeData]);
            end

            if isscalar(idx) || ~isvalid(app.play_PlotPanel.UserData)
                idx = idx(1);
                
                % Garante que apenas ficará selecionado um único nó da árvore.
                % Posteriormente, ajusta visualização da árvore, expandindo
                % os nós relacionados ao nó selecionado.
                play_TreeCollapseStyle(app)

                % Em relação ao FLUXO ANTERIORMENTE SELECIONADO, atualiza-se
                % o controle do playback, caso habilitado.
                
                if isempty(app.play_PlotPanel.UserData) || ~isvalid(app.play_PlotPanel.UserData) || ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                    play_CustomizationValueChanged(app)
                end

                % Ao plotar dados relacionados a um fluxo de espectro, salva-se
                % o handle do nó selecionado da árvore na propriedade "UserData"
                % do painel app.play_PlotPanel. A propriedade "NodeData" deste 
                % nó armazena o índice do app.specData. Se for alterada a seleção 
                % da árvore, e isso resultar na escolha de um único fluxo de 
                % espectro, atualiza-se o plot.

                if app.plotFlag
                    if ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                        app.plotFlag = -1;
                    end
                else
                    if isempty(app.play_PlotPanel.UserData) || ~isvalid(app.play_PlotPanel.UserData) || ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                        plotFcn.StartUp(app, idx)
                        plotFcn.Draw(app, idx)
                        plot_axesLayout(app)
                    end
                end
            end

            % Aspectos relacionados ao modo RELATÓRIO.
            if app.menu_Button3.Value
                report_Algorithms(app, idx)

            elseif app.menu_Button4.Value
                edit_gpsLayout(app, '')
                edit_unitLevelLayout(app)

            elseif app.menu_Button5.Value
                misc_OperationLayout(app, idx)
            end
            drawnow
            
        end

        % Image clicked function: play_PlaybackLoop, play_PlaybackPlay
        function play_PlaybackToolbarButtonCallback(app, event)
            
            switch event.Source
                case app.play_PlaybackPlay
                    idx = [];
                    if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                        idx = app.play_PlotPanel.UserData.NodeData;
                    end
        
                    if idx && ~app.plotFlag
                        app.plotFlag = 1;
                        app.play_PlaybackPlay.ImageSource = 'stop_32.png';                
                        plot_MainLoop(app, idx, numel(app.specData(idx).Data{1}))
        
                    else
                        app.plotFlag = 0;
                        app.play_PlaybackPlay.ImageSource = 'play_32.png';
                    end

                %---------------------------------------------------------%
                case app.play_PlaybackLoop
                    switch app.play_PlaybackLoop.Tag
                        case 'loop';   set(app.play_PlaybackLoop, Tag='direct', ImageSource='playbackStraight_32Blue.png')
                        case 'direct'; set(app.play_PlaybackLoop, Tag='loop',   ImageSource='playbackLoop_32Blue.png')
                    end
            end

        end

        % Value changing function: play_PlaybackSlider
        function play_PlaybackSliderValueChanging(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            nSweeps = numel(app.specData(idx).Data{1});
            
            app.timeIndex = round(event.Value/100 * nSweeps);
            if app.timeIndex < 1
                app.timeIndex = 1;
            elseif app.timeIndex > nSweeps
                app.timeIndex = nSweeps;
            end

            if ~app.plotFlag
                if ~isempty(app.obj_Persistance)
                    plotFcn.Persistance(app, idx, 'Update')
                end
    
                app.line_ClrWrite.YData = app.specData(idx).Data{2}(:, app.timeIndex)';
    
                for ii = 1:numel(app.mkr_Label)
                    app.mkr_Label(ii).Position(2) = app.line_ClrWrite.YData(app.line_ClrWrite.MarkerIndices(ii));
                end
    
                if app.play_Waterfall.Value && ~isempty(app.line_WaterFallTime) && strcmp(app.play_Waterfall_Timestamp.Value, 'on')
                    app.line_WaterFallTime.YData = [app.specData(idx).Data{1}(app.timeIndex), app.specData(idx).Data{1}(app.timeIndex)];
                end

                app.play_Timestamp.Text = sprintf('%d de %d\n%s', app.timeIndex, nSweeps, app.specData(idx).Data{1}(app.timeIndex));
            end
            
            event = struct(event);
            if ~isfield(event, 'EventSource')
                plot_DriveTestUpdate(app)
            end
            
        end

        % Value changed function: play_Average, play_LayoutRatio, 
        % ...and 5 other components
        function play_ControlButtonPushed(app, event)
            
            [idx, ~, LevelUnit] = plot_updateLine(app);

            if ~isempty(idx)
                switch event.Source
                    case app.play_MinHold
                        if app.play_MinHold.Value
                            plotFcn.minHold(app, idx, app.line_ClrWrite.YData, LevelUnit)
                        else
                            plot_deleteLine(app, 'MinHold')
                        end
    
                    %-----------------------------------------------------%
                    case app.play_Average
                        if app.play_Average.Value
                            plotFcn.Average(app, idx, app.line_ClrWrite.YData, LevelUnit)
                        else
                            plot_deleteLine(app, 'Average')
                        end
    
                    %-----------------------------------------------------%
                    case app.play_MaxHold
                        if app.play_MaxHold.Value
                            plotFcn.maxHold(app, idx, app.line_ClrWrite.YData, LevelUnit)
                        else
                            plot_deleteLine(app, 'MaxHold')
                        end

                    %-----------------------------------------------------%
                    case app.play_Persistance
                        if app.play_Persistance.Value
                            app.play_RadioButton_Persistance.Value = 1;
                            play_ControlsPanelSelectionChanged(app)

                            plotFcn.Persistance(app, idx, 'Creation')
                        else
                            plotFcn.Persistance(app, idx, 'Delete')
                        end
    
                    %-----------------------------------------------------%
                    case app.play_Occupancy
                        if app.play_Occupancy.Value
                            app.play_RadioButton_Occupancy.Value = 1;
                            play_ControlsPanelSelectionChanged(app)

                            occIndex = play_OCCIndex(app, idx, 'PLAYBACK/REPORT');
                            plotFcn.OCC(app, idx, 'Creation', LevelUnit, occIndex)
                        else
                            plotFcn.OCC(app, idx, 'Delete',   LevelUnit, -1)
                        end
                        layoutFcn.occVisibility(app, LevelUnit)
                        plot_axesLayout(app)
    
                    %-----------------------------------------------------%
                    case app.play_Waterfall
                        if app.play_Waterfall.Value
                            app.play_RadioButton_Waterfall.Value = 1;
                            play_ControlsPanelSelectionChanged(app)
                        end
                        plotFcn.WaterFall(app, idx, 'Creation', LevelUnit)
                        plot_axesLayout(app)
    
                    %-----------------------------------------------------%
                    case app.play_LayoutRatio
                        plot_axesPosition(app)
                end
                drawnow nocallbacks
            end

        end

        % Value changed function: play_LineVisibility
        function play_LineVisibilityValueChanged(app, event)
            
            try
                set(app.line_ClrWrite, 'Visible', app.play_LineVisibility.Value)
                set(app.mkr_Label,     'Visible', app.play_LineVisibility.Value)
            catch
            end

        end

        % Value changed function: play_TraceIntegration
        function play_TraceIntegrationValueChanged(app, event)

            app.General.Integration.Trace = str2double(event.Value);

            if isinf(app.General.Integration.Trace) || strcmp(event.PreviousValue, 'Inf')
                plot_resetLines(app)
            end

        end

        % Value changed function: play_Limits_xLim1, play_Limits_xLim2
        function play_xLimitsValueChanged(app, event)
            
            FreqRange = app.play_Limits_xLim2.Value - app.play_Limits_xLim1.Value;
            if FreqRange > 0
                app.axes1.XLim = [app.play_Limits_xLim1.Value, app.play_Limits_xLim2.Value];
            else
                switch event.Source.Tag
                    case 'FreqStart'; app.play_Limits_xLim1.Value = event.PreviousValue;
                    case 'FreqStop';  app.play_Limits_xLim2.Value = event.PreviousValue;
                end
            end

        end

        % Value changed function: play_Limits_yLim1, play_Limits_yLim2
        function play_yLimitsValueChanged(app, event)

            LevelRange = app.play_Limits_yLim2.Value - app.play_Limits_yLim1.Value;
            if LevelRange > 0
                app.axes1.YLim = [app.play_Limits_yLim1.Value, app.play_Limits_yLim2.Value];
            else
                switch event.Source.Tag
                    case 'MinLevel'; app.play_Limits_yLim1.Value = event.PreviousValue;
                    case 'MaxLevel'; app.play_Limits_yLim2.Value = event.PreviousValue;
                end
            end

        end

        % Selection changed function: play_ControlsPanel
        function play_ControlsPanelSelectionChanged(app, event)
            
            switch app.play_ControlsPanel.SelectedObject
                case app.play_RadioButton_Persistance; app.play_ControlsTab1Info.RowHeight(5:7) = {'1x',0,0};
                case app.play_RadioButton_Occupancy;   app.play_ControlsTab1Info.RowHeight(5:7) = {0,'1x',0};
                case app.play_RadioButton_Waterfall;   app.play_ControlsTab1Info.RowHeight(5:7) = {0,0,'1x'};
            end
            
        end

        % Value changed function: play_Customization
        function play_CustomizationValueChanged(app, event)
            
            if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                idx = app.play_PlotPanel.UserData.NodeData;

                % !! TESTE !!
                if numel(idx) > 1
                    idx
                    idx = idx(1);
                end
                % !! TESTE !!

                if app.play_Customization.Value
                    [ParentTag, DataIndex] = plotFcn.DataTipSearch(app, 'all');

                    if isempty(app.play_OCC_THRCaptured.Value)
                        occTHRCaptured = [];
                    else
                        occTHRCaptured = app.play_OCC_THRCaptured.Value;
                    end
    
                    app.specData(idx).UserData.customPlayback =                                                                                           ...
                        struct('Type', 'manual',                                                                                                          ...
                               'Parameters', struct('Controls',    struct('play_MinHold',                     app.play_MinHold.Value,                     ...
                                                                          'play_Average',                     app.play_Average.Value,                     ...
                                                                          'play_MaxHold',                     app.play_MaxHold.Value,                     ...
                                                                          'play_Persistance',                 app.play_Persistance.Value,                 ...
                                                                          'play_Occupancy',                   app.play_Occupancy.Value,                   ...
                                                                          'play_Waterfall',                   app.play_Waterfall.Value,                   ...
                                                                          'play_LayoutRatio',                 app.play_LayoutRatio.Value),                ...
                                                    'Persistance', struct('play_Persistance_Interpolation',   app.play_Persistance_Interpolation.Value,   ...
                                                                          'play_Persistance_Samples',         app.play_Persistance_Samples.Value,         ...
                                                                          'play_Persistance_Transparency',    app.play_Persistance_Transparency.Value),   ...
                                                    'Waterfall',   struct('play_Waterfall_Decimation',        app.play_Waterfall_Decimation.Value),       ...
                                                    'Datatip',     struct('ParentTag',                        ParentTag,                                  ...
                                                                          'DataIndex',                        DataIndex)));
                else
                    app.specData(idx).UserData.customPlayback = struct('Type', 'auto', 'Parameters', []);
                end
            end
            
        end

        % Callback function: play_Persistance_Colormap, 
        % ...and 4 other components
        function play_Persistance_Callbacks(app, event)
            
            if app.play_Persistance.Value
                switch event.Source
                    case app.play_Persistance_Interpolation
                        app.obj_Persistance.handle.Interpolation = app.play_Persistance_Interpolation.Value;
    
                    case app.play_Persistance_Samples
                        delete(app.obj_Persistance.handle)
                        app.obj_Persistance = [];

                        play_ControlButtonPushed(app, struct('Source', app.play_Persistance))

                    case app.play_Persistance_Transparency
                        app.obj_Persistance.handle.CData(isnan(app.obj_Persistance.handle.CData)) = 0; 
                        app.obj_Persistance.handle.AlphaData = double(logical(app.obj_Persistance.handle.CData)) * app.play_Persistance_Transparency.Value;

                    case app.play_Persistance_Colormap
                        plot_Colormap_axes1(app)

                    case app.play_Persistance_cLim_Mode
                        app.axes1.CLimMode = 'auto';
                        app.play_Persistance_cLim1.Value = app.axes1.CLim(1);
                        app.play_Persistance_cLim2.Value = app.axes1.CLim(2);
                end                
                drawnow nocallbacks
            end
            
        end

        % Value changed function: play_Persistance_cLim1, 
        % ...and 1 other component
        function play_Persistance_cLimValueChanged(app, event)

            LevelRange = app.play_Persistance_cLim2.Value - app.play_Persistance_cLim1.Value;
            if LevelRange > 0
                app.axes1.CLim = [app.play_Persistance_cLim1.Value, app.play_Persistance_cLim2.Value];
            else
                switch event.Source
                    case app.play_Persistance_cLim1; app.play_Persistance_cLim1.Value = event.PreviousValue;
                    case app.play_Persistance_cLim2; app.play_Persistance_cLim2.Value = event.PreviousValue;
                end
            end

        end

        % Callback function: play_Waterfall_Colormap, 
        % ...and 4 other components
        function play_Waterfall_Callbacks(app, event)
            
            app.progressDialog.Visible = 'visible';

            switch event.Source
                case app.play_Waterfall_Decimation
                    idx = app.play_PlotPanel.UserData.NodeData;
                    LevelUnit = app.specData(idx).MetaData.LevelUnit;

                    [~, ~, XData, YData] = plotFcn.DataTipSearch(app, 'app.axes3');

                    plotFcn.WaterFall(app, idx, 'Delete',   LevelUnit)
                    plotFcn.WaterFall(app, idx, 'Creation', LevelUnit)

                    for ii = 1:numel(XData)
                        datatip(app.img_WaterFall, XData{ii}, YData{ii});
                    end

                %---------------------------------------------------------%
                case app.play_Waterfall_Timestamp
                    switch app.play_Waterfall_Timestamp.Value
                        case 'on'
                            idx = app.play_PlotPanel.UserData.NodeData;
                            app.line_WaterFallTime = line(app.axes3, [app.Band.xArray(1), app.Band.xArray(end)], [app.specData(idx).Data{1}(app.timeIndex), app.specData(idx).Data{1}(app.timeIndex)], [app.axes1.YLim(2) app.axes1.YLim(2)], ...
                                                                    'Color', 'red', 'LineWidth', 1, 'PickableParts', 'none', 'Tag', 'WaterFall_time');

                        case 'off'
                            delete(app.line_WaterFallTime) 
                            app.line_WaterFallTime = [];
                    end

                %---------------------------------------------------------%
                case app.play_Waterfall_Colormap
                    plot_Colormap_axes3(app)

                %---------------------------------------------------------%
                case app.play_Waterfall_Interpolation
                    app.img_WaterFall.MeshStyle = app.play_Waterfall_Interpolation.Value;

                %---------------------------------------------------------%
                case app.play_Waterfall_cLim_Mode
                    app.axes3.CLim = app.restoreView{4};

                    app.play_Waterfall_cLim1.Value = app.axes3.CLim(1);
                    app.play_Waterfall_cLim2.Value = app.axes3.CLim(2);
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Value changed function: play_Waterfall_cLim1, 
        % ...and 1 other component
        function play_Waterfall_cLimValueChanged(app, event)

            LevelRange = app.play_Waterfall_cLim2.Value - app.play_Waterfall_cLim1.Value;
            if LevelRange > 0
                app.axes3.CLim = [app.play_Waterfall_cLim1.Value, app.play_Waterfall_cLim2.Value];
            else
                switch event.Source
                    case app.play_Waterfall_cLim1; app.play_Waterfall_cLim1.Value = event.PreviousValue;
                    case app.play_Waterfall_cLim2; app.play_Waterfall_cLim2.Value = event.PreviousValue;
                end
            end
            
        end

        % Value changed function: play_OCC_IntegrationTime, 
        % ...and 8 other components
        function play_Occupancy_Callbacks(app, event)
            
            play_OCCNewPlot(app)
            
        end

        % Selection changed function: play_Channel_RadioGroup
        function play_Channel_RadioGroupSelectionChanged(app, event)
            
            switch app.play_Channel_RadioGroup.SelectedObject
                case app.play_Channel_ReferenceList
                    app.play_Channel_List.Enable         = 1;
                    app.play_Channel_ListUpdate.Enable   = 1;
                    set(findobj(app.play_Channel_Grid, 'Type', 'uinumericeditfield'), Enable=0)
                    app.play_Channel_nChannels.Enable    = 1;
                    play_Channel_ListValueChanged(app)

                case app.play_Channel_Multiples
                    app.play_Channel_List.Enable         = 0;
                    app.play_Channel_ListUpdate.Enable   = 0;
                    set(findobj(app.play_Channel_Grid, 'Type', 'uinumericeditfield'), Enable=1)
                    app.play_Channel_Class.Items         = app.channelObj.FindPeaks.Name;

                case app.play_Channel_Single
                    app.play_Channel_List.Enable         = 0;
                    app.play_Channel_ListUpdate.Enable   = 0;
                    
                    app.play_Channel_FirstChannel.Enable = 1;
                    app.play_Channel_BW.Enable           = 1;

                    set(app.play_Channel_nChannel,  Enable=0, Value=app.play_Channel_FirstChannel.Value)
                    set(app.play_Channel_StepWidth, Enable=0, Value=-1)
                    app.play_Channel_nChannels.Value     = 1;
                    app.play_Channel_Class.Items         = app.channelObj.FindPeaks.Name;
                    play_Channel_FirstChannelValueChanged(app)
            end
            
        end

        % Value changed function: play_Channel_List
        function play_Channel_ListValueChanged(app, event)

            idx = str2double(extractBefore(app.play_Channel_List.Value, ':'));

            app.play_Channel_FirstChannel.Value = app.channelObj.Channel.FirstChannel(idx);
            app.play_Channel_nChannel.Value     = app.channelObj.Channel.LastChannel(idx);
            app.play_Channel_StepWidth.Value    = max(-1, app.channelObj.Channel.StepWidth(idx) * 1000);
            app.play_Channel_BW.Value           = max(-1, app.channelObj.Channel.ChannelBW(idx) * 1000);
            app.play_Channel_Class.Items        = app.channelObj.Channel.FindPeaksName(idx);

            if ~isempty(app.channelObj.Channel.FreqList{idx})
                FreqList = app.channelObj.Channel.FreqList{idx};
            else
                FreqList = app.channelObj.Channel.FirstChannel(idx):app.channelObj.Channel.StepWidth(idx):app.channelObj.Channel.LastChannel(idx);
            end

            app.play_Channel_nChannels.Value    = numel(FreqList);
            play_ChannelListSample(app, FreqList)
            
            
        end

        % Image clicked function: play_Channel_ListUpdate
        function play_Channel_ListUpdateImageClicked(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            app.specData(idx).UserData.channelLibIndex = app.channelObj.FindRelatedBands(app.specData(idx));
            play_Channel_TreeBuilding(app, idx)

        end

        % Value changed function: play_Channel_FirstChannel, 
        % ...and 2 other components
        function play_Channel_FirstChannelValueChanged(app, event)
            
            FreqList = [];

            switch app.play_Channel_RadioGroup.SelectedObject
                case app.play_Channel_Multiples
                    if (app.play_Channel_nChannel.Value >= app.play_Channel_FirstChannel.Value) && (app.play_Channel_StepWidth.Value > 0)
                        FreqList = app.play_Channel_FirstChannel.Value:app.play_Channel_StepWidth.Value/1000:app.play_Channel_nChannel.Value;
                        
                        app.play_Channel_nChannels.Value = numel(FreqList);
                    else
                        app.play_Channel_nChannels.Value = -1;
                    end

                case app.play_Channel_Single
                    app.play_Channel_nChannel.Value = app.play_Channel_FirstChannel.Value;
                    FreqList = app.play_Channel_FirstChannel.Value;
            end

            play_ChannelListSample(app, FreqList)
            
        end

        % Selection changed function: play_Channel_Tree
        function play_Channel_TreeSelectionChanged(app, event)
            
            if numel(app.play_Channel_Tree.SelectedNodes) == 1
                chIndex = app.play_Channel_Tree.SelectedNodes.NodeData;
                
                if chIndex > 0
                    app.play_Channel_ReferenceList.Value = 1;
                    play_Channel_RadioGroupSelectionChanged(app)

                    app.play_Channel_List.Value = app.play_Channel_List.Items{chIndex};
                    play_Channel_ListValueChanged(app)

                else
                    idx = app.play_PlotPanel.UserData.NodeData;
                    chManualIndex = app.play_Channel_Tree.SelectedNodes.UserData;

                    if app.specData(idx).UserData.channelManual.StepWidth(chManualIndex) > 0
                        app.play_Channel_Multiples.Value = 1;
                    else
                        app.play_Channel_Single.Value    = 1;
                    end
                    play_Channel_RadioGroupSelectionChanged(app)

                    app.play_Channel_FirstChannel.Value  = app.specData(idx).UserData.channelManual.FirstChannel(chManualIndex);
                    app.play_Channel_nChannel.Value      = app.specData(idx).UserData.channelManual.LastChannel(chManualIndex);
                    app.play_Channel_StepWidth.Value     = max(-1, app.specData(idx).UserData.channelManual.StepWidth(chManualIndex) * 1000);
                    app.play_Channel_BW.Value            = max(-1, app.specData(idx).UserData.channelManual.ChannelBW(chManualIndex) * 1000);
                    app.play_Channel_Class.Items         = app.specData(idx).UserData.channelManual.FindPeaksName(chManualIndex);
        
                    if app.play_Channel_StepWidth.Value > 0
                        FreqList = app.specData(idx).UserData.channelManual.FirstChannel(chManualIndex):app.specData(idx).UserData.channelManual.StepWidth(chManualIndex):app.specData(idx).UserData.channelManual.LastChannel(chManualIndex);
                    else
                        FreqList = app.play_Channel_FirstChannel.Value;
                    end
        
                    app.play_Channel_nChannels.Value     = numel(FreqList);
                    play_ChannelListSample(app, FreqList)
                end

                app.play_Channel_ContextMenu_add.Enable = "on";
            else
                app.play_Channel_ContextMenu_add.Enable = "off";
            end
            
        end

        % Image clicked function: play_Channel_add
        function play_Channel_addChannel(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            play_ChannelAddValidations(app, idx)

        end

        % Menu selected function: play_Channel_ContextMenu_del
        function play_Channel_ContextMenu_delChannel(app, event)
            
            if ~isempty(app.play_Channel_Tree.SelectedNodes)
                idx = app.play_PlotPanel.UserData.NodeData;

                chIndex = [app.play_Channel_Tree.SelectedNodes.NodeData];
                % Inicialmente, apaga as faixas importadas do arquivo
                % "ChannelLib.json"
                if any(chIndex > 0)
                    app.specData(idx).UserData.channelLibIndex = setdiff(app.specData(idx).UserData.channelLibIndex, chIndex(chIndex>0)');
                end

                % E agora as faixas ou canais incluídas manualmente...
                if any(chIndex < 0)
                    chManualIndex = [app.play_Channel_Tree.SelectedNodes(chIndex<0).UserData];
                    app.specData(idx).UserData.channelManual(chManualIndex,:) = [];
                end
                
                play_Channel_TreeBuilding(app,  idx)
            end

        end

        % Menu selected function: play_Channel_ContextMenu_add
        function play_Channel_ContextMenu_addSelected(app, event)
            
            if numel(app.play_Channel_Tree.SelectedNodes) == 1
                idx1 = app.play_PlotPanel.UserData.NodeData;
                idx2 = app.play_Channel_Tree.SelectedNodes.NodeData;

                if idx2 > 0
                    xLim1 = app.channelObj.Channel.Band{idx2}(1);
                    xLim2 = app.channelObj.Channel.Band{idx2}(2);
                else
                    idx3  = app.play_Channel_Tree.SelectedNodes.UserData;
                    xLim1 = app.specData(idx1).UserData.channelManual.Band{idx3}(1);
                    xLim2 = app.specData(idx1).UserData.channelManual.Band{idx3}(2);
                end

                xLim1 = max(xLim1, app.play_BandLimits_xLim1.Limits(1));
                xLim2 = min(xLim2, app.play_BandLimits_xLim1.Limits(2));

                app.play_BandLimits_xLim1.Value = xLim1;
                app.play_BandLimits_xLim2.Value = xLim2;

                if ~app.play_BandLimits_Status.Value
                    app.play_BandLimits_Status.Value = 1;
                    play_BandLimits_StatusValueChanged(app)
                end
                play_BandLimits_addImageClicked(app)
            end

        end

        % Selection changed function: play_FindPeaks_RadioGroup
        function play_FindPeaks_RadioGroupSelectionChanged(app, event)
            
            switch app.play_FindPeaks_RadioGroup.SelectedObject
                case app.play_FindPeaks_auto
                    play_FindPeaks_AlgorithmValueChanged(app)

                case app.play_FindPeaks_File
                    app.play_ControlsTab3Info.RowHeight(3:4) = {0,59};

                otherwise % ROI | DataTips
                    app.play_ControlsTab3Info.RowHeight(3:4) = {0,0};
            end
            
        end

        % Value changed function: play_FindPeaks_Algorithm
        function play_FindPeaks_AlgorithmValueChanged(app, event)
            
            switch app.play_FindPeaks_Algorithm.Value
                case 'FindPeaks'
                    app.play_ControlsTab3Info.RowHeight(3:4) = {174,0};
                    app.play_FindPeaks_ParametersGrid.RowHeight([3 4 7]) = {25,22,0};
                    
                    app.play_FindPeaks_prominenceLabel.Visible = 1;
                    app.play_FindPeaks_prominence.Visible      = 1;

                    app.play_FindPeaks_ClassLabel.Visible = 0;
                    app.play_FindPeaks_Class.Visible      = 0;

                case 'FindPeaks+OCC'
                    app.play_ControlsTab3Info.RowHeight(3:4) = {262,0};
                    app.play_FindPeaks_ParametersGrid.RowHeight([3 4 7]) = {0,0,140};

                    app.play_FindPeaks_prominenceLabel.Visible = 0;
                    app.play_FindPeaks_prominence.Visible      = 0;

                    app.play_FindPeaks_ClassLabel.Visible = 1;
                    app.play_FindPeaks_Class.Visible      = 1;
            end

        end

        % Selection changed function: play_FindPeaks_Tree
        function play_FindPeaks_TreeSelectionChanged(app, event)
            
            if isscalar(app.play_FindPeaks_Tree.SelectedNodes)
                idx1 = app.play_PlotPanel.UserData.NodeData;
                idx2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;

                set(app.play_FindPeaks_PeakCF, 'Value', round(app.specData(idx1).UserData.Emissions.Frequency(idx2), 3), 'Enable', 1)
                set(app.play_FindPeaks_PeakBW, 'Value', round(app.specData(idx1).UserData.Emissions.BW(idx2), 3),        'Enable', 1)

                emissionInfo = jsondecode(app.specData(idx1).UserData.Emissions.Detection{idx2});
                if isfield(emissionInfo, 'Description')
                    set(app.play_FindPeaks_Description, 'Value', emissionInfo.Description, 'Enable', 1)
                else
                    set(app.play_FindPeaks_Description, 'Value', '', 'Enable', 1)
                end

                if strcmp(app.play_FindPeaks_Tree.SelectedNodes.Icon, 'signalTruncated_32.png')
                    app.play_FindPeaks_ContextMenu_digital.Enable = 0;
                    app.play_FindPeaks_ContextMenu_analog.Enable  = 1;
                else
                    app.play_FindPeaks_ContextMenu_digital.Enable = 1;
                    app.play_FindPeaks_ContextMenu_analog.Enable  = 0;
                end
                app.play_FindPeaks_ContextMenu_search.Enable = 1;
                app.play_FindPeaks_ContextMenu_Drive.Enable  = 1;

                if exist('event', 'var')
                    plotFcn.clrWrite(app, idx1, 'TreeSelectionChanged', [])
                end

            else
                set(app.play_FindPeaks_PeakCF,      'Value', -1, 'Enable', 0)
                set(app.play_FindPeaks_PeakBW,      'Value', -1, 'Enable', 0)
                set(app.play_FindPeaks_Description, 'Value', '', 'Enable', 0)

                set(app.play_FindPeaks_ContextMenu_edit.Children, 'Enable', 1)
                app.play_FindPeaks_ContextMenu_search.Enable = 0;
                app.play_FindPeaks_ContextMenu_Drive.Enable  = 0;
            end
            
        end

        % Image clicked function: play_FindPeaks_add
        function play_FindPeaks_addEmission(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            
            % Identificação dos novos valores de frequência, largura de
            % banda e índice.
            switch app.play_FindPeaks_RadioGroup.SelectedObject
                %---------------------------------------------------------%
                case app.play_FindPeaks_auto
                    switch app.play_FindPeaks_Algorithm.Value
                        case 'FindPeaks'
                            Attributes = struct('Algorithm',  app.play_FindPeaks_Algorithm.Value,  ...
                                                'Fcn',        app.play_FindPeaks_Trace.Value,      ...
                                                'NPeaks',     app.play_FindPeaks_Numbers.Value,    ...
                                                'THR',        app.play_FindPeaks_THR.Value,        ...
                                                'Prominence', app.play_FindPeaks_prominence.Value, ...
                                                'Distance',   app.play_FindPeaks_distance.Value,   ...
                                                'BW',         app.play_FindPeaks_BW.Value);

                            [newIndex, newFreq, newBW, Method] = fcn.Detection_FindPeaks(app.specData, idx, Attributes);

                        case 'FindPeaks+OCC'
                            Attributes = struct('Algorithm',   app.play_FindPeaks_Algorithm.Value,   ...
                                                'Distance',    app.play_FindPeaks_distance.Value,    ...
                                                'BW',          app.play_FindPeaks_BW.Value,          ...
                                                'Prominence1', app.play_FindPeaks_Prominence1.Value, ...
                                                'Prominence2', app.play_FindPeaks_Prominence2.Value, ...
                                                'meanOCC',     app.play_FindPeaks_meanOCC.Value,     ...
                                                'maxOCC',      app.play_FindPeaks_maxOCC.Value);
                            
                            [newIndex, newFreq, newBW, Method] = fcn.Detection_FindPeaksPlusOCC(app, app.specData, idx, Attributes);
                    end    
                    newBW  = newBW * 1000;


                %---------------------------------------------------------%
                case app.play_FindPeaks_ROI
                    for ii = 1:numel(app.axes1.Toolbar.Children)
                        if isprop(app.axes1.Toolbar.Children(ii), 'Value') && app.axes1.Toolbar.Children(ii).Value
                            appUtil.modalWindow(app.UIFigure, 'warning', 'Operação não realizada! É preciso desabilitar qualquer interação com o eixo 1 dos plots...');
                            return
                        end
                    end

                    plotFcn.axesInteraction.DisableDefaultInteractions([app.axes1, app.axes2, app.axes3])
                    pause(.1)

                    app.play_FindPeaks_add.Enable = 0;
                    mkrTemp  = drawrectangle(app.axes1, Color=[0.40,0.73,0.88], LineWidth=1, Tag='mkrTemp');
                    app.play_FindPeaks_add.Enable = 1;

                    plotFcn.axesInteraction.EnableDefaultInteractions([app.axes1, app.axes2, app.axes3])
    
                    newFreq  = mkrTemp.Position(1) + mkrTemp.Position(3)/2; % Em MHz
                    newBW    = mkrTemp.Position(3) * 1000;                  % Em kHz
                    newIndex = round((newFreq .* 1e+6 - app.Band.bCoef) ./ app.Band.aCoef);
                    Method   = jsonencode(struct('Algorithm', 'Manual'));

                    if ismember(newIndex, [1, app.specData(idx).MetaData.DataPoints])                    
                       delete(mkrTemp)
                       return
                    end


                %---------------------------------------------------------%
                case app.play_FindPeaks_DataTips
                    hDataTips = findobj('Type', 'DataTip');
                    if isempty(hDataTips)
                        return
                    end
    
                    newFreq = [];
                    ii = 0;
                    while ii < numel(hDataTips)
                        ii = ii+1;
                        if isequal(hDataTips(ii).Parent.Parent.Parent.Parent, app.play_PlotPanel)
                            newFreq(end+1,1) = double(round(hDataTips(ii).X, 3));   % Em MHz
                        end
                    end
                    newBW    = zeros(numel(newFreq), 1);
                    newIndex = round((newFreq .* 1e+6 - app.Band.bCoef) ./ app.Band.aCoef);
                    Method   = repmat({jsonencode(struct('Algorithm', 'Manual'))}, numel(newFreq), 1);


                %---------------------------------------------------------%
                case app.play_FindPeaks_File
                    switch app.play_FindPeaks_ExternalFile.Value
                        case 'Generic (csv, txt, json, xls, xlsx)'
                            fileFormats = {'*.csv;*.txt;*.json;*.xls;*.xlsx', '(*.csv,*.txt,*.json,*.xls,*.xlsx)'};
                        case 'Romes (csv)'
                            fileFormats = {'*.csv', '(*.csv)'};
                    end
                    [fileName, filePath] = uigetfile(fileFormats, '', app.General.fileFolder.lastVisited);
                    figure(app.UIFigure)

                    if isequal(fileName, 0)
                        return
                    end
                    
                    fileFullPath    = fullfile(filePath, fileName);
                    [~, ~, fileExt] = fileparts(fileName);

                    try
                        switch lower(fileExt)
                            case '.json'
                                t = struct2table(jsondecode(fileread(fileFullPath)));
                            otherwise
                                switch app.play_FindPeaks_ExternalFile.Value
                                    case 'Generic (csv, txt, json, xls, xlsx)'
                                        t = readtable(fileFullPath);
                                    case 'Romes (csv)'
                                        t = fileReader.RomesCSV(fileFullPath);
                                end
                        end
                        t.Properties.VariableNames = {'Frequency', 'BW', 'Description'};

                        newIndex   = round((t.Frequency .* 1e+6 - app.Band.bCoef) ./ app.Band.aCoef);
                        rangeIndex = (newIndex < 1) | (newIndex > app.specData(idx).MetaData.DataPoints);
                        if any(rangeIndex)
                            t(rangeIndex,:)      = [];
                            newIndex(rangeIndex) = [];
                        end

                        newFreq  = t.Frequency;                             % Em MHz
                        newBW    = t.BW;                                    % Em kHz
                        
                        Method   = {};
                        for ii = 1:height(t)
                            Method{ii,1} = jsonencode(struct('Algorithm', 'ExternalFile', 'Description', t.Description{ii}));
                        end

                    catch ME
                        appUtil.modalWindow(app.UIFigure, 'warning', ME.message);
                        return                    
                    end
            end
            
            % Inclusão das novas informações na variável app.specData e 
            % ajuste do plot.
            if ~isempty(newIndex)
                app.progressDialog.Visible = 'visible';

                NN = numel(newIndex);
                if ischar(Method)
                    Method = {Method};
                end

                app.specData(idx).UserData.Emissions(end+1:end+NN,1:5) = table(newIndex, newFreq, newBW, true(numel(newIndex),1), Method);
                play_BandLimits_updateEmissions(app, idx, newIndex)
                play_UpdatePeaksTable(app, idx)

                app.progressDialog.Visible = 'hidden';

            else
                if exist('mkrTemp', 'var')
                    delete(mkrTemp)
                end

                appUtil.modalWindow(app.UIFigure, 'warning', 'Não identificado pico que atenda as condições estabelecidas.');
            end
            
        end

        % Menu selected function: play_FindPeaks_ContextMenu_del
        function play_FindPeaks_delEmission(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            
            for ii = numel(app.play_FindPeaks_Tree.SelectedNodes):-1:1
                app.specData(idx).UserData.Emissions(app.play_FindPeaks_Tree.SelectedNodes(ii).NodeData,:) = [];
            end
            
            plotFcn.clrWrite(app, idx, 'DeleteButtonPushed', 1)
            play_UpdatePeaksTable(app, idx)
            
        end

        % Value changed function: play_FindPeaks_Description, 
        % ...and 2 other components
        function play_FindPeaks_editEmission(app, event)
            
            idx1 = app.play_PlotPanel.UserData.NodeData;
            idx2 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;

            emissionInfo = jsondecode(app.specData(idx1).UserData.Emissions.Detection{idx2});

            switch event.Source
                case app.play_FindPeaks_PeakCF
                    if (app.play_FindPeaks_PeakCF.Value*1e+6 < app.specData(idx1).MetaData.FreqStart) || ...
                       (app.play_FindPeaks_PeakCF.Value*1e+6 > app.specData(idx1).MetaData.FreqStop)
                    
                       app.play_FindPeaks_PeakCF.Value = round(app.specData(idx1).UserData.Emissions.Frequency(idx2), 3);
                       return
                    end
                    
                    newIndex = round((app.play_FindPeaks_PeakCF.Value*1e+6 - app.Band.bCoef)/app.Band.aCoef);
                    app.play_FindPeaks_PeakCF.Value = app.Band.xArray(newIndex);

                    emissionInfo.Algorithm = 'Manual';                    
                    app.specData(idx1).UserData.Emissions(idx2,[1:2, 5]) = {newIndex, app.play_FindPeaks_PeakCF.Value, jsonencode(emissionInfo)};
                    play_BandLimits_updateEmissions(app, idx1, newIndex)

                case app.play_FindPeaks_PeakBW
                    emissionInfo.Algorithm = 'Manual';
                    app.specData(idx1).UserData.Emissions(idx2,[3, 5]) = {app.play_FindPeaks_PeakBW.Value, jsonencode(emissionInfo)};
                    plotFcn.clrWrite(app, idx1, 'PeakValueChanged', idx2)

                case app.play_FindPeaks_Description
                    app.play_FindPeaks_Description.Value = strtrim(app.play_FindPeaks_Description.Value);
                    
                    emissionInfo.Description = app.play_FindPeaks_Description.Value;
                    app.specData(idx1).UserData.Emissions.Detection{idx2} = jsonencode(emissionInfo);
            end

            play_UpdatePeaksTable(app, idx1)
            
        end

        % Menu selected function: play_FindPeaks_ContextMenu_analog, 
        % ...and 1 other component
        function play_FindPeaks_TruncatedEmission(app, event)

            idx1 = app.play_PlotPanel.UserData.NodeData;

            for ii = 1:numel(app.play_FindPeaks_Tree.SelectedNodes)
                idx2 = app.play_FindPeaks_Tree.SelectedNodes(ii).NodeData;
    
                switch event.Source.Text
                    case 'Truncar frequência'
                        app.specData(idx1).UserData.Emissions.isTruncated(idx2) = 1;
                        app.play_FindPeaks_Tree.SelectedNodes(ii).Icon          = 'signalTruncated_32.png'; 
    
                    case 'Não truncar'
                        app.specData(idx1).UserData.Emissions.isTruncated(idx2) = 0;
                        app.play_FindPeaks_Tree.SelectedNodes(ii).Icon          = 'signalUntruncated_32.png';
                end
            end
            play_FindPeaks_TreeSelectionChanged(app)
            
        end

        % Menu selected function: play_FindPeaks_ContextMenu_search
        function play_FindPeaks_Classification(app, event)
            
            global RFDataHub

            idx1 = app.play_PlotPanel.UserData.NodeData;
            idx2 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;

            % Inicialmente, trunca-se a frequência da emissão selecionada, 
            % caso aplicável.            
            if app.specData(idx1).UserData.Emissions.isTruncated(idx2)
                Truncated = TruncatedFrequency(app.channelObj, app.specData(idx1), idx2);
            else
                Truncated = app.specData(idx1).UserData.Emissions.Frequency(idx2);
            end            

            % Identifica na base de dados offline (anateldb) aqueles registros 
            % que possuem a mesma frequência (tolerância: 1e-5).
            idx3 = find(abs(RFDataHub.Frequency - Truncated) <= class.Constants.floatDiffTolerance);
            if ~isempty(idx3)
                auxDistance      = fcn.gpsDistance(struct('lat', app.specData(idx1).GPS.Latitude, 'lon', app.specData(idx1).GPS.Longitude), RFDataHub(idx3, 6:7));
                [Distance, idx4] = min(auxDistance);
                Description      = class.RFDataHub.Description(RFDataHub, idx3(idx4));

                if app.specData(idx1).UserData.Emissions.isTruncated(idx2)
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) foi truncada em <b>%.5f MHz</b>.\n\n'                            ...
                                   'Foram identificados <b>%d registros</b> de estações cujas frequências centrais coincidem com %.3f MHz.\n\n' ...
                                   'A estação mais próxima do local de monitoração está a <b>%.1f km</b>, tendo como descrição "<b>%s</b>"'],   ...
                                   app.specData(idx1).UserData.Emissions.Frequency(idx2), Truncated, numel(idx3), Truncated, Distance, Description);
                else
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) não foi truncada.\n\n'                                           ...
                                   'Foram identificados <b>%d registros</b> de estações cujas frequências centrais coincidem com %.3f MHz.\n\n' ...
                                   'A estação mais próxima do local de monitoração está a <b>%.1f km</b>, tendo como descrição "<b>%s</b>"'],   ...
                                   app.specData(idx1).UserData.Emissions.Frequency(idx2), numel(idx3), Truncated, Distance, Description);
                end

            else
                if app.specData(idx1).UserData.Emissions.isTruncated(idx2)
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) foi truncada em <b>%.5f MHz</b>.\n\n'           ...
                                   'Não foi identificado registro de estação cuja frequência central coincide com %.5f MHz.'], ...
                                   app.specData(idx1).UserData.Emissions.Frequency(idx2), Truncated, Truncated);
                else
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) não foi truncada.\n\n'                          ...
                                   'Não foi identificado registro de estação cuja frequência central coincide com %.5f MHz.'], ...
                                   app.specData(idx1).UserData.Emissions.Frequency(idx2), Truncated);
                end
            end

            appUtil.modalWindow(app.UIFigure, 'warning', msg);

        end

        % Menu selected function: play_FindPeaks_ContextMenu_Drive
        function play_FindPeaks_DriveTest(app, event)
            
            if isempty(app.auxiliarWin2)
                idx1 = app.play_PlotPanel.UserData.NodeData;
    
                msg = '';
                if ~ismember(app.specData(idx1).MetaData.DataType, [1, 2])
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas arquivos gerados pelo appColeta.';
                elseif app.specData(idx1).GPS.Count <= 1
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas fluxo de espectro que possua registrado as coordenadas geográficas de ao menos dois locais de monitoração.';
                elseif app.specData(idx1).GPS.Count ~= numel(app.specData(idx1).Data{1})
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas fluxo de espectro cujo número de coordenadas geográficas registrado seja igual ao número de varreduras (relação um para um).';
                end

                if ~isempty(msg)
                    appUtil.modalWindow(app.UIFigure, 'warning', msg);
                    return
                end

                app.progressDialog.Visible = 'visible';

                idx2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;
                app.auxiliarWin2 = auxApp.winDriveTest(app, idx1, idx2);

                app.progressDialog.Visible = 'hidden';
                
            else
                figure(app.auxiliarWin2.UIFigure)
            end

        end

        % Value changed function: play_FindPeaks_maxOCC, 
        % ...and 1 other component
        function play_FindPeaks_OCCValueChanged(app, event)

            switch event.Source
                case app.play_FindPeaks_meanOCC
                    if app.play_FindPeaks_meanOCC.Value > app.play_FindPeaks_maxOCC.Value
                        app.play_FindPeaks_maxOCC.Value = app.play_FindPeaks_meanOCC.Value;
                    end

                case app.play_FindPeaks_maxOCC
                    if app.play_FindPeaks_maxOCC.Value < app.play_FindPeaks_meanOCC.Value
                        app.play_FindPeaks_meanOCC.Value = app.play_FindPeaks_maxOCC.Value;
                    end
            end

        end

        % Value changed function: play_FindPeaks_Class
        function play_FindPeaks_ClassValueChanged(app, event)
            
            idx = find(strcmp(app.channelObj.FindPeaks.Name, app.play_FindPeaks_Class.Value), 1);

            app.play_FindPeaks_distance.Value    = 1000 * app.channelObj.FindPeaks.Distance(idx);
            app.play_FindPeaks_BW.Value          = 1000 * app.channelObj.FindPeaks.BW(idx);
            app.play_FindPeaks_Prominence1.Value = app.channelObj.FindPeaks.Prominence1(idx);
            app.play_FindPeaks_Prominence2.Value = app.channelObj.FindPeaks.Prominence2(idx);
            app.play_FindPeaks_meanOCC.Value     = app.channelObj.FindPeaks.meanOCC(idx);
            app.play_FindPeaks_maxOCC.Value      = app.channelObj.FindPeaks.maxOCC(idx);
                        
        end

        % Value changed function: play_BandLimits_Status
        function play_BandLimits_StatusValueChanged(app, event)
            
            idx1 = app.play_PlotPanel.UserData.NodeData;
            play_BandLimits_Layout(app, idx1)

            if app.play_BandLimits_Status.Value                       && ...
                ~isempty(app.specData(idx1).UserData.bandLimitsTable) && ...
                ~isempty(app.specData(idx1).UserData.Emissions)

                msg = 'Confirma a reanálise das emissões, eventualmente eliminando aquelas que não estão em uma das subfaixas sob análise?';
                selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if strcmp(selection, 'Cancelar')
                    app.play_BandLimits_Status.Value = 0;
                    play_BandLimits_Layout(app, idx1)
                    return
                end

                % Identificar o índice da emissão selecionada, para o qual
                % foi desenhado um ROI no app.axes1.
                idx2 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;
                newIndex = app.specData(idx1).UserData.Emissions.Index(idx2);

                play_BandLimits_updateEmissions(app, idx1, newIndex)
                play_UpdatePeaksTable(app, idx1)
            end

            plotFcn.BandLimits(app, idx1)
            
        end

        % Image clicked function: play_BandLimits_add
        function play_BandLimits_addImageClicked(app, event)
            
            idx1 = app.play_PlotPanel.UserData.NodeData;
            bandLimitsTable = app.specData(idx1).UserData.bandLimitsTable;

            % Validações:
            if app.play_BandLimits_xLim2.Value <= app.play_BandLimits_xLim1.Value                    
                appUtil.modalWindow(app.UIFigure, 'warning', 'Subfaixa inválida.');
                return

            elseif any((bandLimitsTable.FreqStart <= app.play_BandLimits_xLim1.Value) & (bandLimitsTable.FreqStop >= app.play_BandLimits_xLim2.Value))
                appUtil.modalWindow(app.UIFigure, 'warning', 'Subfaixa já contemplada.');
                return
            end

            % Compara subfaixa a analisar inserida com os registros já existentes.
            xLim1 = app.play_BandLimits_xLim1.Value;
            xLim2 = app.play_BandLimits_xLim2.Value;

            % Passo 1 de 2
            Flag = true;
            for ii = 1:height(bandLimitsTable)
                if (xLim1 <= bandLimitsTable.FreqStart(ii)) && (xLim2 >= bandLimitsTable.FreqStop(ii))
                    bandLimitsTable(ii,:) = {xLim1, xLim2};
                    Flag = false;
                    continue
                
                elseif (xLim1 <= bandLimitsTable.FreqStart(ii)) && (xLim2 > bandLimitsTable.FreqStart(ii)) && (xLim2 < bandLimitsTable.FreqStop(ii))
                    bandLimitsTable(ii,1) = {xLim1};
                    Flag = false;
                    continue

                elseif (xLim1 > bandLimitsTable.FreqStart(ii)) && (xLim1 < bandLimitsTable.FreqStop(ii)) && (xLim2 >= bandLimitsTable.FreqStop(ii))
                    bandLimitsTable(ii,2) = {xLim2};
                    Flag = false;
                    continue
                end
            end

            if Flag
                idx2 = height(bandLimitsTable)+1;
                bandLimitsTable(idx2,:) = {xLim1, xLim2};
            end
            bandLimitsTable = sortrows(bandLimitsTable, 'FreqStart');

            % Passo 2 de 2
            for ii = height(bandLimitsTable):-1:2
                if bandLimitsTable.FreqStart(ii) <= bandLimitsTable.FreqStop(ii-1)
                    bandLimitsTable(ii-1,2) = {bandLimitsTable.FreqStop(ii)};
                    bandLimitsTable(ii,:)   = [];
                end
            end

            if isempty(app.specData(idx1).UserData.bandLimitsTable) && ...
              ~isempty(app.specData(idx1).UserData.Emissions)

                msg = 'Confirma a reanálise das emissões, eventualmente eliminando aquelas que não estão em uma das subfaixas sob análise?';
                selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if strcmp(selection, 'Cancelar')
                    return
                end

                % Identificar o índice da emissão selecionada, para o qual
                % foi desenhado um ROI no app.axes1.
                idx2 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;
                newIndex = app.specData(idx1).UserData.Emissions.Index(idx2);

                app.specData(idx1).UserData.bandLimitsTable = bandLimitsTable;
                play_BandLimits_updateEmissions(app, idx1, newIndex)
                play_UpdatePeaksTable(app, idx1)

            else
                app.specData(idx1).UserData.bandLimitsTable = bandLimitsTable;
            end

            play_BandLimits_TreeBuilding(app, idx1)
            plotFcn.BandLimits(app, idx1)

        end

        % Menu selected function: play_BandLimits_ContextMenu_del
        function play_BandLimits_ContextMenu_delSelected(app, event)
            
            idx1 = app.play_PlotPanel.UserData.NodeData;
            idx2 = [app.play_BandLimits_Tree.SelectedNodes.NodeData];
            
            if height(app.specData(idx1).UserData.bandLimitsTable) > 1
                msg = 'Confirma a reanálise das emissões, eventualmente eliminando aquelas que não estão em uma das subfaixas sob análise?';
                selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if strcmp(selection, 'Cancelar')
                    return
                end
            end

            app.specData(idx1).UserData.bandLimitsTable(idx2,:) = [];
            if exist('selection', 'var')
                play_BandLimits_updateEmissions(app, idx1, app.specData(idx1).UserData.Emissions.Index)
                play_UpdatePeaksTable(app, idx1)
            end

            play_BandLimits_TreeBuilding(app, idx1)
            plotFcn.BandLimits(app, idx1)

        end

        % Button pushed function: play_DeleteAll
        function play_DeleteAllButtonPushed(app, event)
            
            msg = 'Confirma a exclusão dos fluxos espectrais e as informações auxiliares a cada um dos fluxos, dentre elas a lista de exceções? Ou a exclusão apenas das figuras auxiliares?';
            selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'Excluir tudo', 'Apenas figuras', 'Cancelar'}, 'DefaultOption', 3, 'CancelOption', 3, 'Interpreter', 'html');
            
            switch selection
                case 'Excluir tudo'
                    DeleteAll(app)
                case 'Apenas figuras'
                    DeleteFigures(app)
            end

        end

        % Button pushed function: report_NewProject
        function report_NewProjectButtonPushed(app, event)
            
                msg = sprintf('Confirma o fechamento do projeto "%s"?', app.report_ProjectName.Value);
                selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if strcmp(selection, 'Cancelar')
                    return
                end

                idx = find(arrayfun(@(x) x.UserData.reportFlag, app.specData));
                for ii = idx
                    app.specData(ii).UserData.reportFlag = false;
                end
                
                app.report_ProjectName.Value     = 'Projeto 001';
                app.report_ProjectFilename.Value = '';
                app.report_Version.Value         = 'Preliminar';
                app.report_Issue.Value           = -1;
                report_TreeBuilding(app)
                
                app.peaksTable(:,:)    = [];
                app.exceptionList(:,:) = [];
                
            if ~isempty(app.auxiliarWin1)
                delete(app.auxiliarWin1)
                app.auxiliarWin1 = [];
            end

        end

        % Button pushed function: report_SaveProject
        function report_SaveProjectButtonPushed(app, event)
            
            if isempty(app.report_Tree.Children)
                msg = 'A criação/edição de um projeto somente é possível quando há ao menos um fluxo espectral a processar.';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                return
            end
            
            if isempty(app.report_ProjectFilename.Value{1})
                defaultFilename = class.Constants.DefaultFileName(app.config_Folder_userPath.Value, 'ProjectData', app.report_Issue.Value);
            else
                defaultFilename = app.report_ProjectFilename.Value{1};
            end
            
            [fileName, filePath, fileIndex] = uiputfile({'*.mat', 'appAnalise (*.mat)'}, '', defaultFilename);
            figure(app.UIFigure)
            
            if fileIndex
                app.progressDialog.Visible = 'visible';

                reportTemplateIndex = find(strcmp(app.report_Type.Items, app.report_Type.Value), 1);
                [idx, reportInfo] = report.GeneralInfo(app, 'Report', reportTemplateIndex);
                prjInfo = struct('Name',          app.report_ProjectName.Value,    ...
                                 'reportInfo',    rmfield(reportInfo, 'Filename'), ...
                                 'peaksTable',    app.peaksTable,                  ...
                                 'exceptionList', app.exceptionList);
                
                fileName = fullfile(filePath, fileName);
                fileWriter.MAT(fileName, 'ProjectData', app.specData(idx), prjInfo)
                
                app.report_ProjectFilename.Value = fileName;
                app.report_ProjectWarn.Visible   = 0;

                app.progressDialog.Visible = 'hidden';
            end

        end

        % Callback function: report_AddProjectAttachment, 
        % ...and 1 other component
        function report_ExternalFilesMenuSelected(app, event)
            
            if isempty(app.report_Tree.Children)
                msg = 'O relacionamento de arquivos externos ao projeto somente é possível se existir ao menos um fluxo espectral a processar.';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                return
            end

            switch event.Source
                case app.report_AddProjectAttachment
                    Type = 'Project';

                case app.report_ContextMenu_ExternalFiles
                    if numel(app.report_Tree.SelectedNodes.NodeData) == 1
                        Type = 'specData';
                    else
                        msg = 'Os arquivos externos ao fluxo espectral - uma imagem e uma tabela - devem ser incluídos individualmente. Portanto, deve-se selecionar apenas um único fluxo espectral.';
                        appUtil.modalWindow(app.UIFigure, 'warning', msg);
                        return
                    end
            end

            auxApp.winAddFiles(app, Type)

        end

        % Value changed function: report_DatePicker1, report_DatePicker2, 
        % ...and 5 other components
        function report_SaveWarn(app, event)

            app.report_ProjectWarn.Visible = 1;
            
        end

        % Image clicked function: report_addButton
        function report_addButtonPushed(app, event)
            
            if ~isempty(app.play_Tree.SelectedNodes)
                idx = unique([app.play_Tree.SelectedNodes.NodeData]);
    
                msg = '';
                if any(ismember(arrayfun(@(x) x.MetaData.DataType, app.specData(idx)), class.Constants.occDataTypes))
                    msg = 'É possível a inclusão apenas de fluxos de espectro. Os fluxos de ocupação automaticamente serão inclusos na edição do relatório, desde que selecionado nos equivalentes fluxos de espectro.';
                elseif any(~arrayfun(@(x) x.GPS.Status, app.specData(idx)))
                    msg = 'Necessária a edição de informações de coordenadas geográficas de fluxo(s) de dados.';                    
                elseif any(arrayfun(@(x) numel(x.Data{1}), app.specData(idx)) == 1)
                    msg = 'É possível a inclusão de fluxo(s) de dados que possua(m) ao menos duas amostras.';
                end

                if ~isempty(msg)
                    appUtil.modalWindow(app.UIFigure, 'warning', msg);
                    return
                end

                if any(~ismember(idx, find(arrayfun(@(x) x.UserData.reportFlag, app.specData))))
                    app.progressDialog.Visible = 'visible';

                    class.userData.reportProperties_DefaultValues(app, idx)
                    report_TreeBuilding(app)
                    report_SaveWarn(app)

                    app.progressDialog.Visible = 'hidden';
                end
            end

        end

        % Menu selected function: report_ContextMenu_del
        function report_ContextMenu_delSelected(app, event)
            
            if ~isempty(app.report_Tree.SelectedNodes)
                idx = app.report_Tree.SelectedNodes.NodeData;

                for ii = idx
                    app.specData(ii).UserData.reportFlag           = false;
                    app.specData(ii).UserData.reportOCC            = [];
                    app.specData(ii).UserData.reportDetection      = [];
                    app.specData(ii).UserData.reportClassification = [];
                end

                report_TreeBuilding(app)
                report_SaveWarn(app)
            end

        end

        % Value changed function: report_DetectionManualMode
        function report_DetectionManualModeValueChanged(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            app.specData(idx).UserData.reportDetection.ManualMode = app.report_DetectionManualMode.Value;
            
            if app.specData(idx).UserData.reportDetection.ManualMode
                app.report_DetectionAlgorithm.Value   = '';
                app.report_DetectionParameters.Value  = '';
            else
                app.report_DetectionAlgorithm.Value   = app.specData(idx).UserData.reportDetection.Algorithm;
                app.report_DetectionParameters.Value  = jsonencode(app.specData(idx).UserData.reportDetection.Parameters);
            end

        end

        % Image clicked function: report_Button2, report_Button3
        function report_playButtonPushed(app, event)
            
            switch event.Source
                case app.report_Button2; Mode = 'Preview';
                case app.report_Button3; Mode = 'Report';
            end

            % EXCLUSÃO FIGURAS INVISÍVEIS:
            DeleteTempFigures(app, 'invisible')
            
            % VALIDAÇÕES:
            if ~sum(arrayfun(@(x) x.UserData.reportFlag, app.specData))
                msg = 'Necessário incluir ao menos um fluxo espectral na lista de fluxos a processar.';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                return

            elseif app.plotFlag
                msg = 'Necessário interromper o playback antes de inicializar análise para geração do relatório.';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                return

            elseif strcmp(Mode, 'Report') && strcmp(app.report_Version.Value, 'Definitiva')
                if app.report_Issue.Value <= 0
                    msg = 'Favor inserir número da inspeção.';
                    appUtil.modalWindow(app.UIFigure, 'warning', msg);
                    return
                end

                msg = sprintf('Confirma que se trata de monitoração relacionada à Inspeção nº %d?', app.report_Issue.Value);
                selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if selection == "Cancelar"
                    return
                end
                
            elseif strcmp(Mode, 'Preview')
                if isempty(app.report_Tree.CheckedNodes)
                    msg = 'Necessário selecionar ao menos um dos fluxos espectrais a processar.';
                    appUtil.modalWindow(app.UIFigure, 'warning', msg);
                    return
                end
            end

            report.Controller(app, Mode)

            % LAYOUT:
            % Esse modo "REPORT" pode detectar, automaticamente, novas
            % emissões. Essa informação é salva na própria app.specData
            % (exceção quando é aplicado um filtro de tempo, o que demanda
            % a criação de uma cópia de app.specData).
            app.play_Tree.SelectedNodes = app.play_PlotPanel.UserData;
            app.play_PlotPanel.UserData = [];
            play_TreeSelectionChanged(app)

            if ~isempty(app.auxiliarWin1)
                delete(app.auxiliarWin1)
                app.auxiliarWin1 = [];
            end

        end

        % Image clicked function: report_Button1
        function report_OpenSignalAnalysis(app, event)
            
            if ~isempty(app.peaksTable)
                if isempty(app.auxiliarWin1)
                    app.progressDialog.Visible = 'visible';                    
                    app.auxiliarWin1 = auxApp.winSignalAnalysis(app);
                    app.progressDialog.Visible = 'hidden';

                else
                    figure(app.auxiliarWin1.UIFigure)
                end

            else
                msg = 'Funcionalidade acessível apenas quando detectada ao menos uma emissão nos fluxos espectrais a processar.';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
            end

        end

        % Button pushed function: report_ClassificationButton, 
        % ...and 2 other components
        function report_AlgorithmButtonPushed(app, event)
            
            switch event.Source
                case app.report_occButton
                    msg = 'A alteração do método de aferição da ocupação por <i>bin</i> do fluxo espectral deve ser feita diretamente no modo PLAYBACK.';
                    appUtil.modalWindow(app.UIFigure, 'warning', msg);

                case app.report_DetectionButton
                    auxApp.winDetection(app);

                case app.report_ClassificationButton
                    auxApp.winClassification(app);
            end

        end

        % Callback function: edit_gpsButton, edit_gpsCity, 
        % ...and 4 other components
        function edit_gpsCallbacks(app, event)
            
            idx = edit_gpsFindIndex(app);
            if isempty(idx)
                return
            end

            switch event.Source
                %---------------------------------------------------------%
                case app.edit_gpsRefresh
                    fcn.gpsProperty(app, idx)
                    edit_gpsLayout(app, 'City+Latitude+Longitude')
                
                %---------------------------------------------------------%
                case app.edit_gpsButton
                    app.edit_gpsLatitude.Editable  = 1;
                    app.edit_gpsLongitude.Editable = 1;
                    focus(app.edit_gpsLatitude)

                %---------------------------------------------------------%
                case app.edit_gpsLatitude
                    focus(app.edit_gpsLongitude)
                    
                %---------------------------------------------------------%
                case app.edit_gpsLongitude
                
                    gps = struct('Latitude',  app.edit_gpsLatitude.Value, ...
                                 'Longitude', app.edit_gpsLongitude.Value);

                    if ~strcmp(app.edit_gpsCitySource.Value, 'manual')
                        [cityName, ~, locInfo] = fcn.gpsFindCity(gps);
                    end

                    for ii = idx
                        edit_gpsUpdate(app, ii, gps.Latitude, gps.Longitude)

                        if ~strcmp(app.edit_gpsCitySource.Value, 'manual')
                            app.specData(ii).GPS.Location       = cityName;
                            app.specData(ii).GPS.LocationSource = locInfo.infoSource;
                        end
                        
                        app.specData(ii).GPS.Edited = true;
                    end
                    edit_gpsLayout(app, 'Latitude+Longitude')

                %---------------------------------------------------------%
                case app.edit_gpsCityButton
                    app.edit_gpsCity.Editable = 1;
                    focus(app.edit_gpsCity)

                %---------------------------------------------------------%
                case app.edit_gpsCity
                    [cityName, Latitude, Longitude] = fcn.gpsFindCoordinates(event.Value);                    
                    if ~isempty(cityName)        
                        msg = sprintf(['Coordenadas geográficas atuais:\n• Latitude: %.6f\n• Longitude: %.6f\n\n' ...
                                       'Coordenadas geográficas da sede de <b>%s</b>:\n• Latitude: %.6f\n• Longitude: %.6f\n\n'               ...
                                       'Deseja atualizar, além da localidade, as informações de Latitude e Longitude?'],                ...
                                       app.edit_gpsLatitude.Value, app.edit_gpsLongitude.Value, cityName, Latitude, Longitude);        
                        selection = uiconfirm(app.UIFigure, textFormatGUI.HTMLParagraph(msg), '', 'Options', {'Sim', 'Não', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 3,'Icon', 'question', 'Interpreter', 'html');

                        if selection == "Cancelar"
                            set(app.edit_gpsCity, Editable=0, Value=event.PreviousValue)
                            return
                        end

                        for ii = idx
                            if selection == "Sim"
                                edit_gpsUpdate(app, ii, Latitude, Longitude)
                            end
                            
                            app.specData(ii).GPS.Location       = cityName;
                            app.specData(ii).GPS.LocationSource = 'manual';                            
                            app.specData(ii).GPS.Edited         = true;
                        end

                        switch selection
                            case 'Sim'; edit_gpsLayout(app, 'City+Latitude+Longitude')
                            case 'Não'; edit_gpsLayout(app, 'City')
                        end
                        
                    else
                        set(app.edit_gpsCity, Editable=0, Value=event.PreviousValue)
                        
                        msg = sprintf(['Não encontrada em base do IBGE o município <b>"%s"</b>. Favor corrigir ' ...
                                       'eventual erro na grafia, inserindo os acentos, no formato Município/UF'], event.Value);
                        appUtil.modalWindow(app.UIFigure, 'warning', msg);                        
                    end
            end

        end

        % Callback function: edit_ContextMenu_del, edit_unitRefresh, 
        % ...and 1 other component
        function edit_unitLevelCallbacks(app, event)
            
            idx1 = unique([app.play_Tree.SelectedNodes.NodeData]);
            if numel(idx1) ~= 1
                return
            end

            switch event.Source
                %---------------------------------------------------------%
                case app.edit_unitRefresh
                    idx2 = 1:height(app.specData(idx1).UserData.measCalibration);
                    Name = app.specData(idx1).UserData.measCalibration.Name;
                    edit_unitLevelCorrection(app, 'Remove', idx1, idx2, Name)

                %---------------------------------------------------------%
                case app.misc_Edit_kFactorAdd
                    Name = app.misc_Edit_kFactor.Value;
                    edit_unitLevelCorrection(app, 'Add', idx1, [], Name)

                %---------------------------------------------------------%
                case app.edit_ContextMenu_del
                    idx2 = app.misc_Edit_kFactorTree.SelectedNodes.NodeData;
                    Name = app.specData(idx1).UserData.measCalibration.Name(idx2);
                    edit_unitLevelCorrection(app, 'Remove', idx1, idx2, Name)
            end
            edit_unitLevelLayout(app)

        end

        % Button pushed function: misc_Del, misc_Duplicate, misc_Export, 
        % ...and 3 other components
        function misc_OperationsCallbacks(app, event)
            
            if app.plotFlag
                play_PlaybackToolbarButtonCallback(app, struct('Source', app.play_PlaybackPlay))
            end

            idx = unique([app.play_Tree.SelectedNodes.NodeData]);
            try
                switch event.Source
                    %-----------------------------------------------------%
                    case app.misc_Save
                        misc_SaveSpectralData(app, idx)

                    %-----------------------------------------------------%
                    case app.misc_Duplicate
                        for ii = 1:numel(idx)
                            app.specData(end+1) = copy(app.specData(idx(ii)), {});
                        end
                        sortIndex    = class.specData.read_SortData(app.specData);
                        app.specData = app.specData(sortIndex);                        
                    
                    %-----------------------------------------------------%
                    case app.misc_Merge
                        if strcmp(misc_checkIfAuxiliarAppIsOpen(app), 'Não')
                            return
                        end
                        
                        app.specData = merge(app.specData, idx, app.UIFigure);

                        % Reinicia os valores de ocupação...
                        idx = idx(1);
                        if ~isempty(app.specData(idx).UserData.reportOCC)
                            app.specData(idx).UserData.reportOCC = [];
                            app.specData(idx).UserData.occCache  = struct('Info', {}, 'THR', {}, 'Data', {});
                            app.specData(idx).UserData.occMethod.CacheIndex = [];

                            class.userData.reportProperties_DefaultValues(app, idx)
                        end
    
                    %-----------------------------------------------------%
                    case app.misc_Del
                        if strcmp(misc_checkIfAuxiliarAppIsOpen(app), 'Não')
                            return
                        end

                        delete(app.specData(idx))
                        app.specData(idx) = [];

                        if isempty(app.specData)
                            DeleteAll(app)
                            return
                        end
    
                    %-----------------------------------------------------%
                    case app.misc_Export
                        misc_ExportUserData(app, idx)
    
                    %-----------------------------------------------------%
                    case app.misc_Import
                        misc_ImportUserData(app)
                end

                SelectedNodesTextList = edit_SelectedNodesText(app);
                hComp = app.misc_Index;
                play_TreeRebuilding(app, SelectedNodesTextList, hComp)

            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', ME.message);
            end

        end

        % Selection changed function: config_ButtonGroup
        function config_ButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.config_ButtonGroup.SelectedObject;
            switch selectedButton
                case app.config_Option_Search
                    app.config_mainGrid.ColumnWidth(2:4) = {'1x',0,0};
                case app.config_Option_Fiscaliza
                    app.config_mainGrid.ColumnWidth(2:4) = {0,'1x',0};
                case app.config_Option_Folder
                    app.config_mainGrid.ColumnWidth(2:4) = {0,0,'1x'};
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnaliseV2 R2024a';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'icon_48.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout.Tooltip = {''};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_File = uitab(app.TabGroup);
            app.Tab1_File.Title = 'FILE';
            app.file_Grid = uigridlayout(app.Tab1_File);
            app.file_toolGrid = uigridlayout(app.file_Grid);
            app.file_OpenFileButton = uiimage(app.file_toolGrid);
            app.file_OpenFileButton.ImageClickedFcn = createCallbackFcn(app, @file_ButtonPushed_OpenFile, true);
            app.file_OpenFileButton.Tooltip = {'Seleciona arquivos'};
            app.file_OpenFileButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Import_24.png');
            app.file_SpecReadButton = uibutton(app.file_toolGrid, 'push');
            app.file_SpecReadButton.ButtonPushedFcn = createCallbackFcn(app, @file_ButtonPushed_SpecRead, true);
            app.file_SpecReadButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Run_24.png');
            app.file_SpecReadButton.Visible = 'off';
            app.file_SpecReadButton.Text = 'Inicia análise';
            app.file_docGrid = uigridlayout(app.file_Grid);
            app.file_TitleGrid = uigridlayout(app.file_docGrid);
            app.file_TitleGrid.Tag = 'COLORLOCKED';
            app.file_TitleIcon = uiimage(app.file_TitleGrid);
            app.file_TitleIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addFiles_32.png');
            app.file_Title = uilabel(app.file_TitleGrid);
            app.file_Title.Text = 'ARQUIVOS';
            app.file_TreeLabel = uilabel(app.file_docGrid);
            app.file_TreeLabel.Text = 'Lista de arquivos';
            app.file_Tree = uitree(app.file_docGrid);
            app.file_Tree.Multiselect = 'on';
            app.file_Tree.SelectionChangedFcn = createCallbackFcn(app, @file_TreeSelectionChanged, true);
            app.file_panelGrid = uigridlayout(app.file_Grid);
            app.file_MetadataLabel = uilabel(app.file_panelGrid);
            app.file_MetadataLabel.Text = 'Metadados';
            app.file_MetadataPanel = uipanel(app.file_panelGrid);
            app.file_MetadataGrid = uigridlayout(app.file_MetadataPanel);
            app.file_Metadata = uihtml(app.file_MetadataGrid);
            app.file_Metadata.HTMLSource = ' ';
            app.file_FilteringLabel = uilabel(app.file_panelGrid);
            app.file_FilteringLabel.Text = 'Filtros';
            app.file_FilteringTypePanel = uibuttongroup(app.file_panelGrid);
            app.file_FilteringTypePanel.SelectionChangedFcn = createCallbackFcn(app, @file_FilteringTypeChanged, true);
            app.file_FilteringType1 = uiradiobutton(app.file_FilteringTypePanel);
            app.file_FilteringType1.Text = 'Faixa de Frequência';
            app.file_FilteringType1.Value = true;
            app.file_FilteringType2 = uiradiobutton(app.file_FilteringTypePanel);
            app.file_FilteringType2.Text = 'ID';
            app.file_FilteringType3 = uiradiobutton(app.file_FilteringTypePanel);
            app.file_FilteringType3.Text = 'Descrição';
            app.file_FilteringType1_Frequency = uidropdown(app.file_panelGrid);
            app.file_FilteringType1_Frequency.Items = {};
            app.file_FilteringType1_Frequency.Value = {};
            app.file_FilteringType2_ID = uidropdown(app.file_panelGrid);
            app.file_FilteringType2_ID.Items = {};
            app.file_FilteringType2_ID.Value = {};
            app.file_FilteringType3_Description = uieditfield(app.file_panelGrid, 'text');
            app.file_FilteringAdd = uiimage(app.file_panelGrid);
            app.file_FilteringAdd.ImageClickedFcn = createCallbackFcn(app, @file_FilteringAddClicked, true);
            app.file_FilteringAdd.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.file_FilteringTree = uitree(app.file_panelGrid);
            app.file_FilteringTree.Multiselect = 'on';
            app.Tab2_Playback = uitab(app.TabGroup);
            app.Tab2_Playback.Title = 'PLAYBACK+REPORT+MISC';
            app.play_Grid = uigridlayout(app.Tab2_Playback);
            app.play_TreeGrid = uigridlayout(app.play_Grid);
            app.play_TreeTitleGrid = uigridlayout(app.play_TreeGrid);
            app.play_TreeTitleGrid.Tag = 'COLORLOCKED';
            app.play_TreeTitleImage = uiimage(app.play_TreeTitleGrid);
            app.play_TreeTitleImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Classification_32.png');
            app.play_TreeTitle = uilabel(app.play_TreeTitleGrid);
            app.play_TreeTitle.Text = 'DADOS';
            app.play_TreeLabelGrid = uigridlayout(app.play_TreeGrid);
            app.play_TreeLabel = uilabel(app.play_TreeLabelGrid);
            app.play_TreeLabel.Text = 'Fluxos espectrais';
            app.play_Lock = uiimage(app.play_TreeLabelGrid);
            app.play_Lock.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.play_Lock.ImageSource = fullfile(pathToMLAPP, 'Icons', 'lockOpen_32.png');
            app.play_TreeLayout = uiimage(app.play_TreeLabelGrid);
            app.play_TreeLayout.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.play_TreeLayout.ImageSource = fullfile(pathToMLAPP, 'Icons', 'layout3_32px.png');
            app.play_Tree = uitree(app.play_TreeGrid);
            app.play_Tree.Multiselect = 'on';
            app.play_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_TreeSelectionChanged, true);
            app.play_MetadataPanel = uipanel(app.play_TreeGrid);
            app.play_MetadataGrid = uigridlayout(app.play_MetadataPanel);
            app.play_Metadata = uihtml(app.play_MetadataGrid);
            app.play_Metadata.HTMLSource = ' ';
            app.play_PlotPanel = uipanel(app.play_Grid);
            app.play_ControlsGrid = uigridlayout(app.play_Grid);
            app.play_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.play_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.play_ControlsTab1Label = uilabel(app.play_ControlsTab1Grid);
            app.play_ControlsTab1Label.Text = 'PLAYBACK';
            app.play_ControlsTab1Image = uiimage(app.play_ControlsTab1Grid);
            app.play_ControlsTab1Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.play_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Playback_32.png');
            app.play_ControlsTab1Info = uigridlayout(app.play_ControlsGrid);
            app.play_GeneralPanelLabel = uilabel(app.play_ControlsTab1Info);
            app.play_GeneralPanelLabel.Text = 'Aspectos gerais';
            app.play_GeneralPanel = uipanel(app.play_ControlsTab1Info);
            app.play_OthersGrid = uigridlayout(app.play_GeneralPanel);
            app.play_LayoutRatioLabel = uilabel(app.play_OthersGrid);
            app.play_LayoutRatioLabel.Text = {'Razão aspecto '; 'eixos:'};
            app.play_LayoutRatio = uidropdown(app.play_OthersGrid);
            app.play_LayoutRatio.Items = {'1:0:0'};
            app.play_LayoutRatio.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_LayoutRatio.Value = '1:0:0';
            app.play_LineVisibilityLabel = uilabel(app.play_OthersGrid);
            app.play_LineVisibilityLabel.Text = 'Visibilidade ClearWrite:';
            app.play_LineVisibility = uidropdown(app.play_OthersGrid);
            app.play_LineVisibility.Items = {'on', 'off'};
            app.play_LineVisibility.ValueChangedFcn = createCallbackFcn(app, @play_LineVisibilityValueChanged, true);
            app.play_LineVisibility.Value = 'on';
            app.play_MinPlotTimeLabel = uilabel(app.play_OthersGrid);
            app.play_MinPlotTimeLabel.Text = {'Tempo mínimo'; 'escrita (ms):'};
            app.play_MinPlotTime = uispinner(app.play_OthersGrid);
            app.play_MinPlotTime.Step = 25;
            app.play_MinPlotTime.Limits = [50 1000];
            app.play_MinPlotTime.RoundFractionalValues = 'on';
            app.play_MinPlotTime.ValueDisplayFormat = '%.0f';
            app.play_MinPlotTime.Value = 100;
            app.play_TraceIntegrationLabel = uilabel(app.play_OthersGrid);
            app.play_TraceIntegrationLabel.Text = 'Fator integração:';
            app.play_TraceIntegration = uidropdown(app.play_OthersGrid);
            app.play_TraceIntegration.Items = {'3', '10', '100', 'Inf'};
            app.play_TraceIntegration.ValueChangedFcn = createCallbackFcn(app, @play_TraceIntegrationValueChanged, true);
            app.play_TraceIntegration.Value = 'Inf';
            app.play_LimitsPanelLabel = uilabel(app.play_OthersGrid);
            app.play_LimitsPanelLabel.Text = 'Limites';
            app.play_LimitsPanel = uipanel(app.play_OthersGrid);
            app.play_LimitsGrid = uigridlayout(app.play_LimitsPanel);
            app.play_Limits_xLim1 = uispinner(app.play_LimitsGrid);
            app.play_Limits_xLim1.ValueDisplayFormat = '%.3f';
            app.play_Limits_xLim1.ValueChangedFcn = createCallbackFcn(app, @play_xLimitsValueChanged, true);
            app.play_Limits_xLim1.Tag = 'FreqStart';
            app.play_Limits_xLim1.Tooltip = {''};
            app.play_Limits_xLimLabel = uilabel(app.play_LimitsGrid);
            app.play_Limits_xLimLabel.Text = 'Frequência';
            app.play_Limits_xLim2 = uispinner(app.play_LimitsGrid);
            app.play_Limits_xLim2.ValueDisplayFormat = '%.3f';
            app.play_Limits_xLim2.ValueChangedFcn = createCallbackFcn(app, @play_xLimitsValueChanged, true);
            app.play_Limits_xLim2.Tag = 'FreqStop';
            app.play_Limits_xLim2.Tooltip = {''};
            app.play_Limits_yLim1 = uispinner(app.play_LimitsGrid);
            app.play_Limits_yLim1.Step = 5;
            app.play_Limits_yLim1.ValueDisplayFormat = '%.1f';
            app.play_Limits_yLim1.ValueChangedFcn = createCallbackFcn(app, @play_yLimitsValueChanged, true);
            app.play_Limits_yLim1.Tag = 'MinLevel';
            app.play_Limits_yLim1.Tooltip = {''};
            app.play_Limits_yLimLabel = uilabel(app.play_LimitsGrid);
            app.play_Limits_yLimLabel.Text = 'Nível';
            app.play_Limits_yLim2 = uispinner(app.play_LimitsGrid);
            app.play_Limits_yLim2.Step = 5;
            app.play_Limits_yLim2.ValueDisplayFormat = '%.1f';
            app.play_Limits_yLim2.ValueChangedFcn = createCallbackFcn(app, @play_yLimitsValueChanged, true);
            app.play_Limits_yLim2.Tag = 'MaxLevel';
            app.play_Limits_yLim2.Tooltip = {''};
            app.play_ControlPanelLabel = uilabel(app.play_ControlsTab1Info);
            app.play_ControlPanelLabel.Text = 'Controles';
            app.play_ControlsPanel = uibuttongroup(app.play_ControlsTab1Info);
            app.play_ControlsPanel.SelectionChangedFcn = createCallbackFcn(app, @play_ControlsPanelSelectionChanged, true);
            app.play_RadioButton_Persistance = uiradiobutton(app.play_ControlsPanel);
            app.play_RadioButton_Persistance.Text = 'Persistência';
            app.play_RadioButton_Persistance.Value = true;
            app.play_RadioButton_Occupancy = uiradiobutton(app.play_ControlsPanel);
            app.play_RadioButton_Occupancy.Text = 'Ocupação';
            app.play_RadioButton_Waterfall = uiradiobutton(app.play_ControlsPanel);
            app.play_RadioButton_Waterfall.Text = 'Waterfall';
            app.play_Persistance_Panel = uipanel(app.play_ControlsTab1Info);
            app.play_PersistanceGrid = uigridlayout(app.play_Persistance_Panel);
            app.play_Persistance_InterpolationLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_InterpolationLabel.Text = 'Interpolação:';
            app.play_Persistance_Interpolation = uidropdown(app.play_PersistanceGrid);
            app.play_Persistance_Interpolation.Items = {'nearest', 'bilinear'};
            app.play_Persistance_Interpolation.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Interpolation.Enable = 'off';
            app.play_Persistance_Interpolation.Value = 'nearest';
            app.play_Persistance_SamplesLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_SamplesLabel.Text = 'Janela:';
            app.play_Persistance_Samples = uidropdown(app.play_PersistanceGrid);
            app.play_Persistance_Samples.Items = {'128', '256', '512', 'full'};
            app.play_Persistance_Samples.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Samples.Enable = 'off';
            app.play_Persistance_Samples.Value = 'full';
            app.play_Persistance_ColormapLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_ColormapLabel.Text = 'Mapa de cor:';
            app.play_Persistance_Colormap = uidropdown(app.play_PersistanceGrid);
            app.play_Persistance_Colormap.Items = {'winter', 'parula', 'turbo'};
            app.play_Persistance_Colormap.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Colormap.Enable = 'off';
            app.play_Persistance_Colormap.Value = 'winter';
            app.play_Persistance_TransparencyLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_TransparencyLabel.Text = 'Transparência:';
            app.play_Persistance_Transparency = uispinner(app.play_PersistanceGrid);
            app.play_Persistance_Transparency.Step = 0.05;
            app.play_Persistance_Transparency.Limits = [0.2 1];
            app.play_Persistance_Transparency.ValueDisplayFormat = '%.2f';
            app.play_Persistance_Transparency.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Transparency.Enable = 'off';
            app.play_Persistance_Transparency.Value = 1;
            app.play_Persistance_cLim_Label = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_cLim_Label.Text = 'Limites (%):';
            app.play_Persistance_cLim_Grid2 = uigridlayout(app.play_PersistanceGrid);
            app.play_Persistance_cLim1 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim1.Step = 0.1;
            app.play_Persistance_cLim1.Limits = [0 Inf];
            app.play_Persistance_cLim1.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim1.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_cLimValueChanged, true);
            app.play_Persistance_cLim1.Enable = 'off';
            app.play_Persistance_cLim1.Tooltip = {''};
            app.play_Persistance_cLim2 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim2.Limits = [0 Inf];
            app.play_Persistance_cLim2.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim2.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_cLimValueChanged, true);
            app.play_Persistance_cLim2.Enable = 'off';
            app.play_Persistance_cLim2.Tooltip = {''};
            app.play_Persistance_cLim_Separation = uilabel(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim_Separation.Enable = 'off';
            app.play_Persistance_cLim_Separation.Text = '-';
            app.play_Persistance_cLim_Mode = uiimage(app.play_PersistanceGrid);
            app.play_Persistance_cLim_Mode.ImageClickedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_cLim_Mode.Enable = 'off';
            app.play_Persistance_cLim_Mode.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.play_OCC_Panel = uipanel(app.play_ControlsTab1Info);
            app.play_OCCGrid = uigridlayout(app.play_OCC_Panel);
            app.play_OCC_MethodLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_MethodLabel.Text = 'Tipo de threshold:';
            app.play_OCC_Method = uidropdown(app.play_OCCGrid);
            app.play_OCC_Method.Items = {'Linear fixo (COLETA)', 'Linear fixo', 'Linear adaptativo', 'Envoltória do ruído'};
            app.play_OCC_Method.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_Method.Value = 'Linear fixo (COLETA)';
            app.play_OCC_IntegrationTimeLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_IntegrationTimeLabel.Text = 'Integração (min):';
            app.play_OCC_IntegrationTime = uidropdown(app.play_OCCGrid);
            app.play_OCC_IntegrationTime.Items = {'1', '5', '15', '30', '60'};
            app.play_OCC_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_IntegrationTime.Tag = 'Factor';
            app.play_OCC_IntegrationTime.Value = '15';
            app.play_OCC_IntegrationTimeCaptured = uieditfield(app.play_OCCGrid, 'numeric');
            app.play_OCC_IntegrationTimeCaptured.Limits = [0 Inf];
            app.play_OCC_IntegrationTimeCaptured.ValueDisplayFormat = '%.0f';
            app.play_OCC_IntegrationTimeCaptured.Editable = 'off';
            app.play_OCC_IntegrationTimeCaptured.Visible = 'off';
            app.play_OCC_OrientationLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_OrientationLabel.Text = 'Orientação:';
            app.play_OCC_Orientation = uidropdown(app.play_OCCGrid);
            app.play_OCC_Orientation.Items = {'bin'};
            app.play_OCC_Orientation.Value = 'bin';
            app.play_OCC_THRLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_THRLabel.Tag = 'THR';
            app.play_OCC_THRLabel.Text = 'Valor (dBm):';
            app.play_OCC_THR = uispinner(app.play_OCCGrid);
            app.play_OCC_THR.RoundFractionalValues = 'on';
            app.play_OCC_THR.ValueDisplayFormat = '%d';
            app.play_OCC_THR.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_THR.Tag = 'THR';
            app.play_OCC_THR.Value = -80;
            app.play_OCC_THRCaptured = uidropdown(app.play_OCCGrid);
            app.play_OCC_THRCaptured.Items = {};
            app.play_OCC_THRCaptured.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_THRCaptured.Tag = 'Factor';
            app.play_OCC_THRCaptured.Visible = 'off';
            app.play_OCC_THRCaptured.Value = {};
            app.play_OCC_OffsetLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_OffsetLabel.Tag = 'Offset';
            app.play_OCC_OffsetLabel.Visible = 'off';
            app.play_OCC_OffsetLabel.Text = 'OffSet (dB):';
            app.play_OCC_Offset = uispinner(app.play_OCCGrid);
            app.play_OCC_Offset.Limits = [3 30];
            app.play_OCC_Offset.RoundFractionalValues = 'on';
            app.play_OCC_Offset.ValueDisplayFormat = '%d';
            app.play_OCC_Offset.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_Offset.Tag = 'Offset';
            app.play_OCC_Offset.Visible = 'off';
            app.play_OCC_Offset.Value = 12;
            app.play_OCC_ceilFactorLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_ceilFactorLabel.Tag = 'Factor';
            app.play_OCC_ceilFactorLabel.Visible = 'off';
            app.play_OCC_ceilFactorLabel.Text = 'Ceifamento:';
            app.play_OCC_ceilFactor = uidropdown(app.play_OCCGrid);
            app.play_OCC_ceilFactor.Items = {'1𝜎', '2𝜎', '3𝜎'};
            app.play_OCC_ceilFactor.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_ceilFactor.Tag = 'Factor';
            app.play_OCC_ceilFactor.Visible = 'off';
            app.play_OCC_ceilFactor.Value = '2𝜎';
            app.play_OCC_noiseLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_noiseLabel.Visible = 'off';
            app.play_OCC_noiseLabel.Text = 'Parâmetros relacionados à estimativa do piso de ruído:';
            app.play_OCC_noisePanel = uipanel(app.play_OCCGrid);
            app.play_OCC_noisePanel.Visible = 'off';
            app.play_OCC_noiseGrid = uigridlayout(app.play_OCC_noisePanel);
            app.play_OCC_noiseFcnLabel = uilabel(app.play_OCC_noiseGrid);
            app.play_OCC_noiseFcnLabel.Text = {'Função'; 'estatística:'};
            app.play_OCC_noiseFcn = uidropdown(app.play_OCC_noiseGrid);
            app.play_OCC_noiseFcn.Items = {'mean', 'median'};
            app.play_OCC_noiseFcn.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_noiseFcn.Value = 'mean';
            app.play_OCC_noiseTrashSamplesLabel = uilabel(app.play_OCC_noiseGrid);
            app.play_OCC_noiseTrashSamplesLabel.Text = {'Amostras'; 'descartadas (%):'};
            app.play_OCC_noiseTrashSamples = uispinner(app.play_OCC_noiseGrid);
            app.play_OCC_noiseTrashSamples.Limits = [0 10];
            app.play_OCC_noiseTrashSamples.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_noiseUsefulSamplesLabel = uilabel(app.play_OCC_noiseGrid);
            app.play_OCC_noiseUsefulSamplesLabel.Text = {'Amostras'; 'úteis (%):'};
            app.play_OCC_noiseUsefulSamples = uispinner(app.play_OCC_noiseGrid);
            app.play_OCC_noiseUsefulSamples.Limits = [10 90];
            app.play_OCC_noiseUsefulSamples.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_noiseUsefulSamples.Value = 20;
            app.play_Waterfall_Panel = uipanel(app.play_ControlsTab1Info);
            app.play_WaterFallGrid = uigridlayout(app.play_Waterfall_Panel);
            app.play_Waterfall_DecimationGrid = uigridlayout(app.play_WaterFallGrid);
            app.play_Waterfall_Decimation_Label = uilabel(app.play_Waterfall_DecimationGrid);
            app.play_Waterfall_Decimation_Label.Text = 'Decimação:';
            app.play_Waterfall_DecimationValue = uilabel(app.play_Waterfall_DecimationGrid);
            app.play_Waterfall_DecimationValue.Text = '1';
            app.play_Waterfall_Decimation = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Decimation.Items = {'auto', '1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.play_Waterfall_Decimation.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Decimation.Enable = 'off';
            app.play_Waterfall_Decimation.Value = 'auto';
            app.play_Waterfall_Timestamp_Label = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_Timestamp_Label.Text = 'Timestamp:';
            app.play_Waterfall_Timestamp = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Timestamp.Items = {'on', 'off'};
            app.play_Waterfall_Timestamp.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Timestamp.Enable = 'off';
            app.play_Waterfall_Timestamp.Value = 'on';
            app.play_Waterfall_ColormapLabel = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_ColormapLabel.Text = 'Mapa de cor:';
            app.play_Waterfall_Colormap = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.play_Waterfall_Colormap.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Colormap.Enable = 'off';
            app.play_Waterfall_Colormap.Value = 'winter';
            app.play_Waterfall_Interpolation_Label = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_Interpolation_Label.Text = 'Direção:';
            app.play_Waterfall_Interpolation = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Interpolation.Items = {'row', 'both'};
            app.play_Waterfall_Interpolation.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Interpolation.Enable = 'off';
            app.play_Waterfall_Interpolation.Value = 'both';
            app.play_Waterfall_cLim_Label = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_cLim_Label.Text = 'Limites (dB):';
            app.play_Waterfall_cLim_Grid2 = uigridlayout(app.play_WaterFallGrid);
            app.play_Waterfall_cLim1 = uispinner(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim1.Step = 5;
            app.play_Waterfall_cLim1.RoundFractionalValues = 'on';
            app.play_Waterfall_cLim1.ValueDisplayFormat = '%.0f';
            app.play_Waterfall_cLim1.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_cLimValueChanged, true);
            app.play_Waterfall_cLim1.Enable = 'off';
            app.play_Waterfall_cLim1.Tooltip = {''};
            app.play_Waterfall_cLim2 = uispinner(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim2.Step = 5;
            app.play_Waterfall_cLim2.RoundFractionalValues = 'on';
            app.play_Waterfall_cLim2.ValueDisplayFormat = '%.0f';
            app.play_Waterfall_cLim2.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_cLimValueChanged, true);
            app.play_Waterfall_cLim2.Enable = 'off';
            app.play_Waterfall_cLim2.Tooltip = {''};
            app.play_Waterfall_cLim_Separation = uilabel(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim_Separation.Enable = 'off';
            app.play_Waterfall_cLim_Separation.Text = '-';
            app.play_Waterfall_cLim_Mode = uiimage(app.play_WaterFallGrid);
            app.play_Waterfall_cLim_Mode.ImageClickedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_cLim_Mode.Enable = 'off';
            app.play_Waterfall_cLim_Mode.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.play_Customization = uicheckbox(app.play_ControlsTab1Info);
            app.play_Customization.ValueChangedFcn = createCallbackFcn(app, @play_CustomizationValueChanged, true);
            app.play_Customization.Text = 'Customizar controles do plot (visibilidade, razão de aspecto dos eixos, DataTips etc).';
            app.play_ControlsTab2Grid = uigridlayout(app.play_ControlsGrid);
            app.play_ControlsTab2Grid.Tag = 'COLORLOCKED';
            app.play_ControlsTab2Label = uilabel(app.play_ControlsTab2Grid);
            app.play_ControlsTab2Label.Text = 'CANAIS';
            app.play_ControlsTab2Image = uiimage(app.play_ControlsTab2Grid);
            app.play_ControlsTab2Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.play_ControlsTab2Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Channel_32.png');
            app.play_ControlsTab2Info = uigridlayout(app.play_ControlsGrid);
            app.play_Channel_Label = uilabel(app.play_ControlsTab2Info);
            app.play_Channel_Label.Text = 'Inclusão de canais';
            app.play_Channel_RadioGroup = uibuttongroup(app.play_ControlsTab2Info);
            app.play_Channel_RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @play_Channel_RadioGroupSelectionChanged, true);
            app.play_Channel_ReferenceList = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_ReferenceList.Text = {'Canalização'; 'de referência'};
            app.play_Channel_ReferenceList.Value = true;
            app.play_Channel_Multiples = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_Multiples.Text = {'Faixa de'; 'frequência'};
            app.play_Channel_Single = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_Single.Text = 'Canal';
            app.play_Channel_Panel = uipanel(app.play_ControlsTab2Info);
            app.play_Channel_Grid = uigridlayout(app.play_Channel_Panel);
            app.play_Channel_ListLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_ListLabel.Text = 'Referência:';
            app.play_Channel_ListUpdate = uiimage(app.play_Channel_Grid);
            app.play_Channel_ListUpdate.ImageClickedFcn = createCallbackFcn(app, @play_Channel_ListUpdateImageClicked, true);
            app.play_Channel_ListUpdate.Tooltip = {''};
            app.play_Channel_ListUpdate.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.play_Channel_List = uidropdown(app.play_Channel_Grid);
            app.play_Channel_List.Items = {};
            app.play_Channel_List.ValueChangedFcn = createCallbackFcn(app, @play_Channel_ListValueChanged, true);
            app.play_Channel_List.Value = {};
            app.play_Channel_nChannelsLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_nChannelsLabel.Text = {'Qtd.'; 'canais:'};
            app.play_Channel_nChannels = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_nChannels.Limits = [-1 Inf];
            app.play_Channel_nChannels.RoundFractionalValues = 'on';
            app.play_Channel_nChannels.ValueDisplayFormat = '%d';
            app.play_Channel_nChannels.Editable = 'off';
            app.play_Channel_nChannels.Value = 1;
            app.play_Channel_FirstChannelLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_FirstChannelLabel.Text = {'Frequência central'; '1º canal (MHz):'};
            app.play_Channel_FirstChannel = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_FirstChannel.Limits = [0 Inf];
            app.play_Channel_FirstChannel.ValueDisplayFormat = '%.3f';
            app.play_Channel_FirstChannel.ValueChangedFcn = createCallbackFcn(app, @play_Channel_FirstChannelValueChanged, true);
            app.play_Channel_nChannelLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_nChannelLabel.Text = {'Frequência central'; 'n-ésimo canal:'};
            app.play_Channel_nChannel = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_nChannel.Limits = [0 Inf];
            app.play_Channel_nChannel.ValueDisplayFormat = '%.3f';
            app.play_Channel_nChannel.ValueChangedFcn = createCallbackFcn(app, @play_Channel_FirstChannelValueChanged, true);
            app.play_Channel_ClassLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_ClassLabel.Text = {'Classe de '; 'emissão:'};
            app.play_Channel_Class = uidropdown(app.play_Channel_Grid);
            app.play_Channel_Class.Items = {};
            app.play_Channel_Class.Value = {};
            app.play_Channel_StepWidthLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_StepWidthLabel.Text = {'Espaçamento entre'; 'canais (kHz):'};
            app.play_Channel_StepWidth = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_StepWidth.Limits = [-1 Inf];
            app.play_Channel_StepWidth.ValueDisplayFormat = '%.1f';
            app.play_Channel_StepWidth.ValueChangedFcn = createCallbackFcn(app, @play_Channel_FirstChannelValueChanged, true);
            app.play_Channel_BWLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_BWLabel.Text = {'Largura do canal'; '(kHz):'};
            app.play_Channel_BW = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_BW.Limits = [-1 Inf];
            app.play_Channel_BW.ValueDisplayFormat = '%.1f';
            app.play_Channel_Sample = uilabel(app.play_Channel_Grid);
            app.play_Channel_Sample.Text = '101.700 MHz, 101.900 MHz, 102.100 MHz, 102.300 MHz, 102.500 MHz, 102.700 MHz...';
            app.play_Channel_add = uiimage(app.play_ControlsTab2Info);
            app.play_Channel_add.ImageClickedFcn = createCallbackFcn(app, @play_Channel_addChannel, true);
            app.play_Channel_add.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.play_BandLimitsGrid = uigridlayout(app.play_ControlsTab2Info);
            app.play_BandLimits_Status = uicheckbox(app.play_BandLimitsGrid);
            app.play_BandLimits_Status.ValueChangedFcn = createCallbackFcn(app, @play_BandLimits_StatusValueChanged, true);
            app.play_BandLimits_Status.Text = '';
            app.play_BandLimits_Label = uilabel(app.play_BandLimitsGrid);
            app.play_BandLimits_Label.Text = 'Limitar detecção de emissões a subfaixa(s)';
            app.play_BandLimits_Panel = uipanel(app.play_ControlsTab2Info);
            app.play_BandLimits_Grid = uigridlayout(app.play_BandLimits_Panel);
            app.play_BandLimits_xLim1 = uieditfield(app.play_BandLimits_Grid, 'numeric');
            app.play_BandLimits_xLim1.ValueDisplayFormat = '%.3f';
            app.play_BandLimits_xLim1.Enable = 'off';
            app.play_BandLimits_xLabel = uilabel(app.play_BandLimits_Grid);
            app.play_BandLimits_xLabel.Text = 'Frequência';
            app.play_BandLimits_xLim2 = uieditfield(app.play_BandLimits_Grid, 'numeric');
            app.play_BandLimits_xLim2.ValueDisplayFormat = '%.3f';
            app.play_BandLimits_xLim2.Enable = 'off';
            app.play_BandLimits_add = uiimage(app.play_ControlsTab2Info);
            app.play_BandLimits_add.ImageClickedFcn = createCallbackFcn(app, @play_BandLimits_addImageClicked, true);
            app.play_BandLimits_add.Enable = 'off';
            app.play_BandLimits_add.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.play_BandLimits_Tree = uitree(app.play_ControlsTab2Info);
            app.play_BandLimits_Tree.Multiselect = 'on';
            app.play_BandLimits_Tree.Enable = 'off';
            app.play_Channel_Tree = uitree(app.play_ControlsTab2Info);
            app.play_Channel_Tree.Multiselect = 'on';
            app.play_Channel_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_Channel_TreeSelectionChanged, true);
            app.play_ControlsTab3Grid = uigridlayout(app.play_ControlsGrid);
            app.play_ControlsTab3Grid.Tag = 'COLORLOCKED';
            app.play_ControlsTab3Label = uilabel(app.play_ControlsTab3Grid);
            app.play_ControlsTab3Label.Text = 'EMISSÕES';
            app.play_ControlsTab3Image = uiimage(app.play_ControlsTab3Grid);
            app.play_ControlsTab3Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.play_ControlsTab3Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Detection_32.png');
            app.play_ControlsTab3Info = uigridlayout(app.play_ControlsGrid);
            app.play_FindPeaks_Label = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_Label.Text = 'Detecção de emissões';
            app.play_FindPeaks_RadioGroup = uibuttongroup(app.play_ControlsTab3Info);
            app.play_FindPeaks_RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_RadioGroupSelectionChanged, true);
            app.play_FindPeaks_auto = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_auto.Text = 'Automática';
            app.play_FindPeaks_auto.Value = true;
            app.play_FindPeaks_ROI = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_ROI.Text = 'ROI';
            app.play_FindPeaks_DataTips = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_DataTips.Text = 'DataTips';
            app.play_FindPeaks_File = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_File.Text = 'Arquivo';
            app.play_FindPeaks_ParametersPanel = uipanel(app.play_ControlsTab3Info);
            app.play_FindPeaks_ParametersGrid = uigridlayout(app.play_FindPeaks_ParametersPanel);
            app.play_FindPeaks_TraceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_TraceLabel.Text = {'Tipo de '; 'traço:'};
            app.play_FindPeaks_Trace = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Trace.Items = {'MinHold', 'Média', 'MaxHold'};
            app.play_FindPeaks_Trace.Value = 'Média';
            app.play_FindPeaks_NumbersLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_NumbersLabel.Text = {'Número de '; 'picos:'};
            app.play_FindPeaks_Numbers = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Numbers.Limits = [1 100];
            app.play_FindPeaks_Numbers.RoundFractionalValues = 'on';
            app.play_FindPeaks_Numbers.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Numbers.Value = 10;
            app.play_FindPeaks_THRLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_THRLabel.Text = {'Threshold'; '(dB):'};
            app.play_FindPeaks_THR = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_THR.Step = 10;
            app.play_FindPeaks_THR.RoundFractionalValues = 'on';
            app.play_FindPeaks_THR.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_THR.Value = -Inf;
            app.play_FindPeaks_prominenceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_prominenceLabel.Text = {'Proeminência '; '(dB):'};
            app.play_FindPeaks_prominence = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_prominence.Step = 10;
            app.play_FindPeaks_prominence.Limits = [1 Inf];
            app.play_FindPeaks_prominence.RoundFractionalValues = 'on';
            app.play_FindPeaks_prominence.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_prominence.Value = 30;
            app.play_FindPeaks_ClassLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_ClassLabel.Visible = 'off';
            app.play_FindPeaks_ClassLabel.Text = {'Classe de '; 'emissão:'};
            app.play_FindPeaks_Class = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Class.Items = {'Genérico', 'Radiocomunicação', 'Radiodifusão (FM)', 'Radiodifusão (TV)'};
            app.play_FindPeaks_Class.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_ClassValueChanged, true);
            app.play_FindPeaks_Class.Visible = 'off';
            app.play_FindPeaks_Class.Value = 'Genérico';
            app.play_FindPeaks_distanceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distanceLabel.Text = {'Distância entre'; 'picos (kHz):'};
            app.play_FindPeaks_distance = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distance.Step = 25;
            app.play_FindPeaks_distance.Limits = [0 Inf];
            app.play_FindPeaks_distance.RoundFractionalValues = 'on';
            app.play_FindPeaks_distance.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_distance.Value = 25;
            app.play_FindPeaks_BWLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_BWLabel.Text = 'Largura ocupada (kHz):';
            app.play_FindPeaks_BW = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_BW.Step = 10;
            app.play_FindPeaks_BW.Limits = [0 Inf];
            app.play_FindPeaks_BW.RoundFractionalValues = 'on';
            app.play_FindPeaks_BW.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_BW.Value = 10;
            app.play_FindPeaks_MeanPanel = uipanel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_MeanPanel.Title = 'MÉDIA';
            app.play_FindPeaks_MeanGrid = uigridlayout(app.play_FindPeaks_MeanPanel);
            app.play_FindPeaks_Prominence1Label = uilabel(app.play_FindPeaks_MeanGrid);
            app.play_FindPeaks_Prominence1Label.Text = {'Proeminência'; '(dB):'};
            app.play_FindPeaks_Prominence1 = uispinner(app.play_FindPeaks_MeanGrid);
            app.play_FindPeaks_Prominence1.Step = 10;
            app.play_FindPeaks_Prominence1.Limits = [1 Inf];
            app.play_FindPeaks_Prominence1.RoundFractionalValues = 'on';
            app.play_FindPeaks_Prominence1.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Prominence1.Value = 10;
            app.play_FindPeaks_MaxHoldPanel = uipanel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_MaxHoldPanel.Title = 'MAXHOLD';
            app.play_FindPeaks_MaxHoldGrid = uigridlayout(app.play_FindPeaks_MaxHoldPanel);
            app.play_FindPeaks_Prominence2Label = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_Prominence2Label.Text = {'Proeminência'; '(dB):'};
            app.play_FindPeaks_Prominence2 = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_Prominence2.Step = 10;
            app.play_FindPeaks_Prominence2.Limits = [1 Inf];
            app.play_FindPeaks_Prominence2.RoundFractionalValues = 'on';
            app.play_FindPeaks_Prominence2.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Prominence2.Value = 30;
            app.play_FindPeaks_meanOCCLabel = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_meanOCCLabel.Text = {'Ocupação'; 'média (%):'};
            app.play_FindPeaks_meanOCC = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_meanOCC.Step = 10;
            app.play_FindPeaks_meanOCC.Limits = [0 100];
            app.play_FindPeaks_meanOCC.RoundFractionalValues = 'on';
            app.play_FindPeaks_meanOCC.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_meanOCC.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_OCCValueChanged, true);
            app.play_FindPeaks_meanOCC.Value = 1;
            app.play_FindPeaks_maxOCCLabel = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_maxOCCLabel.Text = {'Ocupação'; 'máxima (%):'};
            app.play_FindPeaks_maxOCC = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_maxOCC.Step = 10;
            app.play_FindPeaks_maxOCC.Limits = [0 100];
            app.play_FindPeaks_maxOCC.RoundFractionalValues = 'on';
            app.play_FindPeaks_maxOCC.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_maxOCC.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_OCCValueChanged, true);
            app.play_FindPeaks_maxOCC.Value = 10;
            app.play_FindPeaks_AlgorithmLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_AlgorithmLabel.Text = 'Algoritmo:';
            app.play_FindPeaks_Algorithm = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Algorithm.Items = {'FindPeaks', 'FindPeaks+OCC'};
            app.play_FindPeaks_Algorithm.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_AlgorithmValueChanged, true);
            app.play_FindPeaks_Algorithm.Value = 'FindPeaks';
            app.play_FindPeaks_ExternalFilePanel = uipanel(app.play_ControlsTab3Info);
            app.play_FindPeaks_ExternalFileGrid = uigridlayout(app.play_FindPeaks_ExternalFilePanel);
            app.play_FindPeaks_ExternalFileLabel = uilabel(app.play_FindPeaks_ExternalFileGrid);
            app.play_FindPeaks_ExternalFileLabel.Text = 'Formato:';
            app.play_FindPeaks_ExternalFile = uidropdown(app.play_FindPeaks_ExternalFileGrid);
            app.play_FindPeaks_ExternalFile.Items = {'Generic (csv, txt, json, xls, xlsx)', 'Romes (csv)'};
            app.play_FindPeaks_ExternalFile.Value = 'Generic (csv, txt, json, xls, xlsx)';
            app.play_FindPeaks_add = uiimage(app.play_ControlsTab3Info);
            app.play_FindPeaks_add.ImageClickedFcn = createCallbackFcn(app, @play_FindPeaks_addEmission, true);
            app.play_FindPeaks_add.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.play_FindPeaks_Tree = uitree(app.play_ControlsTab3Info);
            app.play_FindPeaks_Tree.Multiselect = 'on';
            app.play_FindPeaks_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_TreeSelectionChanged, true);
            app.play_FindPeaks_PeakCFLabel = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_PeakCFLabel.Text = 'Frequência central (MHz):';
            app.play_FindPeaks_PeakCF = uieditfield(app.play_ControlsTab3Info, 'numeric');
            app.play_FindPeaks_PeakCF.ValueDisplayFormat = '%.3f';
            app.play_FindPeaks_PeakCF.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_editEmission, true);
            app.play_FindPeaks_PeakCF.Enable = 'off';
            app.play_FindPeaks_PeakBWLabel = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_PeakBWLabel.Text = 'Largura ocupada (kHz):';
            app.play_FindPeaks_PeakBW = uieditfield(app.play_ControlsTab3Info, 'numeric');
            app.play_FindPeaks_PeakBW.ValueDisplayFormat = '%.3f';
            app.play_FindPeaks_PeakBW.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_editEmission, true);
            app.play_FindPeaks_PeakBW.Enable = 'off';
            app.play_FindPeaks_DescriptionLabel = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_DescriptionLabel.Text = {'Informações'; 'complementares:'};
            app.play_FindPeaks_Description = uieditfield(app.play_ControlsTab3Info, 'text');
            app.play_FindPeaks_Description.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_editEmission, true);
            app.play_FindPeaks_Description.Enable = 'off';
            app.report_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.report_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.report_ControlsTab1Label = uilabel(app.report_ControlsTab1Grid);
            app.report_ControlsTab1Label.Text = 'FISCALIZAÇÃO';
            app.report_ControlsTab1Image = uiimage(app.report_ControlsTab1Grid);
            app.report_ControlsTab1Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.report_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Report_32.png');
            app.report_ControlsTab1Info = uigridlayout(app.play_ControlsGrid);
            app.report_Tree = uitree(app.report_ControlsTab1Info, 'checkbox');
            app.report_TimeStampPanel = uipanel(app.report_ControlsTab1Info);
            app.report_TimeStampGrid = uigridlayout(app.report_TimeStampPanel);
            app.report_DatePicker1 = uidatepicker(app.report_TimeStampGrid);
            app.report_DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.report_DatePicker1.ValueChangedFcn = createCallbackFcn(app, @report_SaveWarn, true);
            app.report_DatePicker1.Enable = 'off';
            app.report_Spinner1 = uispinner(app.report_TimeStampGrid);
            app.report_Spinner1.Limits = [0 23];
            app.report_Spinner1.RoundFractionalValues = 'on';
            app.report_Spinner1.ValueDisplayFormat = '%.0f';
            app.report_Spinner1.ValueChangedFcn = createCallbackFcn(app, @report_SaveWarn, true);
            app.report_Spinner1.Enable = 'off';
            app.report_Spinner2 = uispinner(app.report_TimeStampGrid);
            app.report_Spinner2.Step = 10;
            app.report_Spinner2.Limits = [0 59];
            app.report_Spinner2.RoundFractionalValues = 'on';
            app.report_Spinner2.ValueDisplayFormat = '%.0f';
            app.report_Spinner2.ValueChangedFcn = createCallbackFcn(app, @report_SaveWarn, true);
            app.report_Spinner2.Enable = 'off';
            app.report_DatePicker2 = uidatepicker(app.report_TimeStampGrid);
            app.report_DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.report_DatePicker2.ValueChangedFcn = createCallbackFcn(app, @report_SaveWarn, true);
            app.report_DatePicker2.Enable = 'off';
            app.report_Spinner3 = uispinner(app.report_TimeStampGrid);
            app.report_Spinner3.Limits = [0 23];
            app.report_Spinner3.RoundFractionalValues = 'on';
            app.report_Spinner3.ValueDisplayFormat = '%.0f';
            app.report_Spinner3.ValueChangedFcn = createCallbackFcn(app, @report_SaveWarn, true);
            app.report_Spinner3.Enable = 'off';
            app.report_Spinner3.Value = 23;
            app.report_Spinner4 = uispinner(app.report_TimeStampGrid);
            app.report_Spinner4.Step = 10;
            app.report_Spinner4.Limits = [0 59];
            app.report_Spinner4.RoundFractionalValues = 'on';
            app.report_Spinner4.ValueDisplayFormat = '%.0f';
            app.report_Spinner4.ValueChangedFcn = createCallbackFcn(app, @report_SaveWarn, true);
            app.report_Spinner4.Enable = 'off';
            app.report_Spinner4.Value = 59;
            app.report_Mark1 = uilabel(app.report_TimeStampGrid);
            app.report_Mark1.Text = ':';
            app.report_Mark2 = uilabel(app.report_TimeStampGrid);
            app.report_Mark2.Text = ':';
            app.report_GeneralPanel = uipanel(app.report_ControlsTab1Info);
            app.report_GeneralGrid = uigridlayout(app.report_GeneralPanel);
            app.report_ProjectName = uieditfield(app.report_GeneralGrid, 'text');
            app.report_ProjectName.Value = 'Projeto 001';
            app.report_SaveProject = uibutton(app.report_GeneralGrid, 'push');
            app.report_SaveProject.ButtonPushedFcn = createCallbackFcn(app, @report_SaveProjectButtonPushed, true);
            app.report_SaveProject.Icon = fullfile(pathToMLAPP, 'Icons', 'saveFile_32.png');
            app.report_SaveProject.Tooltip = {'Salvar projeto'};
            app.report_SaveProject.Text = '';
            app.report_ReportPanel = uipanel(app.report_GeneralGrid);
            app.report_ReportGrid = uigridlayout(app.report_ReportPanel);
            app.report_IssueLabel = uilabel(app.report_ReportGrid);
            app.report_IssueLabel.Text = 'Inspeção:';
            app.report_Issue = uieditfield(app.report_ReportGrid, 'numeric');
            app.report_Issue.Limits = [-1 Inf];
            app.report_Issue.RoundFractionalValues = 'on';
            app.report_Issue.ValueDisplayFormat = '%d';
            app.report_Issue.ValueChangedFcn = createCallbackFcn(app, @report_SaveWarn, true);
            app.report_Issue.Value = -1;
            app.report_Type = uidropdown(app.report_ReportGrid);
            app.report_Type.Items = {};
            app.report_Type.Value = {};
            app.report_TypeLabel = uilabel(app.report_ReportGrid);
            app.report_TypeLabel.Text = 'Modelo:';
            app.report_AddProjectAttachment = uibutton(app.report_ReportGrid, 'push');
            app.report_AddProjectAttachment.ButtonPushedFcn = createCallbackFcn(app, @report_ExternalFilesMenuSelected, true);
            app.report_AddProjectAttachment.Icon = fullfile(pathToMLAPP, 'Icons', 'attach0_32.png');
            app.report_AddProjectAttachment.Tooltip = {'Arquivos externos'};
            app.report_AddProjectAttachment.Text = '';
            app.report_Version = uidropdown(app.report_ReportGrid);
            app.report_Version.Items = {'Preliminar', 'Definitiva'};
            app.report_Version.Value = 'Preliminar';
            app.report_VersionLabel = uilabel(app.report_ReportGrid);
            app.report_VersionLabel.Text = 'Versão:';
            app.report_ReportLabel = uilabel(app.report_GeneralGrid);
            app.report_ReportLabel.Text = 'Relatório:';
            app.report_ProjectFilename = uitextarea(app.report_GeneralGrid);
            app.report_ProjectFilename.Editable = 'off';
            app.report_NewProject = uibutton(app.report_GeneralGrid, 'push');
            app.report_NewProject.ButtonPushedFcn = createCallbackFcn(app, @report_NewProjectButtonPushed, true);
            app.report_NewProject.Icon = fullfile(pathToMLAPP, 'Icons', 'addFiles_32.png');
            app.report_NewProject.Tooltip = {'Novo projeto'};
            app.report_NewProject.Text = '';
            app.report_TimeStampLabel = uilabel(app.report_ControlsTab1Info);
            app.report_TimeStampLabel.Text = 'Período de observação a processar';
            app.report_TreeLabel = uilabel(app.report_ControlsTab1Info);
            app.report_TreeLabel.Text = 'Fluxos espectrais a processar';
            app.report_addButton = uiimage(app.report_ControlsTab1Info);
            app.report_addButton.ImageClickedFcn = createCallbackFcn(app, @report_addButtonPushed, true);
            app.report_addButton.Tooltip = {''};
            app.report_addButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.report_ProjectWarn = uiimage(app.report_ControlsTab1Info);
            app.report_ProjectWarn.Tooltip = {'Pendente salvar o projeto'};
            app.report_ProjectWarn.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Warn_18.png');
            app.report_ProjectPanel = uilabel(app.report_ControlsTab1Info);
            app.report_ProjectPanel.Text = 'Projeto';
            app.report_ControlsTab2Grid = uigridlayout(app.play_ControlsGrid);
            app.report_ControlsTab2Grid.Tag = 'COLORLOCKED';
            app.report_ControlsTab2Label = uilabel(app.report_ControlsTab2Grid);
            app.report_ControlsTab2Label.Text = 'ALGORITMOS';
            app.report_ControlsTab2Image = uiimage(app.report_ControlsTab2Grid);
            app.report_ControlsTab2Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.report_ControlsTab2Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Detection_32.png');
            app.report_ControlsTab2Info = uigridlayout(app.play_ControlsGrid);
            app.report_occPanel = uipanel(app.report_ControlsTab2Info);
            app.report_occGrid = uigridlayout(app.report_occPanel);
            app.report_occMethodLabel = uilabel(app.report_occGrid);
            app.report_occMethodLabel.Text = 'Método:';
            app.report_occMethod = uieditfield(app.report_occGrid, 'text');
            app.report_occMethod.Editable = 'off';
            app.report_occParameters = uitextarea(app.report_occGrid);
            app.report_occParameters.Editable = 'off';
            app.report_occButton = uibutton(app.report_occGrid, 'push');
            app.report_occButton.ButtonPushedFcn = createCallbackFcn(app, @report_AlgorithmButtonPushed, true);
            app.report_occButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.report_occButton.Enable = 'off';
            app.report_occButton.Tooltip = {''};
            app.report_occButton.Text = '';
            app.report_DetectionLabel = uilabel(app.report_ControlsTab2Info);
            app.report_DetectionLabel.Text = 'Detecção de emissões';
            app.report_DetectionPanel = uipanel(app.report_ControlsTab2Info);
            app.report_DetectionGrid = uigridlayout(app.report_DetectionPanel);
            app.report_DetectionParameters = uitextarea(app.report_DetectionGrid);
            app.report_DetectionParameters.Tag = 'Detection';
            app.report_DetectionParameters.Editable = 'off';
            app.report_DetectionBandsLabel = uilabel(app.report_DetectionGrid);
            app.report_DetectionBandsLabel.Text = 'Faixa sob análise:';
            app.report_DetectionAlgorithmLabel = uilabel(app.report_DetectionGrid);
            app.report_DetectionAlgorithmLabel.Text = 'Algoritmo:';
            app.report_DetectionAlgorithm = uieditfield(app.report_DetectionGrid, 'text');
            app.report_DetectionAlgorithm.Tag = 'Detection';
            app.report_DetectionAlgorithm.Editable = 'off';
            app.report_DetectionButton = uibutton(app.report_DetectionGrid, 'push');
            app.report_DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @report_AlgorithmButtonPushed, true);
            app.report_DetectionButton.Tag = 'Detection';
            app.report_DetectionButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.report_DetectionButton.Enable = 'off';
            app.report_DetectionButton.Tooltip = {''};
            app.report_DetectionButton.Text = '';
            app.report_DetectionBands = uitextarea(app.report_DetectionGrid);
            app.report_DetectionBands.Tag = 'Detection';
            app.report_DetectionBands.Editable = 'off';
            app.report_DetectionManualMode = uicheckbox(app.report_DetectionGrid);
            app.report_DetectionManualMode.ValueChangedFcn = createCallbackFcn(app, @report_DetectionManualModeValueChanged, true);
            app.report_DetectionManualMode.Enable = 'off';
            app.report_DetectionManualMode.Text = 'Restringir emissões às identificadas no PLAYBACK.';
            app.report_ClassificationPanel = uipanel(app.report_ControlsTab2Info);
            app.report_ClassificationGrid = uigridlayout(app.report_ClassificationPanel);
            app.report_ClassificationAlgorithmLabel = uilabel(app.report_ClassificationGrid);
            app.report_ClassificationAlgorithmLabel.Text = 'Algoritmo:';
            app.report_ClassificationAlgorithm = uieditfield(app.report_ClassificationGrid, 'text');
            app.report_ClassificationAlgorithm.Editable = 'off';
            app.report_ClassificationButton = uibutton(app.report_ClassificationGrid, 'push');
            app.report_ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @report_AlgorithmButtonPushed, true);
            app.report_ClassificationButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.report_ClassificationButton.Enable = 'off';
            app.report_ClassificationButton.Tooltip = {''};
            app.report_ClassificationButton.Text = '';
            app.report_ClassificationParameters = uitextarea(app.report_ClassificationGrid);
            app.report_ClassificationParameters.Editable = 'off';
            app.report_occLabel = uilabel(app.report_ControlsTab2Info);
            app.report_occLabel.Text = 'Ocupação';
            app.report_ClassificationLabel = uilabel(app.report_ControlsTab2Info);
            app.report_ClassificationLabel.Text = 'Classificação de emissões';
            app.report_ControlsTab3Grid = uigridlayout(app.play_ControlsGrid);
            app.report_ControlsTab3Grid.Tag = 'COLORLOCKED';
            app.report_ControlsTab3Label = uilabel(app.report_ControlsTab3Grid);
            app.report_ControlsTab3Label.Text = 'API FISCALIZA';
            app.report_ControlsTab3Image = uiimage(app.report_ControlsTab3Grid);
            app.report_ControlsTab3Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.report_ControlsTab3Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Redmine_18.png');
            app.report_ControlsTab3Info = uigridlayout(app.play_ControlsGrid);
            app.report_Fiscaliza_PanelLabel = uilabel(app.report_ControlsTab3Info);
            app.report_Fiscaliza_PanelLabel.Text = 'Aspectos gerais';
            app.report_FiscalizaRefresh = uiimage(app.report_ControlsTab3Info);
            app.report_FiscalizaRefresh.Tooltip = {'Atualiza informações da inspeção'};
            app.report_FiscalizaRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.report_FiscalizaPanel = uipanel(app.report_ControlsTab3Info);
            app.report_FiscalizaGrid = uigridlayout(app.report_FiscalizaPanel);
            app.report_FiscalizaIcon = uiimage(app.report_FiscalizaGrid);
            app.report_FiscalizaIcon.Tag = 'FiscalizaPlaceHolder';
            app.report_FiscalizaIcon.Enable = 'off';
            app.report_FiscalizaIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Redmine_512.png');
            app.edit_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.edit_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.edit_ControlsTab1Label = uilabel(app.edit_ControlsTab1Grid);
            app.edit_ControlsTab1Label.Text = 'EDIÇÃO';
            app.edit_ControlsTab1Image = uiimage(app.edit_ControlsTab1Grid);
            app.edit_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.edit_ControlsTab1Info = uigridlayout(app.play_ControlsGrid);
            app.edit_gpsRefresh = uiimage(app.edit_ControlsTab1Info);
            app.edit_gpsRefresh.ImageClickedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.edit_gpsPanel = uipanel(app.edit_ControlsTab1Info);
            app.edit_gpsGrid = uigridlayout(app.edit_gpsPanel);
            app.edit_gpsLocationLabel1 = uilabel(app.edit_gpsGrid);
            app.edit_gpsLocationLabel1.Text = 'Coordenadas geográficas';
            app.edit_gpsLocationPanel = uipanel(app.edit_gpsGrid);
            app.edit_gpsLocationGrid = uigridlayout(app.edit_gpsLocationPanel);
            app.edit_gpsSourceLabel = uilabel(app.edit_gpsLocationGrid);
            app.edit_gpsSourceLabel.Text = 'Fonte:';
            app.edit_gpsSource = uieditfield(app.edit_gpsLocationGrid, 'text');
            app.edit_gpsSource.Editable = 'off';
            app.edit_gpsSource.Value = 'auto';
            app.edit_gpsLatitudeLabel = uilabel(app.edit_gpsLocationGrid);
            app.edit_gpsLatitudeLabel.Text = 'Latitude:';
            app.edit_gpsLatitude = uieditfield(app.edit_gpsLocationGrid, 'numeric');
            app.edit_gpsLatitude.Limits = [-90 90];
            app.edit_gpsLatitude.ValueDisplayFormat = '%.6f';
            app.edit_gpsLatitude.ValueChangedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsLatitude.Editable = 'off';
            app.edit_gpsLatitude.Value = -1;
            app.edit_gpsLongitudeLabel = uilabel(app.edit_gpsLocationGrid);
            app.edit_gpsLongitudeLabel.Text = 'Longitude:';
            app.edit_gpsLongitude = uieditfield(app.edit_gpsLocationGrid, 'numeric');
            app.edit_gpsLongitude.Limits = [-180 180];
            app.edit_gpsLongitude.ValueDisplayFormat = '%.6f';
            app.edit_gpsLongitude.ValueChangedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsLongitude.Editable = 'off';
            app.edit_gpsLongitude.Value = -1;
            app.edit_gpsButton = uibutton(app.edit_gpsLocationGrid, 'push');
            app.edit_gpsButton.ButtonPushedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.edit_gpsButton.Tooltip = {''};
            app.edit_gpsButton.Text = '';
            app.edit_gpsLocationLabel2 = uilabel(app.edit_gpsGrid);
            app.edit_gpsLocationLabel2.Text = 'Localidade';
            app.edit_gpsCityPanel = uipanel(app.edit_gpsGrid);
            app.edit_gpsCityGrid = uigridlayout(app.edit_gpsCityPanel);
            app.edit_gpsCitySourceLabel = uilabel(app.edit_gpsCityGrid);
            app.edit_gpsCitySourceLabel.Text = 'Fonte:';
            app.edit_gpsCitySource = uieditfield(app.edit_gpsCityGrid, 'text');
            app.edit_gpsCitySource.Editable = 'off';
            app.edit_gpsCitySource.Value = 'API';
            app.edit_gpsCityLabel = uilabel(app.edit_gpsCityGrid);
            app.edit_gpsCityLabel.Text = 'Município/UF:';
            app.edit_gpsCity = uieditfield(app.edit_gpsCityGrid, 'text');
            app.edit_gpsCity.ValueChangedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsCity.Editable = 'off';
            app.edit_gpsCityButton = uibutton(app.edit_gpsCityGrid, 'push');
            app.edit_gpsCityButton.ButtonPushedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsCityButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.edit_gpsCityButton.Tooltip = {''};
            app.edit_gpsCityButton.Text = '';
            app.edit_unitLabel = uilabel(app.edit_ControlsTab1Info);
            app.edit_unitLabel.Text = 'Unidade de medida';
            app.edit_unitRefresh = uiimage(app.edit_ControlsTab1Info);
            app.edit_unitRefresh.ImageClickedFcn = createCallbackFcn(app, @edit_unitLevelCallbacks, true);
            app.edit_unitRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.edit_unitPanel = uipanel(app.edit_ControlsTab1Info);
            app.misc_Edit_unitGrid = uigridlayout(app.edit_unitPanel);
            app.misc_Edit_unitRawLabel = uilabel(app.misc_Edit_unitGrid);
            app.misc_Edit_unitRawLabel.Text = {'Unidade original'; 'de medida:'};
            app.misc_Edit_unitRaw = uieditfield(app.misc_Edit_unitGrid, 'text');
            app.misc_Edit_unitRaw.Editable = 'off';
            app.misc_Edit_kFactorLabel = uilabel(app.misc_Edit_unitGrid);
            app.misc_Edit_kFactorLabel.Text = {'Curva de correção:'; '(calibração, fator-k)'};
            app.misc_Edit_kFactor = uidropdown(app.misc_Edit_unitGrid);
            app.misc_Edit_kFactor.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (Argus)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (690 MHz - 6 GHz)'};
            app.misc_Edit_kFactor.Value = '';
            app.misc_Edit_kFactorAdd = uiimage(app.misc_Edit_unitGrid);
            app.misc_Edit_kFactorAdd.ImageClickedFcn = createCallbackFcn(app, @edit_unitLevelCallbacks, true);
            app.misc_Edit_kFactorAdd.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.misc_Edit_kFactorTree = uitree(app.misc_Edit_unitGrid);
            app.edit_gpsLabel = uilabel(app.edit_ControlsTab1Info);
            app.edit_gpsLabel.Text = 'Local da monitoração';
            app.misc_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.misc_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.misc_ControlsTab1Label = uilabel(app.misc_ControlsTab1Grid);
            app.misc_ControlsTab1Label.Text = 'MISCELÂNEAS';
            app.misc_ControlsTab1Image = uiimage(app.misc_ControlsTab1Grid);
            app.misc_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Misc_32.png');
            app.misc_ControlsTab1Info = uigridlayout(app.play_ControlsGrid);
            app.misc_IndexLabel = uilabel(app.misc_ControlsTab1Info);
            app.misc_IndexLabel.Text = 'Índice(s) relacionado(s) ao(s) fluxo(s) selecionado(s)';
            app.misc_Index = uieditfield(app.misc_ControlsTab1Info, 'text');
            app.misc_Label1 = uilabel(app.misc_ControlsTab1Info);
            app.misc_Label1.Text = 'Operação';
            app.misc_Panel1 = uipanel(app.misc_ControlsTab1Info);
            app.misc_Grid1 = uigridlayout(app.misc_Panel1);
            app.misc_Save = uibutton(app.misc_Grid1, 'push');
            app.misc_Save.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Save.Icon = fullfile(pathToMLAPP, 'Icons', 'saveFile_32.png');
            app.misc_Save.Tooltip = {''};
            app.misc_Save.Text = '';
            app.misc_SaveLabel = uilabel(app.misc_Grid1);
            app.misc_SaveLabel.Text = {'Salvar'; 'fluxo(s)'};
            app.misc_Duplicate = uibutton(app.misc_Grid1, 'push');
            app.misc_Duplicate.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Duplicate.Icon = fullfile(pathToMLAPP, 'Icons', 'duplicateFile_32.png');
            app.misc_Duplicate.Tooltip = {''};
            app.misc_Duplicate.Text = '';
            app.misc_DuplicateLabel = uilabel(app.misc_Grid1);
            app.misc_DuplicateLabel.Text = {'Duplicar'; 'fluxo(s)'};
            app.misc_Merge = uibutton(app.misc_Grid1, 'push');
            app.misc_Merge.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Merge.Icon = fullfile(pathToMLAPP, 'Icons', 'Merge_32.png');
            app.misc_Merge.Tooltip = {''};
            app.misc_Merge.Text = '';
            app.misc_MergeLabel = uilabel(app.misc_Grid1);
            app.misc_MergeLabel.Text = {'Mesclar'; 'fluxos'};
            app.misc_Del = uibutton(app.misc_Grid1, 'push');
            app.misc_Del.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Del.Icon = fullfile(pathToMLAPP, 'Icons', 'Delete_32Red.png');
            app.misc_Del.Tooltip = {''};
            app.misc_Del.Text = '';
            app.misc_DelLabel = uilabel(app.misc_Grid1);
            app.misc_DelLabel.Text = {'Excluir'; 'fluxo(s)'};
            app.misc_Serarator = uiimage(app.misc_Grid1);
            app.misc_Serarator.Enable = 'off';
            app.misc_Serarator.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV.png');
            app.misc_Export = uibutton(app.misc_Grid1, 'push');
            app.misc_Export.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Export.Icon = fullfile(pathToMLAPP, 'Icons', 'Export_24.png');
            app.misc_Export.Tooltip = {''};
            app.misc_Export.Text = '';
            app.misc_ExportLabel = uilabel(app.misc_Grid1);
            app.misc_ExportLabel.Text = {'Exportar'; 'análise'};
            app.misc_Import = uibutton(app.misc_Grid1, 'push');
            app.misc_Import.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Import.Icon = fullfile(pathToMLAPP, 'Icons', 'Import_24.png');
            app.misc_Import.Tooltip = {''};
            app.misc_Import.Text = '';
            app.misc_ImportLabel = uilabel(app.misc_Grid1);

            app.misc_ImportLabel.Text = {'Importar'; 'análise'};
            app.play_DeleteAll = uibutton(app.misc_Grid1, 'push');
            app.play_DeleteAll.ButtonPushedFcn = createCallbackFcn(app, @play_DeleteAllButtonPushed, true);
            app.play_DeleteAll.Icon = fullfile(pathToMLAPP, 'Icons', 'Trash_32.png');
            app.play_DeleteAll.Tooltip = {'Exclui todas as informações espectrais'};
            app.play_DeleteAll.Text = '';
            app.misc_SaveLabel_2 = uilabel(app.misc_Grid1);
            app.misc_SaveLabel_2.Text = 'Reiniciar análise';
            app.play_toolGrid = uigridlayout(app.play_Grid);
            app.play_PlaybackSlider = uislider(app.play_toolGrid);
            app.play_PlaybackSlider.MajorTicks = [0 50 100];
            app.play_PlaybackSlider.MajorTickLabels = {''};
            app.play_PlaybackSlider.ValueChangingFcn = createCallbackFcn(app, @play_PlaybackSliderValueChanging, true);
            app.play_PlaybackSlider.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.play_MinHold = uibutton(app.play_toolGrid, 'state');
            app.play_MinHold.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_MinHold.Tooltip = {'MinHold'};
            app.play_MinHold.Icon = fullfile(pathToMLAPP, 'Icons', 'MinHold_32.png');
            app.play_MinHold.Text = '';
            app.play_Average = uibutton(app.play_toolGrid, 'state');
            app.play_Average.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_Average.Tooltip = {'Média'};
            app.play_Average.Icon = fullfile(pathToMLAPP, 'Icons', 'Average_32.png');
            app.play_Average.Text = '';
            app.play_MaxHold = uibutton(app.play_toolGrid, 'state');
            app.play_MaxHold.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_MaxHold.Tooltip = {'MaxHold'};
            app.play_MaxHold.Icon = fullfile(pathToMLAPP, 'Icons', 'MaxHold_32.png');
            app.play_MaxHold.Text = '';
            app.play_Persistance = uibutton(app.play_toolGrid, 'state');
            app.play_Persistance.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_Persistance.Tooltip = {'Persistência'};
            app.play_Persistance.Icon = fullfile(pathToMLAPP, 'Icons', 'Persistance_36.png');
            app.play_Persistance.Text = '';
            app.play_Occupancy = uibutton(app.play_toolGrid, 'state');
            app.play_Occupancy.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_Occupancy.Tooltip = {'Ocupação'};
            app.play_Occupancy.Icon = fullfile(pathToMLAPP, 'Icons', 'Occupancy_32.png');
            app.play_Occupancy.Text = '';
            app.play_Waterfall = uibutton(app.play_toolGrid, 'state');
            app.play_Waterfall.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_Waterfall.Tooltip = {'Waterfall'};
            app.play_Waterfall.Icon = fullfile(pathToMLAPP, 'Icons', 'Waterfall_24.png');
            app.play_Waterfall.Text = '';
            app.misc_LayoutLeft = uiimage(app.play_toolGrid);
            app.misc_LayoutLeft.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.misc_LayoutLeft.ImageSource = fullfile(pathToMLAPP, 'Icons', 'ArrowLeft_32.png');
            app.misc_LayoutRight = uiimage(app.play_toolGrid);
            app.misc_LayoutRight.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.misc_LayoutRight.ImageSource = fullfile(pathToMLAPP, 'Icons', 'ArrowRight_32.png');
            app.play_Timestamp = uilabel(app.play_toolGrid);
            app.play_Timestamp.Text = {'22 de 328 '; '22/02/2022 08:00:00 '};
            app.play_PlaybackPlay = uiimage(app.play_toolGrid);
            app.play_PlaybackPlay.ImageClickedFcn = createCallbackFcn(app, @play_PlaybackToolbarButtonCallback, true);
            app.play_PlaybackPlay.Tooltip = {'Playback'};
            app.play_PlaybackPlay.ImageSource = fullfile(pathToMLAPP, 'Icons', 'play_32.png');
            app.play_PlaybackLoop = uiimage(app.play_toolGrid);
            app.play_PlaybackLoop.ImageClickedFcn = createCallbackFcn(app, @play_PlaybackToolbarButtonCallback, true);
            app.play_PlaybackLoop.Tag = 'loop';
            app.play_PlaybackLoop.Tooltip = {'Loop do playback'};
            app.play_PlaybackLoop.ImageSource = fullfile(pathToMLAPP, 'Icons', 'playbackLoop_32Blue.png');
            app.report_Button1 = uiimage(app.play_toolGrid);
            app.report_Button1.ImageClickedFcn = createCallbackFcn(app, @report_OpenSignalAnalysis, true);
            app.report_Button1.Tag = 'REPORT';
            app.report_Button1.Enable = 'off';
            app.report_Button1.Tooltip = {'Edita provável emissor de emissões'};
            app.report_Button1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'exceptionList_32.png');
            app.report_Button2 = uiimage(app.play_toolGrid);
            app.report_Button2.ImageClickedFcn = createCallbackFcn(app, @report_playButtonPushed, true);
            app.report_Button2.Tag = 'REPORT';
            app.report_Button2.Enable = 'off';
            app.report_Button2.Tooltip = {'Identifica emissões do(s) fluxo(s) selecionado(s)'};
            app.report_Button2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Zoom_32.png');
            app.report_Button3 = uiimage(app.play_toolGrid);
            app.report_Button3.ImageClickedFcn = createCallbackFcn(app, @report_playButtonPushed, true);
            app.report_Button3.Tag = 'REPORT';
            app.report_Button3.Enable = 'off';
            app.report_Button3.Tooltip = {'Gera relatório'};
            app.report_Button3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Publish_HTML_16.png');
            app.report_FiscalizaUpdateImage = uiimage(app.play_toolGrid);
            app.report_FiscalizaUpdateImage.Tag = 'REPORT';
            app.report_FiscalizaUpdateImage.Enable = 'off';
            app.report_FiscalizaUpdateImage.Tooltip = {'Atualiza inspeção no FISCALIZA'};
            app.report_FiscalizaUpdateImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Up_24.png');
            app.report_FiscalizaAutoFillImage = uiimage(app.play_toolGrid);
            app.report_FiscalizaAutoFillImage.Tag = 'REPORT';
            app.report_FiscalizaAutoFillImage.Enable = 'off';
            app.report_FiscalizaAutoFillImage.Tooltip = {'Preenche campos automaticamente'};
            app.report_FiscalizaAutoFillImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'AutoFill_36Blue.png');
            app.Tab3_Config = uitab(app.TabGroup);
            app.Tab3_Config.Title = 'CONFIG';
            app.config_mainGrid = uigridlayout(app.Tab3_Config);
            app.config_Control = uigridlayout(app.config_mainGrid);
            app.config_ControlMenu = uigridlayout(app.config_Control);
            app.config_menuUnderline = uiimage(app.config_ControlMenu);
            app.config_menuUnderline.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineH.png');
            app.config_menuBtn1Grid = uigridlayout(app.config_ControlMenu);
            app.config_menuBtn1Label = uilabel(app.config_menuBtn1Grid);
            app.config_menuBtn1Label.Text = 'Configurações';
            app.config_menuBtn1Icon = uiimage(app.config_menuBtn1Grid);
            app.config_menuBtn1Icon.Tag = '1';
            app.config_menuBtn1Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Settings_18.png');
            app.config_Label = uilabel(app.config_Control);
            app.config_Label.Text = 'PARÂMETROS A CONFIGURAR';
            app.config_ButtonGroup = uibuttongroup(app.config_Control);
            app.config_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @config_ButtonGroupSelectionChanged, true);
            app.config_Option_Search = uiradiobutton(app.config_ButtonGroup);
            app.config_Option_Search.Text = 'Pesquisa';
            app.config_Option_Search.Value = true;
            app.config_Option_Fiscaliza = uiradiobutton(app.config_ButtonGroup);
            app.config_Option_Fiscaliza.Text = 'API Fiscaliza';
            app.config_Option_Folder = uiradiobutton(app.config_ButtonGroup);
            app.config_Option_Folder.Text = 'Mapeamento de pastas';
            app.config_Option1Grid = uigridlayout(app.config_mainGrid);
            app.config_FolderMapLabel = uilabel(app.config_Option1Grid);
            app.config_FolderMapLabel.Text = 'MAPEAMENTO DE PASTAS';
            app.config_FolderMapPanel = uipanel(app.config_Option1Grid);
            app.config_FolderMapGrid = uigridlayout(app.config_FolderMapPanel);
            app.config_Folder_DataHubGETLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETLabel.Text = 'DataHub - GET:';
            app.config_Folder_DataHubGET = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_DataHubGET.Editable = 'off';
            app.config_Folder_DataHubGETButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETButton.Tag = 'DataHub_GET';
            app.config_Folder_DataHubGETButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Folder_DataHubPOSTLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_DataHubPOSTLabel.Text = 'DataHub - POST:';
            app.config_Folder_DataHubPOST = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_DataHubPOST.Editable = 'off';
            app.config_Folder_DataHubPOSTButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_DataHubPOSTButton.Tag = 'DataHub_POST';
            app.config_Folder_DataHubPOSTButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Folder_pythonPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_pythonPathLabel.Text = 'Pasta do ambiente virtual Python (lib fiscaliza):';
            app.config_Folder_pythonPath = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_pythonPath.Editable = 'off';
            app.config_Folder_pythonPathButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_pythonPathButton.Tag = 'pythonPath';
            app.config_Folder_pythonPathButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Folder_userPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_userPathLabel.Text = 'Pasta do usuário:';
            app.config_Folder_userPath = uidropdown(app.config_FolderMapGrid);
            app.config_Folder_userPath.Items = {''};
            app.config_Folder_userPath.Value = '';
            app.config_Folder_userPathButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_userPathButton.Tag = 'userPath';
            app.config_Folder_userPathButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Option2Grid = uigridlayout(app.config_mainGrid);
            app.config_SearchModeLabel = uilabel(app.config_Option2Grid);
            app.config_SearchModeLabel.Text = 'MODO';
            app.config_SearchModePanel = uibuttongroup(app.config_Option2Grid);
            app.config_SearchModeTokenSuggestion = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeTokenSuggestion.Tag = 'strcmp';
            app.config_SearchModeTokenSuggestion.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à palavra que está sendo escrita, sugerindo <i>tokens</i> relacionados.</p>';
            app.config_SearchModeTokenSuggestion.Interpreter = 'html';
            app.config_SearchModeTokenSuggestion.Value = true;
            app.config_SearchModeListOfWords = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeListOfWords.Tag = 'contains';
            app.config_SearchModeListOfWords.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à uma lista de palavras separadas por vírgulas. Não há sugestão de <i>tokens</i> relacionados.</p>';
            app.config_SearchModeListOfWords.Interpreter = 'html';
            app.config_MiscelaneousLabel1 = uilabel(app.config_Option2Grid);
            app.config_MiscelaneousLabel1.Text = 'ALGORITMO SUGESTÃO DE TOKENS';
            app.config_MiscelaneousPanel1 = uipanel(app.config_Option2Grid);
            app.config_MiscelaneousGrid1 = uigridlayout(app.config_MiscelaneousPanel1);
            app.config_nMinCharactersLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinCharactersLabel.Text = 'Qtd. mínima caracteres:';
            app.config_nMinCharacters = uispinner(app.config_MiscelaneousGrid1);
            app.config_nMinCharacters.Limits = [2 4];
            app.config_nMinCharacters.RoundFractionalValues = 'on';
            app.config_nMinCharacters.ValueDisplayFormat = '%d';
            app.config_nMinCharacters.Value = 2;
            app.config_nMinWordsLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinWordsLabel.Interpreter = 'html';
            app.config_nMinWordsLabel.Text = 'Qtd. mínima <i>tokens</i>:';
            app.config_nMinWords = uidropdown(app.config_MiscelaneousGrid1);
            app.config_nMinWords.Items = {'20', '50', '100'};
            app.config_nMinWords.Value = '20';
            app.config_MiscelaneousLabel2 = uilabel(app.config_Option2Grid);
            app.config_MiscelaneousLabel2.Text = 'ANOTAÇÃO DO TIPO "WORDCLOUD"';
            app.config_MiscelaneousPanel2 = uipanel(app.config_Option2Grid);
            app.config_MiscelaneousGrid2 = uigridlayout(app.config_MiscelaneousPanel2);
            app.config_WordCloudAlgorithmLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudAlgorithmLabel.Text = 'Algoritmo gráfico:';
            app.config_WordCloudAlgorithm = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudAlgorithm.Items = {'D3.js', 'MATLAB built-in'};
            app.config_WordCloudAlgorithm.Value = 'D3.js';
            app.config_WordCloudColumnLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumnLabel.Text = 'Pesquisa orientada à coluna:';
            app.config_WordCloudColumn = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumn.Items = {'Modelo', 'Nome Comercial'};
            app.config_WordCloudColumn.Value = 'Modelo';
            app.config_SelectedTableColumnsLabel = uilabel(app.config_Option2Grid);
            app.config_SelectedTableColumnsLabel.Text = 'VISIBILIDADE DE COLUNAS';
            app.config_SelectedTableColumns = uitree(app.config_Option2Grid, 'checkbox');
            app.config_Option3Grid = uigridlayout(app.config_mainGrid);
            app.config_FiscalizaVersionLabel = uilabel(app.config_Option3Grid);
            app.config_FiscalizaVersionLabel.Text = 'VERSÃO DO SISTEMA';
            app.config_FiscalizaVersion = uibuttongroup(app.config_Option3Grid);
            app.config_FiscalizaPD = uiradiobutton(app.config_FiscalizaVersion);
            app.config_FiscalizaPD.Text = 'FISCALIZA PRODUÇÃO';
            app.config_FiscalizaHM = uiradiobutton(app.config_FiscalizaVersion);
            app.config_FiscalizaHM.Text = 'FISCALIZA <font style="color: red;">HOMOLOGAÇÃO</font> (versão destinada a testes)';
            app.config_FiscalizaHM.Interpreter = 'html';
            app.config_FiscalizaHM.Value = true;
            app.config_DefaultIssueValuesLabel = uilabel(app.config_Option3Grid);
            app.config_DefaultIssueValuesLabel.Text = 'VALORES PADRÕES DE CAMPOS DA INSPEÇÃO';
            app.config_DefaultIssueValuesLock = uiimage(app.config_Option3Grid);
            app.config_DefaultIssueValuesLock.ImageSource = fullfile(pathToMLAPP, 'Icons', 'lockClose_18Gray.png');
            app.config_DefaultIssueValuesPanel = uipanel(app.config_Option3Grid);
            app.config_DefaultIssueValuesGrid = uigridlayout(app.config_DefaultIssueValuesPanel);
            app.config_CadastroSTELLabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_CadastroSTELLabel.Text = 'Entidade com cadastro STEL?';
            app.config_CadastroSTEL = uidropdown(app.config_DefaultIssueValuesGrid);
            app.config_CadastroSTEL.Items = {};
            app.config_CadastroSTEL.Enable = 'off';
            app.config_CadastroSTEL.Value = {};
            app.config_GerarPLAILabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_GerarPLAILabel.Text = 'Gerar PLAI?';
            app.config_GerarPLAI = uidropdown(app.config_DefaultIssueValuesGrid);
            app.config_GerarPLAI.Items = {};
            app.config_GerarPLAI.Enable = 'off';
            app.config_GerarPLAI.Value = {};
            app.config_TipoPLAILabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_TipoPLAILabel.Text = 'Tipo do PLAI:';
            app.config_TipoPLAI = uidropdown(app.config_DefaultIssueValuesGrid);
            app.config_TipoPLAI.Items = {};
            app.config_TipoPLAI.Enable = 'off';
            app.config_TipoPLAI.Value = {};
            app.config_MotivoLAILabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_MotivoLAILabel.Text = 'Motivo de LAI:';
            app.config_MotivoLAI = uitree(app.config_DefaultIssueValuesGrid, 'checkbox');
            app.config_MotivoLAI.Enable = 'off';
            app.config_ServicoInspecaoLabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_ServicoInspecaoLabel.Text = 'Serviços da Inspeção:';
            app.config_ServicoInspecao = uitree(app.config_DefaultIssueValuesGrid, 'checkbox');
            app.config_ServicoInspecao.Enable = 'off';
            app.menu_Panel = uipanel(app.GridLayout);
            app.menu_Grid = uigridlayout(app.menu_Panel);
            app.menu_Grid.Tag = 'COLORLOCKED';
            app.menu_Button1 = uibutton(app.menu_Grid, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button1.Tag = 'FILE';
            app.menu_Button1.Enable = 'off';
            app.menu_Button1.Tooltip = {''};
            app.menu_Button1.Icon = fullfile(pathToMLAPP, 'Icons', 'OpenFile_32Yellow.png');
            app.menu_Button1.Text = '';
            app.menu_Button2 = uibutton(app.menu_Grid, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button2.Tag = 'PLAYBACK';
            app.menu_Button2.Enable = 'off';
            app.menu_Button2.Tooltip = {''};
            app.menu_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'Playback_32White.png');
            app.menu_Button2.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button3.Tag = 'REPORT';
            app.menu_Button3.Enable = 'off';
            app.menu_Button3.Tooltip = {''};
            app.menu_Button3.Icon = fullfile(pathToMLAPP, 'Icons', 'Report_32White.png');
            app.menu_Button3.Text = '';
            app.menu_Button4 = uibutton(app.menu_Grid, 'state');
            app.menu_Button4.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button4.Tag = 'EDIT';
            app.menu_Button4.Enable = 'off';
            app.menu_Button4.Tooltip = {''};
            app.menu_Button4.Icon = fullfile(pathToMLAPP, 'Icons', 'Edit_32White.png');
            app.menu_Button4.Text = '';
            app.menu_Button5 = uibutton(app.menu_Grid, 'state');
            app.menu_Button5.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button5.Tag = 'MISC';
            app.menu_Button5.Enable = 'off';
            app.menu_Button5.Tooltip = {''};
            app.menu_Button5.Icon = fullfile(pathToMLAPP, 'Icons', 'Misc_32White.png');
            app.menu_Button5.Text = '';
            app.menu_Button6 = uibutton(app.menu_Grid, 'state');
            app.menu_Button6.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button6.Tag = 'CONFIG';
            app.menu_Button6.Enable = 'off';
            app.menu_Button6.Tooltip = {''};
            app.menu_Button6.Icon = fullfile(pathToMLAPP, 'Icons', 'Settings_36White.png');
            app.menu_Button6.Text = '';
            app.jsBackDoor = uihtml(app.menu_Grid);
            app.RFDataHubModule = uiimage(app.menu_Grid);
            app.RFDataHubModule.ImageClickedFcn = createCallbackFcn(app, @menu_OpenExternalApp_OthersButtons, true);
            app.RFDataHubModule.Enable = 'off';
            app.RFDataHubModule.ImageSource = fullfile(pathToMLAPP, 'Icons', 'mosaic_32White.png');
            app.FigurePosition = uiimage(app.menu_Grid);
            app.FigurePosition.ImageClickedFcn = createCallbackFcn(app, @FigurePositionImageClicked, true);
            app.FigurePosition.Enable = 'off';
            app.FigurePosition.ImageSource = fullfile(pathToMLAPP, 'Icons', 'layout1_32White.png');
            app.AppInfo = uiimage(app.menu_Grid);
            app.AppInfo.ImageClickedFcn = createCallbackFcn(app, @FigurePositionImageClicked, true);
            app.AppInfo.Enable = 'off';
            app.AppInfo.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Dots_32White.png');
            app.Image = uiimage(app.menu_Grid);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV_White.png');
            app.Image_2 = uiimage(app.menu_Grid);
            app.Image_2.Enable = 'off';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV_White.png');
            app.home_mainGrid = uigridlayout(app.GridLayout);
            app.home_GIF = uiimage(app.home_mainGrid);
            app.home_GIF.Enable = 'off';
            app.home_GIF.ImageSource = fullfile(pathToMLAPP, 'Icons', 'SplashScreen.gif');
            app.home_Title = uilabel(app.home_mainGrid);
            app.home_Title.Interpreter = 'html';
            app.home_Title.Text = 'appAnalise<font style="font-size:12px;"> v. 1.80</font>';
            app.file_ContextMenu_Tree1 = uicontextmenu(app.UIFigure);
            app.file_ContextMenu_delTree1Node = uimenu(app.file_ContextMenu_Tree1);
            app.file_ContextMenu_delTree1Node.MenuSelectedFcn = createCallbackFcn(app, @file_ContextMenu_delTree1NodeSelected, true);
            app.file_ContextMenu_delTree1Node.Text = 'Excluir';
            app.file_ContextMenu_Tree2 = uicontextmenu(app.UIFigure);
            app.file_ContextMenu_delTree2Node = uimenu(app.file_ContextMenu_Tree2);
            app.file_ContextMenu_delTree2Node.MenuSelectedFcn = createCallbackFcn(app, @file_ContextMenu_delTree2NodeSelected, true);
            app.file_ContextMenu_delTree2Node.Text = 'Excluir';
            app.play_FindPeaks_ContextMenu = uicontextmenu(app.UIFigure);
            app.play_FindPeaks_ContextMenu_Drive = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_Drive.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_DriveTest, true);
            app.play_FindPeaks_ContextMenu_Drive.Text = 'Drive-test';
            app.play_FindPeaks_ContextMenu_search = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_search.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_Classification, true);
            app.play_FindPeaks_ContextMenu_search.Text = 'Consultar';
            app.play_FindPeaks_ContextMenu_edit = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_edit.Text = 'Editar';
            app.play_FindPeaks_ContextMenu_analog = uimenu(app.play_FindPeaks_ContextMenu_edit);
            app.play_FindPeaks_ContextMenu_analog.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_TruncatedEmission, true);
            app.play_FindPeaks_ContextMenu_analog.Text = 'Não truncar';
            app.play_FindPeaks_ContextMenu_digital = uimenu(app.play_FindPeaks_ContextMenu_edit);
            app.play_FindPeaks_ContextMenu_digital.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_TruncatedEmission, true);
            app.play_FindPeaks_ContextMenu_digital.Text = 'Truncar frequência';
            app.play_FindPeaks_ContextMenu_del = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_delEmission, true);
            app.play_FindPeaks_ContextMenu_del.Text = 'Excluir';
            app.play_Channel_ContextMenu = uicontextmenu(app.UIFigure);
            app.play_Channel_ContextMenu_del = uimenu(app.play_Channel_ContextMenu);
            app.play_Channel_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @play_Channel_ContextMenu_delChannel, true);
            app.play_Channel_ContextMenu_del.Text = 'Excluir';
            app.play_Channel_ContextMenu_add = uimenu(app.play_Channel_ContextMenu);
            app.play_Channel_ContextMenu_add.MenuSelectedFcn = createCallbackFcn(app, @play_Channel_ContextMenu_addSelected, true);
            app.play_Channel_ContextMenu_add.Text = 'Adicionar limites à detecção';
            app.report_ContextMenu = uicontextmenu(app.UIFigure);
            app.report_ContextMenu_del = uimenu(app.report_ContextMenu);
            app.report_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @report_ContextMenu_delSelected, true);
            app.report_ContextMenu_del.Separator = 'on';
            app.report_ContextMenu_del.Text = 'Excluir';
            app.report_ContextMenu_ExternalFiles = uimenu(app.report_ContextMenu);
            app.report_ContextMenu_ExternalFiles.MenuSelectedFcn = createCallbackFcn(app, @report_ExternalFilesMenuSelected, true);
            app.report_ContextMenu_ExternalFiles.Text = 'Arquivos externos';
            app.play_BandLimits_ContextMenu = uicontextmenu(app.UIFigure);
            app.play_BandLimits_ContextMenu_del = uimenu(app.play_BandLimits_ContextMenu);
            app.play_BandLimits_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @play_BandLimits_ContextMenu_delSelected, true);
            app.play_BandLimits_ContextMenu_del.Text = 'Excluir';
            app.edit_ContextMenu = uicontextmenu(app.UIFigure);
            app.edit_ContextMenu_del = uimenu(app.edit_ContextMenu);
            app.edit_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @edit_unitLevelCallbacks, true);
            app.edit_ContextMenu_del.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAppAnalise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end