classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Grid                      matlab.ui.container.GridLayout
        imgShowHide               matlab.ui.control.Image
        Panel                     matlab.ui.container.Panel
        Panel_Grid                matlab.ui.container.GridLayout
        Tab1_Grid1                matlab.ui.container.GridLayout
        Info                      matlab.ui.control.TextArea
        InfoLabel                 matlab.ui.control.Label
        Tree                      matlab.ui.container.CheckBoxTree
        TreeLabel                 matlab.ui.control.Label
        Emissions                 matlab.ui.control.DropDown
        Tab1_Grid2                matlab.ui.container.GridLayout
        EmissionsRefresh          matlab.ui.control.Image
        EmissionsLabel            matlab.ui.control.Label
        binTable_Panel            matlab.ui.container.GridLayout
        binTable_Grid             matlab.ui.container.GridLayout
        binTable_Info             matlab.ui.control.Label
        binTable_Col4             matlab.ui.control.Label
        binTable_Col3             matlab.ui.control.Label
        binTable_Col2             matlab.ui.control.Label
        binTable_Col1             matlab.ui.control.Label
        binTable_UITable          matlab.ui.control.Table
        binTable_Fcn              matlab.ui.control.DropDown
        binTable_FcnLabel         matlab.ui.control.Label
        binTable_Type             matlab.ui.control.DropDown
        binTable_TypeLabel        matlab.ui.control.Label
        binTable_Distance         matlab.ui.control.Spinner
        binTable_DistanceLabel    matlab.ui.control.Label
        locTable_Panel            matlab.ui.container.GridLayout
        locTable_Grid             matlab.ui.container.GridLayout
        locTable_Info             matlab.ui.control.Label
        locTable_Col4             matlab.ui.control.Label
        locTable_Col3             matlab.ui.control.Label
        locTable_Col2             matlab.ui.control.Label
        locTable_Col1             matlab.ui.control.Label
        locTable_UITable          matlab.ui.control.Table
        locTable_Fcn              matlab.ui.control.DropDown
        locTable_FcnLabel         matlab.ui.control.Label
        Tab4_Panel                matlab.ui.container.GridLayout
        Tab4_Panel1               matlab.ui.container.Panel
        Tab4_Grid1                matlab.ui.container.GridLayout
        Panel_2                   matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        TipoDropDown              matlab.ui.control.DropDown
        TipoDropDownLabel         matlab.ui.control.Label
        FatorSpinner              matlab.ui.control.Spinner
        FatorSpinnerLabel         matlab.ui.control.Label
        imgDensity_Title_3        matlab.ui.control.Label
        imgDensity_ButtonGroup_2  matlab.ui.container.ButtonGroup
        imgDensity_binData_3      matlab.ui.control.RadioButton
        imgDensity_binData_2      matlab.ui.control.RadioButton
        imgDensity_rawData_2      matlab.ui.control.RadioButton
        imgDensity_SourceLabel_2  matlab.ui.control.Label
        imgDensity_Title          matlab.ui.control.Label
        imgDensity_Panel          matlab.ui.container.Panel
        imgDensity_Grid           matlab.ui.container.GridLayout
        imgDensity_Radius         matlab.ui.control.Slider
        imgDensity_RadiusLabel    matlab.ui.control.Label
        imgScatter_Panel          matlab.ui.container.Panel
        imgScatter_Grid           matlab.ui.container.GridLayout
        imgScatter_Radius         matlab.ui.control.Slider
        imgScatter_RadiusLabel    matlab.ui.control.Label
        imgScatter_Title          matlab.ui.control.Label
        imgRoute_Panel            matlab.ui.container.Panel
        imgRoute_Grid             matlab.ui.container.GridLayout
        imgRoute_Size             matlab.ui.control.Slider
        imgRoute_Color            matlab.ui.control.Button
        imgRoute_PropertyLabel    matlab.ui.control.Label
        imgRoute_Type             matlab.ui.control.DropDown
        imgRoute_TypeLabel        matlab.ui.control.Label
        imgRoute_Title            matlab.ui.control.Label
        Colormap                  matlab.ui.control.DropDown
        ColormapLabel             matlab.ui.control.Label
        imgDensity                matlab.ui.control.Image
        imgScatter                matlab.ui.control.Image
        imgRoute                  matlab.ui.control.Image
        Tab4_Grid                 matlab.ui.container.GridLayout
        Tab4_Title                matlab.ui.control.Label
        Tab4_Image                matlab.ui.control.Image
        Tab3_Grid                 matlab.ui.container.GridLayout
        Tab3_Title                matlab.ui.control.Label
        Tab3_Image                matlab.ui.control.Image
        Tab2_Grid                 matlab.ui.container.GridLayout
        Tab2_Title                matlab.ui.control.Label
        Tab2_Image                matlab.ui.control.Image
        Tab1_Grid                 matlab.ui.container.GridLayout
        Tab1_Title                matlab.ui.control.Label
        Tab1_Image                matlab.ui.control.Image
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
        specData
        Index
        
        rawTable
        locTable
        binTable

        UIAxes1
        hRoute
        hCar
        hScatter
        hDensity
        
        % UIAxes2
        hMinHold
        hClearWrite
        hMaxHold        
        hMeanHold

        % UIAxes3
        hChannelPower
        hSmoothedData
        hROI_Line
        hROI_Label        
        
    end
    
    methods (Access = private)

        function Fcn1_AxesCreation(app)

            app.UIAxes1 = geoaxes(app.Grid, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater');

            while true
                try
                    addToolbarMapButton(axtoolbar(app.UIAxes1, "default"), "basemap", BasemapNames=["darkwater", "satellite", "streets-light", "streets-dark", "topographic", "streets", "colorterrain", "grayterrain"])

                    app.UIAxes1.Layout.Row    = 1;
                    app.UIAxes1.Layout.Column = 3:4;
                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';

                    colormap(app.UIAxes1, app.Colormap.Value)
                    hold(app.UIAxes1, 'on')

                    break
                catch
                end
            end

            app.UIAxes2.Interactions = dataTipInteraction;
            enableDefaultInteractivity(app.UIAxes2)
            
            app.UIAxes3.Interactions = [];

        end
        

        function Fcn2_General(app)   

            % EMISSÕES 
            Fcn2_General_Emissions(app)

            % ARQUIVOS
            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end

            for ii = 1:height(app.specData.RelatedFiles)
                uitreenode(app.Tree, 'Text', app.specData.RelatedFiles.Name{ii});
            end
            app.Tree.CheckedNodes = app.Tree.Children(1);
            TreeCheckedNodesChanged(app)
        
        end


        function Fcn2_General_Emissions(app)

            emissionTable = app.specData.Emissions;
            
            app.Emissions.Items = {};
            for ii = 1:height(emissionTable)
                app.Emissions.Items{end+1} = sprintf('%d: %.3f MHz @ %.3f kHz',        ...
                                                          ii,                          ...
                                                          emissionTable.Frequency(ii), ...
                                                          emissionTable.BW(ii));
            end

        end


        function Fcn3_rawTable(app)

            % app.rawTable
            FK1 = -1*ones(app.specData.Samples, 1, 'int32');
            FK2 = FK1; 

            timestamp = app.specData.Data{1}';
            
            Lat  = [];
            Long = [];
            for ii = 1:numel(app.specData.RelatedGPS)
                Lat  = [Lat;  app.specData.RelatedGPS{ii}.Matrix(:,1)];
                Long = [Long; app.specData.RelatedGPS{ii}.Matrix(:,2)];
            end
            [LatEq, LongEq] = grn2eqa(Lat, Long);
            
            rawPower = Fcn4_chPower(app);
            app.rawTable = table(FK1, FK2, timestamp, Lat, Long, LatEq, LongEq, rawPower);


            % app.locTable
            lat_String = strsplit(sprintf('%.6f\n', Lat),  '\n')';
            lon_String = strsplit(sprintf('%.6f\n', Long), '\n')';
            latlong    = string(lat_String(1:end-1)) + ", " + string(lon_String(1:end-1));

            [latlong_String, latlong_Index, app.rawTable.FK1] = unique(latlong, 'stable');
            
            PK1      = int32(1:numel(latlong_String))';
            locLat   = Lat(latlong_Index);
            locLong  = Long(latlong_Index);
            locCount = accumarray(app.rawTable.FK1, 1);
            locPower = Fcn4_locPower(app);

            app.locTable = table(PK1, locLat, locLong, locCount, locPower);


            % app.binTable
            [binLat, binLong, binCount] = hista(app.rawTable.lat, app.rawTable.long, (app.binTable_Distance.Value/1000)^2);            
            [binLatEq, binLongEq] = grn2eqa(binLat, binLong);
            PK2 = int32(1:numel(binLat))';
            
            dist = pdist2([app.rawTable.longEq, app.rawTable.latEq], [binLongEq, binLatEq]);            
            [~, app.rawTable.FK2] = min(dist, [], 2);
            binPower = Fcn4_binPower(app);

            app.binTable = table(PK2, binLat, binLong, binCount, binPower);

        end


        function Fcn4_UITables(app)

            app.locTable_UITable.Data = app.locTable(:,1:4);
            app.binTable_UITable.Data = app.binTable(:,1:4);

            % app.locTable
            [locCount_min, locCount_max] = bounds(app.locTable.locCount);

            ptDist = deg2km(distance('gc', [app.locTable.locLat(1:end-1), app.locTable.locLong(1:end-1)], [app.locTable.locLat(2:end), app.locTable.locLong(2:end)]));
            [ptDist_min, ptDist_max] = bounds(ptDist*1000);
            ptDist_mean = mean(ptDist)*1000; 
            
            auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', ptDist_min, ptDist_mean, ptDist_max);
            auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto',    locCount_min, locCount_max);

            app.locTable_Info.Text = sprintf('%s\n%.0f |–––––––%s| %.0f varreduras/ponto', auxString1, locCount_min, repmat('–', 1, numel(auxString1)-numel(auxString2)+1), locCount_max);


            % app.binTable 
            [binCount_min, binCount_max] = bounds(app.binTable.binCount);
            app.binTable_Info.Text = sprintf('%.0f |–––––––| %.0f varreduras/ponto', binCount_min, binCount_max);

        end


        function chPower = Fcn4_chPower(app)

            idx = find(strcmp(app.Emissions.Items, app.Emissions.Value), 1);
            FreqCenter = app.specData.Emissions.Frequency(idx) * 1e+6;
            BW         = app.specData.Emissions.BW(idx)        * 1e+3;

            chPower = Misc_chPower(app.specData, [FreqCenter-BW/2, FreqCenter+BW/2]);

        end


        function locPower = Fcn4_locPower(app)

            switch app.locTable_Fcn.Value
                case 'Mínima';  locFcn = @min;
                case 'Média';   locFcn = @(x) pow2db(mean(db2pow(x)));
                case 'Mediana'; locFcn = @(x) pow2db(median(db2pow(x)));
                case 'RMS';     locFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'Máxima';  locFcn = @max;
            end
            locPower = splitapply(locFcn, app.rawTable.rawPower, app.rawTable.FK1);

        end


        function binPower = Fcn4_binPower(app)

            switch app.binTable_Fcn.Value
                case 'Mínima';  binFcn = @min;
                case 'Média';   binFcn = @(x) pow2db(mean(db2pow(x)));
                case 'Mediana'; binFcn = @(x) pow2db(median(db2pow(x)));
                case 'RMS';     binFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'Máxima';  binFcn = @max;
            end
            binPower = splitapply(binFcn, app.rawTable.rawPower, app.rawTable.FK2);

        end

        function Fcn5_Plot_General(app)

            idx = find(strcmp(app.Emissions.Items, app.Emissions.Value), 1);

            F0_roi = app.specData.Emissions.Frequency(idx) - app.specData.Emissions.BW(idx) / 2000;
            BW_roi = app.specData.Emissions.BW(idx)/1e+3;

            xData  = linspace(app.specData.MetaData.FreqStart/1e+6, app.specData.MetaData.FreqStop/1e+6, app.specData.MetaData.DataPoints);
            minY   = min(app.specData.statsData(:, 1));
            maxY   = max(app.specData.statsData(:, 4));

            color1   = [1.00,0.07,0.65]; % MaxHold
            color2   = [0.38,0.60,0.73]; % MinHold
            color3   = [0.39,0.83,0.07]; % Median or mean
            color4   = [0.93,0.69,0.13]; % ClearWrite
            roiColor = [0.40,0.73,0.88]; %[.00 .45 .74]; % Band roi region

            % Plot
            hold(app.UIAxes2, 'on')
            area(app.UIAxes2, xData, app.specData.statsData(:, 1), 'EdgeColor', color2, 'FaceColor', color2, 'BaseValue', minY);
            app.hClearWrite = plot(app.UIAxes2, xData, app.specData.Data{2}(:, 1), 'Color', color4);
            plot(app.UIAxes2, xData, app.specData.statsData(:, 3), 'Color', color3);
            plot(app.UIAxes2, xData, app.specData.statsData(:, 4), 'Color', color1);
            hold(app.UIAxes2, 'off')

            set(app.UIAxes2, 'XLim', [xData(1), xData(end)], 'YLim', [minY, maxY])
            ylabel(app.UIAxes2, sprintf('Nível (%s)', app.specData.MetaData.metaString{1}))

            images.roi.Rectangle(app.UIAxes2, Position = [F0_roi, -200, BW_roi, 400], ...
                                              Color = roiColor, MarkerSize = 1, Deletable = 0, EdgeAlpha = 0, InteractionsAllowed = 'none');

            for ii = 1:numel(app.UIAxes2.Children)
                if ismember(app.UIAxes2.Children(ii).Type, {'line', 'area', 'surface'}) 
                    Misc_DataTipSettings(app.UIAxes2.Children(ii), app.specData.MetaData.metaString{1})
                end
            end

        end


        function Fcn5_Plot_chPower(app)

            color4   = [0.93,0.69,0.13]; % ClearWrite
            roiColor = [0.40,0.73,0.88]; %[.00 .45 .74]; % Band roi region

            % Plot
            [minY, maxY] = bounds(app.specTable.chPower);
            set(app.UIAxes3, 'XLim', [1, height(app.specTable)], 'YLim', [minY, maxY], ...
                             'XTick', round(linspace(1,height(app.specTable), 5)))
            app.UIAxes3.XAxis.Exponent = 0;
            
            hold(app.UIAxes3, 'on')
            
            plot(app.UIAxes3, app.specTable.chPower, 'Color', color4);
            app.hSmoothedData  = plot(app.UIAxes3, smoothdata(app.specTable.chPower, app.TipoDropDown.Value, 'SmoothingFactor', app.FatorSpinner.Value));
            app.hROI_Line = images.roi.Line(app.UIAxes3, 'Position', [1 minY; 1 maxY], 'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate');
            app.hROI_Label = text(app.UIAxes3, 1, app.UIAxes3.YLim(end), sprintf('  %.0f dBm', app.specTable.chPower(1)), 'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red');
            
            hold(app.UIAxes3, 'off')
            
            addlistener(app.hROI_Line, 'MovingROI', @app.timeROICallback);
            addlistener(app.hROI_Line, 'ROIMoved',  @app.timeROICallback);

        end


        function timeROICallback(app, src, event)
            
            idx = round(event.Source.Position(1));

            switch(event.EventName)
                case{'MovingROI'}
                    disableDefaultInteractivity(app.UIAxes3)                    
                    try
                        app.hROI_Label.Position(1) = idx;
                        app.hROI_Label.String      = sprintf('  %.0f dBm', app.specTable.chPower(idx));

                        app.hClearWrite.YData = app.specData.Data{2}(:,idx);
                        set(app.hCar, 'LatitudeData', app.specTable.lat(idx), 'LongitudeData', app.specTable.long(idx))
                    catch
                    end
                    
                case{'ROIMoved'}
                    enableDefaultInteractivity(app.UIAxes3)

                    [minY, maxY] = bounds(app.specTable.chPower);
                    app.hROI_Line.Position = [idx minY; idx maxY];
            end
            
        end


        function Fcn5_Plot_Route(app)

            switch app.imgRoute_Type.Value
                case 'Ponto'
                    app.hRoute = geoscatter(app.UIAxes1, app.locTable_UITable.Data.lat, app.locTable_UITable.Data.long, ...
                                            'filled', 'Marker', 'o',                                  ...
                                            'MarkerFaceColor', app.imgRoute_Color.BackgroundColor,    ...
                                            'MarkerEdgeColor', app.imgRoute_Color.BackgroundColor,    ...
                                            'SizeData', .5*app.imgRoute_Size.Value);
                
                case 'Linha'
                    app.hRoute = geoplot(app.UIAxes1, app.locTable_UITable.Data.lat, app.locTable_UITable.Data.long, ...
                                         'Color', app.imgRoute_Color.BackgroundColor,              ...
                                         'LineWidth', .5*app.imgRoute_Size.Value);
                    
                case 'Densidade'
                    app.hRoute = geodensityplot(app.UIAxes1, app.locTable_UITable.Data.lat, app.locTable_UITable.Data.long, ...
                                         'FaceColor', app.imgRoute_Color.BackgroundColor,                 ...
                                         'Radius', 50*app.imgRoute_Size.Value);
            end
            app.hCar = geoscatter(app.UIAxes1, app.locTable_UITable.Data.lat(1), app.locTable_UITable.Data.long(1), ...
                                            'filled', 'Marker', 'o',     ...
                                            'MarkerFaceColor', 'red',    ...
                                            'MarkerEdgeColor', 'red',    ...
                                            'SizeData', 50);
            app.imgRoute.ImageSource = 'DriveTest_Route_ON.png';

        end


        function Fcn5_Plot_Scatter(app)

            app.hScatter = geoscatter(app.UIAxes1, app.locTable_UITable.Data.lat, app.locTable_UITable.Data.long, app.locTable_UITable.Data.latlong_Count, app.locTable_UITable.Data.latlong_Count, 'filled');
            app.imgScatter.ImageSource = 'DriveTest_Scatter_ON.png';

        end


        function Fcn5_Plot_Density(app)

            app.hDensity = geodensityplot(app.UIAxes1, app.locTable_UITable.Data.lat, app.locTable_UITable.Data.long, app.locTable_UITable.Data.latlong_Count, 'FaceColor','interp', 'Radius', 400);
            app.imgDensity.ImageSource = 'DriveTest_Density_ON.png';

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx, Tag)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.UIFigure.Tag = Tag;
            
            app.specData = app.CallingApp.specData(idx);
            app.Index    = idx;

            Fcn1_AxesCreation(app)
            Fcn2_General(app)
            Fcn3_rawTable(app)
            Fcn4_chPower(app)
            
            Fcn5_Plot_General(app)
            Fcn5_Plot_chPower(app)
            Fcn5_Plot_Route(app)

            imgTreeSelectionChanged(app, struct('Source', app.Tab1_Image))
            
            drawnow nocallbacks
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            
            checkedNodes = app.Tree.CheckedNodes;
            if isempty(checkedNodes)
                app.Tree.CheckedNodes = event.PreviousCheckedNodes;
                return
            end

            metaData = '';
            for ii = 1:numel(checkedNodes)
                idx = find(strcmp(app.specData.RelatedFiles.Name, checkedNodes(ii).Text), 1);

                ObservationTime = sprintf('%s - %s', datestr(app.specData.RelatedFiles.BeginTime(idx), 'dd/mm/yyyy HH:MM:SS'), datestr(app.specData.RelatedFiles.EndTime(idx), 'dd/mm/yyyy HH:MM:SS'));

                metaData = sprintf(['%s• %s\n' ...
                                    '  %s\n' ...
                                    '  %.0f varreduras, %.3f seg (tempo de revisita)\n\n'], ...
                                    metaData, ...
                                    app.specData.RelatedFiles.Name{idx}, ...
                                    ObservationTime, ...
                                    app.specData.RelatedFiles.Samples(idx), ...
                                    app.specData.RelatedFiles.RevisitTime(idx));
            end

            app.Info.Value = sprintf(['Faixa: %.3f - %.3f MHz\n\n'  ...
                                      'Pontos por traço: %.0f\n'    ...
                                      'Unidade: %s\n'               ...
                                      'Resolução: %.3f kHz\n\n%s'], ...
                                      app.specData.MetaData.FreqStart / 1e+6,  ...
                                      app.specData.MetaData.FreqStop  / 1e+6,  ...
                                      app.specData.MetaData.DataPoints,        ...
                                      app.specData.MetaData.metaString{1},     ...
                                      app.specData.MetaData.Resolution / 1e+3, ...
                                      metaData);

        end

        % Image clicked function: EmissionsRefresh
        function EmissionsRefreshImageClicked(app, event)
            
            preSelection = extractAfter(app.Emissions.Value, ': ');
            
            app.specData = app.CallingApp.specData(app.Index);
            Fcn2_General_Emissions(app)

            idx = find(contains(app.Emissions.Items, preSelection), 1);
            if ~isempty(idx)
                app.Emissions.Value = app.Emissions.Items(idx);
            else
                Fcn5_Plot(app)
            end

        end

        % Image clicked function: imgShowHide
        function imgShowHideClicked(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.imgShowHide.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.imgShowHide.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

        end

        % Image clicked function: imgDensity, imgRoute, imgScatter
        function imgPlotButtonClicked(app, event)
            
            switch event.Source
                case app.imgRoute
                    if isempty(app.hRoute)
                        Fcn5_Plot_Route(app);
                    else
                        if app.hRoute.Visible
                            app.hRoute.Visible = 0;
                            app.imgRoute.ImageSource = 'DriveTest_Route_OFF.png';
                        else
                            app.hRoute.Visible = 1;
                            app.imgRoute.ImageSource = 'DriveTest_Route_ON.png';
                        end
                    end

                case app.imgScatter
                    if isempty(app.hScatter)
                        Fcn5_Plot_Scatter(app);
                    else
                        if app.hScatter.Visible
                            app.hScatter.Visible = 0;
                            app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
                        else
                            app.hScatter.Visible = 1;
                            app.imgScatter.ImageSource = 'DriveTest_Scatter_ON.png';
                        end
                    end

                case app.imgDensity
                    if isempty(app.hDensity)
                        Fcn5_Plot_Density(app);
                    else
                        if app.hDensity.Visible
                            app.hDensity.Visible = 0;
                            app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
                        else
                            app.hDensity.Visible = 1;
                            app.imgDensity.ImageSource = 'DriveTest_Density_ON.png';
                        end
                    end
            end

        end

        % Image clicked function: Tab1_Image, Tab2_Image, Tab3_Image, 
        % Tab4_Image
        function imgTreeSelectionChanged(app, event)
            
            switch event.Source
                case app.Tab1_Image; app.Panel_Grid.RowHeight([2,4,6,8]) = {'1x',0,0,0};
                case app.Tab2_Image; app.Panel_Grid.RowHeight([2,4,6,8]) = {0,'1x',0,0};
                case app.Tab3_Image; app.Panel_Grid.RowHeight([2,4,6,8]) = {0,0,'1x',0};
                case app.Tab4_Image; app.Panel_Grid.RowHeight([2,4,6,8]) = {0,0,0,'1x'};
            end

        end

        % Value changing function: imgRoute_Size
        function imgRoute_SizeValueChanging(app, event)
            
            try
                switch app.imgRoute_Type.Value
                    case {'Ponto', 'Linha'}
                        set(app.hRoute, 'LineWidth', .5*event.Value)

                    case 'Densidade'
                        set(app.hRoute, 'Radius', 50*event.Value)
                end
            catch
            end

        end

        % Button pushed function: imgRoute_Color
        function imgRoute_ColorPushed(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                app.imgRoute_Color.BackgroundColor = selectedColor;

                try
                    switch app.imgRoute_Type.Value
                        case 'Ponto'
                            set(app.hRoute, 'MarkerFaceColor', selectedColor, ...
                                            'MarkerEdgeColor', selectedColor)

                        case 'Linha'
                            set(app.hRoute, 'Color', selectedColor)

                        case 'Densidade'
                            set(app.hRoute, 'FaceColor', selectedColor)
                    end
                catch
                end
            end

        end

        % Value changed function: imgRoute_Type
        function imgRoute_TypeValueChanged(app, event)

            if ~isempty(app.hRoute)
                delete(app.hRoute)
            end
            Fcn5_Plot_Route(app)
            
        end

        % Value changed function: Colormap
        function ColormapValueChanged(app, event)
            
            colormap(app.UIAxes1, app.Colormap.Value)
            
        end

        % Value changed function: FatorSpinner, TipoDropDown
        function TipoDropDownValueChanged(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");            
            app.hSmoothedData.YData = smoothdata(app.specTable.chPower, app.TipoDropDown.Value, 'SmoothingFactor', app.FatorSpinner.Value);
            delete(d)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.Grid);
            xlabel(app.UIAxes3, 'Amostra')
            ylabel(app.UIAxes3, 'Potência canal (dBm)')
            app.UIAxes2 = uiaxes(app.Grid);
            xlabel(app.UIAxes2, 'Frequência')
            ylabel(app.UIAxes2, 'Nível (dBm)')
            app.Panel = uipanel(app.Grid);
            app.Panel_Grid = uigridlayout(app.Panel);
            app.Tab1_Grid = uigridlayout(app.Panel_Grid);
            app.Tab1_Grid.Tag = 'COLORLOCKED';
            app.Tab1_Image = uiimage(app.Tab1_Grid);
            app.Tab1_Image.ImageClickedFcn = createCallbackFcn(app, @imgTreeSelectionChanged, true);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab1_Title = uilabel(app.Tab1_Grid);
            app.Tab1_Title.Text = 'ASPECTOS GERAIS';
            app.Tab2_Grid = uigridlayout(app.Panel_Grid);
            app.Tab2_Grid.Tag = 'COLORLOCKED';
            app.Tab2_Image = uiimage(app.Tab2_Grid);
            app.Tab2_Image.ImageClickedFcn = createCallbackFcn(app, @imgTreeSelectionChanged, true);
            app.Tab2_Image.ImageSource = 'Instr_Global.png';
            app.Tab2_Title = uilabel(app.Tab2_Grid);
            app.Tab2_Title.Text = 'PONTOS DE MEDIÇÃO';
            app.Tab3_Grid = uigridlayout(app.Panel_Grid);
            app.Tab3_Grid.Tag = 'COLORLOCKED';
            app.Tab3_Image = uiimage(app.Tab3_Grid);
            app.Tab3_Image.ImageClickedFcn = createCallbackFcn(app, @imgTreeSelectionChanged, true);
            app.Tab3_Image.ImageSource = 'LT_Detection.png';
            app.Tab3_Title = uilabel(app.Tab3_Grid);
            app.Tab3_Title.Text = 'DATA BINNING';
            app.Tab4_Grid = uigridlayout(app.Panel_Grid);
            app.Tab4_Grid.Tag = 'COLORLOCKED';
            app.Tab4_Image = uiimage(app.Tab4_Grid);
            app.Tab4_Image.ImageClickedFcn = createCallbackFcn(app, @imgTreeSelectionChanged, true);
            app.Tab4_Image.ImageSource = 'LT_Layers.png';
            app.Tab4_Title = uilabel(app.Tab4_Grid);
            app.Tab4_Title.Text = 'CAMADAS';
            app.Tab4_Panel = uigridlayout(app.Panel_Grid);
            app.imgRoute = uiimage(app.Tab4_Panel);
            app.imgRoute.ImageClickedFcn = createCallbackFcn(app, @imgPlotButtonClicked, true);
            app.imgRoute.Tooltip = {'Rota'};
            app.imgRoute.ImageSource = 'DriveTest_Route_OFF.png';
            app.imgScatter = uiimage(app.Tab4_Panel);
            app.imgScatter.ImageClickedFcn = createCallbackFcn(app, @imgPlotButtonClicked, true);
            app.imgScatter.Tooltip = {'Distorção'};
            app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
            app.imgDensity = uiimage(app.Tab4_Panel);
            app.imgDensity.ImageClickedFcn = createCallbackFcn(app, @imgPlotButtonClicked, true);
            app.imgDensity.Tooltip = {'Densidade'};
            app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
            app.Tab4_Panel1 = uipanel(app.Tab4_Panel);
            app.Tab4_Grid1 = uigridlayout(app.Tab4_Panel1);
            app.ColormapLabel = uilabel(app.Tab4_Grid1);
            app.ColormapLabel.Text = 'Mapa de cor:';
            app.Colormap = uidropdown(app.Tab4_Grid1);
            app.Colormap.Items = {'hot', 'summer', 'winter', 'jet'};
            app.Colormap.ValueChangedFcn = createCallbackFcn(app, @ColormapValueChanged, true);
            app.Colormap.Value = 'jet';
            app.imgRoute_Title = uilabel(app.Tab4_Grid1);
            app.imgRoute_Title.Text = 'Rota:';
            app.imgRoute_Panel = uipanel(app.Tab4_Grid1);
            app.imgRoute_Grid = uigridlayout(app.imgRoute_Panel);
            app.imgRoute_TypeLabel = uilabel(app.imgRoute_Grid);
            app.imgRoute_TypeLabel.Text = 'Tipo:';
            app.imgRoute_Type = uidropdown(app.imgRoute_Grid);
            app.imgRoute_Type.Items = {'Ponto', 'Linha', 'Densidade'};
            app.imgRoute_Type.ValueChangedFcn = createCallbackFcn(app, @imgRoute_TypeValueChanged, true);
            app.imgRoute_Type.Value = 'Linha';
            app.imgRoute_PropertyLabel = uilabel(app.imgRoute_Grid);
            app.imgRoute_PropertyLabel.Text = 'Propriedades (cor, espessura/raio):';
            app.imgRoute_Color = uibutton(app.imgRoute_Grid, 'push');
            app.imgRoute_Color.ButtonPushedFcn = createCallbackFcn(app, @imgRoute_ColorPushed, true);
            app.imgRoute_Color.Text = '';
            app.imgRoute_Size = uislider(app.imgRoute_Grid);
            app.imgRoute_Size.Limits = [1 9];
            app.imgRoute_Size.MajorTicks = [1 3 5 7 9];
            app.imgRoute_Size.MajorTickLabels = {''};
            app.imgRoute_Size.ValueChangingFcn = createCallbackFcn(app, @imgRoute_SizeValueChanging, true);
            app.imgRoute_Size.Value = 1;
            app.imgScatter_Title = uilabel(app.Tab4_Grid1);
            app.imgScatter_Title.Text = 'Distorção:';
            app.imgScatter_Panel = uipanel(app.Tab4_Grid1);
            app.imgScatter_Grid = uigridlayout(app.imgScatter_Panel);
            app.imgScatter_RadiusLabel = uilabel(app.imgScatter_Grid);
            app.imgScatter_RadiusLabel.Text = 'Raio:';
            app.imgScatter_Radius = uislider(app.imgScatter_Grid);
            app.imgScatter_Radius.Limits = [1 9];
            app.imgScatter_Radius.MajorTicks = [1 3 5 7 9];
            app.imgScatter_Radius.MajorTickLabels = {''};
            app.imgScatter_Radius.Value = 1;
            app.imgDensity_Panel = uipanel(app.Tab4_Grid1);
            app.imgDensity_Grid = uigridlayout(app.imgDensity_Panel);
            app.imgDensity_RadiusLabel = uilabel(app.imgDensity_Grid);
            app.imgDensity_RadiusLabel.Text = 'Raio:';
            app.imgDensity_Radius = uislider(app.imgDensity_Grid);
            app.imgDensity_Radius.Limits = [1 9];
            app.imgDensity_Radius.MajorTicks = [1 3 5 7 9];
            app.imgDensity_Radius.MajorTickLabels = {''};
            app.imgDensity_Radius.Value = 1;
            app.imgDensity_Title = uilabel(app.Tab4_Grid1);
            app.imgDensity_Title.Text = 'Densidade:';
            app.imgDensity_SourceLabel_2 = uilabel(app.Tab4_Grid1);
            app.imgDensity_SourceLabel_2.Text = 'Fonte:';
            app.imgDensity_ButtonGroup_2 = uibuttongroup(app.Tab4_Grid1);
            app.imgDensity_rawData_2 = uiradiobutton(app.imgDensity_ButtonGroup_2);
            app.imgDensity_rawData_2.Text = 'Dados brutos';
            app.imgDensity_rawData_2.Value = true;
            app.imgDensity_binData_2 = uiradiobutton(app.imgDensity_ButtonGroup_2);
            app.imgDensity_binData_2.Text = 'Dados processados (Pontos de medição)';
            app.imgDensity_binData_3 = uiradiobutton(app.imgDensity_ButtonGroup_2);
            app.imgDensity_binData_3.Text = 'Dados processados (Data Binning)';
            app.imgDensity_Title_3 = uilabel(app.Tab4_Grid1);
            app.imgDensity_Title_3.Text = 'Suavização da curva:';
            app.Panel_2 = uipanel(app.Tab4_Grid1);
            app.GridLayout = uigridlayout(app.Panel_2);
            app.FatorSpinnerLabel = uilabel(app.GridLayout);
            app.FatorSpinnerLabel.Text = 'Fator:';
            app.FatorSpinner = uispinner(app.GridLayout);
            app.FatorSpinner.Step = 0.1;
            app.FatorSpinner.Limits = [0 1];
            app.FatorSpinner.ValueDisplayFormat = '%.2f';
            app.FatorSpinner.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.FatorSpinner.Value = 0.25;
            app.TipoDropDownLabel = uilabel(app.GridLayout);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TipoDropDown = uidropdown(app.GridLayout);
            app.TipoDropDown.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.TipoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.TipoDropDown.Value = 'gaussian';
            app.locTable_Panel = uigridlayout(app.Panel_Grid);
            app.locTable_FcnLabel = uilabel(app.locTable_Panel);
            app.locTable_FcnLabel.Text = 'Operação de sumarização:';
            app.locTable_Fcn = uidropdown(app.locTable_Panel);
            app.locTable_Fcn.Items = {'Mínima', 'Média', 'Mediana', 'RMS', 'Máxima'};
            app.locTable_Fcn.Value = 'Média';
            app.locTable_Grid = uigridlayout(app.locTable_Panel);
            app.locTable_UITable = uitable(app.locTable_Grid);
            app.locTable_UITable.ColumnName = {''; ''; ''; ''};
            app.locTable_UITable.RowName = {};
            app.locTable_UITable.SelectionType = 'row';
            app.locTable_Col1 = uilabel(app.locTable_Grid);
            app.locTable_Col1.Text = {'  ID'; '  '};
            app.locTable_Col2 = uilabel(app.locTable_Grid);
            app.locTable_Col2.Text = {'  Latitude'; '  '};
            app.locTable_Col3 = uilabel(app.locTable_Grid);
            app.locTable_Col3.Text = {'  Longitude'; '  '};
            app.locTable_Col4 = uilabel(app.locTable_Grid);
            app.locTable_Col4.Text = {'  Varreduras'; '  '};
            app.locTable_Info = uilabel(app.locTable_Grid);
            app.locTable_Info.Text = '10 |––––––––––––| 90 varreduras/ponto';
            app.binTable_Panel = uigridlayout(app.Panel_Grid);
            app.binTable_DistanceLabel = uilabel(app.binTable_Panel);
            app.binTable_DistanceLabel.Interpreter = 'html';
            app.binTable_DistanceLabel.Text = 'Distância <i>bins</i> (metros):';
            app.binTable_Distance = uispinner(app.binTable_Panel);
            app.binTable_Distance.Step = 50;
            app.binTable_Distance.Limits = [50 500];
            app.binTable_Distance.RoundFractionalValues = 'on';
            app.binTable_Distance.ValueDisplayFormat = '%.0f';
            app.binTable_Distance.Value = 100;
            app.binTable_TypeLabel = uilabel(app.binTable_Panel);
            app.binTable_TypeLabel.Text = 'Tipo:';
            app.binTable_Type = uidropdown(app.binTable_Panel);
            app.binTable_Type.Items = {'Grid retangular'};
            app.binTable_Type.Value = 'Grid retangular';
            app.binTable_FcnLabel = uilabel(app.binTable_Panel);
            app.binTable_FcnLabel.Text = 'Operação de sumarização:';
            app.binTable_Fcn = uidropdown(app.binTable_Panel);
            app.binTable_Fcn.Items = {'Mínima', 'Média', 'Mediana', 'RMS', 'Máxima'};
            app.binTable_Fcn.Value = 'Média';
            app.binTable_Grid = uigridlayout(app.binTable_Panel);
            app.binTable_UITable = uitable(app.binTable_Grid);
            app.binTable_UITable.ColumnName = {''; ''; ''; ''};
            app.binTable_UITable.RowName = {};
            app.binTable_UITable.SelectionType = 'row';
            app.binTable_Col1 = uilabel(app.binTable_Grid);
            app.binTable_Col1.Text = {'  ID'; '  '};
            app.binTable_Col2 = uilabel(app.binTable_Grid);
            app.binTable_Col2.Text = {'  Latitude'; '  '};
            app.binTable_Col3 = uilabel(app.binTable_Grid);
            app.binTable_Col3.Text = {'  Longitude'; '  '};
            app.binTable_Col4 = uilabel(app.binTable_Grid);
            app.binTable_Col4.Text = {'  Varreduras'; '  '};
            app.binTable_Info = uilabel(app.binTable_Grid);
            app.binTable_Info.Text = '10 |––––––––––––| 90 varreduras/ponto';
            app.Tab1_Grid1 = uigridlayout(app.Panel_Grid);
            app.Tab1_Grid2 = uigridlayout(app.Tab1_Grid1);
            app.EmissionsLabel = uilabel(app.Tab1_Grid2);
            app.EmissionsLabel.Text = 'Emissão:';
            app.EmissionsRefresh = uiimage(app.Tab1_Grid2);
            app.EmissionsRefresh.ImageClickedFcn = createCallbackFcn(app, @EmissionsRefreshImageClicked, true);
            app.EmissionsRefresh.ImageSource = 'LT_refresh.png';
            app.Emissions = uidropdown(app.Tab1_Grid1);
            app.Emissions.Items = {};
            app.Emissions.Value = {};
            app.TreeLabel = uilabel(app.Tab1_Grid1);
            app.TreeLabel.Text = 'Arquivo(s) relacionado(s):';
            app.Tree = uitree(app.Tab1_Grid1, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.InfoLabel = uilabel(app.Tab1_Grid1);
            app.InfoLabel.Text = 'Metadados:';
            app.Info = uitextarea(app.Tab1_Grid1);
            app.Info.Editable = 'off';
            app.imgShowHide = uiimage(app.Grid);
            app.imgShowHide.ImageClickedFcn = createCallbackFcn(app, @imgShowHideClicked, true);
            app.imgShowHide.ImageSource = 'LT_HideLeft.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end