classdef winMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                         matlab.ui.Figure
        Grid1                           matlab.ui.container.GridLayout
        menu_Panel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        MainMenu                        matlab.ui.container.GridLayout
        menu_Grid1                      matlab.ui.container.GridLayout
        Report_Fiscaliza_2              matlab.ui.control.Button
        Report_Fiscaliza                matlab.ui.control.Button
        Button_EditInfo                 matlab.ui.control.StateButton
        Button_Separator3               matlab.ui.control.Image
        Button_Separator3_2             matlab.ui.control.Image
        Button_File                     matlab.ui.control.StateButton
        Button_Home                     matlab.ui.control.StateButton
        Button_SaveFile                 matlab.ui.control.StateButton
        Button_Report                   matlab.ui.control.StateButton
        Button_Playback                 matlab.ui.control.StateButton
        Button_Separator2               matlab.ui.control.Image
        menu_Grid2                      matlab.ui.container.GridLayout
        menu_searchPath                 matlab.ui.control.Image
        menu_userPath                   matlab.ui.control.DropDown
        ToolBar_Panel                   matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        ToolBar                         matlab.ui.container.GridLayout
        ToolBar_Button1                 matlab.ui.control.Button
        ToolBar_Button2                 matlab.ui.control.Button
        ToolBar_Button3                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_Home                       matlab.ui.container.Tab
        Home_Grid1                      matlab.ui.container.GridLayout
        Home_mainTitle                  matlab.ui.control.Label
        Home_ProjectLogo                matlab.ui.control.Image
        Home_subTitle2                  matlab.ui.control.Label
        Tab2_File                       matlab.ui.container.Tab
        File_Grid1                      matlab.ui.container.GridLayout
        File_GridB                      matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        File_GridB2                     matlab.ui.container.GridLayout
        File_LevelUnit                  matlab.ui.control.EditField
        File_LevelUnitLabel             matlab.ui.control.Label
        File_SamplesLabel               matlab.ui.control.Label
        File_Samples                    matlab.ui.control.NumericEditField
        EstimativadeusodamemriaMBLabel  matlab.ui.control.Label
        File_EstimatedMemory            matlab.ui.control.NumericEditField
        File_DataPointsLabel            matlab.ui.control.Label
        File_DataPoints                 matlab.ui.control.NumericEditField
        File_CityLabel                  matlab.ui.control.Label
        File_City                       matlab.ui.control.EditField
        File_ObservationTimeLabel       matlab.ui.control.Label
        File_ObservationTime            matlab.ui.control.EditField
        File_OperationLabel             matlab.ui.control.Label
        File_Operation                  matlab.ui.control.EditField
        File_ResolutionLabel            matlab.ui.control.Label
        File_Resolution                 matlab.ui.control.NumericEditField
        File_DescriptionLabel           matlab.ui.control.Label
        File_Description                matlab.ui.control.EditField
        File_GridB1                     matlab.ui.container.GridLayout
        File_MemoryLabel                matlab.ui.control.Label
        File_Memory                     matlab.ui.control.NumericEditField
        File_NumberLabel                matlab.ui.control.Label
        File_Number                     matlab.ui.control.NumericEditField
        File_Label4                     matlab.ui.control.Label
        File_Button2                    matlab.ui.control.Button
        File_GridA                      matlab.ui.container.GridLayout
        File_Button1_2                  matlab.ui.control.Button
        File_Info                       matlab.ui.control.Label
        File_Tree2                      matlab.ui.container.Tree
        udpLogger_Label3                matlab.ui.control.Label
        File_Panel1                     matlab.ui.container.Panel
        File_GridA1                     matlab.ui.container.GridLayout
        File_FilesList                  matlab.ui.control.TextArea
        File_FilesListLabel             matlab.ui.control.Label
        File_FileType                   matlab.ui.control.TextArea
        File_FileTypeLabel              matlab.ui.control.Label
        File_Label2                     matlab.ui.control.Label
        File_Button1                    matlab.ui.control.Button
        File_Tree1                      matlab.ui.container.Tree
        File_Label1                     matlab.ui.control.Label
        Tab3_Playback                   matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        mainInfo_Grid                   matlab.ui.container.GridLayout
        Playback_Separator2_5           matlab.ui.control.Image
        Playback_Separator2_2           matlab.ui.control.Image
        Report_EditPlots                matlab.ui.control.StateButton
        mainInfo_Text                   matlab.ui.control.TextArea
        mainInfo_TextLabel              matlab.ui.control.Label
        Button_DriveTest                matlab.ui.control.Button
        Button_Mask                     matlab.ui.control.Button
        mainInfo_DeleteAll_2            matlab.ui.control.Button
        mainInfo_Tree                   matlab.ui.container.Tree
        mainInfo_DeleteAll              matlab.ui.control.Button
        mainInfo_Label1                 matlab.ui.control.Label
        mainInfo_Warning                matlab.ui.control.Image
        mainInfo_Label2                 matlab.ui.control.Label
        mainInfo_Expand                 matlab.ui.control.Image
        Grid3_Playback                  matlab.ui.container.GridLayout
        Playback_Separator2_4           matlab.ui.control.Image
        Playback_Separator2_6           matlab.ui.control.Image
        Playback_occCheckBox            matlab.ui.control.StateButton
        Playback_wfCheckBox             matlab.ui.control.StateButton
        Playback_traceCheckBox3         matlab.ui.control.StateButton
        Playback_traceCheckBox1         matlab.ui.control.StateButton
        Playback_traceCheckBox2         matlab.ui.control.StateButton
        Playback_Fcn                    matlab.ui.control.Button
        Playback_Layout                 matlab.ui.control.Button
        Playback_Button1_2              matlab.ui.control.Button
        Playback_Separator2_3           matlab.ui.control.Image
        Playback_Button1                matlab.ui.control.Button
        Playback_Button4                matlab.ui.control.Button
        Playback_Button5                matlab.ui.control.Button
        Playback_mainLabel              matlab.ui.control.Label
        Playback_Panel0                 matlab.ui.container.Panel
        Playback_Grid0                  matlab.ui.container.GridLayout
        Playback_Grid1                  matlab.ui.container.GridLayout
        PeakBWLabel                     matlab.ui.control.Label
        PeakBW                          matlab.ui.control.NumericEditField
        PeakCFLabel                     matlab.ui.control.Label
        PeakCF                          matlab.ui.control.NumericEditField
        Playback_peakLabel0             matlab.ui.control.Label
        Playback_Panel1_2               matlab.ui.container.Panel
        Playback_traceGrid_2            matlab.ui.container.GridLayout
        Playback_yLim2                  matlab.ui.control.Spinner
        Playback_yLim1                  matlab.ui.control.Spinner
        ySpinnerLabel                   matlab.ui.control.Label
        Playback_xLim2                  matlab.ui.control.Spinner
        xSpinnerLabel                   matlab.ui.control.Label
        Playback_xLim1                  matlab.ui.control.Spinner
        Playback_peakTree               matlab.ui.container.Tree
        Playback_addMarker              matlab.ui.control.Image
        Playback_Panel2                 matlab.ui.container.Panel
        Playback_peakGrid               matlab.ui.container.GridLayout
        Playback_peakTrace              matlab.ui.control.DropDown
        Playback_peakTraceLabel         matlab.ui.control.Label
        Playback_peakNumbersLabel       matlab.ui.control.Label
        Playback_peakNumbers            matlab.ui.control.Spinner
        Playback_peakTHR                matlab.ui.control.Spinner
        Playback_peakProeminence        matlab.ui.control.Spinner
        Playback_peakDistance           matlab.ui.control.Spinner
        Playback_peakBW                 matlab.ui.control.Spinner
        Playback_peakTHRLabel           matlab.ui.control.Label
        Playback_peakProeminenceLabel   matlab.ui.control.Label
        Playback_peakDistanceLabel      matlab.ui.control.Label
        Playback_peakBWLabel            matlab.ui.control.Label
        Playback_peakRadioGroup         matlab.ui.container.ButtonGroup
        Playback_peakRadio3             matlab.ui.control.RadioButton
        Playback_peakRadio1             matlab.ui.control.RadioButton
        Playback_peakRadio2             matlab.ui.control.RadioButton
        Playback_traceLabel0            matlab.ui.control.Label
        Playback_Grid2                  matlab.ui.container.GridLayout
        Playback_peakLabel0_3           matlab.ui.control.Label
        Playback_peakLabel0_2           matlab.ui.control.Label
        Panel_14                        matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        Playback_occPanel               matlab.ui.container.Panel
        Playback_occGrid2               matlab.ui.container.GridLayout
        Playback_tSamplesLabel          matlab.ui.control.Label
        Playback_tSamples               matlab.ui.control.Spinner
        Playback_occFcnLabel            matlab.ui.control.Label
        Playback_uSamplesLabel          matlab.ui.control.Label
        Playback_occFcn                 matlab.ui.control.DropDown
        Playback_uSamples               matlab.ui.control.Spinner
        Playback_occLabel               matlab.ui.control.Label
        Playback_occConfLevelLabel      matlab.ui.control.Label
        Playback_occConfLevel           matlab.ui.control.DropDown
        Playback_occTimeLabel           matlab.ui.control.Label
        Playback_occTime                matlab.ui.control.Spinner
        Playback_occOffsetLabel         matlab.ui.control.Label
        Playback_occOffset              matlab.ui.control.Spinner
        Playback_occValue               matlab.ui.control.Spinner
        Playback_occValueLabel          matlab.ui.control.Label
        Playback_occType                matlab.ui.control.DropDown
        Playback_occTypeLabel           matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        Playback_wfLineLabel_2          matlab.ui.control.Label
        Playback_AxesHoles              matlab.ui.control.DropDown
        Playback_wfLineLabel            matlab.ui.control.Label
        Playback_wfLine                 matlab.ui.control.DropDown
        EstiloLabel_2                   matlab.ui.control.Label
        Playback_DecimateFactor         matlab.ui.control.DropDown
        EstiloLabel                     matlab.ui.control.Label
        Playback_ColorMap               matlab.ui.control.DropDown
        Playback_Grid3                  matlab.ui.container.GridLayout
        Playback_Slider                 matlab.ui.control.Slider
        Tab4_Report                     matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Grid3_Report                    matlab.ui.container.GridLayout
        Report_tableView                matlab.ui.control.Button
        Report_NewProject               matlab.ui.control.Button
        Report_Separator1               matlab.ui.control.Image
        Report_Label0                   matlab.ui.control.Label
        Report_Panel0                   matlab.ui.container.Panel
        Report_Grid0                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        Report_Sublabel6                matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        Report_ClassificationParameters  matlab.ui.control.TextArea
        Report_ClassificationButton     matlab.ui.control.Button
        Report_Classification           matlab.ui.control.EditField
        Report_ClassificationLabel      matlab.ui.control.Label
        Report_Sublabel5                matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        Report_DetectionButton          matlab.ui.control.Button
        Report_Detection                matlab.ui.control.EditField
        Report_DetectionLabel           matlab.ui.control.Label
        Report_BandPanelLabel           matlab.ui.control.Label
        Report_PlaybackOnly             matlab.ui.control.CheckBox
        Report_DetectionParameters      matlab.ui.control.TextArea
        Report_BandPanel                matlab.ui.container.Panel
        Report_BandGrid                 matlab.ui.container.GridLayout
        Report_BandLim2                 matlab.ui.control.Spinner
        Report_BandSliderLabel          matlab.ui.control.Label
        Report_BandLim1                 matlab.ui.control.Spinner
        Panel_6                         matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        Report_occParameters            matlab.ui.control.TextArea
        Report_occButton                matlab.ui.control.Button
        Report_occMethod                matlab.ui.control.EditField
        Report_occMethodLabel           matlab.ui.control.Label
        Report_Sublabel5_2              matlab.ui.control.Label
        Report_Grid1                    matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        Report_addButton                matlab.ui.control.Image
        Report_Sublabel3                matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Report_Issue                    matlab.ui.control.NumericEditField
        Report_IssueLabel               matlab.ui.control.Label
        RelatrioLabel                   matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        Report_VersionLabel             matlab.ui.control.Label
        Report_Version                  matlab.ui.control.DropDown
        Report_TypeLabel                matlab.ui.control.Label
        Report_Type                     matlab.ui.control.DropDown
        Report_SaveProject              matlab.ui.control.Button
        ProjectFilename                 matlab.ui.control.EditField
        ProjectNameLabel                matlab.ui.control.Label
        ProjectName                     matlab.ui.control.EditField
        Panel_9                         matlab.ui.container.Panel
        Report_Grid4                    matlab.ui.container.GridLayout
        Report_Spinner4                 matlab.ui.control.Spinner
        Report_Spinner3                 matlab.ui.control.Spinner
        Report_DatePicker2              matlab.ui.control.DatePicker
        Report_Spinner2                 matlab.ui.control.Spinner
        Report_Spinner1                 matlab.ui.control.Spinner
        Report_DatePicker1              matlab.ui.control.DatePicker
        Report_Sublabel4                matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        Report_Sublabel1                matlab.ui.control.Label
        Report_ImageWarn                matlab.ui.control.Image
        Report_Tree                     matlab.ui.container.CheckBoxTree
        Report_previewButton            matlab.ui.control.Button
        Report_playButton               matlab.ui.control.Button
        Tab5_Edit                       matlab.ui.container.Tab
        Edit_Grid1                      matlab.ui.container.GridLayout
        Grid3_Info                      matlab.ui.container.GridLayout
        Info_RefreshGPS                 matlab.ui.control.Button
        Info_Label0                     matlab.ui.control.Label
        Info_Button                     matlab.ui.control.Button
        Info_Panel2                     matlab.ui.container.Panel
        Info_Grid3                      matlab.ui.container.GridLayout
        AntenaDropDownLabel             matlab.ui.control.Label
        AntenaDropDown                  matlab.ui.control.DropDown
        Info_Label1_3                   matlab.ui.control.Label
        Info_TableStatusInfo            matlab.ui.control.Label
        Info_MeanValues                 matlab.ui.control.Label
        Info_OCC                        matlab.ui.control.DropDown
        OcupaoLabel                     matlab.ui.control.Label
        Info_Label2_2                   matlab.ui.control.Label
        GridLayout14                    matlab.ui.container.GridLayout
        Info_Column6                    matlab.ui.control.Label
        Info_Column5                    matlab.ui.control.Label
        Info_Column4                    matlab.ui.control.Label
        Info_Column3                    matlab.ui.control.Label
        Info_Column2                    matlab.ui.control.Label
        Info_Column1                    matlab.ui.control.Label
        Info_Table                      matlab.ui.control.Table
        Info_Panel2B                    matlab.ui.container.Panel
        Info_Grid2_2                    matlab.ui.container.GridLayout
        Info_Longitude                  matlab.ui.control.NumericEditField
        Info_LongitudeLabel             matlab.ui.control.Label
        Info_LatitudeLabel              matlab.ui.control.Label
        Info_Latitude                   matlab.ui.control.NumericEditField
        Info_City                       matlab.ui.control.EditField
        Info_CityLabel                  matlab.ui.control.Label
        Info_CheckBox                   matlab.ui.control.CheckBox
        Info_Label2                     matlab.ui.control.Label
        Info_Label1                     matlab.ui.control.Label
        Tab6_Save                       matlab.ui.container.Tab
        Save_Grid1                      matlab.ui.container.GridLayout
        Save_Grid2                      matlab.ui.container.GridLayout
        Save_Label1                     matlab.ui.control.Label
        Save_Button                     matlab.ui.control.Button
        Save_Panel1                     matlab.ui.container.Panel
        Save_Grid3                      matlab.ui.container.GridLayout
        Save_Note                       matlab.ui.control.Label
        Save_Panel3                     matlab.ui.container.Panel
        Save_Grid5                      matlab.ui.container.GridLayout
        Save_Spinner4                   matlab.ui.control.Spinner
        Save_Spinner3                   matlab.ui.control.Spinner
        Save_DatePicker2                matlab.ui.control.DatePicker
        Save_Spinner2                   matlab.ui.control.Spinner
        Save_Spinner1                   matlab.ui.control.Spinner
        Save_DatePicker1                matlab.ui.control.DatePicker
        Save_ObservationTime            matlab.ui.control.CheckBox
        Save_Label2                     matlab.ui.control.Label
        ContextMenu_File1               matlab.ui.container.ContextMenu
        File_ContextMenu1               matlab.ui.container.Menu
        ContextMenu_Playback            matlab.ui.container.ContextMenu
        Playback_ContextMenu1           matlab.ui.container.Menu
        EditarMenu                      matlab.ui.container.Menu
        TruncarfrequnciaMenu            matlab.ui.container.Menu
        NotruncarMenu                   matlab.ui.container.Menu
        ContextMenu_File2               matlab.ui.container.ContextMenu
        File_ContextMenu2               matlab.ui.container.Menu
        ContextMenu_Report              matlab.ui.container.ContextMenu
        Report_ContextMenu1             matlab.ui.container.Menu
    end

    
    properties (Access = public)

        metaData = struct('File', {}, 'Type', {}, 'Data', {}, 'Samples', {}, 'Memory', {});
        Samples  = [];
        specData = [];

        fig   = [];
        axes1 = [];
        axes2 = [];
        axes3 = [];
        
        line_ClearWrite = [];                                               % ClearWrite (main trace)
        line_OCC        = [];                                               % OCC roi/line
        line_OCCLabel   = [];                                               % OCC roi/line label
        mkr_ROI         = [];                                               % Selected marker roi
        mkr_Label       = [];                                               % Markers labels
        line_wfTime     = [];                                               % Waterfall timestamp
        cBar1           = [];                                               % Waterfall colorbar
        
        RestoreView = [];
        timeIndex   =  1;
        
        occFlag  = 0;
        plotFlag = 0;
        axesFlag = 0;
        
        x;
        Span;
        aCoef;
        bCoef;
        Unit;
        
        occTHR;                                                             % "OffSet Statistical OCC"

        specDataTypes = [1, 4, 7, 60, 61, 63, 64, 67, 68, 167, 168, 1809];
        occDataTypes  = [8, 62, 65, 69];

        General = struct('Version', '',                         ...
                         'WarnCount', 0,                        ...
                         'Merge', struct('MandatoryFields', {}, ...
                                         'OptionalFields',  {}, ...
                                         'gpsDistance', [],     ...
                                         'AntennaFlag', []),    ...
                         'Resolution', 120,            ...                  % Image resolution
                         'Colors', [0.07, 0.07, 0.75;  ...                  % color1 (MinHold)
                                    0.39, 0.83, 0.07;  ...                  % color2 (Median or Mean)
                                    1.00, 0.07, 0.65;  ...                  % color3 (MaxHold)
                                    0.93, 0.69, 0.13], ...                  % color4 (ClearWrite)
                         'Models', [],                 ...                  % Report templates (app.InspectionType)
                         'Report', '')                                      % Final vesion of the report
        
        RootFolder     = [];
        
        traceInfo     = struct('SelectedNode', 0, ...
                               'Mode', [0, 0, 0], ...
                               'plotLayout', 0);

        peaksTable    = table('Size', [0, 21],                                                                                                                                                                                 ...
                              'VariableTypes', {'cell', 'single', 'single', 'uint16', 'double', 'single', 'double', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'int16', 'int32', 'cell', 'cell', 'cell', 'cell', 'cell'}, ...
                              'VariableNames', {'Tag', 'Latitude', 'Longitude', 'Index', 'Frequency', 'Truncated', 'BW', 'minLevel', 'meanLevel', 'maxLevel', 'meanOCC', 'maxOCC', 'Type', 'regulatoryInfo', 'Service', 'Station', 'Description', 'Distance', 'Compliance', 'RiskLevel', 'Method'});

        exceptionList = table('Size', [0, 10],                                                                                       ...
                              'VariableTypes', {'cell', 'double', 'cell', 'cell', 'int16', 'int32', 'cell', 'cell', 'cell', 'cell'}, ...
                              'VariableNames', {'Tag', 'Frequency', 'Type', 'regulatoryInfo', 'Service', 'Station', 'Description', 'Distance', 'Compliance', 'RiskLevel'});
        
        auxWin1       = [];                                                 % winTreeEdit
        auxWin2       = [];                                                 % winDriveTest
        auxWin3       = [];                                                 % winFiscaliza
        auxWin4       = [];                                                 % winMaskDraw
        auxWin5       = [];                                                 % winSettings
        auxWin6       = [];                                                 % winRepository
        auxWin7       = [];                                                 % winReport
        
    end
    
    
    methods (Access = public)
        
        function startupError(app)
            
            delete(app.fig)
            delete(app)
            
        end


        function [mainMonitor, x0] = Misc_appPosition(app)

            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
                        
            if mainMonitor(3) >= 1016; x0 = 1016;
            else;                      x0 = mainMonitor(3);
            end
            
            if mainMonitor(4) > 768;   y0 = mainMonitor(4) - 696 - 30;
            else;                      y0 = 42;
            end

            app.MainApp.Position = [mainMonitor(1), mainMonitor(2)+y0, x0, 696];

        end


        function [x1, y1] = Misc_figPosition(app, mainMonitor, x0)

                if mainMonitor(3) > x0 + 640; x1 = x0+6;
                else;                         x1 = mainMonitor(3)-640;
                end
                y1 = mainMonitor(4) - 480 - 30;

        end


        function Misc_axesCreation(app)
            
            [mainMonitor, x0] = Misc_appPosition(app);
            [x1, y1] = Misc_figPosition(app, mainMonitor, x0);
            
            app.fig   = figure('Name', 'appAnálise: Plot', 'NumberTitle', 'off', 'Position', [x1, y1, 640, 480], ...
                               'Visible', 'off', 'Color', [0.94,0.94,0.94], 'GraphicsSmoothing', 'on',           ...
                               'MenuBar', 'none', 'ToolBar', 'none', 'CloseRequestFcn', "set(gcf, 'Visible', 'off')");
            
            warning('off')
            jFrame = get(app.fig, 'javaframe');
            jIcon  = javax.swing.ImageIcon(fullfile(app.RootFolder, 'Icons', 'Anatel.png'));
            jFrame.setFigureIcon(jIcon);
            warning('on')
            
            app.axes1 = axes(app.fig, 'Box', 'on', 'Color', [0,0,0], 'FontName', 'Helvetica', ...
                                      'FontSize', 7, 'FontSmoothing', 'on',                   ...
                                      'XColor', [0,0,0], 'XGrid', 'on', 'XMinorGrid', 'on',   ...
                                      'YColor', [0,0,0], 'YGrid', 'on', 'YMinorGrid', 'on',   ...
                                      'XTickLabel', {}, 'GridAlpha', .25, 'GridColor', [0.94,0.94,0.94], ...
                                      'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                      'Unit',  'normalized', 'InnerPosition',  [.08 .1 .85 .82], ...
                                      'TitleHorizontalAlignment', 'right', 'Toolbar', []);
            
            app.axes2 = axes(app.fig, 'Box', 'on', 'Color', [0,0,0], 'FontName', 'Helvetica', ...
                                      'FontSize', 7, 'FontSmoothing', 'on',                   ...
                                      'XColor', [0,0,0], 'XGrid', 'on', 'XMinorGrid', 'on',   ...
                                      'YColor', [0,0,0], 'YGrid', 'on', 'YMinorGrid', 'on',   ...
                                      'XTickLabel', {}, 'GridAlpha', .25, 'GridColor', [0.94,0.94,0.94],  ...
                                      'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                      'Unit', 'normalized', 'InnerPosition', [0 0 0 0], 'Toolbar', []);
            
            app.axes3 = axes(app.fig, 'Box', 'on', 'LineWidth', 1, 'Color', [0.94,0.94,0.94],        ...
                                      'FontName', 'Helvetica', 'FontSize', 7, 'FontSmoothing', 'on', ...
                                      'XColor', [0,0,0], 'YColor', [0,0,0], 'XTickLabel', {},        ...
                                      'Unit', 'normalized', 'InnerPosition', [0 0 0 0], 'Toolbar', []);

            set(app.axes1.Title,    'FontSize', 7, 'FontWeight',   'bold', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes1.Subtitle, 'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes1.XLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes1.YLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])

            set(app.axes2.XLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes2.YLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            
            set(app.axes3.XLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes3.YLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            
            hold(app.axes1, 'on')
            hold(app.axes2, 'on')
            hold(app.axes3, 'on')
            
            app.axes1.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
            app.axes2.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
            app.axes3.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
            
            enableDefaultInteractivity(app.axes1)
            enableDefaultInteractivity(app.axes2)
            enableDefaultInteractivity(app.axes3)

            addlistener(app.axes1, 'XLim', 'PostSet', @app.Playback_xLimits);
            addlistener(app.axes1, 'YLim', 'PostSet', @app.Playback_yLimits);                
            
            app.cBar1 = colorbar('Location', 'manual', 'Units', 'normalized', 'Position', [0.935, 0.1, 0.01, 0.1], ...
                                 'AxisLocation', 'out', 'FontName', 'Helvetica', 'FontSize', 7, 'Color', [0,0,0]);

        end
        
        
        function Layout_ModeOperation(app)
            
            if app.Button_Home.Value
                ind = 1;
                
            elseif app.Button_File.Value
                ind = 2;
            
            elseif app.Button_Playback.Value
                ind = 3;
                
                app.mainInfo_Grid.Parent      = app.GridLayout5;
                app.mainInfo_Tree.Multiselect = 0;
                
                if ~isempty(app.mainInfo_Tree.SelectedNodes)              && ...
                   isnumeric(app.mainInfo_Tree.SelectedNodes(1).NodeData) && ...
                   app.mainInfo_Tree.SelectedNodes(1).NodeData == app.traceInfo.SelectedNode
                    
                    app.mainInfo_Tree.SelectedNodes = app.mainInfo_Tree.SelectedNodes(1);
                    
                    app.Playback_addMarker.Enable = 1;
                    app.Playback_Slider.Enable    = 1;
                    app.Button_Mask.Enable        = 1;
                    app.Playback_Button4.Enable   = 1;
                    app.Playback_Button5.Enable   = 1;
                    app.Button_DriveTest.Enable   = 1;
                    
                    app.fig.Visible = 1;
                else
                    app.mainInfo_Tree.SelectedNodes = [];
                    
                    app.Playback_addMarker.Enable = 0;
                    app.Playback_Slider.Enable    = 0;
                    app.Button_Mask.Enable        = 0;
                    app.Playback_Button4.Enable   = 0;
                    app.Playback_Button5.Enable   = 0;
                    app.Button_DriveTest.Enable   = 0;
                    
                    app.fig.Visible = 0;
                    app.occFlag     = 0;
                end

                Layout_Playback(app)
                Layout_expandTree(app, 'mainInfo')
                
            elseif app.Button_Report.Value
                ind = 4;
                
                app.mainInfo_Grid.Parent      = app.GridLayout6;
                app.mainInfo_Tree.Multiselect = 1;
                
                FilteredRows = find([app.specData.reportFlag]);
                if ~isempty(FilteredRows)
                    app.Report_playButton.Enable    = 1;
                    app.Report_previewButton.Enable = 1;
                else
                    app.Report_playButton.Enable    = 0;
                    app.Report_previewButton.Enable = 0;
                end
                
                if ~isempty(app.mainInfo_Tree.SelectedNodes)
                    app.Report_addButton.Enable = 1;
                else
                    app.Report_addButton.Enable = 0;
                end
                Layout_expandTree(app, 'mainInfo')
                                  
            elseif app.Button_EditInfo.Value
                ind = 5;
                
                app.mainInfo_Grid.Parent      = app.Edit_Grid1;
                app.mainInfo_Tree.Multiselect = 0;
                
                if numel(app.mainInfo_Tree.SelectedNodes) > 1
                    app.mainInfo_Tree.SelectedNodes = app.mainInfo_Tree.SelectedNodes(1);
                end
                
                if ~isempty(app.mainInfo_Tree.SelectedNodes) && isnumeric(app.mainInfo_Tree.SelectedNodes.NodeData)
                    EditInfo_StartupLayout(app, 'on')
                else
                    app.mainInfo_Tree.SelectedNodes = [];
                    EditInfo_StartupLayout(app, 'off')
                end
                
                Layout_expandTree(app, 'mainInfo')
                
            elseif app.Button_SaveFile.Value
                ind = 6;
                
                app.mainInfo_Grid.Parent      = app.Save_Grid1;
                app.mainInfo_Tree.Multiselect = 1;
                
                if ~isempty(app.mainInfo_Tree.SelectedNodes)
                    app.Save_Button.Enable = 1;
                else
                    app.Save_Button.Enable = 0;
                end
                
                Save_ObservationTimeValueChanged(app)
                Layout_expandTree(app, 'mainInfo')
                
            end
            app.TabGroup.SelectedTab = app.TabGroup.Children(ind);
            
        end
        
        
        function Layout_expandTree(app, Tree)
            
            switch Tree
                case 'mainInfo'
                    for ii = 1:numel(app.mainInfo_Tree.Children)
                        expand(app.mainInfo_Tree.Children(ii));
                    end
                    app.mainInfo_Expand.ImageSource = 'General_Colapse.png';
                    
                case 'Report'
                    for ii = 1:numel(app.Report_Tree.Children)
                        expand(app.Report_Tree.Children(ii));
                    end
                    
                case 'File'
                    for ii = 1:numel(app.File_Tree2.Children)
                        expand(app.File_Tree2.Children(ii));
                    end
                    
            end

        end
        
        
        function imageIcon = Layout_imageIconTree(app, Node)
            
            if contains(Node, 'RFeye', 'IgnoreCase', true);         imageIcon = 'Instr_CRFS.png';
            elseif contains(Node, {'FSL', 'FSVR', 'EB500', 'ETM'}); imageIcon = 'Instr_R&S.png';
            elseif contains(Node, {'N9344C', 'N9936B'});            imageIcon = 'Instr_KeySight.png';
            elseif contains(Node, 'MS2720T');                       imageIcon = 'Instr_Anritsu.png';
            elseif contains(Node, 'SA2500');                        imageIcon = 'Instr_Tektronix.png';
            else;                                                   imageIcon = 'Warn_Red.png';
            end
            
        end
        
        
        function Layout_Project_startup(app, prj_Info)
            
            ind = find(ismember([app.metaData.Type], {'Project data'}));
            app.ProjectFilename.Value = app.metaData(ind).File;
            
            app.ProjectName.Value = prj_Info.Name;
            app.peaksTable        = prj_Info.peaksTable;
            app.exceptionList     = prj_Info.exceptionList;
            
            app.Report_Issue.Value   = prj_Info.reportInfo.Issue;
            
            Type = prj_Info.reportInfo.Name;
            if ismember(Type, app.Report_Type.Items)
                app.Report_Type.Value        = Type;
            else
                app.Report_Type.Items{end+1} = Type;
                app.Report_Type.Value        = app.Report_Type{end};
                
                app.General.Models(end+1,:)  = prj_Info.reportInfo.Name;
            end
            
            app.Report_Version.Value = prj_Info.reportInfo.Version;
            Report_VersionValueChanged(app)
            
            app.Report_DatePicker1.Value = prj_Info.reportInfo.TimeStamp(1);
            app.Report_Spinner1.Value    = hour(prj_Info.reportInfo.TimeStamp(1));
            app.Report_Spinner2.Value    = minute(prj_Info.reportInfo.TimeStamp(1));
                        
            app.Report_DatePicker2.Value = prj_Info.reportInfo.TimeStamp(2);
            app.Report_Spinner3.Value    = hour(prj_Info.reportInfo.TimeStamp(2));
            app.Report_Spinner4.Value    = minute(prj_Info.reportInfo.TimeStamp(2));
            
            app.Report_ImageWarn.Visible = 0;
            
        end
        
        
        function Layout_Playback(app)
            
            selectedNode = app.mainInfo_Tree.SelectedNodes;
            
            if isempty(selectedNode)
                app.traceInfo.SelectedNode = 0;
                app.traceInfo.Mode         = [0, 0, 0];
                
                Layout_Playback_Spec(app)
                
            elseif isnumeric(selectedNode.NodeData)
                if selectedNode.NodeData ~= app.traceInfo.SelectedNode
                    if ismember(app.specData(selectedNode.NodeData).MetaData.DataType, app.specDataTypes)
                        if app.occFlag
                            app.occFlag = 0;
                            Layout_Playback_Spec(app)
                        end
                        
                    else
                        if ~app.occFlag
                            app.occFlag = 1;
                            Layout_Playback_OCC(app)
                        end
                        
                        switch app.traceInfo.plotLayout
                            case 1; app.traceInfo.plotLayout = 0;
                            case 3; app.traceInfo.plotLayout = 2;
                        end
                    end
                    traceInfoCallback1_SelectedNode_Mode(app)
                    
                    Playback_TreeBuilding(app, 1)
                    Playback_RestoreView(app, selectedNode)
                end
            end
            
        end

        
        function Layout_Playback_Spec(app)
            
            % tracePanel  
            set(app.Playback_traceCheckBox1, 'Value', 0, 'Enable', 1)
            set(app.Playback_traceCheckBox2, 'Value', 0, 'Enable', 1)
            set(app.Playback_traceCheckBox3, 'Value', 0, 'Enable', 1)
            
            % peakPanel            
            if app.Playback_peakRadio2.Value
                app.Playback_peakRadio1.Value  = 1;
            end
            app.Playback_peakRadio2.Enable = 1;
            Playback_peakRadioGroupSelectionChanged(app)
            Playback_TreeBuilding(app, 1)
            
            % occPanel
            set(app.Playback_occCheckBox,       'Enable', 1, 'Value', 0)
            set(findall(groot, 'Parent', app.GridLayout23, '-not', 'Type', 'uipanel'), 'Enable', 0)
            set(app.Playback_occGrid2.Children, 'Enable', 0)
            
            traceInfoCallback2_plotLayout(app)
            
        end
        
        
        function Layout_Playback_OCC(app)
                        
            % tracePanel 
            set(app.Playback_traceCheckBox1, 'Value', 0, 'Enable', 0)
            set(app.Playback_traceCheckBox2, 'Value', 0, 'Enable', 0)
            set(app.Playback_traceCheckBox3, 'Value', 0, 'Enable', 0)
            
            % peakPanel
            if app.Playback_peakRadio2.Value
                app.Playback_peakRadio1.Value  = 1;
            end
            app.Playback_peakRadio2.Enable = 0;             
            Playback_peakRadioGroupSelectionChanged(app)
            Playback_TreeBuilding(app, 1)

            % occPanel
            set(app.Playback_occCheckBox,       'Enable', 0, 'Value', 0)
            set(findall(groot, 'Parent', app.GridLayout23, '-not', 'Type', 'uipanel'), 'Enable', 0)
            set(app.Playback_occGrid2.Children, 'Enable', 0)
            
        end


        function Layout_Report(app)

            idx = [];
            if ~isempty(app.Report_Tree.SelectedNodes)
                idx = app.Report_Tree.SelectedNodes.NodeData;
            end

            if ~isempty(idx) && isnumeric(idx)
                % OCC objects
                app.Report_occMethod.Value     = app.specData(idx).reportOCC.Method;
                app.Report_occButton.Enable    = 1;
                app.Report_occParameters.Value = app.specData(idx).reportOCC.Parameters;

                % Detection objects
                Limits = [app.specData(idx).MetaData.FreqStart, app.specData(idx).MetaData.FreqStop] / 1e+6;
                Step   = 1;
                if diff(Limits) < 1; Step = 0.001;
                end

                set(app.Report_BandGrid.Children, 'Enable', 1)
                set(app.Report_BandLim1, 'Enable', 1, 'Limits', Limits, 'Step', Step, 'Value', app.specData(idx).reportDetection.Band(1), 'BackgroundColor', [1,1,1], 'FontColor', [0,0,0])
                set(app.Report_BandLim2, 'Enable', 1, 'Limits', Limits, 'Step', Step, 'Value', app.specData(idx).reportDetection.Band(2), 'BackgroundColor', [1,1,1], 'FontColor', [0,0,0])
                set(app.Report_PlaybackOnly, 'Enable', 1, 'Value', app.specData(idx).reportDetection.ManualMode)
                
                app.Report_Detection.Value           = app.specData(idx).reportDetection.Algorithm;
                app.Report_DetectionButton.Enable    = 1;
                app.Report_DetectionParameters.Value = app.specData(idx).reportDetection.Parameters;

                % Classification objects
                app.Report_Classification.Value           = app.specData(idx).reportClassification.Algorithm;
                app.Report_ClassificationButton.Enable    = 1;
                app.Report_ClassificationParameters.Value = app.specData(idx).reportClassification.Parameters;

            else
                % OCC objects
                app.Report_occMethod.Value     = '';
                app.Report_occButton.Enable    = 0;
                app.Report_occParameters.Value = '';

                % Detection objects
                Limits = [-1, -0.999];

                set(app.Report_BandGrid.Children, 'Enable', 0)
                set(app.Report_BandLim1, 'Enable', 0, 'Limits', Limits, 'Step', 0.001, 'BackgroundColor', [.94,.94,.94], 'FontColor', [.94,.94,.94])
                set(app.Report_BandLim2, 'Enable', 0, 'Limits', Limits, 'Step', 0.001, 'BackgroundColor', [.94,.94,.94], 'FontColor', [.94,.94,.94])
                set(app.Report_PlaybackOnly, 'Enable', 0, 'Value', 0)
                
                app.Report_Detection.Value           = '';
                app.Report_DetectionButton.Enable    = 0;
                app.Report_DetectionParameters.Value = '';

                % Classification objects
                app.Report_Classification.Value           = '';
                app.Report_ClassificationButton.Enable    = 0;
                app.Report_ClassificationParameters.Value = '';
            end

        end
        
        
        function Layout_PlotOCC(app)
            
            if app.Playback_occCheckBox.Value
                Playback_occTypeValueChanged(app)
                
                app.Playback_occTypeLabel.Enable = 1;
                app.Playback_occType.Enable       = 1;
                
            else
                set(findall(groot, 'Parent', app.GridLayout23, '-not', 'Type', 'uipanel'), 'Enable', 0)
                set(app.Playback_occGrid2.Children, 'Enable', 0)
            end
            
        end
        
        
        function File_Tree1SelectionChanged_Aux(app, Status)
            
            switch Status
                case 'Off'
                    app.File_FileType.Value        = '';
                    app.File_FilesList.Value       = '';
                                                            
                    File_Tree2SelectionChanged_Aux(app, 'Off')
                    
                    set(app.File_GridA1.Children, 'Enable', 0)
                    
                    delete(app.File_Tree2.Children)
                    app.File_Tree2.Enable = 0;                    
                    
                case 'On'
                    set(app.File_GridA1.Children, 'Enable', 1)
                    app.File_Tree2.Enable = 1;
            end
            
        end
        
        
        function File_Tree2SelectionChanged_Aux(app, Status)
            
            switch Status
                case 'Off'
                    app.File_DataPoints.FontColor      = [.94,.94,.94];
                    app.File_Resolution.FontColor      = [.94,.94,.94];
                    app.File_Samples.FontColor         = [.94,.94,.94];
                    app.File_EstimatedMemory.FontColor = [.94,.94,.94];
                    
                    app.File_Operation.Value   = '';
                    app.File_LevelUnit.Value   = '';
                    app.File_Description.Value = '';
                    app.File_ObservationTime.Value = '';
                    app.File_City.Value            = '';
                    
                    set(app.File_GridB2.Children, 'Enable', 0)
                    
                case 'On'
                    app.File_DataPoints.FontColor      = [0,0,0];
                    app.File_Resolution.FontColor      = [0,0,0];
                    app.File_Samples.FontColor         = [0,0,0];
                    app.File_EstimatedMemory.FontColor = [0,0,0];
                    
                    set(app.File_GridB2.Children, 'Enable', 1)
            end
            
        end
                
        
        function File_GeneralReader(app, ind)
            
            [~, name, ext] = fileparts(app.metaData(ind).File);
            
            if strcmpi(ext, '.bin')
                fileID = fopen(app.metaData(ind).File);
                Format = fread(fileID, [1 36], '*char');
                fclose(fileID);

                if contains(Format, 'CRFS', "IgnoreCase", true)
                    app.metaData(ind).Data = FileReader_CRFSBin_mat2(app.metaData(ind).File, 'MetaData', []);
                elseif contains(Format, 'RFlookBin', "IgnoreCase", true)
                    app.metaData(ind).Data = FileReader_RFLookBin(app.metaData(ind).File, 'MetaData');
                else
                    error('O arquivo indicado a seguir parece não ser de um dos formatos binários cuja leitura foi implantada no appAnálise.\n• %s', [name ext])
                end
                
            elseif strcmpi(ext, '.sm1809')
                app.metaData(ind).Data = FileReader_SM1809(app.metaData(ind).File, 'MetaData', []);
                    
            elseif strcmpi(ext, '.csv')
                app.metaData(ind).Data = FileReader_ArgusCSV(app.metaData(ind).File, 'SingleFile', []);
                
            elseif strcmp(ext, '.mat')
                load(app.metaData(ind).File, '-mat', 'prj_metaData')
                app.metaData(ind).Data = prj_metaData;
                
            end
            
            if isempty([app.metaData(ind).Data.Samples])
                error('O arquivo indicado a seguir não possui informação espectral.\n• %s', [name ext])
            end
            
            app.metaData(ind).Samples = [app.metaData(ind).Data.Samples]';
            app.metaData(ind).Memory  = File_GeneralReader_EstimatedMemory(app, ind);
            
        end
        
        
        function EstimatedMemory = File_GeneralReader_EstimatedMemory(app, ii)
            
            EstimatedMemory = 0;
            for jj = 1:numel(app.metaData(ii).Data)
                EstimatedMemory = EstimatedMemory + 4 * app.metaData(ii).Data(jj).Samples .* app.metaData(ii).Data(jj).MetaData.DataPoints .* 1e-6;
            end
            
        end
        
        
        function File_Tree1_Building(app)

            delete(app.File_Tree1.Children)
                        
            app.File_Number.Value = numel(app.metaData);
            if app.File_Number.Value
                app.File_Button2.Enable = 1;
                
                for ii = 1:app.File_Number.Value
                    imageIcon      = Layout_imageIconTree(app, app.metaData(ii).Data(1).Node);                    
                    [~, name, ext] = fileparts(app.metaData(ii).File);
                    
                    uitreenode(app.File_Tree1, 'Text', [name ext], ...
                                               'NodeData', ii,     ...
                                               'Icon', imageIcon,  ...
                                               'ContextMenu', app.ContextMenu_File1);
                end
                app.File_Tree1.SelectedNodes = app.File_Tree1.Children(1);
                
            else
                app.File_Button2.Enable = 0;
                delete(app.File_Tree2.Children)
            end
            
            File_Tree1SelectionChanged(app)
            
        end
        
        
        function File_Tree2_Building(app)
            
            delete(app.File_Tree2.Children);
            
            ind = app.File_Tree1.SelectedNodes.NodeData;
            
            Category = {};
            for ii = 1:numel(app.metaData(ind).Data)
                Category = [Category, app.metaData(ind).Data(ii).Node];
            end
            Category = unique(Category);
            
            for ii = 1:numel(Category)
                imageIcon = Layout_imageIconTree(app, Category{ii});
                TempNode  = uitreenode(app.File_Tree2, 'Text', Category{ii},     ...
                                                       'NodeData', Category{ii}, ...
                                                       'Icon', imageIcon);
                
                for jj = 1:numel(app.metaData(ind).Data)
                    if strcmp(Category{ii}, app.metaData(ind).Data(jj).Node)
                        if ismember(app.metaData(ind).Data(jj).MetaData.DataType, app.specDataTypes)
                            uitreenode(TempNode, 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz',                         ...
                                                                 app.metaData(ind).Data(jj).ThreadID,                    ...
                                                                 app.metaData(ind).Data(jj).MetaData.FreqStart .* 1e-6,  ...
                                                                 app.metaData(ind).Data(jj).MetaData.FreqStop  .* 1e-6), ...
                                                 'NodeData', jj, 'ContextMenu', app.ContextMenu_File2);
                            
                        else
                            uitreenode(TempNode, 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz (Ocupação)',       ...
                                                                 app.metaData(ind).Data(jj).ThreadID,             ...
                                                                 app.metaData(ind).Data(jj).MetaData.FreqStart .* 1e-6, ...
                                                                 app.metaData(ind).Data(jj).MetaData.FreqStop .* 1e-6), ... 
                                                 'NodeData', jj, 'ContextMenu', app.ContextMenu_File2);
                        end
                    end
                end
            end
            Layout_expandTree(app, 'File')
            
            app.File_Tree2.SelectedNodes = app.File_Tree2.Children(1).Children(1);
            File_Tree2SelectionChanged(app)
            
        end


        function File_specDataReader(app, d)
            
            % Organização dos fluxos de dados e pré-alocação
            File_specDataReader_Aux1(app)
            
            % Leitura dos dados de espectro
            prj_specData = [];
            
            for ii = 1:numel(app.metaData)
                [~, name, ext] = fileparts(app.metaData(ii).File);
                filename = [name ext];
                if length(filename) <= 50; d.Message = sprintf('Em andamento a leitura de dados do espectro do arquivo:\n• %s\n\n%d de %d', filename, ii, numel(app.metaData));
                else;                      d.Message = sprintf('Em andamento a leitura de dados do espectro do arquivo:\n• %s\n\n%d de %d', [filename(1:48) '...'], ii, numel(app.metaData));
                end
                
                if strcmpi(ext, '.bin')
                    fileID = fopen(app.metaData(ii).File);
                    Format = fread(fileID, [1 36], '*char');
                    fclose(fileID);

                    if contains(Format, 'CRFS', "IgnoreCase", true)
                        SpecInfo = FileReader_CRFSBin_mat2(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                    elseif contains(Format, 'RFlookBin', "IgnoreCase", true)
                        SpecInfo = FileReader_RFLookBin(app.metaData(ii).File, 'SingleFile');
                    end

                elseif strcmpi(ext, '.sm1809')
                    SpecInfo = FileReader_SM1809(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                    
                elseif strcmpi(ext, '.csv')
                    SpecInfo = app.metaData(ii).Data;
                    
                elseif strcmpi(ext, '.mat')
                    load(app.metaData(ii).File, '-mat', 'prj_specData', 'prj_Info');
                    
                    if strcmp(app.metaData(ii).Type{1}, 'Spectral data')
                        SpecInfo = prj_specData;
                    else
                        SpecInfo = rmfield(prj_specData, {'occRelated', 'occFlag', ...
                                                          'reportFlag', 'report_occInfo', 'report_peakInfo', 'Emissions'});
                        Layout_Project_startup(app, prj_Info)
                    end
                end
                
                % Eliminar alguns campos para possibilitar comparar o fluxo
                % lido (SpecInfo >> auxSpecInfo) com o já salvo (app.specData >> auxSpecData)
                auxSpecInfo = File_specDataReader_Aux3(app, SpecInfo);
                auxSpecData = File_specDataReader_Aux3(app, app.specData);
                
                for jj = 1:numel(SpecInfo)
                    % Necessário pois no leitor do RFLookBin, por exemplo, não é lida a
                    % informação de GPS.
                    if isempty(SpecInfo(jj).RelatedFiles) || isempty(SpecInfo(jj).gps)
                        SpecInfo(jj).RelatedFiles = app.metaData(ii).Data(jj).RelatedFiles;
                        SpecInfo(jj).gps          = app.metaData(ii).Data(jj).gps;
                    end
                    auxSpecInfo_GPS = [SpecInfo(jj).gps.Latitude, SpecInfo(jj).gps.Longitude];
                    
                    ind1 = [];
                    for kk = 1:numel(app.specData)
                        auxSpecData_GPS = [app.specData(kk).gps.Latitude, app.specData(kk).gps.Longitude];
                        Distance_GPS    = Misc_lldistkm(auxSpecInfo_GPS, auxSpecData_GPS) * 1000;
                        
                        if isequal(auxSpecData(kk), auxSpecInfo(jj)) && Distance_GPS <= app.General.Merge.DistanceValue
                            ind1 = kk;
                            break
                        end
                    end

                    if isempty(ind1)
                        continue
                    end
                    
                    if ii == 1; ind2 = 1;
                    else;       ind2 = sum(app.Samples(ind1, 1:(ii-1)))+1;
                    end
                    ind3 = sum(app.Samples(ind1, 1:ii));
                    
                    app.specData(ind1).Data{1}(1,ind2:ind3) = SpecInfo(jj).Data{1};
                    app.specData(ind1).Data{2}(:,ind2:ind3) = SpecInfo(jj).Data{2};
                    
                    if ~any(contains(app.specData(ind1).RelatedFiles, SpecInfo(jj).RelatedFiles))
                        app.specData(ind1).RelatedFiles = [app.specData(ind1).RelatedFiles, SpecInfo(jj).RelatedFiles];
                        app.specData(ind1).RelatedGPS   = [app.specData(ind1).RelatedGPS,   SpecInfo(jj).RelatedGPS];
                    end
                end
            end
            app.specData = rmfield(app.specData, 'Blocks');
            
            % Manipulações acessórias
            d.Message = ['Em andamento manipulações acessórias, como aferição de parâmetros estatísticos ' ...
                         'e identificação de fluxo(s) de ocupação relacionado(s).'];
            File_specDataReader_Aux2(app, prj_specData)
            
        end
        
        
        function File_specDataReader_Aux1(app)
            
            for ii = 1:numel(app.metaData)
                if ii == 1
                    % Inicialização da variável app.specData e app.Samples
                    app.specData = app.metaData(1).Data;
                    app.Samples  = app.metaData(1).Samples;
                    if isempty(app.Samples); app.Samples = 0;
                    end
                    
                else
                    % Organização dos fluxos de dados de outros arquivos (caso existentes)
                    SpecInfo   = app.metaData(ii).Data;
                    auxSamples = app.metaData(ii).Samples;
                    if isempty(auxSamples); auxSamples = 0;
                    end
                    
                    auxSpecInfo = File_specDataReader_Aux3(app, SpecInfo);
                    for jj = 1:numel(SpecInfo)
                        auxSpecInfo_GPS = [SpecInfo(jj).gps.Latitude, SpecInfo(jj).gps.Longitude];
                        
                        auxSpecData     = File_specDataReader_Aux3(app, app.specData);
                        for kk = 1:numel(app.specData)                        
                            auxSpecData_GPS = [app.specData(kk).gps.Latitude, app.specData(kk).gps.Longitude];
                            Distance_GPS    = Misc_lldistkm(auxSpecInfo_GPS, auxSpecData_GPS) * 1000;
                            
                            if isequal(auxSpecData(kk), auxSpecInfo(jj)) && Distance_GPS <= app.General.Merge.DistanceValue
                                app.Samples(kk, ii) = auxSamples(jj);
                                break
                                
                            elseif kk == numel(app.specData)
                                ind = numel(app.specData)+1;
                                
                                app.specData(ind)    = SpecInfo(jj);
                                app.Samples(ind, ii) = auxSamples(jj);
                            end
                        end
                    end
                end
            end
            
            % Pré-alocação
            totalSamples = sum(app.Samples, 2);
            for ii = 1:numel(app.specData)
                app.specData(ii).Samples = totalSamples(ii);
                app.specData(ii).Data    = {repmat(datetime([0 0 0 0 0 0], 'Format', 'dd/MM/yyyy HH:mm:ss'), 1, totalSamples(ii)), ...
                                            zeros(app.specData(ii).MetaData.DataPoints, totalSamples(ii), 'single')};
            end
            
        end
        
        
        function File_specDataReader_Aux2(app, prj_specData)

            % Ordenação dos fluxos dos dados:
            % - Primário:   Node
            % - Secundário: ThreadID
            tableNode      = {};
            for ii = 1:numel(app.specData)
                tableNode = [tableNode; app.specData(ii).Node];
            end
            [~,    idx1] = sortrows(table(tableNode, [app.specData.ThreadID]'));
            app.specData = app.specData(idx1);
            app.Samples  = app.Samples(idx1,:);
            
            % Fluxos de dados ordenados temporalmente
            for ii = 1:numel(app.specData)
                if ~issorted(app.specData(ii).Data{1})
                    [app.specData(ii).Data{1}, idx2] = sort(app.specData(ii).Data{1});
                    app.specData(ii).Data{2}         = app.specData(ii).Data{2}(:,idx2);
                end

                % Criação de campos úteis no appAnálise
                app.specData(ii).Emissions            = [];            
                app.specData(ii).reportFlag           = [];
                app.specData(ii).reportOCC            = struct('Related', [], 'Index', [], 'Method', '', 'Parameters', '');
                app.specData(ii).reportDetection      = [];
                app.specData(ii).reportClassification = [];
            end
                        
            % Ajuste de alguns campos, no caso de ter sido aberto um
            % projeto appAnálise.
            if ~isempty(prj_specData) && isfield(prj_specData, 'reportFlag')
                for ii = 1:numel(prj_specData)
                    for jj = 1:numel(app.specData)
                        auxPrjData  = File_specDataReader_Aux3(app, prj_specData(ii));
                        auxSpecData = File_specDataReader_Aux3(app, app.specData(jj));
                        
                        if isequal(rmfield(auxPrjData,  {'Emissions', 'reportFlag', 'reportOCC', 'reportDetection', 'reportClassification'}), ...
                                   rmfield(auxSpecData, {'Emissions', 'reportFlag', 'reportOCC', 'reportDetection', 'reportClassification'}))
                            
                            % Precisa tirar também o SampleTime
                            app.specData(jj).Emissions            = prj_specData(ii).Emissions;                            
                            app.specData(jj).reportFlag           = prj_specData(ii).reportFlag;
                            app.specData(jj).reportOCC            = prj_specData(ii).reportOCC;
                            app.specData(jj).reportDetection      = prj_specData(ii).reportDetection;
                            app.specData(jj).reportClassification = prj_specData(ii).reportClassification;                            
                        end
                    end
                end
            end
            
            for ii = 1:numel(app.specData)
                % ESTATÍSTICA DAS INFORMAÇÕES DE GPS CONSTANTE NOS DIVERSOS
                % ARQUIVOS
                gpsTable = table('Size', [0, 4],                                                 ...
                                 'VariableTypes', {'double', 'double',   'double',    'double'}, ...
                                 'VariableNames', {'Status', 'Latitude', 'Longitude', 'Count'});  
                
                for jj = 1:numel(app.specData(ii).RelatedGPS)
                    if app.specData(ii).RelatedGPS{jj}.Status ~= 0
                        gpsTable(end+1,:) = {app.specData(ii).RelatedGPS{jj}.Status,    ...
                                             app.specData(ii).RelatedGPS{jj}.Latitude,  ...
                                             app.specData(ii).RelatedGPS{jj}.Longitude, ...
                                             app.specData(ii).RelatedGPS{jj}.Count};
                    end
                end
                
                if height(gpsTable) > 1
                    app.specData(ii).gps.Status    = min(gpsTable.Status(gpsTable.Status ~= 0));
                    app.specData(ii).gps.Latitude  = sum(gpsTable.Count .* gpsTable.Latitude)  ./ sum(gpsTable.Count);
                    app.specData(ii).gps.Longitude = sum(gpsTable.Count .* gpsTable.Longitude) ./ sum(gpsTable.Count);
                    app.specData(ii).gps.Location  = FindCity(app.specData(ii).gps);
                end
                
                if isempty(app.specData(ii).reportFlag)
                    app.specData(ii).reportFlag = 0;
                end
                
                % ESTATÍSTICA DOS FLUXOS DE ESPECTRO E O SEU MAPEAMENTO COM OS FLUXOS DE OCC
                if ismember(app.specData(ii).MetaData.DataType, app.specDataTypes)
                    app.specData(ii).statsData = [min(app.specData(ii).Data{2}, [], 2), ...
                                                  median(app.specData(ii).Data{2},  2), ...
                                                  mean(app.specData(ii).Data{2},    2), ...
                                                  max(app.specData(ii).Data{2}, [], 2)];
            
                    occInd = [];
                    for jj = 1:numel(app.specData)
                        if ismember(app.specData(jj).MetaData.DataType, app.occDataTypes)
                            occInd = [occInd, jj];
                        end
                    end

                    for kk = occInd
                        logEvaluation = strcmp(app.specData(ii).Node, app.specData(kk).Node)                         & ...
                                        app.specData(ii).MetaData.FreqStart  == app.specData(kk).MetaData.FreqStart  & ...
                                        app.specData(ii).MetaData.FreqStop   == app.specData(kk).MetaData.FreqStop   & ...
                                        app.specData(ii).MetaData.DataPoints == app.specData(kk).MetaData.DataPoints & ...
                                        strcmp(app.specData(ii).TaskName, app.specData(kk).TaskName);
            
                        if logEvaluation
                            app.specData(ii).reportOCC.Related = [app.specData(ii).reportOCC.Related, kk];
                        end
                    end
                    
                    if ~isempty(app.specData(ii).reportOCC.Related)
                        app.specData(ii).reportOCC.Index = app.specData(ii).reportOCC.Related(1);
                    end
                    
                % ESTATÍSTICA DOS FLUXOS DE OCC
                elseif ismember(app.specData(ii).MetaData.DataType, app.occDataTypes)
                    app.specData(ii).statsData = [min(app.specData(ii).Data{2}, [], 2), ...
                                                  mean(app.specData(ii).Data{2},    2), ...
                                                  max(app.specData(ii).Data{2}, [], 2)];
                end
            end
            
        end
        
        
        function Data = File_specDataReader_Aux3(app, ReferenceData)
            
            FieldsToRemove = {'ObservationTime'; 'Samples'; 'Data'; 'statsData'; 'RelatedFiles'; 'RelatedGPS'; 'gps'};
            if isfield(ReferenceData, 'Blocks')
                FieldsToRemove{end+1} = 'Blocks';
            end
            
            Data = rmfield(ReferenceData, [FieldsToRemove; app.General.Merge.OptionalFieldsToRemove]);
            
            for ii = 1:numel(Data)
                Data(ii).MetaData = rmfield(Data(ii).MetaData, 'SampleTime');
                
                if strcmp(app.General.Merge.ThreadIDStatus, 'remove');    Data(ii).MetaData = rmfield(Data(ii).MetaData, 'ThreadID');
                end
                
                if strcmp(app.General.Merge.DataTypeStatus, 'remove');    Data(ii).MetaData = rmfield(Data(ii).MetaData, 'DataType');
                end
                
                if strcmp(app.General.Merge.AntennaNameStatus, 'remove'); Data(ii).MetaData = rmfield(Data(ii).MetaData, 'metaString');
                end              
            end
            
        end
        
        
        function File_specDataReader_Error(app)
            
            app.Samples  = [];
            app.specData = [];
            
            if ~isempty(app.mainInfo_Tree.Children)
                delete(app.mainInfo_Tree.Children)
            end
            
            if ~isempty(app.Report_Tree.Children)
                delete(app.Report_Tree.Children)
            end
            
            app.fig.Visible = 0;
            mainInfo_DeleteAuxiliarWin_ButtonPushed(app)
            
            app.Button_File.Value      = 1;
            app.Button_Playback.Enable = 0;
            app.Button_Report.Enable   = 0;
            app.Button_EditInfo.Enable = 0;
            app.Button_SaveFile.Enable = 0;
            
            mainMenu_File(app)
            
        end
        
        
        function mainInfo_TreeBuilding(app)
            
            delete(app.mainInfo_Tree.Children);
            
            app.mainInfo_Expand.ImageSource = 'General_Expand.png';
            app.mainInfo_Text.Value         = '';
            app.General.WarnCount      = 0;
            
            % Criação dos nós da árvore.
            for ii = 1:length(app.specData)
                allNodes{ii,1} = app.specData(ii).Node;
            end
            [uniqueNodes, ~, ic] = unique(allNodes);
            a_counts = accumarray(ic, 1);
            
            for ii = 1:length(uniqueNodes)
                imageIcon    = Layout_imageIconTree(app, uniqueNodes{ii});
                Category(ii) = uitreenode(app.mainInfo_Tree, 'Text', uniqueNodes{ii},     ...
                                                             'NodeData', uniqueNodes{ii}, ...
                                                             'Icon', imageIcon);
                
                auxIndex = find(ic == ii);
                for jj = 1:a_counts(ii)
                    if ismember(app.specData(auxIndex(jj)).MetaData.DataType, app.specDataTypes)
                        Attributes(jj) = uitreenode(Category(ii), 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz',             ...
                                                                          app.specData(auxIndex(jj)).ThreadID,                ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.FreqStart, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.FreqStop), ...
                                                                  'NodeData', auxIndex(jj));
                        
                        % GPS
                        if app.specData(auxIndex(jj)).gps.Status == -1
                            uitreenode(Attributes(jj), 'Text', sprintf('GPS: %.6f, %.6f (%s)', app.specData(auxIndex(jj)).gps.Latitude,  ...
                                                                                               app.specData(auxIndex(jj)).gps.Longitude, ...
                                                                                               app.specData(auxIndex(jj)).gps.Location), ...
                                                       'NodeData', auxIndex(jj), 'Icon', 'General_Manual.png');
                        elseif app.specData(auxIndex(jj)).gps.Status == 0
                            app.General.WarnCount = app.General.WarnCount + 1;
                            uitreenode(Attributes(jj), 'Text', 'GPS', 'NodeData', auxIndex(jj), 'Icon', 'Warn_Red.png');
                        else
                            uitreenode(Attributes(jj), 'Text', sprintf('GPS: %.6f, %.6f (%s)', app.specData(auxIndex(jj)).gps.Latitude,  ...
                                                                                               app.specData(auxIndex(jj)).gps.Longitude, ...
                                                                                               app.specData(auxIndex(jj)).gps.Location), ...
                                                       'NodeData', auxIndex(jj));
                        end
                            
                        % Fluxo(s) de Ocupação relacionado(s) ao de espectro
                        if ~isempty(app.specData(auxIndex(jj)).reportOCC.Related)
                        Temp2 = uitreenode(Attributes(jj), 'Text', 'Fluxo(s) de ocupação', 'NodeData', auxIndex(jj));

                            if isempty(app.specData(auxIndex(jj)).reportOCC.Index)
                                uitreenode(Temp2, 'Text', 'Ocupação a ser aferida', 'NodeData', auxIndex(jj), 'Icon', 'General_Manual.png');
                            end
                            
                            auxIndex2 = app.specData(auxIndex(jj)).reportOCC.Related;
                            for kk = auxIndex2
                                if (app.specData(auxIndex(jj)).reportOCC.Index == kk) & (kk == auxIndex2(1))
                                    uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', ...
                                               app.specData(kk).MetaData.Threshold,                     ...
                                               app.specData(kk).MetaData.metaString{1},                 ...
                                               app.specData(kk).ThreadID), 'NodeData', auxIndex(jj), 'Icon', 'General_Auto.png');
                                else
                                    if app.specData(auxIndex(jj)).reportOCC.Index == kk
                                        uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', ...
                                                   app.specData(kk).MetaData.Threshold,                     ...
                                                   app.specData(kk).MetaData.metaString{1},                 ...
                                                   app.specData(kk).ThreadID), 'NodeData', auxIndex(jj), 'Icon', 'General_Manual.png');
                                    else
                                        uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', ...
                                                   app.specData(kk).MetaData.Threshold,                     ...
                                                   app.specData(kk).MetaData.metaString{1},                 ...
                                                   app.specData(kk).ThreadID), 'NodeData', auxIndex(jj));
                                    end
                                end                     
                            end
                        end
                        
                    else
                        Attributes(jj) = uitreenode(Category(ii), 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz (Ocupação)',  ...
                                                                          app.specData(auxIndex(jj)).ThreadID,                ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.FreqStart, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.FreqStop), ...
                                                                  'NodeData', auxIndex(jj));
                        
                        % Threshold
                        uitreenode(Attributes(jj), 'Text', sprintf('Threshold: %d %s', app.specData(auxIndex(jj)).MetaData.Threshold, app.specData(auxIndex(jj)).MetaData.metaString{1}), ...
                                                   'NodeData', auxIndex(jj));

                    end
                    % Varreduras
                    Temp1 = uitreenode(Attributes(jj), 'Text', sprintf('%d varreduras', app.specData(auxIndex(jj)).Samples), ...
                                                       'NodeData', auxIndex(jj));
                    uitreenode(Temp1, 'Text', sprintf('%s (Início)', datestr(app.specData(auxIndex(jj)).Data{1}(1), 'dd/mm/yyyy HH:MM:SS')), ...
                                      'NodeData', auxIndex(jj));
                    uitreenode(Temp1, 'Text', sprintf('%s (Fim)', datestr(app.specData(auxIndex(jj)).Data{1}(end),  'dd/mm/yyyy HH:MM:SS')), ...
                                      'NodeData', auxIndex(jj));
                    
                end
            end
            
            if app.General.WarnCount == 0
                app.mainInfo_Label2.Text = sprintf('%d alerta(s)', app.General.WarnCount);
                app.mainInfo_Label2.FontColor = 'black';
            else
                app.mainInfo_Label2.Text = sprintf('%d alerta(s) crítico(s)', app.General.WarnCount);
                app.mainInfo_Label2.FontColor = 'red';
            end
            
        end
        
        
        function Playback_TreeBuilding(app, selectedEmission)

            if ~isempty(app.Playback_peakTree.Children)
                delete(app.Playback_peakTree.Children)
            end
            
            ind1 = app.traceInfo.SelectedNode;

            if app.traceInfo.SelectedNode                
                if ~isempty(app.specData(ind1).Emissions)
                    for ii = 1:height(app.specData(ind1).Emissions)
                        if app.specData(ind1).Emissions.Type(ii); Icon = 'TruncatedPeak.png';
                        else;                                     Icon = 'NoTruncatedPeak.png';
                        end
    
                        uitreenode(app.Playback_peakTree, 'Text', sprintf('%d: %.3f MHz @ %.3f kHz', ii, app.specData(ind1).Emissions{ii,2}, app.specData(ind1).Emissions{ii,3}), ...
                                                          'NodeData', ii, 'Icon', Icon, 'ContextMenu', app.ContextMenu_Playback);
                    end
    
                    app.Playback_peakTree.SelectedNodes = app.Playback_peakTree.Children(selectedEmission);
                end                
            end
            Playback_peakTreeSelectionChanged(app)
            
        end
        
        
        function Playback_RestoreView(app, selectedNodes)
            
                if ~ismember(app.specData(selectedNodes.NodeData).MetaData.DataType, app.occDataTypes)
                    auxValue = min(app.specData(selectedNodes.NodeData).statsData(:,1));
                    downYLim = auxValue - mod(auxValue, 10);
                
                    auxValue = max(app.specData(selectedNodes.NodeData).statsData(:,end));
                    upYLim   = auxValue - mod(auxValue, 10) + 10;                    
                else
                    downYLim = 0;
                    upYLim   = 100;
                end                
                app.RestoreView = {[app.specData(selectedNodes.NodeData).MetaData.FreqStart, ...
                                    app.specData(selectedNodes.NodeData).MetaData.FreqStop] ./ 1e+6,  [downYLim, upYLim]};
            
        end


        function Playback_xLimits(app, src, evt)

            app.Playback_xLim1.Value = round(app.axes1.XLim(1), 3);
            app.Playback_xLim2.Value = round(app.axes1.XLim(2), 3);

        end
                
        
        function Playback_yLimits(app, src, evt)

            app.Playback_yLim1.Value = round(double(app.axes1.YLim(1)), 1);
            app.Playback_yLim2.Value = round(double(app.axes1.YLim(2)), 1);

        end

        
        function Plot(app)
            
            app.fig.Visible = 1;
            
            t1 = 0;
            while app.timeIndex <= app.specData(app.traceInfo.SelectedNode).Samples
                if ~app.plotFlag
                    return
                elseif app.axesFlag
                    Plot_Layout1(app)
                    Plot_Layout2(app)
                    Plot_StartUp1(app)
                    Plot_StartUp2(app);
                    Plot_StartUp3(app)
                end
                
% %             TimeStamp and Slider Update
                tic

                
% %             Axes 1: Spectrum plot ans its markers
                app.line_ClearWrite.YData = app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex)';
                
                for ii = 1:numel(app.mkr_Label)
                    app.mkr_Label(ii).Position(2) = app.line_ClearWrite.YData(app.line_ClearWrite.MarkerIndices(ii));
                end
                
% %             Axes2: OCC Plot
                if ismember(app.traceInfo.plotLayout, [1,3])
                    switch app.Playback_occType.Value
                        case 'Linear'
                            app.axes2.Children(1).YData = ((app.Playback_occTime.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.axes1.Children(end).YData > app.Playback_occValue.Value))/app.Playback_occTime.Value;
                        case 'Piso de ruído (Offset)'
                            app.axes2.Children(1).YData = ((app.Playback_occTime.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.axes1.Children(end).YData > app.occTHR))/app.Playback_occTime.Value;
                    end
                    app.axes2.Children(2).YData = max(app.axes2.Children(1).YData, app.axes2.Children(2).YData);
                end
                                
% %             Axes3: WaterFall Plot
                if ismember(app.traceInfo.plotLayout, [2, 3])
                    timestampValue = repmat(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 1, 2);
                    app.line_wfTime.YData = timestampValue;
                end
                drawnow
                
                
% %             Pause
                pause(0.001)
%                 t0 = toc;
%                 if t0 < app.Playback_Pause.Value/1000; pause(app.Playback_Pause.Value/1000 - t0)
%                 else;                                  pause(0.001)
%                 end
                t1 = (9*t1+toc)/10;
                
                app.axes1.Title.String{4} = sprintf('%s (%d de %d). Tempo de escrita: %.0f ms.', ...
                                            datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS'), ...
                                            app.timeIndex, app.specData(app.traceInfo.SelectedNode).Samples, 1000*t1);
                app.Playback_Slider.Value = 100 * app.timeIndex/app.specData(app.traceInfo.SelectedNode).Samples;
                
% %             Reload Flag
                if app.timeIndex == app.specData(app.traceInfo.SelectedNode).Samples
                    if strcmp(app.Playback_Button4.Icon, 'Playback_Icon4A.png'); app.timeIndex = 1;
                    else;                                                        break
                    end
                else
                    app.timeIndex = app.timeIndex+1;
                end                
            end
            
            Playback_ButtonPlayPushed(app)
            
        end
        
        
        function Plot_Layout1(app)
            
            cla(app.axes1)
            cla(app.axes2)
            cla(app.axes3)
            
            app.fig.Visible = 1;
            
            app.timeIndex             = 1;
            app.Playback_Slider.Value = 0;
            
            app.line_ClearWrite = [];
            app.line_OCC        = [];
            app.line_OCCLabel   = [];
            app.mkr_ROI        = [];
            app.mkr_Label  = [];
            app.line_wfTime     = [];
            
% %         Desabilita WaterFall caso fluxo de dados tenha até duas amostras.    
            if app.specData(app.traceInfo.SelectedNode).Samples > 2
                if ~app.occFlag; app.Playback_occCheckBox.Enable = 1;
                end
                app.Playback_wfCheckBox.Enable  = 1;
            else
                set(app.Playback_occCheckBox, 'Enable', 0, 'Value', 0)
                Playback_occCheckBoxValueChanged(app)
                
                set(app.Playback_wfCheckBox, 'Enable', 0, 'Value', 0)
                Playback_wfCheckBoxValueChanged(app)
            end
            
        end
            
        
        function Plot_Layout2(app)
            
% %         Layout
            linkaxes([app.axes1, app.axes2, app.axes3], 'off')
                        
            switch app.traceInfo.plotLayout
                case 0
                    app.axes1.InnerPosition = [.08 .08 .85 .8];
                    app.axes2.InnerPosition = [0 0 0 0];
                    app.axes3.InnerPosition = [0 0 0 0];
                    app.cBar1.Position      = [0 0 0 0];
                    
                    app.axes1.XTickLabelMode = 'auto';

                    xlabel(app.axes1, 'Frequência (MHz)')
                    
                case 1
                    app.axes1.InnerPosition = [.08 .48 .85 .4];
                    app.axes2.InnerPosition = [.08 .08 .85 .38];
                    app.axes3.InnerPosition = [0 0 0 0];
                    app.cBar1.Position      = [0 0 0 0];
                    
                    app.axes1.XTickLabel     = {};
                    app.axes2.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, 'Frequência (MHz)')
                    
                    linkaxes([app.axes1, app.axes2], 'x')

                case 2
                    if app.Playback_Layout.Icon == "newPlot_Factor1.png"
                        app.axes1.InnerPosition = [.08 .48 .85 .4];
                        app.axes2.InnerPosition = [0 0 0 0];
                        app.axes3.InnerPosition = [.08 .08 .85 .38];
                    else
                        app.axes1.InnerPosition = [.08 .72 .85 .16];
                        app.axes2.InnerPosition = [0 0 0 0];
                        app.axes3.InnerPosition = [.08 .08 .85 .62];
                    end
                    app.cBar1.Position      = [.9405 .08 .0145 .10];
                    
                    app.axes1.XTickLabel = {};
                    app.axes3.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (MHz)')
                    
                    linkaxes([app.axes1, app.axes3], 'x')

                case 3
                    if app.Playback_Layout.Icon == "newPlot_Factor1.png"
                        app.axes1.InnerPosition = [.08 .58 .85 .30];
                        app.axes2.InnerPosition = [.08 .40 .85 .16];
                        app.axes3.InnerPosition = [.08 .08 .85 .30];
                    else
                        app.axes1.InnerPosition = [.08 .72 .85 .16];
                        app.axes2.InnerPosition = [.08 .54 .85 .16];
                        app.axes3.InnerPosition = [.08 .08 .85 .44];
                    end
                    app.cBar1.Position      = [.9405 .08  .0145 .10];
                    
                    app.axes1.XTickLabel = {};
                    app.axes2.XTickLabel = {};
                    app.axes3.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (MHz)')
                    
                    linkaxes([app.axes1, app.axes2, app.axes3], 'x')
            end
            
        end
        
        
        function Plot_StartUp1(app)
            
            ind1 = app.traceInfo.SelectedNode;
            
% %         Axes1 (Spectrum plot): Figure name, title and subtitle.
            metaString = app.specData(ind1).MetaData.metaString([3,4,2]);
            idx1       = cellfun(@(x) ~isempty(x), metaString);
            metaString = strjoin(metaString(idx1), ', ');

            app.axes1.Title.String = sprintf(['\\fontsize{8}\\bf%s\n'                               ...
                                              '\\fontsize{7}\\rm%.3f - %.3f MHz, %s, %.0f pontos\n' ...
                                              'GPS: %.6f, %.6f (%s)'],                      ...
                                              app.specData(ind1).Node,                      ...
                                              app.specData(ind1).MetaData.FreqStart / 1e+6, ...
                                              app.specData(ind1).MetaData.FreqStop  / 1e+6, ...
                                              metaString,                             ...
                                              app.specData(ind1).MetaData.DataPoints, ...
                                              app.specData(ind1).gps.Latitude,        ...
                                              app.specData(ind1).gps.Longitude,       ...
                                              app.specData(ind1).gps.Location);
            
            app.axes1.Title.String{4} = sprintf('%s (%d de %d)',                                               ...
                                                datestr(app.specData(ind1).Data{1}(1), 'dd/mm/yyyy HH:MM:SS'), ...
                                                app.timeIndex, app.specData(ind1).Samples);
            
            if ~ismember(app.specData(ind1).MetaData.DataType, app.occDataTypes)
                app.Unit = app.specData(ind1).MetaData.metaString{1};
                yLabel   = sprintf('Nível (%s)', app.Unit);
                app.Playback_peakTHRLabel.Text{2} = sprintf('(%s):', app.Unit);
            else
                yLabel   = 'Ocupação (%)';
                app.Unit = '%';
            end
            
            set(app.axes1, XLim=app.RestoreView{1}, YLim=app.RestoreView{2})
            ylabel(app.axes1, yLabel);
            
% %          Axes 2 (OCC plot)
            if app.Playback_occCheckBox.Value
                set(app.axes2, XLim=app.axes1.XLim, YLim=[0, 100]);
                ylabel(app.axes2, 'Ocupação (%)');
            end
            
% %          Axes 3 (WaterFall plot)
            if app.Playback_wfCheckBox.Value
                if diff(app.axes1.YLim) > 100
                    Li = (app.axes1.YLim(1)+diff(app.axes1.YLim)-100);
                    Ls = app.axes1.YLim(2);
                else
                    Li = app.axes1.YLim(1);
                    Ls = app.axes1.YLim(2);
                end
                caxis(app.axes3, [Li Ls])
                colormap(app.axes3, app.Playback_ColorMap.Value);
                
                set(app.cBar1, Limits=[Li, Ls], Ticks=[Li, Ls], TickLabels={num2str(Li), num2str(Ls)})             
                set(app.axes3, XLim=app.axes1.XLim)
                ylabel(app.axes3, 'Amostras');
            end
            
        end
        
        
        function Plot_StartUp2(app)
            
            ind1 = app.traceInfo.SelectedNode;
            
            app.x = round(linspace(app.specData(ind1).MetaData.FreqStart ./ 1e+6, ...
                                   app.specData(ind1).MetaData.FreqStop  ./ 1e+6, ...
                                   app.specData(ind1).MetaData.DataPoints), 3);
            
            app.Span  = (app.specData(ind1).MetaData.FreqStop - app.specData(ind1).MetaData.FreqStart);
            app.aCoef = app.Span ./ (app.specData(ind1).MetaData.DataPoints - 1);
            app.bCoef = app.specData(ind1).MetaData.FreqStart - app.aCoef;
            
            Plot_StartUp2_Spectrum(app, 'InitialPlot', ind1, 1)         % Main trace (ClearWrite) and its markers
            Plot_StartUp2_TraceMode(app, ind1)                          % Optional traces (MinHold, Median/Mean, MaxHold)

            if app.Playback_occCheckBox.Value                           % OCC
                Plot_StartUp2_OCC(app, ind1)
            end
            
            if app.Playback_wfCheckBox.Value
                Plot_StartUp2_WaterFall(app)                            % WaterFall
            end
            drawnow nocallbacks
            app.axesFlag = 0;
            
        end
        
        
        function Plot_StartUp2_Spectrum(app, Type, ind1, selectedEmission)
            
            % Startup
            switch Type
                case 'InitialPlot'
                    app.line_ClearWrite = plot(app.axes1, app.x, app.specData(ind1).Data{2}(:, app.timeIndex), Tag='ClearWrite',                   ...
                                               Color=app.General.Colors(4,:), Marker='.', MarkerIndices=[], MarkerFaceColor=[0.40,0.73,0.88], ...
                                               MarkerEdgeColor=[0.40,0.73,0.88], MarkerSize=14);

                case 'TreeSelectionChanged'
                    ind2 = app.Playback_peakTree.SelectedNodes.NodeData;
                    app.mkr_ROI.Position(:, [1, 3]) = [app.specData(ind1).Emissions.Frequency(ind2)-app.specData(ind1).Emissions.BW(ind2)/(2*1000), ...
                                                        app.specData(ind1).Emissions.BW(ind2)/1000];
                
                case {'AddButtonPushed', 'PeakValueChanged'}
                    delete(findobj('Tag', 'mkrTemp', '-or', 'Tag', 'mkrLine', '-or', 'Tag', 'mkrLabels'))

                case 'DeleteButtonPushed'
                    delete(findobj('Tag', 'mkrTemp', '-or', 'Tag', 'mkrLine', '-or', 'Tag', 'mkrLabels', '-or', 'Tag', 'mkrROI'))
                    
                    app.mkr_ROI       = [];
                    app.mkr_Label = [];
            end

            % Processing...
            switch Type
                case {'InitialPlot', 'AddButtonPushed', 'PeakValueChanged', 'DeleteButtonPushed'}
                    Playback_TreeBuilding(app, selectedEmission)

                    if isempty(app.specData(ind1).Emissions)
                        app.line_ClearWrite.MarkerIndices = [];

                    else
                        app.line_ClearWrite.MarkerIndices = app.specData(ind1).Emissions.Index;

                        mkrLabels = {};
                        for ii = 1:height(app.specData(ind1).Emissions)
                            mkrLabels = [mkrLabels {['  ' num2str(ii)]}];
        
                            FreqStart = app.specData(ind1).Emissions.Frequency(ii)-app.specData(ind1).Emissions.BW(ii)/(2*1000);
                            FreqStop  = app.specData(ind1).Emissions.Frequency(ii)+app.specData(ind1).Emissions.BW(ii)/(2*1000);
                            BW        = app.specData(ind1).Emissions.BW(ii)/1000;
                            Level     = app.RestoreView{2}(1)+1;
                            
                            % Cria uma linha por emissão, posicionando-o na parte inferior
                            % do plot.
                            line(app.axes1, [FreqStart, FreqStop], [Level, Level], ...
                                            Color=[0.40,0.73,0.88], LineWidth=1, ...
                                            Marker='.',             MarkerSize=14, ...
                                            PickableParts='none',   Tag='mkrLine')
            
                            % Cria um ROI para a emissão selecionada, posicionando-o em
                            % todo o plot.
                            if ii == selectedEmission
                                newPosition = [FreqStart, app.RestoreView{2}(1)+1, ...
                                               BW,        app.RestoreView{2}(2)-app.RestoreView{2}(1)-2];
                                
                                if isempty(app.mkr_ROI)
                                    Plot_StartUp2_ROI(app, newPosition)
                                else
                                    app.mkr_ROI.Position = newPosition;
                                end
                            end
                        end
                        uistack(app.line_ClearWrite, 'top');

                        app.mkr_Label = text(app.axes1, app.specData(ind1).Emissions.Frequency, double(app.specData(ind1).Data{2}(app.specData(ind1).Emissions.Index, app.timeIndex)), mkrLabels, ...
                                                             Color=[0.40,0.73,0.88], FontSize=7, FontWeight='bold', FontName='Helvetica', FontSmoothing='on', Tag='mkrLabels');
                    end
            end

        end


        function Plot_StartUp2_ROI(app, newPosition)

            app.mkr_ROI = images.roi.Rectangle(app.axes1, Position=newPosition, ...
                                                           Color=[0.40,0.73,0.88], ...
                                                           MarkerSize=5,           ...
                                                           Deletable=0,            ...
                                                           LineWidth=1,            ...
                                                           Tag='mkrROI');

            addlistener(app.mkr_ROI, 'MovingROI', @app.mkrLineROI);
            addlistener(app.mkr_ROI, 'ROIMoved',  @app.mkrLineROI);

        end
        
        

        function Plot_StartUp2_TraceMode(app, ind1)
            
            auxIndex1 = find(app.traceInfo.Mode ~= 0);
            if ~isempty(auxIndex1)
                    for ii = auxIndex1                        
                        switch ii
                            case 1; auxStr = 'MinHold';
                            case 2
                                switch app.traceInfo.Mode(2)
                                    case 2; auxStr = 'Mediana';
                                    case 3; auxStr = 'Média';
                                end
                            case 3; auxStr = 'MaxHold';
                        end
                        plot(app.axes1, app.x, app.specData(ind1).statsData(:, app.traceInfo.Mode(ii)), ...
                             'Tag', auxStr, 'Color', app.General.Colors(ii,:));
                    end
            end
            
        end
        
        
        function Plot_StartUp2_OCC(app, ind1)
            
            occObj = findobj('Tag', 'occROI');

            if isempty(occObj)
                if strcmp(app.specData(ind1).MetaData.metaString{1}, 'dBm') && (app.Playback_occValue.Value > 0)
                    app.Playback_occValueLabel.Text = 'Valor (dBm):';
                    app.Playback_occValue.Value     = -80;

                elseif strcmp(app.specData(ind1).MetaData.metaString{1}, 'dBμV') && (app.Playback_occValue.Value < 0)
                    app.Playback_occValueLabel.Text = 'Valor (dBμV):';
                    app.Playback_occValue.Value     = 27;
                    
                elseif strcmp(app.specData(ind1).MetaData.metaString{1}, 'dBμV/m') && (app.Playback_occValue.Value < 0)
                    app.Playback_occValueLabel.Text = 'Valor (dBμV/m):';
                    app.Playback_occValue.Value     = 40;
                end
                
                if app.Playback_occValue.Value > app.axes1.YLim(2)
                    app.Playback_occValue.Value = double(app.axes1.YLim(2));
                end
                
                switch app.Playback_occType.Value
                    case 'Linear'
                        app.occTHR   = app.Playback_occValue.Value;
                        app.line_OCC = images.roi.Line(app.axes1,'Position',[app.x(1) app.Playback_occValue.Value; app.x(end) app.Playback_occValue.Value], 'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate', 'Tag', 'occROI');
                        
                        addlistener(app.line_OCC, 'MovingROI', @app.occLineROI);
                        addlistener(app.line_OCC, 'ROIMoved',  @app.occLineROI);
                        
                    case 'Piso de ruído (Offset)'
                        occInfo = struct('Offset',   app.Playback_occOffset.Value,                              ...
                                         'Fcn',      app.Playback_occFcn.Value,                                 ...
                                         'Samples', [app.Playback_tSamples.Value, app.Playback_uSamples.Value], ...
                                         'Factor',   app.Playback_occConfLevel.Value);

                        app.occTHR   = Misc_occThresholdEstimation('Piso de ruído (Offset)', app.specData(ind1), occInfo);
                        app.line_OCC = plot(app.axes1, app.x, app.occTHR, 'Color', 'red', 'LineStyle', '-', 'LineWidth', 1, 'Marker', 'o', 'MarkerSize', 4, 'MarkerIndices', [1, app.specData(ind1).MetaData.DataPoints], 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'black', 'Tag', 'occROI');
                end
                app.line_OCCLabel = text(app.axes1, app.x(end), double(app.occTHR(end)), '  THR', 'FontName', 'Helvetica', 'FontSize', 7, 'Color', 'red', 'Tag', 'occROI');
                
                plot(app.axes2, app.x, zeros(1, numel(app.axes1.Children(end).YData), 'single'), 'Tag', 'maxOCC',  'Color', app.General.Colors(3,:));   % MaxHold
                plot(app.axes2, app.x, zeros(1, numel(app.axes1.Children(end).YData), 'single'), 'Tag', 'meanOCC', 'Color', app.General.Colors(2,:));   % Média
            
            else
                if isa(occObj(end), 'images.roi.Line')
                    app.line_OCC.Position(:,2)    = [app.Playback_occValue.Value; app.Playback_occValue.Value];
                    app.line_OCCLabel.Position(2) = app.Playback_occValue.Value;

                else
                    occInfo = struct('Offset',   app.Playback_occOffset.Value,                              ...
                                     'Fcn',      app.Playback_occFcn.Value,                                 ...
                                     'Samples', [app.Playback_tSamples.Value, app.Playback_uSamples.Value], ...
                                     'Factor',   app.Playback_occConfLevel.Value);

                    app.occTHR                    = Misc_occThresholdEstimation('Piso de ruído (Offset)', app.specData(ind1), occInfo);                    
                    app.line_OCC.YData            = app.occTHR;
                    app.line_OCCLabel.Position(2) = double(app.occTHR(end));
                end
                
                set(app.axes2.Children, 'YData', zeros(1, length(app.line_ClearWrite.YData), 'single'));
            end
            
        end    
                
        
        function Plot_StartUp2_WaterFall(app)

            ind1 = app.traceInfo.SelectedNode;
            
            if isempty(findobj('Tag', 'wfSurface'))
                if strcmp(app.Playback_DecimateFactor.Value, 'Automático')
                    auxSize = app.specData(ind1).MetaData.DataPoints .* app.specData(ind1).Samples;
                    if auxSize > 1474560; Decimate = ceil(auxSize/1474560);
                    else;                 Decimate = 1;
                    end
                else
                    Decimate = str2double(app.Playback_DecimateFactor.Value);
                end

                while true
                    t = app.specData(ind1).Data{1}(1:Decimate:end);
                    if numel(t) > 1; break
                    else;            Decimate = round(Decimate/2);
                    end
                end

                if ~strcmp(app.Playback_DecimateFactor.Value, 'Automático')
                    app.Playback_DecimateFactor.Value = num2str(Decimate);
                end

                if t(1) == t(end)
                    t(end) = t(1)+seconds(1);
                end
                app.RestoreView{3} = [t(1), t(end)];
                
                [X, Y] = meshgrid(app.x, t);
                mesh(app.axes3, X, Y, app.specData(ind1).Data{2}(:,1:Decimate:end)', 'MeshStyle', app.Playback_AxesHoles.Value, 'SelectionHighlight', 'off', 'Tag', 'wfSurface')
                view(app.axes3, 0, 90);
                
                if strcmp(app.Playback_wfLine.Value, 'Ativada')
                    app.line_wfTime = line(app.axes3, [app.x(1), app.x(end)], [app.specData(ind1).Data{1}(app.timeIndex), app.specData(ind1).Data{1}(app.timeIndex)], [app.axes1.YLim(2) app.axes1.YLim(2)], ...
                                                      'Color', 'red', 'LineWidth', 1, 'PickableParts', 'none', 'Tag', 'wfTimeStamp');
                end
                
                tTickLabel    = linspace(t(1), t(end), 3);
                [~, tIndex]   = min(abs(app.specData(ind1).Data{1} - tTickLabel(2)));
                tTickLabel(2) =  app.specData(ind1).Data{1}(tIndex);

                set(app.axes3, 'YLim' , [t(1), t(end)], 'YTick', tTickLabel, 'YTickLabel', [1, round(tIndex), app.specData(ind1).Samples])

                hText = findobj('Tag', 'DecimateLabel');
                if isempty(hText)
                    text(app.axes3, 1.01, .98, 0, sprintf('FD: %.0f', Decimate), 'Units', 'normalized', 'FontName', 'Helvetica', 'FontSize', 7, 'Tag', 'DecimateLabel')
                else
                    hText.String = sprintf('FD: %.0f', Decimate);
                end
            end
            
        end


        function Plot_StartUp3(app)
            
            ind1 = app.traceInfo.SelectedNode;

            % axes1
            for ii = 1:numel(app.axes1.Children)
                if strcmpi(app.axes1.Children(ii).Type, 'line')
                    if ~strcmp(app.axes1.Children(ii).Tag, 'mkrLine')
                        Misc_PlotDataTipSettings(app.specData(ind1), app.axes1.Children(ii), app.specData(ind1).MetaData.metaString{1})
                    end
                end
            end
            
            % axes2
            for ii = 1:numel(app.axes2.Children)
                if strcmpi(app.axes2.Children(ii).Type, 'line')
                    Misc_PlotDataTipSettings(app.specData(ind1), app.axes2.Children(ii), 'OCC')
                end
            end
            
            % axes3
            for ii = 1:numel(app.axes3.Children)
                if strcmpi(app.axes3.Children(ii).Type, 'surface')
                    Misc_PlotDataTipSettings(app.specData(ind1), app.axes3.Children(ii), app.specData(ind1).MetaData.metaString{1})
                end
            end
            
        end
        
        
        function traceInfoCallback1_SelectedNode_Mode(app)
            
            app.traceInfo.SelectedNode = app.mainInfo_Tree.SelectedNodes.NodeData;
            
            % median and mean
            if app.Playback_traceCheckBox1.Value
                if app.Playback_Fcn.Tooltip == "Média"
                    app.traceInfo.Mode(2) = 3;
                else
                    app.traceInfo.Mode(2) = 2;
                end
            else
                app.traceInfo.Mode(2) = 0;
            end
            
            % MinHold
            if app.Playback_traceCheckBox2.Value
                app.traceInfo.Mode(1) = 1;
            else
                app.traceInfo.Mode(1) = 0;
            end

            % MaxHold
            if app.Playback_traceCheckBox3.Value
                app.traceInfo.Mode(3) = 4;
            else
                app.traceInfo.Mode(3) = 0;
            end
            
        end
        
        
        function traceInfoCallback2_plotLayout(app)
            
            if app.Playback_occCheckBox.Value && app.Playback_wfCheckBox.Value
                app.traceInfo.plotLayout = 3;
            elseif app.Playback_wfCheckBox.Value
                app.traceInfo.plotLayout = 2;
            elseif app.Playback_occCheckBox.Value
                app.traceInfo.plotLayout = 1;
            else
                app.traceInfo.plotLayout = 0;
            end
            
        end

        
        function occLineROI(app, src, event)
            
            switch(event.EventName)
                case{'MovingROI'}
                    disableDefaultInteractivity(app.axes1)
                    
                    app.Playback_occValue.Value = event.CurrentPosition(1,2);
                    app.line_OCCLabel.Position(2)      = event.CurrentPosition(1,2);
                    
                case{'ROIMoved'}
                    enableDefaultInteractivity(app.axes1)
                    
                    set(app.axes2.Children, 'YData', zeros(1, length(app.line_ClearWrite.YData), 'single'));
            end
            
        end
        
        
        function mkrLineROI(app, src, event)

            ind1 = app.traceInfo.SelectedNode;

            switch(event.EventName)
                case{'MovingROI'}
                    disableDefaultInteractivity(app.axes1)

                    FreqCenter = app.mkr_ROI.Position(1) + app.mkr_ROI.Position(3)/2;
                    if (FreqCenter*1e+6 < app.specData(ind1).MetaData.FreqStart) || ...
                       (FreqCenter*1e+6 > app.specData(ind1).MetaData.FreqStop)
                    
                       return
                    end

                    app.PeakCF.Value = round(FreqCenter, 3);
                    app.PeakBW.Value = round(app.mkr_ROI.Position(3) * 1000, 3);

                    ind2 = app.Playback_peakTree.SelectedNodes(1).NodeData;
                    app.line_ClearWrite.MarkerIndices(ind2) = round((app.PeakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    
                    % Se tiver apenas um marcador, então a string fica como
                    % cell. Caso tenha mais de um marcador, então a string
                    % fica como char.
                    markerTag = findobj('Type', 'Text', 'String', sprintf('  %d', ind2));
                    if isempty(markerTag)
                        markerTag = findobj('Type', 'Text', 'String', {sprintf('  %d', ind2)});
                    end
                    markerTag.Position(1:2) = [app.PeakCF.Value, app.line_ClearWrite.YData(app.line_ClearWrite.MarkerIndices(ind2))];
                    
                    set(app.Playback_peakTree.Children(ind2), 'Text', sprintf("%d: %.3f MHz @ %.3f kHz", ind2, app.PeakCF.Value, app.PeakBW.Value), ...
                                                              'NodeData', ind2)
                    
                case{'ROIMoved'}
                    enableDefaultInteractivity(app.axes1)
                    
                    ind2 = app.Playback_peakTree.SelectedNodes(1).NodeData;
                    
                    newIndex = round((app.PeakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    
                    app.specData(ind1).Emissions(ind2,[1:3, 5]) = {newIndex, app.PeakCF.Value, app.PeakBW.Value, 'Detecção manual.'};
                    app.specData(ind1).Emissions = sortrows(app.specData(ind1).Emissions, 'Index');
                    
                    selectedEmission = find(app.specData(ind1).Emissions.Index == newIndex, 1);
                    Plot_StartUp2_Spectrum(app, 'PeakValueChanged', ind1, selectedEmission)
            end
            
        end
        
        
        function Report_InfoCreation(app, filteredRows)
            
            % Criação dos campos "reportDetection" e "reportClassification", caso aplicável.
            if ~isempty(filteredRows)
                for ii = filteredRows
                    app.specData(ii).reportFlag = 1;
                    
                    if isempty(app.specData(ii).reportDetection)
                        if isempty(app.specData(ii).reportOCC.Index)
                            app.specData(ii).reportOCC.Method     = 'Linear adaptativo';
                            app.specData(ii).reportOCC.Parameters = jsonencode(struct('IntegrationTime', 15, 'Offset', 12, 'Fcn',  'Média', 'Samples', [0, 20]));
                        end

                        app.specData(ii).reportDetection = struct('Band', [app.specData(ii).MetaData.FreqStart, app.specData(ii).MetaData.FreqStop] / 1e+6, ...
                                                                  'ManualMode', 0,                                                                          ...
                                                                  'Algorithm', 'appAnálise v. 1.00',                                                        ...
                                                                  'Parameters', jsonencode(struct('Cn_minDist', 25, 'Cn_BW', 10,                            ...
                                                                                                  'C1_Fcn', 'Média', 'C1_Proeminence', 10,                  ...
                                                                                                  'C2_Proeminence', 30, 'C2_meanOCC', 1, 'C2_maxOCC', 10)));

                        app.specData(ii).reportClassification = struct('Algorithm', 'appAnálise v. 1.00', ...
                                                                       'Parameters', ''); % JSON com o que precisa... tipo os "30 km"
                    end
                end
            end
            Report_TreeBuilding(app)
            
        end
        
        
        function Report_TreeBuilding(app)
            
            delete(app.Report_Tree.Children);
            
            FilteredRows = find([app.specData.reportFlag]);
            if isempty(FilteredRows)
                app.Report_playButton.Enable    = 0;
                app.Report_previewButton.Enable = 0;

                Report_TreeBuilding_Aux(app, 'Off')
                
            else
                app.Report_playButton.Enable    = 1;
                app.Report_previewButton.Enable = 1;

                Report_TreeBuilding_Aux(app, 'On')
                
                for ii = 1:numel(FilteredRows)
                    allNodes{ii,1} = app.specData(FilteredRows(ii)).Node;
                end
                [uniqueNodes, ~, ic] = unique(allNodes);
                a_counts = accumarray(ic, 1);
                
                for ii = 1:length(uniqueNodes)
                    imageIcon    = Layout_imageIconTree(app, uniqueNodes{ii});
                    Category(ii) = uitreenode(app.Report_Tree, 'Text', uniqueNodes{ii}, 'NodeData', uniqueNodes{ii}, 'Icon', imageIcon, 'ContextMenu', app.ContextMenu_Report);
                    
                    auxIndex1 = find(ic == ii);
                    for jj = 1:a_counts(ii)
                        idx = FilteredRows(auxIndex1(jj));
                        
                        uitreenode(Category(ii), 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz',    ...
                                                         app.specData(idx).ThreadID,                ...
                                                         1e-6*app.specData(idx).MetaData.FreqStart, ...
                                                         1e-6*app.specData(idx).MetaData.FreqStop), ...
                                                 'NodeData', idx, 'ContextMenu', app.ContextMenu_Report);
                    end
                end                
                Layout_expandTree(app, 'Report')
            end
            
        end
        
        
        function Report_TreeBuilding_Aux(app, Flag)
            
            switch Flag
                case 'Off'
                    app.Report_DatePicker1.Value = NaT;
                    app.Report_DatePicker2.Value = NaT;
                    
                    app.Report_Spinner1.Value = 0;
                    app.Report_Spinner2.Value = 0;
                    app.Report_Spinner3.Value = 23;
                    app.Report_Spinner4.Value = 59;
                    
                case 'On'
                    FilteredRows = find([app.specData.reportFlag]);
                    
                    StartDate = app.specData(FilteredRows(1)).Data{1}(1);
                    StopDate  = app.specData(FilteredRows(1)).Data{1}(end);
                    for ii = 1:numel(FilteredRows)
                        StartDate = min(StartDate, app.specData(FilteredRows(ii)).Data{1}(1));
                        StopDate  = max(StopDate,  app.specData(FilteredRows(ii)).Data{1}(end));
                    end
                    
                    app.Report_DatePicker1.Limits = [StartDate, StopDate];
                    app.Report_DatePicker1.Value  = app.Report_DatePicker1.Limits(1);
                    
                    app.Report_DatePicker2.Limits = app.Report_DatePicker1.Limits;
                    app.Report_DatePicker2.Value  = app.Report_DatePicker2.Limits(2);
            end
            
        end
        
        
        function ReportGenerator_PeaksUpdate(app, CheckedRows, Peaks)
            
            if isequal(CheckedRows, find([app.specData.reportFlag]))
                if ~isempty(Peaks); app.peaksTable      = Peaks;
                else;               app.peaksTable(:,:) = [];
                end
                
            else
                for ii = CheckedRows
                    Tag = sprintf('%s\nThreadID %d: %.3f - %.3f MHz', app.specData(ii).Node,                       ...
                                                                      app.specData(ii).ThreadID,                   ...
                                                                      app.specData(ii).MetaData.FreqStart ./ 1e+6, ...
                                                                      app.specData(ii).MetaData.FreqStop  ./ 1e+6);
                    if ~isempty(Peaks)
                        newTags = unique(Peaks.Tag);

                        ind = find(strcmp(newTags, Tag));
                        if ~isempty(ind)
                            oldInd = find(strcmp(app.peaksTable.Tag, newTags(ind)));
                            newInd = find(strcmp(Peaks.Tag,          newTags(ind)));
                            
                            app.peaksTable(oldInd, :)                  = [];
                            app.peaksTable(end+1:end+numel(newInd), :) = Peaks(newInd,:);
                            
                            app.peaksTable = sortrows(app.peaksTable, 'Tag');
                        else
                            oldInd = find(strcmp(app.peaksTable.Tag, Tag));
                            app.peaksTable(oldInd, :) = [];
                        end
                    else
                        oldInd = find(strcmp(app.peaksTable.Tag, Tag));
                        app.peaksTable(oldInd, :) = [];
                    end
                end
            end
            
        end


        function ReportGenerator_Main(app, Mode)
      
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            d.Message = sprintf(['Em andamento a análise dos fluxos de dados selecionados, o que inclui diversas manipulações, ' ...
                                 'como, por exemplo, a busca de emissões, a comparação com a base de dados da Agência e a geração de plots.']);
                
            try
                reportInfo = ReportGenerator_Aux1(app, Mode);
            
                % FLUXOS DE DADOS A PROCESSAR
                if Mode == "Preview"
                    CheckedRows = [];
                    for mm = 1:numel(app.Report_Tree.CheckedNodes)
                        if isnumeric(app.Report_Tree.CheckedNodes(mm).NodeData)
                            CheckedRows = [CheckedRows, app.Report_Tree.CheckedNodes(mm).NodeData];
                        end
                    end
                else
                    CheckedRows = find([app.specData.reportFlag]);
                end
                
                idx = Misc_FindOCCData(app.specData(CheckedRows));
                ProjectRows = sort([CheckedRows, idx]);
                
                % OCC Map (Step 1 of 2)
                OCCLength = numel(idx);
                if OCCLength
                    OCCMap = zeros(OCCLength, 2);
                    
                    jj = 0;
                    for ii = 1:numel(ProjectRows)
                        if ~isempty(app.specData(ProjectRows(ii)).reportOCC.Index)
                            jj=jj+1;
                            OCCMap(jj,:) = [ProjectRows(ii), app.specData(ProjectRows(ii)).reportOCC.Index];
                            app.specData(ProjectRows(ii)).reportOCC.Index = find(ProjectRows == OCCMap(jj,2));
                        end
                    end
                end
                
                if Mode == "Report"
                    [htmlReport, Peaks] = ReportGenerator(app.specData(ProjectRows), reportInfo, app.exceptionList);
                    ReportGenerator_PeaksUpdate(app, CheckedRows, Peaks)

                    if app.Report_Version.Value == "Definitiva"
                        filename = fullfile(app.menu_userPath.Value, sprintf('Report_%s_%.0f.html', datestr(datetime('now'), 'yyyy.mm.dd_THH.MM.SS'), app.Report_Issue.Value));
                    else
                        filename = fullfile(app.menu_userPath.Value, sprintf('~Report_%s_%.0f.html', datestr(datetime('now'), 'yyyy.mm.dd_THH.MM.SS'), app.Report_Issue.Value));
                    end

                    fileID = fopen(filename, 'w');
                    fprintf(fileID, '%s', htmlReport);
                    fclose(fileID);

                    if app.Report_Version.Value == "Definitiva"
                        app.General.Report = filename;
                        
                        [ReportProject, tableStr] = ReportGenerator_Aux2(app, ProjectRows);
                        save(replace(filename, '.html', '.mat'), 'ReportProject', '-mat', '-v7.3')
                        writematrix(tableStr, replace(filename, '.html', '.json'), "FileType", "text", "QuoteStrings", false)
                    end
                    web(filename, '-new')

                else
                    Peaks = PreviewGenerator(app.specData(ProjectRows), reportInfo);
                    ReportGenerator_PeaksUpdate(app, CheckedRows, Peaks)
                end
    
                % OCC Map (Step 2 of 2)
                if OCCLength
                    for jj = 1:height(OCCMap)
                        app.specData(OCCMap(jj,1)).reportOCC.Index = OCCMap(jj,2);
                    end
                end
                

            catch ME
                report = getReport(ME);
                uialert(app.MainApp, report, 'appAnálise', 'Interpreter','html');
                return
            end
            delete(d)

        end
        
        
        function reportInfo = ReportGenerator_Aux1(app, Mode)
            
            Date1 = app.Report_DatePicker1.Value + hours(app.Report_Spinner1.Value) + minutes(app.Report_Spinner2.Value);
            Date2 = app.Report_DatePicker2.Value + hours(app.Report_Spinner3.Value) + minutes(app.Report_Spinner4.Value);
            
            if Date1 > Date2
                error('Os valores inseridos para aplicação do filtro de TimeStamp devem ser ajustados!')
            end
            
            idx = find(strcmp(app.Report_Type.Items, app.Report_Type.Value), 1);
            
            reportInfo = struct('appVersion', app.General.Version,                            ...
                                'Issue',      app.Report_Issue.Value,                         ...
                                'General',    struct('Mode',       Mode,                      ...
                                                     'Version',    app.Report_Version.Value,  ...
                                                     'Resolution', app.General.Resolution,    ...
                                                     'RootFolder', app.RootFolder,            ...
                                                     'UserPath',   app.menu_userPath.Value),  ...
                                'Model',      struct('Name',       app.Report_Type.Value,     ...
                                                     'Type',       app.General.Models(idx,:), ...
                                                     'Template',  fileread(fullfile(app.RootFolder, 'DataBase', app.General.Models.Template{idx}))), ...
                                'TimeStamp',  [Date1, Date2],                                 ...
                                'Filename',   '');
            
        end
        
        
        function [ReportProject, tableStr] = ReportGenerator_Aux2(app, ProjectRows)

            % Variável que possibilitará o preenchimento dos campos
            % estruturados na inspeção (no Fiscaliza).
            ReportProject = struct('Issue',           app.Report_Issue.Value,       ...
                                   'Latitude',        [], 'Longitude',          [], ...
                                   'City',            [], 'Bands',              [], ...
                                   'F0',              [], 'F1',                 [], ...
                                   'emissionsValue1',  0, 'emissionsValue2',     0, 'emissionsValue3',     0, ...
                                   'Services',        [], 'tableJournal',       []);

            % Preenchimento dos dados...            
            idx = find(strcmp(app.General.Models.Name, app.Report_Type.Value), 1);
            ReportProject.Services = app.General.Models.RelatedServices{idx};
            
            % Definir FreqStart, FreqStop, Latitude, Longitude e City que
            % irão compor inspeção no Fiscaliza.
            ReportProject.Bands = {};
            for ii = 1:numel(ProjectRows)
                if ismember(app.specData(ii).MetaData.DataType, app.specDataTypes)
                    ReportProject.Bands(end+1) = {sprintf('%.3f - %.3f MHz', app.specData(ii).MetaData.FreqStart ./ 1e+6, app.specData(ii).MetaData.FreqStop ./ 1e+6)};
                    
                    if isempty(ReportProject.Latitude)
                        ReportProject.Latitude  = app.specData(ProjectRows(ii)).gps.Latitude;
                        ReportProject.Longitude = app.specData(ProjectRows(ii)).gps.Longitude;
                        ReportProject.City      = {app.specData(ProjectRows(ii)).gps.Location};
                        
                        ReportProject.F0 = app.specData(ProjectRows(ii)).MetaData.FreqStart;
                        ReportProject.F1 = app.specData(ProjectRows(ii)).MetaData.FreqStop;
                    
                    else
                        if ~ismember(app.specData(ProjectRows(ii)).gps.Location, ReportProject.City)
                            ReportProject.City{end+1,1} = app.specData(ProjectRows(ii)).gps.Location;
                        end                        
                        
                        ReportProject.F0 = min(ReportProject.F0, app.specData(ProjectRows(ii)).MetaData.FreqStart);
                        ReportProject.F1 = max(ReportProject.F1, app.specData(ProjectRows(ii)).MetaData.FreqStop);
                    end
                end
            end
            
            % Juntar numa mesma variável a informação gerada pelo algoritmo
            % embarcado no appAnálise (app.peaksTable) com a informação
            % gerada pelo fiscal (app.exceptionList).
            infoTable = table('Size', [0, 22],                                                                                                                 ...
                              'VariableTypes', {'cell',   'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16',  ...
                                                'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16', 'uint16'}, ...
                              'VariableNames', {'Banda', 'N1_Licenciada', 'N1_NaoLicenciada', 'N1_NaoLicenciavel',                                             ...
                                                         'N2_Fundamental', 'N2_Harmonico', 'N2_Produto', 'N2_Espuria', 'N2_NaoIdentificada', 'N2_NaoManifestada', 'N2_Pendente', ...
                                                         'N3_Licenciada', 'N3_NaoLicenciada', 'N3_NaoLicenciavel',                                                               ...
                                                         'N4_Baixo', 'N4_Medio', 'N4_Alto',                                                                                      ...
                                                         'N5_Radcom', 'N5_ClasseC', 'N5_ClasseB', 'N5_ClasseA', 'N5_ClasseE'});
            countTable = app.peaksTable;

            if ~isempty(app.peaksTable)
                for ii = 1:height(app.exceptionList)
                    ind = intersect(find(strcmp(countTable.Tag,  app.exceptionList.Tag(ii))), ...
                                    find(countTable.Frequency == app.exceptionList.Frequency(ii)));
                    
                    for jj = 1:numel(ind)
                        countTable.regulatoryInfo{ind(jj)} = app.exceptionList.regulatoryInfo{ii};
                        countTable.Type{ind(jj)}           = app.exceptionList.Type{ii};
                        countTable.Description{ind(jj)}    = app.exceptionList.Description{ii};
                        countTable.Compliance{ind(jj)}     = app.exceptionList.Compliance{ii};
                        countTable.RiskLevel{ind(jj)}      = app.exceptionList.RiskLevel{ii};
                    end
                end
                countTable.Tag = extractAfter(countTable.Tag, ': ');
                
                % Journal table 1:
                Bands = unique(countTable.Tag, 'stable');
                for ii = 1:numel(Bands)
                    N1_Licenciada      = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.regulatoryInfo, 'Licenciada'))));
                    N1_NaoLicenciada   = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.regulatoryInfo, 'Não licenciada'))));
                    N1_NaoLicenciavel  = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.regulatoryInfo, 'Não passível de licenciamento'))));

                    N2_Fundamental     = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.Type, 'Fundamental'))));
                    N2_Harmonico       = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.Type, 'Harmônico de fundamental'))));
                    N2_Produto         = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.Type, 'Produto de intermodulação'))));
                    N2_Espuria         = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.Type, 'Espúrio'))));
                    N2_NaoIdentificada = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.Type, 'Não identificado'))));
                    N2_NaoManifestada  = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.Type, 'Não se manifestou'))));
                    N2_Pendente        = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.Type, 'Pendente de identificação'))));

                    N3_Licenciada      = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), ...
                                               intersect(find(strcmp(countTable.regulatoryInfo, 'Licenciada')), find(strcmp(countTable.Compliance, 'Sim')))));
                    N3_NaoLicenciada   = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), ...
                                               intersect(find(strcmp(countTable.regulatoryInfo, 'Não licenciada')), find(strcmp(countTable.Compliance, 'Sim')))));
                    N3_NaoLicenciavel  = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), ...
                                               intersect(find(strcmp(countTable.regulatoryInfo, 'Não passível de licenciamento')), find(strcmp(countTable.Compliance, 'Sim')))));
                    
                    N4_Baixo           = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.RiskLevel, 'Baixo'))));
                    N4_Medio           = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.RiskLevel, 'Médio'))));
                    N4_Alto            = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(strcmp(countTable.RiskLevel, 'Alto'))));

                    N5_Radcom          = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(cellfun(@(x) ~isempty(x), regexp(countTable.Description, '\[SRD\] [RADCOM231]{3,6}', 'match')))));
                    N5_ClasseC         = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(cellfun(@(x) ~isempty(x), regexp(countTable.Description, '\[MOS\] [FMTV]{2}-C[1-7], C', 'match')))));
                    N5_ClasseB         = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(cellfun(@(x) ~isempty(x), regexp(countTable.Description, '\[MOS\] [FMTV]{2}-C[1-7], B', 'match')))));
                    N5_ClasseA         = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(cellfun(@(x) ~isempty(x), regexp(countTable.Description, '\[MOS\] [FMTV]{2}-C[1-7], A', 'match')))));
                    N5_ClasseE         = numel(intersect(find(strcmp(countTable.Tag, Bands{ii})), find(cellfun(@(x) ~isempty(x), regexp(countTable.Description, '\[MOS\] [FMTV]{2}-C[1-7], E', 'match')))));
                    
                    infoTable(ii,:) = {Bands{ii}, N1_Licenciada, N1_NaoLicenciada, N1_NaoLicenciavel,                                                       ...
                                                  N2_Fundamental, N2_Harmonico, N2_Produto, N2_Espuria, N2_NaoIdentificada, N2_NaoManifestada, N2_Pendente, ...
                                                  N3_Licenciada, N3_NaoLicenciada, N3_NaoLicenciavel,                                                       ...
                                                  N4_Baixo, N4_Medio, N4_Alto,                                                                              ...
                                                  N5_Radcom, N5_ClasseC, N5_ClasseB, N5_ClasseA, N5_ClasseE};
                end

                ReportProject.emissionsValue1 = sum(infoTable{:,2:4}, 'all');                               % Qtd. emissões
                ReportProject.emissionsValue2 = sum(infoTable{:,2});                                        % Qtd. emissões licenciadas
                ReportProject.emissionsValue3 = sum(infoTable{:,5:8}, 'all');                               % Qtd. emissões identificadas
            end
            ReportProject.tableJournal = infoTable;

            % Arquivo JSON
            tableStr = ReportGenerator_Aux3(app, ProjectRows, countTable);

        end
        
        
        function tableStr = ReportGenerator_Aux3(app, ProjectRows, countTable)
            
            % Tabelas que irão compor o JSON que será carregado como anexo
            % à inspeção (no Fiscaliza).
            TaskTable   = table('Size', [0, 12],                                                                                                                 ...
                                'VariableTypes', {'uint16', 'cell', 'single', 'single', 'double', 'double', 'cell', 'cell', 'uint32', 'double', 'cell', 'cell'}, ...
                                'VariableNames', {'PK1', 'Node', 'Latitude', 'Longitude', 'FreqStart', 'FreqStop', 'BeginTime', 'EndTime', 'Samples', 'timeOCC', 'Description', 'RelatedFiles'});

            MethodTable = table('Size', [0, 2], 'VariableTypes', {'uint16', 'cell'}, 'VariableNames', {'PK2', 'Method'});
            
            PeakTable   = countTable(:,[5:19 21]);
            
            PeakTable.Frequency = round(PeakTable.Frequency, 3);
            PeakTable.BW        = round(1000 * PeakTable.BW, 3);
                        
            Method = unique(countTable.Method);
            MethodTable.PK2(1:numel(Method)) = 1:numel(Method)';
            MethodTable.Method(1:numel(Method)) = Method;


            jj = 0;
            for ii = ProjectRows
                if ismember(app.specData(ii).MetaData.DataType, app.specDataTypes)
                    jj = jj+1;

                    occInfo = jsondecode(app.specData(ii).reportOCC.Parameters);
                    
                    TaskTable(end+1,:) = {jj,                                  ...
                                          app.specData(ii).Node,               ...
                                          app.specData(ii).gps.Latitude,       ...
                                          app.specData(ii).gps.Longitude,      ...
                                          app.specData(ii).MetaData.FreqStart ./ 1e+6, ...
                                          app.specData(ii).MetaData.FreqStop  ./ 1e+6, ...
                                          datestr(app.specData(ii).Data{1}(1),   'dd/mm/yyyy HH:MM:SS'), ...
                                          datestr(app.specData(ii).Data{1}(end), 'dd/mm/yyyy HH:MM:SS'), ...
                                          app.specData(ii).Samples,             ...
                                          occInfo.IntegrationTime,              ...
                                          app.specData(ii).Description,         ...
                                          strjoin(app.specData(ii).RelatedFiles, ', ')};
                    
                    Tag = sprintf('%s\nThreadID %d: %.3f - %.3f MHz', app.specData(ii).Node,     ...
                                                                      app.specData(ii).ThreadID, ...
                                                                      app.specData(ii).MetaData.FreqStart ./ 1e+6, ...
                                                                      app.specData(ii).MetaData.FreqStop  ./ 1e+6);
                    
                    idx1 = find(strcmp(app.peaksTable.Tag, Tag));
                    if ~isempty(idx1)
                        PeakTable.FK1(idx1) = uint16(jj);
                    end
                end
            end

            for ii = 1:numel(Method)
                idx2 = find(strcmp(app.peaksTable.Method, Method(ii)));
                if ~isempty(idx2)
                    PeakTable.FK2(idx2) = uint16(ii);
                end
            end
            PeakTable = movevars(PeakTable, {'FK1', 'FK2'}, 'Before', 1);

            tableStr  = jsonencode(struct('ReferenceData1', TaskTable, 'ReferenceData2', MethodTable, 'MeasurementData', PeakTable), 'PrettyPrint', true);
            
        end
        
        
        function EditInfo_StartupLayout(app, Flag)
            
            app.Info_RefreshGPS.Enable = 0;
            app.Info_Button.Enable     = 0;
            
            switch Flag
                case 'on'
                    selectedNodes = app.mainInfo_Tree.SelectedNodes;
                    
% % % % % % % % % % % Painel: "Informações passíveis de edição relacionada ao fluxo selecionado"
                    % GPS
                    gpsTable = table('Size', [0, 6],                                                                   ...
                                     'VariableTypes', {'double', 'cell', 'double', 'cell',     'cell',      'double'}, ...
                                     'VariableNames', {'ID',     'File', 'Status', 'Latitude', 'Longitude', 'Count'});                    
                    
                    warning('off')
                    for ii = 1:numel(app.specData(selectedNodes.NodeData).RelatedFiles)
                        gpsTable(ii,:) = {ii,                                                                             ...
                                          app.specData(selectedNodes.NodeData).RelatedFiles{ii},                          ...
                                          app.specData(selectedNodes.NodeData).RelatedGPS{ii}.Status,                     ...
                                          sprintf('%.6f', app.specData(selectedNodes.NodeData).RelatedGPS{ii}.Latitude),  ...
                                          sprintf('%.6f', app.specData(selectedNodes.NodeData).RelatedGPS{ii}.Longitude), ...
                                          app.specData(selectedNodes.NodeData).RelatedGPS{ii}.Count};
                    end
                    warning('on')
                    app.Info_Table.Data = gpsTable;
                    
                    indValid = find(gpsTable.Status ~= 0);
                    if ~isempty(indValid)
                        Latitude  = sum(gpsTable.Count(indValid) .* cellfun(@(x) str2double(x), gpsTable.Latitude(indValid)))  ./ sum(gpsTable.Count(indValid));
                        Longitude = sum(gpsTable.Count(indValid) .* cellfun(@(x) str2double(x), gpsTable.Longitude(indValid))) ./ sum(gpsTable.Count(indValid));
                        
                        app.Info_MeanValues.Text = sprintf('Latitude: %.6f\nLongitude:%.6f', Latitude, Longitude);
                        
                        if app.specData(selectedNodes.NodeData).gps.Status == -1
                            app.Info_RefreshGPS.Enable = 1;
                        end
                    else
                        app.Info_MeanValues.Text = 'Local da monitoração desconhecido';
                    end
                    
                    set(app.Info_Latitude,  'Enable', 1, 'Value', app.specData(selectedNodes.NodeData).gps.Latitude)
                    set(app.Info_Longitude, 'Enable', 1, 'Value', app.specData(selectedNodes.NodeData).gps.Longitude)
                    set(app.Info_City,      'Enable', 1, 'Value', app.specData(selectedNodes.NodeData).gps.Location)
                    set(app.Info_CheckBox,  'Enable', 1)
                        
                    if app.specData(selectedNodes.NodeData).gps.Status <= 0
                        set(app.Info_Latitude,  'Enable', 1)
                        set(app.Info_Longitude, 'Enable', 1)
                        set(app.Info_City,      'Enable', 1)
                        set(app.Info_CheckBox,  'Enable', 1)
                    end
                    
                    
                    % Unidade de medida
                    app.AntenaDropDown.Value = '';
                    if ismember(app.specData(selectedNodes.NodeData).MetaData.metaString(1), {'dBm', 'dBμV'}) && ...
                       ismember(app.specData(selectedNodes.NodeData).MetaData.DataType, app.specDataTypes)
                        app.Info_Grid3.RowHeight(9:11) = {33,15,22};
                    else
                        app.Info_Grid3.RowHeight(9:11) = {0,0,0};
                    end
                                        
                    
                    % Fluxo de ocupação
                    if isempty(app.specData(selectedNodes.NodeData).reportOCC.Related)
                        app.Info_Grid3.RowHeight(7:8) = {0,0};
                        
                    else
                        app.Info_Grid3.RowHeight(7:8) = {33,22};
                        
                        app.Info_OCC.Items = {'Ocupação a ser aferida'};
                        for ii = 1:length(app.specData(selectedNodes.NodeData).reportOCC.Related)
                            app.Info_OCC.Items{ii+1} = sprintf('Threshold: %d %s (ThreadID %d)', ...
                                                               app.specData(app.specData(selectedNodes.NodeData).reportOCC.Related(ii)).MetaData.Threshold, ...
                                                               app.specData(selectedNodes.NodeData).MetaData.metaString{1},                          ...
                                                               app.specData(app.specData(selectedNodes.NodeData).reportOCC.Related(ii)).ThreadID);
                        end
                        
                        if isempty(app.specData(selectedNodes.NodeData).reportOCC.Index)
                            app.Info_OCC.Value = app.Info_OCC.Items{1};
                        else
                            auxIndex = find(app.specData(selectedNodes.NodeData).reportOCC.Related == app.specData(selectedNodes.NodeData).reportOCC.Index, 1);
                            app.Info_OCC.Value = app.Info_OCC.Items{auxIndex+1};
                        end
                    end
                
                case 'off'
                    app.Info_Grid3.RowHeight(7:11) = {0, 0, 0, 0, 0};
                    
                    app.Info_Table.Data      = [];
                    app.Info_MeanValues.Text = '';
                    
                    set(app.Info_Latitude,  'Enable', 0, 'Value', -1)
                    set(app.Info_Longitude, 'Enable', 0, 'Value', -1)
                    set(app.Info_City,      'Enable', 0, 'Value', '')
                    set(app.Info_CheckBox,  'Enable', 0, 'Value', 0)
            end
            
        end
        
        
        function EditInfo_GPSNewValue(app)
            
            ind = app.mainInfo_Tree.SelectedNodes.NodeData;
            
            gpsTable = table('Size', [0, 4],                                                 ...
                             'VariableTypes', {'double', 'double',   'double',    'double'}, ...
                             'VariableNames', {'Status', 'Latitude', 'Longitude', 'Count'});  
            
            kk = 0;
            for jj = 1:numel(app.specData(ind).RelatedGPS)
                if app.specData(ind).RelatedGPS{jj}.Status ~= 0
                    kk = kk+1;
                    gpsTable(kk,:) = {app.specData(ind).RelatedGPS{jj}.Status,    ...
                                      app.specData(ind).RelatedGPS{jj}.Latitude,  ...
                                      app.specData(ind).RelatedGPS{jj}.Longitude, ...
                                      app.specData(ind).RelatedGPS{jj}.Count};
                end
            end

            if height(gpsTable)
                app.specData(ind).gps.Status    = min(gpsTable.Status(gpsTable.Status ~= 0));
                app.specData(ind).gps.Latitude  = sum(gpsTable.Count .* gpsTable.Latitude)  ./ sum(gpsTable.Count);
                app.specData(ind).gps.Longitude = sum(gpsTable.Count .* gpsTable.Longitude) ./ sum(gpsTable.Count);
                app.specData(ind).gps.Count     = mean(gpsTable.Count);
                app.specData(ind).gps.Location  = FindCity(app.specData(ind).gps);
            else
                app.specData(ind).gps = struct('Status', 0, 'Latitude', -1, 'Longitude', -1, 'Count', 0, 'Location', '');
            end
            
        end
        
        
        function EditInfo_InfoChangedLayout(app)
            
            NodeParent(1) = app.mainInfo_Tree.SelectedNodes;
            while ~isequal(app.mainInfo_Tree, NodeParent(end))
                ind1 = numel(NodeParent);
                
                NodeParent(ind1+1) = NodeParent(ind1).Parent;
            end
            
            NodeData1 = NodeParent(end-1).NodeData;          
            NodeData2 = app.mainInfo_Tree.SelectedNodes.NodeData;

            mainInfo_TreeBuilding(app)
            Layout_expandTree(app, 'mainInfo')

            ind1 = [];
            for ii = 1:numel(app.mainInfo_Tree.Children)
                if strcmp(app.mainInfo_Tree.Children(ii).NodeData, NodeData1)
                    ind1 = ii;
                    break
                end
            end
            ind2 = find([app.mainInfo_Tree.Children(ind1).Children.NodeData] == NodeData2, 1);
            
            app.mainInfo_Tree.SelectedNodes = app.mainInfo_Tree.Children(ind1).Children(ind2);
            expand(app.mainInfo_Tree.SelectedNodes)
            
            mainInfo_TreeSelectionChanged(app)
            
        end
        
        
        function Misc_userPath(app)
            
            if isfile(fullfile(app.RootFolder, 'DataBase', 'UserPath.cfg'))
                fileID   = fopen(fullfile(app.RootFolder, 'DataBase', 'UserPath.cfg'), 'rt');
                userPath = fread(fileID, [1, Inf], '*char');
                fclose(fileID);
                
                userPath_ind = find(strcmp(app.menu_userPath.Items, userPath), 1);
                if isempty(userPath_ind)
                    app.menu_userPath.Items(end+1) = {userPath};
                    app.menu_userPath.Value        = userPath;
                else
                    app.menu_userPath.Value        = userPath;                        
                end                    
            end
        
        end
        
        
        function Misc_InspectionType(app)
            
            app.General.Models    = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'DataBase', 'InspectionType.cfg'))));
            app.Report_Type.Items = app.General.Models.Name;
            
        end
                
        
        function filteredRows = Misc_FilteredRows(app)
            
            selectedNode = app.mainInfo_Tree.SelectedNodes;
            filteredRows = [];
            
            for ii = 1:numel(selectedNode)
                if isnumeric(selectedNode(ii).NodeData)
                    ind = selectedNode(ii).NodeData;
                else
                    [~, ind] = find(cellfun(@(x) strcmp(x, selectedNode(ii).NodeData), {app.specData.Node}));
                end
                filteredRows = [filteredRows, ind];
            end
            filteredRows = unique(filteredRows);
            
        end
        
        
        function Data = Misc_DateTimeFilter(app, filteredRows)
            
            auxDate1 = app.Save_DatePicker1.Value;
            auxDate1.Format = "dd/MM/yyyy HH:mm:ss";
            auxDate1 = auxDate1 + hours(app.Save_Spinner1.Value) + minutes(app.Save_Spinner2.Value);
            
            auxDate2 = app.Save_DatePicker2.Value;
            auxDate2.Format = "dd/MM/yyyy HH:mm:ss";
            auxDate2 = auxDate2 + hours(app.Save_Spinner3.Value) + minutes(app.Save_Spinner4.Value);
            
            if auxDate1 > auxDate2
                error('Os valores inseridos para aplicação do filtro de TimeStamp devem ser ajustados.')
            end
            
            Data = Misc_TimeStampFilter(app.specData(filteredRows), [auxDate1, auxDate2], 0);
            
        end


        function Misc_MergeType(app)
            
            app.General.Merge = jsondecode(fileread(fullfile(app.RootFolder, 'DataBase', 'MergeType.cfg')));
            if isempty(app.General.Merge.DistanceValue)
                app.General.Merge.DistanceValue = Inf;
            end                  
        
        end
        
        
        function auxWin_TreeEdit(app)
            
            Report_SaveWarn(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            
            app.MainApp.Visible = 0;
            
            Flag = 1;
            if isdeployed
                if isfile(fullfile(pwd, 'appAnalise.exe')) && isfile(fullfile(pwd, 'DataBase', 'AnatelDB.mat'))
                    app.RootFolder = pwd;
                    
                else
                    aserver = actxserver('WScript.Shell');                    
                    tmpRoot = '';
                    if isfile(fullfile(pwd, 'appAnalise.lnk'))
                        tmpRoot = aserver.CreateShortcut(fullfile(pwd, 'appAnalise.lnk'));
                        
                    elseif isfile(fullfile(getenv('USERPROFILE'), 'Desktop', 'appAnalise.lnk'))
                        tmpRoot = aserver.CreateShortcut(fullfile(getenv('USERPROFILE'), 'Desktop', 'appAnalise.lnk'));
                        
                    elseif isfile(fullfile('C:\Users\Public\Desktop', 'appAnalise.lnk'))
                        tmpRoot = aserver.CreateShortcut(fullfile('C:\Users\Public\Desktop', 'appAnalise.lnk'));
                        
                    elseif isfile(fullfile('C:\ProgramData\Microsoft\Windows\Start Menu\Programs', 'appAnalise.lnk'))
                        tmpRoot = aserver.CreateShortcut(fullfile('C:\ProgramData\Microsoft\Windows\Start Menu\Programs', 'appAnalise.lnk'));
                        
                    else
                        lnkFiles = [dir(fullfile(pwd, '*.lnk'));                              ...
                                    dir(fullfile(getenv('USERPROFILE'), 'Desktop', '*.lnk')); ...
                                    dir('C:\Users\Public\Desktop\*.lnk');                     ...
                                    dir('C:\ProgramData\Microsoft\Windows\Start Menu\Programs\*.lnk')];
                        
                        for ii = 1:numel(lnkFiles)
                            tmpRoot = aserver.CreateShortcut(fullfile(lnkFiles(ii).folder, lnkFiles(ii).name));
                            if contains(tmpRoot.TargetPath, 'appAnalise.exe')
                                break
                            end
                        end
                    end

                    if ~isempty(tmpRoot)
                        tmpRoot = fileparts(tmpRoot.TargetPath);
                        cd(tmpRoot)
                        
                        app.RootFolder = pwd;
                    else
                        Flag = 0;
                        
                        msg = ['O appAnálise não identificou o seu local de instalação (PATH). '                   ...
                               'Independente disso, ele pode ser iniciado clicando diretamente no seu executável ' ...
                               '(e não em algum atalho). Favor reportar o ocorrido a Eric (GR08).'];
                        uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'error', 'CloseFcn', @(~,~)startupError(app));
                    end
                end
                app.menu_userPath.Items = {fullfile(getenv('USERPROFILE'), 'Downloads')};
                
            else
                prjPath = matlab.project.rootProject;
                appPath = fullfile(char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder), 'appAnalise', 'appAnalise');
                
                if ~isempty(prjPath) & strcmp(prjPath.Name, 'appAnalise')
                    app.RootFolder          = char(prjPath.RootFolder);
                    app.menu_userPath.Items = {fullfile(app.RootFolder, 'Temp')};
                    
                elseif isfolder(appPath)
                    app.RootFolder          = appPath;
                    app.menu_userPath.Items = {fullfile(getenv('USERPROFILE'), 'Downloads')};
                    
                else
                    Flag = 0;
                    
                    msg = 'O appAnálise somente poderá ser executado se o projeto "appAnalise.prj" estiver aberto ou instalado no Matlab.';
                    uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'error', 'CloseFcn', @(~,~)startupError(app));
                end
            end
            
            if Flag
                % Temp folder creation...
                if ~isfolder(fullfile(app.RootFolder, 'Temp'))
                    mkdir(fullfile(app.RootFolder, 'Temp'))
                end

                % appAnalise, Fiscaliza and AnatelDB versions.
                [app.General.Version, msgConsole] = Misc_appVersions(app.RootFolder);

                % Console message...
                if isdeployed
                    fprintf('\n%s\n', msgConsole);
                end

                % UserPath, InspectionType & FindPeaksDefaultValues
                Misc_userPath(app)
                Misc_MergeType(app)
                Misc_InspectionType(app)
                Misc_FindPeaksDefaultValues(app.RootFolder)
                
                % App position & figure/axes creation
                ToolBar_Button1Pushed(app)
                Misc_axesCreation(app)
                drawnow nocallbacks

                s = uistyle('HorizontalAlignment', 'left');
                addStyle(app.Info_Table, s, 'column', 1:6);
                
                app.Playback_Grid1.RowHeight{5} = 0;

                app.MainApp.Visible = 1;
            end
            
        end

        % Close request function: MainApp
        function closeRequest(app, event)

            delete(fullfile(app.RootFolder, 'Temp', '~Report*.html'))
            delete(fullfile(app.RootFolder, 'Temp', '~Image*.png'))

            delete(fullfile(app.menu_userPath.Value, '~Report*.html'))
            delete(fullfile(app.menu_userPath.Value, '~Image*.png'))
            
            delete(app.auxWin1)
            delete(app.auxWin2)
            delete(app.auxWin3)
            delete(app.auxWin4)
            delete(app.auxWin5)
            delete(app.auxWin6)
            delete(app.auxWin7)
            
            delete(app.fig)
            drawnow nocallbacks

            delete(app)
            
        end

        % Image clicked function: menu_searchPath
        function General_searchPathImageClicked(app, event)
            
            newUserPath = uigetdir(app.menu_userPath.Value, 'Escolha a pasta de trabalho');
            figure(app.MainApp)
            
            if newUserPath
                ind = find(strcmp(app.menu_userPath.Items, newUserPath), 1);
                
                if isempty(ind)
                    app.menu_userPath.Items(end+1) = {newUserPath};
                    app.menu_userPath.Value        = newUserPath;
                    
                    menu_userPathValueChanged(app)
                else
                    app.menu_userPath.Value        = app.menu_userPath.Items(ind);
                end
            end
            
        end

        % Value changed function: Button_Home
        function mainMenu_Home(app, event)
            
            if app.Button_Home.Value
                set(findall(groot, 'Parent', app.menu_Grid1, 'Type', 'uistatebutton', '-not', 'Tag', 'HOME'), 'Value', 0)
            else
                app.Button_Home.Value     = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_File
        function mainMenu_File(app, event)

            if app.Button_File.Value
                set(findall(groot, 'Parent', app.menu_Grid1, 'Type', 'uistatebutton', '-not', 'Tag', 'FILE'), 'Value', 0)
            else
                app.Button_File.Value     = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_Playback
        function mainMenu_Playback(app, event)
            
            if app.Button_Playback.Value
                set(findall(groot, 'Parent', app.menu_Grid1, 'Type', 'uistatebutton', '-not', 'Tag', 'PLAYBACK'), 'Value', 0)
            else
                app.Button_Playback.Value = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_Report
        function mainMenu_Report(app, event)
            
            if app.Button_Report.Value
                set(findall(groot, 'Parent', app.menu_Grid1, 'Type', 'uistatebutton', '-not', 'Tag', 'REPORT'), 'Value', 0)
            else
                app.Button_Report.Value   = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_EditInfo
        function mainMenu_EditInfo(app, event)
            
            if app.Button_EditInfo.Value
                set(findall(groot, 'Parent', app.menu_Grid1, 'Type', 'uistatebutton', '-not', 'Tag', 'MISC'), 'Value', 0)
            else
                app.Button_EditInfo.Value = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_SaveFile
        function mainMenu_SaveFile(app, event)
            
            if app.Button_SaveFile.Value
                set(findall(groot, 'Parent', app.menu_Grid1, 'Type', 'uistatebutton', '-not', 'Tag', 'EXPORT'), 'Value', 0)
            else
                app.Button_SaveFile.Value = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Button pushed function: ToolBar_Button1
        function ToolBar_Button1Pushed(app, event)
            
            [mainMonitor, x0] = Misc_appPosition(app);
            
            if ~isempty(app.fig)
                [x1, y1] = Misc_figPosition(app, mainMonitor, x0);
            
                app.fig.Position = [x1, y1, 640, 480];
            end
            
        end

        % Button pushed function: ToolBar_Button2
        function ToolBar_Button2Pushed(app, event)
            
            if isempty(app.auxWin5)
                d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Em andamento...');
                
                app.auxWin5 = winSettings(app);
                drawnow nocallbacks

                delete(d)
            else
                figure(app.auxWin5.UIFigure)
            end
            
        end

        % Button pushed function: ToolBar_Button3
        function ToolBar_Button3Pushed(app, event)

            msg = ['Aplicação de pós-processamento de dados gerados em monitorações desenvolvida no âmbito dos '    ...
                   'projetos 3A e RFlook - Processos nºs 53554.000939/2020-26 e 53500.063493/2020-76 - e evoluída ' ...
                   'no âmbito do Sentinela - Processo nº 53500.004826/2022-97'];
            
            uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'info');

        end

        % Button pushed function: File_Button1
        function File_Button1Pushed(app, event)

            [Files, Path, FileType] = uigetfile({'*.bin',    'appColeta, Logger (*.bin)';      ...
                                                 '*.sm1809', 'SM1809 (*.sm1809)';              ...
                                                 '*.csv',    'Argus (*.csv)';                  ...
                                                 '*.mat',    'appColeta, appAnálise (*.mat)'}, ...
                                                 'Selecione um ou mais arquivos', 'MultiSelect', 'on');
            figure(app.MainApp)
            
            if ~FileType
                return
            end
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            if ~iscell(Files)
                Files = {Files};
            end
            
            RepeteadFiles = {};
            for ii = 1:numel(Files)
                if length(Files{ii}) <= 50; d.Message = sprintf('Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d', Files{ii}, ii, numel(Files));
                else;                       d.Message = sprintf('Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d', [Files{ii}(1:48) '...'], ii, numel(Files));
                end
                
                RelatedFiles = {};
                for jj = 1:numel(app.metaData)
                    RelatedFiles = [RelatedFiles, unique([app.metaData(jj).Data.RelatedFiles])];
                end
                   
                switch FileType
                    case {1, 2, 3}
                        if ~any(contains(RelatedFiles, Files(ii)))
                            ind = numel(app.metaData)+1;
                            
                            app.metaData(ind).File = fullfile(Path, Files{ii});
                            app.metaData(ind).Type = {'Spectral data'};
                        else
                            RepeteadFiles(end+1) = Files(ii);
                            continue
                        end
                        
                    case 4
                        lastwarn('')
                        load(fullfile(Path, Files{ii}), '-mat', 'prj_Type', 'prj_RelatedFiles')
                        [~, warnID] = lastwarn;
                        
                        % Validações...
                        if strcmp(warnID, 'MATLAB:load:variableNotFound')
                            msg = sprintf('O arquivo indicado a seguir não possui a variável "prj_Type", não sendo, portanto, um arquivo gerado pelo appAnálise ou appColeta.\n• %s', Files{ii});
                            uialert(app.MainApp, msg, 'appAnálise', 'Icon', '');
                            
                            continue
                            
                        elseif ~isempty(strfind([app.metaData.File], Files{ii}))
                            msg = sprintf('O arquivo indicado a seguir já tinha sido lido.\n• %s', Files{ii});
                            uialert(app.MainApp, msg, 'appAnálise', 'Icon', '');
                            
                            continue
                            
                        elseif any(contains(RelatedFiles, prj_RelatedFiles))
                            msg = sprintf(['O arquivo indicado a seguir não será lido por já ter sido lido ao menos um arquivo relacionado ao ' ...
                                           'projeto appAnálise.\n• %s\n\nArquivo(s) relacionado(s) ao projeto appAnálise já lido(s):\n%s'],   ...
                                           Files{ii}, strjoin(cellfun(@(x) sprintf('• %s', x), RelatedFiles(contains(RelatedFiles, prj_RelatedFiles)), 'UniformOutput', false), '\n'));
                            uialert(app.MainApp, msg, 'appAnálise', 'Icon', '');
                            
                            continue

                        elseif ~isempty(app.metaData) && strcmp(prj_Type{1}, 'Project data') && ismember({'Project data'}, [app.metaData.Type])
                            msg = sprintf('O arquivo indicado a seguir não será lido porque já foram lidos os metadados de outro projeto appAnálise.\n• %s', Files{ii});
                            uialert(app.MainApp, msg, 'appAnálise', 'Icon', '');
                            
                            continue
                            
                        else
                            ind = numel(app.metaData)+1;
                            
                            app.metaData(ind).File = fullfile(Path, Files{ii});
                            app.metaData(ind).Type = prj_Type;
                        end
                end
                
                try
                    File_GeneralReader(app, ind)

                catch ME
                    report = getReport(ME);
                    uialert(app.MainApp, report, 'appAnálise', 'Interpreter', 'html')
                    
                    if contains(ME.message, 'O arquivo indicado a seguir')
                        if ind == 1; app.metaData = struct('File', {}, 'Type', {}, 'RelatedFiles', {}, 'Data', {}, 'Samples', {}, 'Memory', {});
                        else;        app.metaData(ind) = [];
                        end
                        
                        ErrorFlag = 0;
                    else
                        app.metaData = struct('File', {}, 'Type', {}, 'RelatedFiles', {}, 'Data', {}, 'Samples', {}, 'Memory', {});
                        app.Samples  = [];
                        
                        ErrorFlag = 1;
                    end
                    fclose all;

                    if ErrorFlag
                        break
                    end
                end
            end
            
            app.File_Memory.Value = sum([app.metaData.Memory]);
            
            if ~isempty(RepeteadFiles)
                msg = 'Os metadados do(s) arquivo(s) indicado(s) a seguir já tinham sido lidos.';
                
                for jj = 1:numel(RepeteadFiles)
                    msg = sprintf('%s\n• %s', msg, RepeteadFiles{jj});
                end
                uialert(app.MainApp, msg, 'appAnálise', 'Icon', '');
            end

            File_Tree1_Building(app)
            app.mainInfo_Warning.Visible = 1;

        end

        % Button pushed function: File_Button2
        function File_Button2Pushed(app, event)
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            if ~isempty(app.Report_Tree.Children)
                delete(app.Report_Tree.Children)
            end
            
            app.occFlag                = 0;
            app.traceInfo.SelectedNode = 0;
            app.timeIndex              = 1;
            app.peaksTable(:,:)        = [];
            app.fig.Visible            = 0;
            
            try
                File_specDataReader(app, d)
            catch ME
                report = getReport(ME);
                uialert(app.MainApp, report, 'appAnálise', 'Interpreter','html');

                File_specDataReader_Error(app)
                return
            end
            
            mainInfo_TreeBuilding(app)

            set(app.menu_Grid1.Children, 'Enable', 1)
            app.Button_Playback.Value = 1;
            mainMenu_Playback(app)
            
            if ~isempty(app.Playback_peakTree.Children)
                delete(app.Playback_peakTree.Children)
            end

            if ~isempty(app.Report_Tree.Children)
                delete(app.Report_Tree.Children)
            end
            
            app.mainInfo_Warning.Visible = 0;
            
        end

        % Selection changed function: File_Tree1
        function File_Tree1SelectionChanged(app, event)
            
            if numel(app.File_Tree1.SelectedNodes) == 1
                File_Tree1SelectionChanged_Aux(app, 'On')
                
                ind1 = app.File_Tree1.SelectedNodes.NodeData;
                
                app.File_FileType.Value  = app.metaData(ind1).Type;
                app.File_FilesList.Value = strjoin(unique([app.metaData(ind1).Data.RelatedFiles]), '\n');
                
                File_Tree2_Building(app)
            
            else
                File_Tree1SelectionChanged_Aux(app, 'Off')
            end            
            
        end

        % Selection changed function: File_Tree2
        function File_Tree2SelectionChanged(app, event)
            
            if numel(app.File_Tree2.SelectedNodes) == 1 && isnumeric(app.File_Tree2.SelectedNodes.NodeData)
                File_Tree2SelectionChanged_Aux(app, 'On')
                
                ind1 = app.File_Tree1.SelectedNodes.NodeData;
                ind2 = app.File_Tree2.SelectedNodes.NodeData;
                
                app.File_DataPoints.Value = app.metaData(ind1).Data(ind2).MetaData.DataPoints;
                
                if isempty(app.metaData(ind1).Data(ind2).MetaData.metaString{4})
                    app.File_Operation.Value = app.metaData(ind1).Data(ind2).MetaData.metaString{3};
                else
                    app.File_Operation.Value = sprintf('%s (Modo), %s (Detector)',                           ...
                                                       app.metaData(ind1).Data(ind2).MetaData.metaString{3}, ...
                                                       app.metaData(ind1).Data(ind2).MetaData.metaString{4});
                end
                
                if ~isempty(app.metaData(ind1).Data(ind2).MetaData.Resolution)
                    set(app.File_Resolution, 'Enable', 1, 'Value', app.metaData(ind1).Data(ind2).MetaData.Resolution ./ 1e+3, 'ValueDisplayFormat', '%.3f')
                else
                    set(app.File_Resolution, 'Enable', 0, 'Value', -1, 'ValueDisplayFormat', '%d')
                end
                
                app.File_LevelUnit.Value       = app.metaData(ind1).Data(ind2).MetaData.metaString{1};
                app.File_Description.Value     = app.metaData(ind1).Data(ind2).Description;
                
                if ~isempty(app.metaData(ind1).Data(ind2).Samples)
                    app.File_Samples.Value         = app.metaData(ind1).Data(ind2).Samples;
                    app.File_EstimatedMemory.Value = 4 .* app.metaData(ind1).Data(ind2).Samples .* app.metaData(ind1).Data(ind2).MetaData.DataPoints .* 1e-6; % float32 (4 bytes)
                else
                    app.File_Samples.Value         = 1;
                    app.File_EstimatedMemory.Value = 4 .* app.metaData(ind1).Data(ind2).MetaData.DataPoints .* 1e-6; % float32 (4 bytes)
                end
                
                if ~isempty(app.metaData(ind1).Data(ind2).gps.Location)
                    set(app.File_City, 'Enable', 1, 'Value', app.metaData(ind1).Data(ind2).gps.Location)
                else
                    set(app.File_City, 'Enable', 0, 'Value', '(desconhecido)')
                end
                
                if ~isempty(app.metaData(ind1).Data(ind2).ObservationTime)
                    set(app.File_ObservationTime, 'Enable', 1, 'Value', app.metaData(ind1).Data(ind2).ObservationTime)
                else
                    set(app.File_ObservationTime, 'Enable', 0, 'Value', '(pendente de implementação)')
                end
                
                app.File_Info.Text = '';
                if app.File_Samples.Value == 65536
                    app.File_Info.Text = 'Atingido o limite do número de traços por fluxo de dados (65536).';
                end
                
            else
                File_Tree2SelectionChanged_Aux(app, 'Off')
            end
            
        end

        % Menu selected function: File_ContextMenu1
        function File_Tree1Delete(app, event)
            
            ind = [];
            for ii = 1:numel(app.File_Tree1.SelectedNodes)
                ind = [ind, app.File_Tree1.SelectedNodes(ii).NodeData];
            end
            
            if numel(ind) < numel(app.metaData)
                app.metaData(ind)     = [];
                app.File_Memory.Value = sum([app.metaData.Memory]);
            else
                app.metaData          = struct('File', {}, 'Type', {}, 'RelatedFiles', {}, 'Data', {}, 'Samples', {}, 'Memory', {});
                app.File_Memory.Value = 0;
            end
            
            File_Tree1_Building(app)
            app.mainInfo_Warning.Visible = 1;
                        
        end

        % Menu selected function: File_ContextMenu2
        function File_Tree2Delete(app, event)
            
            ind1 = app.File_Tree1.SelectedNodes.NodeData;
            
            ind2 = [];
            for ii = 1:numel(app.File_Tree2.SelectedNodes)
                if ~isnumeric(app.File_Tree2.SelectedNodes(ii).NodeData)
                    msg = 'Somente poderão ser excluídos os fluxos de dados individualmente, devendo restar ao menos um fluxo de dados.';
                    uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning')
                    
                    return
                end
                
                ind2 = [ind2, app.File_Tree2.SelectedNodes(ii).NodeData];
            end
            
            if numel(app.metaData(ind1).Data) > numel(ind2)
                app.metaData(ind1).Data(ind2)    = [];
                app.metaData(ind1).Samples(ind2) = [];
                
                
            else
                msg = 'O arquivo selecionado deve possuir ao menos um fluxo de dados.';
                uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning')
            end
            
            app.metaData(ind1).Memory = File_GeneralReader_EstimatedMemory(app, ind1);
            app.File_Memory.Value     = sum([app.metaData.Memory]);
            
            File_Tree2_Building(app)
            app.mainInfo_Warning.Visible = 1;
            
        end

        % Selection changed function: mainInfo_Tree
        function mainInfo_TreeSelectionChanged(app, event)
            
            selectedNodes = app.mainInfo_Tree.SelectedNodes;
            
            % Especificidades de cada funcionalidade.            
            if app.Button_Report.Value 
                app.Report_Tree.SelectedNodes  = [];
                app.Report_addButton.Enable    = 1;

                Layout_Report(app)
                
            elseif app.Button_Playback.Value
                InitialSelectedNode = app.traceInfo.SelectedNode;
                Layout_Playback(app)
                
                figure(app.fig)
                
                if app.plotFlag
                    if isnumeric(selectedNodes.NodeData) && (selectedNodes.NodeData ~= InitialSelectedNode)
                        app.timeIndex             = 1;
                        app.Playback_Slider.Value = 0;
                        
                        if app.specData(selectedNodes.NodeData).Samples > 1
                            app.axesFlag = 1;
                        else
                            Plot_Layout1(app)
                            Plot_Layout2(app)
                            Plot_StartUp1(app)
                            Plot_StartUp2(app)
                            Plot_StartUp3(app)
                        end
                    end
                else
                    if isnumeric(selectedNodes.NodeData)
                        app.Playback_addMarker.Enable = 1;
                        app.Playback_Slider.Enable    = 1;
                        app.Button_Mask.Enable        = 1;
                        app.Playback_Button4.Enable   = 1;
                        app.Playback_Button5.Enable   = 1;
                        app.Button_DriveTest.Enable   = 1;
                        
                       if selectedNodes.NodeData ~= InitialSelectedNode
                            Plot_Layout1(app)
                            Plot_Layout2(app)
                            Plot_StartUp1(app)
                            Plot_StartUp2(app)
                            Plot_StartUp3(app)
                       end
                    end
                end
                
            elseif app.Button_EditInfo.Value
                if isnumeric(app.mainInfo_Tree.SelectedNodes.NodeData)
                    EditInfo_StartupLayout(app, 'on')
                else
                    app.mainInfo_Tree.SelectedNodes  = [];
                    EditInfo_StartupLayout(app, 'off')
                end
                
            elseif app.Button_SaveFile.Value
                if ~isempty(app.mainInfo_Tree.SelectedNodes)
                    app.Save_Button.Enable = 1;
                else
                    app.Save_Button.Enable = 0;
                end
                
                Save_ObservationTimeValueChanged(app)
            end
            

            % Caixa de texto com metadados.
            if numel(selectedNodes) == 1 && isnumeric(selectedNodes.NodeData)
                app.mainInfo_Text.Enable = 1;
                
                string1 = {sprintf('Script: "%s"\nDescrição: "%s"',                     ...
                           app.specData(selectedNodes.NodeData).TaskName,               ...
                           app.specData(selectedNodes.NodeData).Description); '';       ...
                           sprintf('Pontos por traço: %d\nUnidade: %s\nOperação: %s',   ...
                           app.specData(selectedNodes.NodeData).MetaData.DataPoints,    ...
                           app.specData(selectedNodes.NodeData).MetaData.metaString{1}, ...
                           app.specData(selectedNodes.NodeData).MetaData.metaString{3})};
                
                if ~isempty(app.specData(selectedNodes.NodeData).MetaData.metaString{4})
                    string1{3} = sprintf('%s (Modo), %s (Detector)', string1{3}, app.specData(selectedNodes.NodeData).MetaData.metaString{4});
                end
                
                if ~isempty(app.specData(selectedNodes.NodeData).MetaData.metaString{2})
                    string1{3} = sprintf('%s\nResolução: %s', string1{3}, app.specData(selectedNodes.NodeData).MetaData.metaString{2});
                end
                
                string1{4} = sprintf('Antena: %s\n\n', app.specData(selectedNodes.NodeData).MetaData.metaString{5});
                string1{5} = sprintf('Arquivo(s) relacionado(s): %s', strjoin(app.specData(selectedNodes.NodeData).RelatedFiles, ', '));
                
                app.mainInfo_Text.Value = string1;
                set(app.Playback_traceGrid_2.Children, 'Enable', 1, 'FontColor', [0,0,0])
                app.Button_Mask.Enable = 1;
                app.Button_DriveTest.Enable = 1;
                
            else
                set(app.mainInfo_Text, 'Enable', 0, 'Value', '')                
                set(app.Playback_traceGrid_2.Children, 'Enable', 0, 'FontColor', [0.94,0.94,0.94])
                app.xSpinnerLabel.FontColor = [0,0,0];
                app.ySpinnerLabel.FontColor = [0,0,0];
                app.Button_Mask.Enable = 0;
                app.Button_DriveTest.Enable = 0;
            end
            
        end

        % Image clicked function: mainInfo_Expand
        function mainInfo_ExpandCollapseTreeNodes(app, event)
            
            if strcmpi(app.mainInfo_Expand.ImageSource, 'General_Expand.png')
                for ii = 1:numel(app.mainInfo_Tree.Children)
                    expand(app.mainInfo_Tree.Children(ii));
                end
                app.mainInfo_Expand.ImageSource = 'General_Colapse.png';
                
            else
                collapse(app.mainInfo_Tree, 'all');
                app.mainInfo_Expand.ImageSource = 'General_Expand.png';
            end
            
        end

        % Button pushed function: mainInfo_DeleteAll
        function mainInfo_DeleteAllButtonPushed(app, event)
            
            msg = ['Confirma a exclusão de todos os fluxos de dados e as informações auxiliares, ' ...
                   'dentre elas a lista de exceções?'];

            selection = uiconfirm(app.MainApp, msg, 'Alerta', 'Options', {'OK', 'Cancelar'}, ...
                                                    'DefaultOption', 1, 'CancelOption', 2);
            
            if strcmp(selection, 'OK')
                
                app.peaksTable(:,:)    = [];
                app.exceptionList(:,:) = [];
                
                app.axes1.Title.String    = '';
                app.axes1.Subtitle.String = '';
                
                cla(app.axes1)
                cla(app.axes2)
                cla(app.axes3)

                File_specDataReader_Error(app)
                
            end
            
        end

        % Button pushed function: mainInfo_DeleteAll_2
        function mainInfo_DeleteAuxiliarWin_ButtonPushed(app, event)
            
            delete(app.auxWin1)
            delete(app.auxWin2)
            delete(app.auxWin3)
            delete(app.auxWin4)
            delete(app.auxWin5)
            delete(app.auxWin6)
            delete(app.auxWin7)
            
            app.auxWin1 = [];
            app.auxWin2 = [];
            app.auxWin3 = [];
            app.auxWin4 = [];
            app.auxWin5 = [];
            app.auxWin6 = [];
            app.auxWin7 = [];

            h = findobj('Type', 'Figure', 'Tag', 'ReportGenerator');
            if ~isempty(h)
                delete(h)
            end
            app.Report_EditPlots.Value = 0;
            
        end

        % Value changed function: Playback_traceCheckBox1
        function Playback_traceCheckBox1ValueChanged(app, event)
            
            if app.Playback_traceCheckBox1.Value
                if app.Playback_Fcn.Tooltip == "Média"
                    app.traceInfo.Mode(2) = 3;
                else
                    app.traceInfo.Mode(2) = 2;
                end
                
                if app.traceInfo.SelectedNode
                    ind1 = app.traceInfo.SelectedNode;
                    
                    newLine = findobj('Type', 'Line', {'Tag', 'Mediana', '-or', 'Tag', 'Média'});
                    if isempty(newLine)
                        newLine = plot(app.axes1, app.x, app.specData(ind1).statsData(:, app.traceInfo.Mode(2)), 'Tag', app.Playback_Fcn.Tooltip, 'Color', app.General.Colors(2,:));
                        Misc_PlotDataTipSettings(app.specData(ind1), newLine, app.specData(ind1).MetaData.metaString{1})
                    else
                        set(newLine, 'YData', app.specData(ind1).statsData(:, app.traceInfo.Mode(2)), 'Tag', app.Playback_Fcn.Tooltip)
                    end
                end
            else
                app.traceInfo.Mode(2) = 0;
                delete(findobj('Type', 'Line', {'Tag', 'Mediana', '-or', 'Tag', 'Média'}))
            end
            drawnow nocallbacks
            
        end

        % Value changed function: Playback_traceCheckBox2
        function Playback_traceCheckBox2ValueChanged(app, event)
            
            if app.Playback_traceCheckBox2.Value
                app.traceInfo.Mode(1) = 1;
                
                if app.traceInfo.SelectedNode
                    ind1 = app.traceInfo.SelectedNode;
                    
                    newLine = plot(app.axes1, app.x, app.specData(ind1).statsData(:, 1), 'Tag', 'MinHold', 'Color', app.General.Colors(1,:));
                    Misc_PlotDataTipSettings(app.specData(ind1), newLine, app.specData(ind1).MetaData.metaString{1})
                end
            else
                app.traceInfo.Mode(1) = 0;
                delete(findobj('Tag', 'MinHold'))
            end
            
        end

        % Value changed function: Playback_traceCheckBox3
        function Playback_traceCheckBox3ValueChanged(app, event)
            
            if app.Playback_traceCheckBox3.Value
                app.traceInfo.Mode(3) = 4;
                
                if app.traceInfo.SelectedNode
                    ind1 = app.traceInfo.SelectedNode;
                    
                    newLine = plot(app.axes1, app.x, app.specData(ind1).statsData(:, 4), 'Tag', 'MaxHold', 'Color', app.General.Colors(3,:));
                    Misc_PlotDataTipSettings(app.specData(ind1), newLine, app.specData(ind1).MetaData.metaString{1})
                end
            else
                app.traceInfo.Mode(3) = 0;
                delete(findobj('Tag', 'MaxHold'))
            end
            
        end

        % Selection changed function: Playback_peakTree
        function Playback_peakTreeSelectionChanged(app, event)
            
            % Startup
            app.PeakCFLabel.Enable = 0;
            app.PeakBWLabel.Enable = 0;
            set(app.PeakCF, 'Value', 0, 'Enable', 0)
            set(app.PeakBW, 'Value', 0, 'Enable', 0)

            % Processing...
            if numel(app.Playback_peakTree.SelectedNodes) == 1
                ind1 = app.traceInfo.SelectedNode;
                ind2 = app.Playback_peakTree.SelectedNodes.NodeData;

                app.PeakCFLabel.Enable = 1;
                app.PeakBWLabel.Enable = 1;
                set(app.PeakCF, 'Value', round(app.specData(ind1).Emissions.Frequency(ind2), 3), 'Enable', 1)
                set(app.PeakBW, 'Value', round(app.specData(ind1).Emissions.BW(ind2), 3),    'Enable', 1)

                if strcmp(app.Playback_peakTree.SelectedNodes.Icon, 'TruncatedPeak.png')
                    app.TruncarfrequnciaMenu.Enable = 0;
                    app.NotruncarMenu.Enable        = 1;
                else
                    app.TruncarfrequnciaMenu.Enable = 1;
                    app.NotruncarMenu.Enable        = 0;
                end

                if exist('event', 'var')
                    Plot_StartUp2_Spectrum(app, 'TreeSelectionChanged', ind1, [])
                end

            elseif numel(app.Playback_peakTree.SelectedNodes) > 1
                set(app.EditarMenu.Children, 'Enable', 1)
            end
            
        end

        % Selection changed function: Playback_peakRadioGroup
        function Playback_peakRadioGroupSelectionChanged(app, event)
            
            switch app.Playback_peakRadioGroup.SelectedObject.Text
                case {'ROI', 'DataTips'}
                    app.Playback_Grid1.RowHeight{5} = 0;
                case 'Automática'
                    app.Playback_Grid1.RowHeight{5} = 178;
            end
            
        end

        % Image clicked function: Playback_addMarker
        function Playback_peakAddPushed(app, event)
            
            ind1 = app.traceInfo.SelectedNode;
            
            % Identificação dos novos valores de frequência, largura de
            % banda e índice.
            if app.Playback_peakRadio1.Value
                d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Favor desenhar o contorno da emissão diretamente no plot...');
                
                app.axes1.Interactions = zoomInteraction;
                app.axes2.Interactions = zoomInteraction;
                app.axes3.Interactions = zoomInteraction;

                mkrTemp = drawrectangle(app.axes1, Color=[0.40,0.73,0.88], LineWidth=1, Tag='mkrTemp');

                if strcmp(app.Playback_Button1_2.Icon, 'Toolbar_Zoom2.png')
                    app.axes1.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
                    app.axes2.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
                    app.axes3.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
                else
                    app.axes1.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
                    app.axes2.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
                    app.axes3.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
                end
                delete(d)

                newFreq = mkrTemp.Position(1) + mkrTemp.Position(3)/2;                                                              % Em MHz
                if (newFreq*1e+6 < app.specData(ind1).MetaData.FreqStart) || ...
                   (newFreq*1e+6 > app.specData(ind1).MetaData.FreqStop)
                
                   delete(mkrTemp)
                   return
                end
                
                newBW    = mkrTemp.Position(3) * 1000;                                                                              % Em kHz
                newIndex = round((newFreq .* 1e+6 - app.bCoef) ./ app.aCoef);
                Method   = 'Detecção manual.';

            elseif app.Playback_peakRadio2.Value
                Attributes = struct('Fcn',         app.Playback_peakTrace.Value,       ...
                                    'NPeaks',      app.Playback_peakNumbers.Value,     ...
                                    'THR',         app.Playback_peakTHR.Value,         ...
                                    'Proeminence', app.Playback_peakProeminence.Value, ...
                                    'Distance',    app.Playback_peakDistance.Value,    ...
                                    'BW',          app.Playback_peakBW.Value);

                [newIndex, newFreq, newBW] = Misc_FindPeaks(app.specData(ind1), Attributes);
                newBW = newBW * 1000;

                Method = sprintf('%s - %s.', 'Algoritmo findpeaks', jsonencode(Attributes));

            else
                h = findobj('Type', 'DataTip');

                newFreq = [];
                ii = 0;
                while ii < numel(h)
                    ii = ii+1;
                    if isequal(h(ii).Parent.Parent.Parent, app.fig)
                newFreq(end+1,1) = double(round(h(ii).X, 3));                                                                   % Em MHz
                    end
                end
                newBW    = zeros(numel(newFreq), 1);
                newIndex = round((newFreq .* 1e+6 - app.bCoef) ./ app.aCoef);
                Method   = 'Detecção manual.';
            end

            % Validações dos índices:
            newIndex(newIndex < 1) = 1;
            newIndex(newIndex > app.specData(ind1).MetaData.DataPoints) = app.specData(ind1).MetaData.DataPoints;
            
            % Inclusão das novas informações na variável app.specData e 
            % ajuste do plot.
            if ~isempty(newIndex)
                if isempty(app.specData(ind1).Emissions)
                    app.specData(ind1).Emissions = table('Size', [0, 5],                                                   ...
                                                         'VariableTypes', {'uint16', 'double', 'double', 'uint8', 'cell'}, ...
                                                         'VariableNames', {'Index', 'Frequency', 'BW', 'Type', 'Method'});
                end
                app.specData(ind1).Emissions(end+1:end+numel(newIndex),:) = table(newIndex, newFreq, newBW, ones(numel(newIndex),1), repmat({Method}, numel(newIndex), 1));

                [~, uniqueIndex, ~]          = unique(app.specData(ind1).Emissions.Index);
                app.specData(ind1).Emissions = app.specData(ind1).Emissions(uniqueIndex,:);
                
                selectedEmission = find(app.specData(ind1).Emissions.Index == newIndex(1), 1);
                Plot_StartUp2_Spectrum(app, 'AddButtonPushed', ind1, selectedEmission)

            else
                uialert(app.MainApp, 'Não identificado pico que atenda as condições estabelecidas', 'appAnálise', 'Icon', 'warning')
            end
            
        end

        % Menu selected function: Playback_ContextMenu1
        function Playback_peakDeletePushed(app, event)
            
            ind1 = app.mainInfo_Tree.SelectedNodes.NodeData;
            
            for ii = numel(app.Playback_peakTree.SelectedNodes):-1:1
                app.specData(ind1).Emissions(app.Playback_peakTree.SelectedNodes(ii).NodeData,:) = [];
            end

            Plot_StartUp2_Spectrum(app, 'DeleteButtonPushed', ind1, 1)
            
        end

        % Value changed function: PeakBW, PeakCF
        function Playback_peakValueChanged(app, event)
            
            ind1 = app.traceInfo.SelectedNode;
            ind2 = app.Playback_peakTree.SelectedNodes(1).NodeData;

            switch event.Source
                case app.PeakCF
                    if (app.PeakCF.Value*1e+6 < app.specData(ind1).MetaData.FreqStart) || ...
                       (app.PeakCF.Value*1e+6 > app.specData(ind1).MetaData.FreqStop)
                    
                       app.PeakCF.Value = round(app.specData(ind1).Emissions.Frequency(ind2), 3);
                       return
                    end
                    
                    newIndex = round((app.PeakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    app.PeakCF.Value = app.x(newIndex);
                    
                    app.specData(ind1).Emissions(ind2,[1:2, 5]) = {newIndex, app.PeakCF.Value, 'Detecção manual.'};
                    app.specData(ind1).Emissions = sortrows(app.specData(ind1).Emissions, 'Index');
                                
                    selectedEmission = find(app.specData(ind1).Emissions.Index == newIndex, 1);
                    Plot_StartUp2_Spectrum(app, 'PeakValueChanged', ind1, selectedEmission)

                case app.PeakBW
                    app.specData(ind1).Emissions(ind2,[3, 5]) = {app.PeakBW.Value, 'Detecção manual.'};
                    Plot_StartUp2_Spectrum(app, 'PeakValueChanged', ind1, ind2)
            end
            
        end

        % Menu selected function: NotruncarMenu, TruncarfrequnciaMenu
        function Playback_TruncatedCheckBoxValueChanged(app, event)
            
            ind1 = app.mainInfo_Tree.SelectedNodes.NodeData;

            for ii = 1:numel(app.Playback_peakTree.SelectedNodes)
                ind2 = app.Playback_peakTree.SelectedNodes(ii).NodeData;
    
                switch event.Source.Text
                    case 'Truncar frequência'
                        app.specData(ind1).Emissions.Type(ind2)      = 1;
                        app.Playback_peakTree.SelectedNodes(ii).Icon = 'TruncatedPeak.png'; 
    
                    case 'Não truncar'
                        app.specData(ind1).Emissions.Type(ind2)      = 0;
                        app.Playback_peakTree.SelectedNodes(ii).Icon = 'NoTruncatedPeak.png';
                end
            end
            Playback_peakTreeSelectionChanged(app)
            
        end

        % Value changed function: Playback_occCheckBox
        function Playback_occCheckBoxValueChanged(app, event)
            
            traceInfoCallback2_plotLayout(app)
            Layout_PlotOCC(app)
            Plot_Layout2(app)
            
            if app.Playback_occCheckBox.Value        
                if app.traceInfo.SelectedNode
                    ind1 = app.traceInfo.SelectedNode;
                    
                    Plot_StartUp1(app)
                    Plot_StartUp2_OCC(app, ind1)
                    Plot_StartUp3(app)
                end
            else
                set(findall(groot, 'Parent', app.GridLayout23, '-not', 'Type', 'uipanel'), 'Enable', 0)
                set(app.Playback_occGrid2.Children, 'Enable', 0)
                
                delete(findobj({'Tag', 'occROI', '-or', 'Tag', 'maxOCC', '-or', 'Tag', 'meanOCC'}))
            end
            
        end

        % Value changed function: Playback_occType
        function Playback_occTypeValueChanged(app, event)
            
            app.Playback_occTime.Enable      = 1;
            app.Playback_occTimeLabel.Enable = 1;
            
            switch app.Playback_occType.Value
                case 'Linear'
                    app.Playback_occValue.Enable          = 1;
                    app.Playback_occValueLabel.Enable     = 1;
                    
                    app.Playback_occOffset.Enable         = 0;
                    app.Playback_occOffsetLabel.Enable    = 0;
                    app.Playback_occConfLevel.Enable      = 0;
                    app.Playback_occConfLevelLabel.Enable = 0;
                    app.Playback_occLabel.Enable          = 0;
                    set(app.Playback_occGrid2.Children, 'Enable', 0)
                    
                case 'Piso de ruído (Offset)'
                    app.Playback_occValue.Enable          = 0;
                    app.Playback_occValueLabel.Enable     = 0;
                                        
                    app.Playback_occOffset.Enable         = 1;
                    app.Playback_occOffsetLabel.Enable    = 1;
                    app.Playback_occConfLevel.Enable      = 1;
                    app.Playback_occConfLevelLabel.Enable = 1;
                    app.Playback_occLabel.Enable          = 1;
                    set(app.Playback_occGrid2.Children, 'Enable', 1)
            end
            
            delete(findobj('Tag', 'occROI'))
            cla(app.axes2)
            
            Playback_occValueChanged(app)
            
        end

        % Value changed function: Playback_occConfLevel, Playback_occFcn, 
        % Playback_occOffset, Playback_occTime, Playback_occValue, 
        % Playback_tSamples, Playback_uSamples
        function Playback_occValueChanged(app, event)
            
            if app.traceInfo.SelectedNode
                ind1 = app.traceInfo.SelectedNode;
                
                Plot_StartUp2_OCC(app, ind1)
                Plot_StartUp3(app)
            end
            
        end

        % Value changed function: Playback_wfCheckBox
        function Playback_wfCheckBoxValueChanged(app, event)
            
                traceInfoCallback2_plotLayout(app)
                Plot_Layout2(app)
                
                if app.Playback_wfCheckBox.Value
                    set(app.GridLayout15.Children, 'Enable', 1)
                    
                    if app.traceInfo.SelectedNode
                        Plot_StartUp1(app)
                        Plot_StartUp2_WaterFall(app)
                        Plot_StartUp3(app)
                    end
                else
                    set(app.GridLayout15.Children, 'Enable', 0)
                    app.Playback_DecimateFactor.Value = 'Automático';
                    
                    delete(findobj({'Tag', 'wfSurface', '-or', 'Tag', 'wfTimeStamp', '-or', 'Tag', 'DecimateLabel'}))
                    app.line_wfTime = [];
                end
            
        end

        % Value changed function: Playback_wfLine
        function Playback_wfLineValueChanged(app, event)
            
            switch app.Playback_wfLine.Value
                case 'Ativada'
                    if app.traceInfo.SelectedNode
                        ind1 = app.traceInfo.SelectedNode;
                        t    = app.specData(ind1).Data{1}(app.timeIndex);
                        
                        app.line_wfTime = line(app.axes3, [app.x(1) app.x(end)], [t(1) t(1)], [app.axes1.YLim(2) app.axes1.YLim(2)], ...
                                                          'Color', 'red', 'LineWidth', 1, 'PickableParts', 'none', 'Tag', 'wfTimeStamp');
                    end
                case 'Desativada'
                    delete(findobj('Tag', 'wfTimeStamp')) 
                    app.line_wfTime = [];
            end
            
        end

        % Value changing function: Playback_Slider
        function Playback_SliderValueChanging(app, event)
            
            ind1 = app.traceInfo.SelectedNode;
            
            app.timeIndex = round(event.Value/100 * app.specData(ind1).Samples);
            if app.timeIndex < 1
                app.timeIndex = 1;
            elseif app.timeIndex > app.specData(ind1).Samples
                app.timeIndex = app.specData(ind1).Samples;
            end
            
            app.axes1.Title.String{4} = sprintf('%s (%d de %d)', ...
                                        datestr(app.specData(ind1).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS'), ...
                                        app.timeIndex, app.specData(ind1).Samples);
            
            app.line_ClearWrite.YData = app.specData(ind1).Data{2}(:, app.timeIndex)';
            
            for ii = 1:numel(app.mkr_Label)
                app.mkr_Label(ii).Position(2) = app.line_ClearWrite.YData(app.line_ClearWrite.MarkerIndices(ii));
            end
            
            if ~isempty(app.line_wfTime) & strcmp(app.Playback_wfLine.Value, 'Ativada')
                app.line_wfTime.YData = repmat(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 1, 2);
            end
            
        end

        % Button pushed function: Playback_Button1
        function Playback_Button1Pushed(app, event)
            
            if ~isempty(app.RestoreView)
                set(app.axes1, 'XLim', app.RestoreView{1}, 'YLim', app.RestoreView{2})
                set(app.axes2, 'YLim', [0,100])
                
                if app.Playback_wfCheckBox.Value
                    set(app.axes3, 'YLim', app.RestoreView{3})
                    view(app.axes3, 0, 90);
                end
            end
            
        end

        % Button pushed function: Button_Mask
        function Button_MaskValueChanged(app, event)
            
            if isempty(app.auxWin4)
                ind = app.mainInfo_Tree.SelectedNodes.NodeData;

                if ismember(app.specData(ind).MetaData.DataType, app.specDataTypes)
                    app.auxWin4  = winMaskDraw(app, app.specData(ind), app.menu_userPath.Value);
                    drawnow nocallbacks
                else
                    uialert(app.MainApp, 'Aplicável apenas para fluxos de espectro', 'appAnálise', 'Icon', 'warning')
                end
            else
                figure(app.auxWin4.UIFigure)
            end
            
        end

        % Button pushed function: Playback_Button4
        function Playback_Button4Pushed(app, event)
            
            if strcmp(app.Playback_Button4.Icon, 'Playback_Icon4A.png')
                app.Playback_Button4.Icon = 'Playback_Icon4B.png';
            else
                app.Playback_Button4.Icon = 'Playback_Icon4A.png';
            end
            
        end

        % Button pushed function: Playback_Button5
        function Playback_ButtonPlayPushed(app, event)

            if ~app.plotFlag
                app.plotFlag = 1;
                set(app.Playback_Button5, 'Text', 'Parar escrita', 'Icon', 'Media_StopWhite.png')
                
                set(app.menu_Grid1.Children, 'Enable', 0);
                app.mainInfo_DeleteAll.Enable = 0;
                
                Plot(app)
            else
                app.plotFlag = 0;
                set(app.Playback_Button5, 'Text', 'Iniciar escrita', 'Icon', 'White_Play.png')
                
                set(app.menu_Grid1.Children, 'Enable', 1)
                app.mainInfo_DeleteAll.Enable = 1;
                
                mainInfo_TreeSelectionChanged(app)
            end
            
        end

        % Selection changed function: Report_Tree
        function Report_TreeSelectionChanged(app, event)
            
            app.mainInfo_Tree.SelectedNodes = [];
            set(app.mainInfo_Text, 'Enable', 0, 'Value', '')

            app.Button_Mask.Enable      = 0;
            app.Button_DriveTest.Enable = 0;
            app.Report_addButton.Enable = 0;
            
            Layout_Report(app)
            
        end

        % Button pushed function: Report_playButton, Report_previewButton
        function Report_playButtonPushed(app, event)
            
            switch event.Source
                case app.Report_previewButton; Mode = 'Preview';
                case app.Report_playButton;    Mode = 'Report';
            end

            % EXCLUSÃO FIGURAS INVISÍVEIS:
            h = findobj('Type', 'Figure', 'Tag', 'ReportGenerator', 'Visible', 0);
            if ~isempty(h)
                delete(h)
            end
            
            % VALIDAÇÕES:
            if (Mode == "Report") && (app.Report_Version.Value == "Definitiva")
                if app.Report_Issue.Value <= 0
                    uialert(app.MainApp, 'Favor inserir número da inspeção.', 'appAnálise', 'Icon', 'warning')
                    return
                end

                selection = uiconfirm(app.MainApp, sprintf('Confirma que se trata de monitoração relacionada à Inspeção nº %d?', app.Report_Issue.Value), ...
                                      'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question');
                
                if selection == "Cancelar"
                    return
                end
                
            elseif Mode == "Preview"
                if isempty(app.Report_Tree.CheckedNodes)
                    msg = 'Necessário selecionar ao menos um fluxo de dados a processar.';
                    uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning')
                    
                    return
                end
            end

            ReportGenerator_Main(app, Mode)

            % EXCLUI JANELA DE "ANÁLISE DE EMISSÕES":
            if ~isempty(app.auxWin1)
                delete(app.auxWin1)
                app.auxWin1 = [];
            end

            app.Report_EditPlots.Value = 0;
            
        end

        % Button pushed function: Report_NewProject
        function Report_NewProjectButtonPushed(app, event)
            
                selection = uiconfirm(app.MainApp, sprintf('Confirma o fechamento do projeto "%s"?', app.ProjectName.Value), ...
                                      'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question');
                
                if strcmp(selection, 'Cancelar')
                    return
                end
                
                app.ProjectFilename.Value = '';
                app.ProjectName.Value    = 'Projeto 001';
                app.Report_Issue.Value   = 0;
                
                app.Report_Version.Value = 'Preliminar';
                Report_VersionValueChanged(app)
                
                FilteredRows = find([app.specData.reportFlag]);
                for ii = FilteredRows
                    app.specData(ii).reportFlag = 0;
                end
                Report_TreeBuilding(app)
                
                app.peaksTable(:,:)    = [];
                app.exceptionList(:,:) = [];
                app.reportInfo         = [];
                
                Report_DeletePlotsButtonPushed(app)
                
            if ~isempty(app.auxWin1)
                delete(app.auxWin1)
                app.auxWin1 = [];
            end
                
        end

        % Button pushed function: Report_SaveProject
        function Report_SaveProjectValueChanged(app, event)
            
            if isempty(app.Report_Tree.Children)
                msg = ['Criação/edição de projeto somente é possível quando há ao menos um fluxo de ' ...
                       'dados a processar.'];            
                uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning');

                return
            end
            
            if isempty(app.ProjectFilename.Value)
                defaultFilename = fullfile(app.menu_userPath.Value, ['appAnalise_' datestr(now,'yymmdd_THHMMSS')]);
            else
                defaultFilename = app.ProjectFilename.Value;
            end
            
            [filename, pathname, index] = uiputfile({'*.mat', 'Projeto appAnálise (*.mat)'}, ...
                                                     'Selecione a pasta e o nome do arquivo', defaultFilename);
            figure(app.MainApp)
            
            if index
                d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', ...
                                               'Message', sprintf('Em andamento a criação/edição do projeto "%s".', app.ProjectName.Value));
                
                ProjectRows = find([app.specData.reportFlag]);
                ProjectRows = sort([ProjectRows, Misc_FindOCCData(app.specData(ProjectRows))]);
                
                OCCLength   = length([app.specData(ProjectRows).reportOCC.Index]);
                if OCCLength
                    OCCMap = zeros(OCCLength, 2);
                    
                    jj = 0;
                    for ii = 1:numel(ProjectRows)
                        if ~isempty(app.specData(ProjectRows(ii)).reportOCC.Index)
                            jj=jj+1;
                            OCCMap(jj,:) = [ProjectRows(ii), app.specData(ProjectRows(ii)).reportOCC.Index];
                            app.specData(ProjectRows(ii)).reportOCC.Index = find(ProjectRows == OCCMap(jj,2));
                        end
                    end
                end
                
                reportInfo = ReportGenerator_Aux1(app, 'Report');
                
                % Estrutura que será salva no arquivo.
                prjInfo = struct('Name',          app.ProjectName.Value,           ...
                                 'reportInfo',    rmfield(reportInfo, 'Filename'), ...
                                 'peaksTable',    app.peaksTable,                  ...
                                 'exceptionList', app.exceptionList);
                
                d.Message = sprintf('Salvando o projeto appAnálise no arquivo:\n• %s', filename);
                FileWriter_MAT73(fullfile(pathname, filename), app.specData(ProjectRows), prjInfo)
                
                if OCCLength
                    for jj = 1:height(OCCMap)
                        app.specData(OCCMap(jj,1)).reportOCC.Index = OCCMap(jj,2);
                    end
                end
                
                app.ProjectFilename.Value     = fullfile(pathname, filename);
                app.Report_ImageWarn.Visible = 0;
            end
            
        end

        % Value changed function: Report_EditPlots
        function Report_EditPlotsValueChanged(app, event)

            h = findobj('Type', 'Figure', 'Tag', 'ReportGenerator');

            if app.Report_EditPlots.Value
                set(app.fig, 'MenuBar', 'figure')
                set(h, 'MenuBar', 'figure')

            else
                set(app.fig, 'MenuBar', 'none')
                set(h, 'MenuBar', 'none')
            end
            
        end

        % Button pushed function: Report_tableView
        function Report_tableViewValueChanged(app, event)
            
            if ~isempty(app.peaksTable)
                if isempty(app.auxWin1)
                    d = uiprogressdlg(app.MainApp, "Indeterminate", "on", "Message", "Em andamento...");
                    
                    app.auxWin1 = winTreeEdit(app);
                    drawnow nocallbacks

                    delete(d)
                else
                    figure(app.auxWin1.UIFigure)
                end
            else
                msg = ['Aplicável apenas quando identificada ao menos uma emissão. Favor alterar os parâmetros dos critérios de' ...
                       'busca de emissões ou, opcionalmente, incluí-los no modo "PLAYBACK".'];
                uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning');
            end
            
        end

        % Button pushed function: Report_Fiscaliza
        function Report_FiscalizaCredential(app, event)
            
            if isempty(app.auxWin3)
                winCredential(app, app.MainApp.Position, app.Report_Issue.Value);
                drawnow nocallbacks
            else
                figure(app.auxWin3.UIFigure)
            end
            
        end

        % Value changed function: ProjectName
        function Report_ProjectNameValueChanged(app, event)
            
            if isempty(app.ProjectName.Value)
                app.ProjectName.Value = 'Projeto 001';
                
                uialert(app.MainApp, 'O campo nome do projeto não pode ficar vazio, tendo sido preenchido com "Projeto 001"', 'appAnálise', 'Icon', 'warning')
            end
            Report_SaveWarn(app)
            
        end

        % Image clicked function: Report_addButton
        function Report_addButtonPushed(app, event)
            
            % Algumas validações...
            filteredRows = Misc_FilteredRows(app);
            for ii = filteredRows
                if ismember(app.specData(ii).MetaData.DataType, app.occDataTypes)
                    msg = ['É possível a inclusão apenas de fluxo de espectro. O fluxo de ocupação automaticamente ' ...
                           'será incluso na edição do relatório, desde que selecionado no próprio fluxo de espectro.'];            
                    uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning');
                    
                    return
                    
                elseif ~app.specData(ii).gps.Status
                    msg = 'Necessária a edição de informações de coordenadas geográficas de fluxo(s) de dado(s).';
                    uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning');
                    
                    return
                    
                elseif app.specData(ii).Samples == 1
                    msg = 'É possível a inclusão de fluxo(s) de dados que possua(m) ao menos duas amostras.';
                    uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning');
                    
                    return
                end
            end
            
            Report_InfoCreation(app, filteredRows)
            Report_SaveWarn(app)
    
        end

        % Menu selected function: Report_ContextMenu1
        function Report_deleteButtonPushed(app, event)
            
            if ~isempty(app.Report_Tree.SelectedNodes)
                idx1 = app.Report_Tree.SelectedNodes.NodeData;

                if isnumeric(idx1)
                    app.specData(idx1).reportFlag           = 0;
                    app.specData(idx1).reportOCC            = struct('Related', [], 'Index', [], 'Method', '', 'Parameters', '');
                    app.specData(idx1).reportDetection      = [];
                    app.specData(idx1).reportClassification = [];

                else
                    idx2 = [];
                    for ii = 1:numel(app.specData)
                        if strcmp(app.specData(ii).Node, idx1)
                            idx2 = [idx2, ii];
                        end
                    end
                    
                    for kk = idx2
                        app.specData(kk).reportFlag           = 0;
                        app.specData(kk).reportOCC            = struct('Related', [], 'Index', [], 'Method', '', 'Parameters', '');
                        app.specData(kk).reportDetection      = [];
                        app.specData(kk).reportClassification = [];
                    end
                end

                Report_TreeBuilding(app)
                Layout_Report(app)

                Report_SaveWarn(app)
            end
            
        end

        % Value changed function: Report_PlaybackOnly
        function Report_Report_PlaybackOnlyValueChanged(app, event)
            
            app.specData(app.Report_Tree.SelectedNodes.NodeData).reportDetection.ManualMode = app.Report_PlaybackOnly.Value;
            Report_SaveWarn(app)
            
        end

        % Value changed function: Info_Latitude, Info_Longitude
        function Info_LongitudeValueChanged(app, event)
            
            if (app.Info_Latitude.Value ~= -1) & (app.Info_Longitude.Value ~= -1)
                gps = struct('Latitude',  app.Info_Latitude.Value, ...
                             'Longitude', app.Info_Longitude.Value);
                
                app.Info_City.Value    = FindCity(gps);                
                app.Info_Button.Enable = 1;
            end
            
        end

        % Value changed function: Info_City
        function Info_CityValueChanged(app, event)
            
            [City, Latitude, Longitude] = FindCoordinates(event.Value);
            
            if ~isempty(City)
                app.Info_City.Value      = City;
                app.Info_Latitude.Value  = Latitude;
                app.Info_Longitude.Value = Longitude;
                app.Info_Button.Enable   = 1;
            else
                msg = sprintf(['Não encontrada em base do IBGE o município "%s". Favor '  ...
                               'corrigir eventual erro na grafia, inserindo os acentos, ' ...
                               'no formato Município/UF'], event.Value);
                
                uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning');
                
                app.Info_City.Value = event.PreviousValue;
            end
            
        end

        % Button pushed function: Info_Button
        function Info_ButtonPushed(app, event)
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Em andamento a edição e a organização dos fluxos de dados.');
            
            ind = app.mainInfo_Tree.SelectedNodes.NodeData;
            
            % GPS
            if (app.specData(ind).gps.Latitude ~= app.Info_Latitude.Value) || (app.specData(ind).gps.Longitude ~= app.Info_Longitude.Value)
                app.specData(ind).gps.Status    = -1;
                app.specData(ind).gps.Latitude  = app.Info_Latitude.Value;
                app.specData(ind).gps.Longitude = app.Info_Longitude.Value;
                app.specData(ind).gps.Location  = app.Info_City.Value;
                
                if app.Info_CheckBox.Value
                    for ii = 1:numel(app.specData)
                        if ii == ind
                            continue
                        end
                        
                        if strcmp(app.specData(ind).Node, app.specData(ii).Node)
                            app.specData(ii).gps.Status    = -1;
                            app.specData(ii).gps.Latitude  = app.Info_Latitude.Value;
                            app.specData(ii).gps.Longitude = app.Info_Longitude.Value;
                            app.specData(ii).gps.Location  = app.Info_City.Value;
                        end
                    end
                end
            end
                        
            
            % OCC
            if ~isempty(app.specData(ind).reportOCC.Related)
                if strcmp(app.Info_OCC.Items{1}, app.Info_OCC.Value)
                    app.specData(ind).reportOCC.Index        = [];
                    app.specData(ind).report_occInfo = struct('Type', 'Linear adaptativo',    ...
                                                              'Value',     [],        ...
                                                              'Time',      15,        ...
                                                              'Offset',    12,        ...
                                                              'ConfLevel', [],        ...
                                                              'statsFcn',  'Mediana', ...
                                                              'Samples',   [0, 20]);
                else
                    auxIndex = find(strcmp(app.Info_OCC.Items, app.Info_OCC.Value), 1) - 1;
                    
                    app.specData(ind).reportOCC.Index        = app.specData(ind).reportOCC.Related(auxIndex);
                    app.specData(ind).report_occInfo = [];
                end
            end
            
            
            % UnitLevel Conversion
            if ~isempty(app.AntenaDropDown.Value)
                kFactor_raw = Misc_kFactorRead(app.RootFolder, app.AntenaDropDown.Value);
                
                if (kFactor_raw.Data(1,1)   <= app.specData(ind).MetaData.FreqStart / 1e+6) && ...
                   (kFactor_raw.Data(end,1) >= app.specData(ind).MetaData.FreqStop  / 1e+6)
                    
                    newThreadID = app.specData(ind).ThreadID+1;
                    while ismember(newThreadID, [app.specData.ThreadID])
                        newThreadID = newThreadID+1;
                    end
                    
                    oldHeight = numel(app.specData);
                    newHeight = oldHeight+1;
                    
                    % Desloca fluxos para que o novo fluxo fique logo após o
                    % que foi editado.
                    app.specData(ind+2:newHeight) = app.specData(ind+1:oldHeight);
                    app.specData(ind+1)           = app.specData(ind);
                    
                    app.specData(ind+1).MetaData.LevelUnit     = 3; 
                    app.specData(ind+1).MetaData.metaString{1} = 'dBμV/m';
                    app.specData(ind+1).MetaData.metaString{5} = sprintf('%s / %s', app.specData(ind+1).MetaData.metaString{5}, ...
                                                                                    app.AntenaDropDown.Value);
                    
                    kFactor = interp1(kFactor_raw.Data(:,1), ...
                                      kFactor_raw.Data(:,2), ...
                                      linspace(app.specData(ind).MetaData.FreqStart ./ 1e+6, ...
                                               app.specData(ind).MetaData.FreqStop  ./ 1e+6, ...
                                               app.specData(ind).MetaData.DataPoints)', ...
                                      'spline');
                    
                    if strcmp(app.specData(ind).MetaData.metaString{1}, 'dBm')
                        kFactor = kFactor + 107;
                    end
                    
                    app.specData(ind+1).Data{2}   = app.specData(ind+1).Data{2}   + kFactor;
                    app.specData(ind+1).statsData = app.specData(ind+1).statsData + kFactor;
                    
                else
                    uialert(app.MainApp, 'A curva de correção da antena selecionada não engloba a faixa monitorada.', 'appAnálise', 'Icon', 'warning')
                end


            end            
            
            
            % Alteração...
            EditInfo_InfoChangedLayout(app)
            
            delete(d)
            drawnow nocallbacks
            
        end

        % Button pushed function: Save_Button
        function Save_ButtonPushed(app, event)
            
            if isempty(app.mainInfo_Tree.SelectedNodes)
                uialert(app.MainApp, 'Selecione ao menos um fluxo de dados.', 'appAnálise', 'Icon', 'warning');
                
                return
            end
            
            defaultFilename = fullfile(app.menu_userPath.Value, ['appAnalise_' datestr(now,'yymmdd_THHMMSS')]);
            
            [filename, pathname, index] = uiputfile({'*.mat',    'appAnálise (*.mat)'; ...
                                                     '*.bin',    'Logger (*.bin)';     ...
                                                     '*.sm1809', 'SM1809 (*.sm1809)'}, ...
                                                     'Selecione a pasta e o nome do arquivo', defaultFilename);
            figure(app.MainApp)
            
            if ~index
                return
            end            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            % Validação 1: O formato CRFS BIN aceita dados gerados apenas por um único sensor RFeye.
            % Validação 2: O formato SM1809 aceita dados gerados apenas por um único sensor, uma vez 
            % que a informação de GPS é única (ou num bloco comum a todos os fluxos, no caso do CRFS BIN, ou 
            % no cabeçalho, no caso do SM1809).
            % Validação 3: O formato SM1809 não aceita fluxos de ocupação.
            filteredRows = Misc_FilteredRows(app);
            switch index
                case 1 % MAT (por enquanto nenhuma restrição)
                    
                case 2 % CRFS Bin
                    Node = app.specData(filteredRows(1)).Node;
                    for ii = 1:numel(filteredRows)
                        if ~strcmp(Node, app.specData(filteredRows(ii)).Node) || ~contains(app.specData(filteredRows(ii)).Node, 'RFeye', 'IgnoreCase', true)
                            msg = 'O formato CRFS Bin armazena dados gerados por um único sensor RFeye.';
                            uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning');
                            
                            return
                        end
                    end
                    
                case 3 % SM1809
                    Node = app.specData(filteredRows(1)).Node;
                    for ii = 1:numel(filteredRows)
                        if ~strcmp(Node, app.specData(filteredRows(ii)).Node)
                            msg = 'O formato SM1809 armazena dados gerados por um único sensor.';
                            uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning');
                            
                            return
                        end
                        
                        if ismember(app.specData(filteredRows(ii)).MetaData.DataType, app.occDataTypes)
                            msg = 'O formato SM1809 não aceita fluxos de ocupação.';
                            uialert(app.MainApp, msg, 'appAnálise', 'Icon', 'warning');
                            
                            return
                        end
                    end
            end
            
            d.Message = 'Em andamento a escrita do arquivo.';
            try
%                 Validação 4: A data inicial não pode ser maior do que a final.
%                 Validação 5: Após aplicar o filtro de timestamp tem que restar 
%                 ao menos duas amostras por fluxo selecionado (para possibilitar
%                 a geração do Waterfall). 
                if app.Save_ObservationTime.Value; Data = Misc_DateTimeFilter(app, filteredRows);
                else;                              Data = app.specData(filteredRows);
                end
                
                filename = fullfile(pathname, filename);
                switch index
                    case 1; FileWriter_MAT73(filename,   Data);
                    case 2; FileWriter_CRFSBin(filename, Data);
                    case 3; FileWriter_SM1809(filename,  Data);
                end
                
            catch ME
                report = getReport(ME);
                uialert(app.MainApp, report, 'appAnálise', 'Interpreter','html');
            end
            
        end

        % Value changed function: Save_ObservationTime
        function Save_ObservationTimeValueChanged(app, event)
            
            if app.Save_ObservationTime.Value
                if isempty(app.mainInfo_Tree.SelectedNodes)
                    app.Save_ObservationTime.Value = 0;
                    set(app.Save_Grid5.Children, 'Enable', 0)
                    
                    return
                else
                    filteredRows = Misc_FilteredRows(app);
                    
                    Start = [];
                    Stop  = [];
                    for ii = 1:numel(filteredRows)
                        Start = [Start app.specData(filteredRows(ii)).Data{1}(1)];
                        Stop  = [Start app.specData(filteredRows(ii)).Data{1}(end)];
                    end
                    
                    app.Save_DatePicker1.Value = min(Start);
                    app.Save_Spinner1.Value    = 0;
                    app.Save_Spinner2.Value    = 0;

                    app.Save_DatePicker2.Value = max(Stop);
                    app.Save_Spinner3.Value    = 23;
                    app.Save_Spinner4.Value    = 59;
                    
                    set(app.Save_Grid5.Children, 'Enable', 1)
                end
            else
                set(app.Save_Grid5.Children, 'Enable', 0)
            end
            
        end

        % Value changed function: Report_DatePicker1, Report_DatePicker2, 
        % Report_Issue, Report_Spinner1, Report_Spinner2, Report_Spinner3, 
        % Report_Spinner4
        function Report_SaveWarn(app, event)

            app.Report_ImageWarn.Visible = 1;
            
        end

        % Value changed function: menu_userPath
        function menu_userPathValueChanged(app, event)
            
            fileID = fopen(fullfile(app.RootFolder, 'DataBase', 'UserPath.cfg'), 'wt');
            fwrite(fileID, app.menu_userPath.Value, 'char');
            fclose(fileID);
            
        end

        % Value changed function: AntenaDropDown, Info_OCC
        function Info_OCCValueChanged(app, event)

            app.Info_Button.Enable = 1;
            
        end

        % Button pushed function: Info_RefreshGPS
        function Info_RefreshGPSButtonPushed(app, event)
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Em andamento a edição e a organização dos fluxos de dados.');
            
            EditInfo_GPSNewValue(app)
            EditInfo_InfoChangedLayout(app)
            
            delete(d)
            drawnow nocallbacks
            
        end

        % Cell edit callback: Info_Table
        function Info_TableCellEdit(app, event)
            
            if ismember(event.NewData, [-1,0,1])
                d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Em andamento a edição e a organização dos fluxos de dados.');
                
                ind = app.mainInfo_Tree.SelectedNodes.NodeData;
                app.specData(ind).RelatedGPS{event.Indices(1)}.Status = event.NewData;
                
                EditInfo_GPSNewValue(app)
                EditInfo_InfoChangedLayout(app)
            
            else
                app.Info_Table.Data{event.Indices(1),event.Indices(2)} = event.PreviousData;
                
                uialert(app.MainApp, 'Status pode ser apenas -1 (manual), 0 (inválido) e 1 (válido)', 'appAnálise', 'Icon', 'error')
            end
            
        end

        % Button pushed function: File_Button1_2
        function File_Button1_2Pushed(app, event)

            if isempty(app.auxWin6)
                app.auxWin6 = winRepository(app);
                drawnow nocallbacks
            else
                figure(app.auxWin6.UIFigure)
            end

        end

        % Button pushed function: Playback_Layout
        function LayoutEspectroOCCWaterfallDropDownValueChanged(app, event)

            if app.Playback_Layout.Icon == "newPlot_Factor1.png"
                app.Playback_Layout.Icon = "newPlot_Factor2.png";
            else
                app.Playback_Layout.Icon = "newPlot_Factor1.png";
            end

            Plot_Layout2(app)
            drawnow nocallbacks

        end

        % Value changed function: Playback_ColorMap
        function Playback_ColorMapValueChanged(app, event)

            colormap(app.axes3, app.Playback_ColorMap.Value)
            drawnow nocallbacks

        end

        % Value changed function: Playback_DecimateFactor
        function Playback_DecimateFactorValueChanged(app, event)
            
            if ~isempty(app.axes3.Children) && ~isempty(app.mainInfo_Tree.SelectedNodes)
                d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');

                delete(findobj({'Tag', 'wfSurface', '-or', 'Tag', 'wfTimeStamp'}))
                app.line_wfTime = [];

                Plot_StartUp2_WaterFall(app)
                Plot_StartUp3(app)
                drawnow nocallbacks
            end

        end

        % Button pushed function: Playback_Button1_2
        function Playback_Button1_2Pushed(app, event)

            if strcmp(app.Playback_Button1_2.Icon, 'Toolbar_Zoom2.png')
                app.axes1.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
                app.axes2.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
                app.axes3.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];

                set(app.Playback_Button1_2, 'Icon', 'Pan.png', 'Tooltip', 'Interação pan')
            else
                app.axes1.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
                app.axes2.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
                app.axes3.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];

                set(app.Playback_Button1_2, 'Icon', 'Toolbar_Zoom2.png', 'Tooltip', 'Interação zoom em região')
            end

        end

        % Value changed function: Playback_xLim1, Playback_xLim2
        function Playback_xLimitsValueChanged(app, event)
            
            FreqRange = app.Playback_xLim2.Value - app.Playback_xLim1.Value;
            if FreqRange > 0
                app.axes1.XLim = [app.Playback_xLim1.Value, app.Playback_xLim2.Value];
            else
                switch event.Source.Tag
                    case 'FreqStart'; app.Playback_xLim1.Value = event.PreviousValue;
                    case 'FreqStop';  app.Playback_xLim2.Value = event.PreviousValue;
                end
            end

        end

        % Value changed function: Playback_yLim1, Playback_yLim2
        function Playback_yLimitsValueChanged(app, event)

            LevelRange = app.Playback_yLim2.Value - app.Playback_yLim1.Value;
            if LevelRange > 0
                app.axes1.YLim = [app.Playback_yLim1.Value, app.Playback_yLim2.Value];
            else
                switch event.Source.Tag
                    case 'MinLevel'; app.Playback_yLim1.Value = event.PreviousValue;
                    case 'MaxLevel'; app.Playback_yLim2.Value = event.PreviousValue;
                end
            end

        end

        % Value changed function: Playback_AxesHoles
        function Playback_AxesHolesValueChanged(app, event)
            
            h = findobj('Tag', 'wfSurface');
            h.MeshStyle = app.Playback_AxesHoles.Value;            

        end

        % Button pushed function: Button_DriveTest
        function Button_DriveTestPushed(app, event)
            
            if isempty(app.auxWin2)
                d = uiprogressdlg(app.MainApp, "Indeterminate", "on", "Message", "Em andamento...");
                ind = app.mainInfo_Tree.SelectedNodes.NodeData;

                app.auxWin2 = winDriveTest(app, app.specData(ind));
                drawnow nocallbacks

                delete(d)
            else
                figure(app.auxWin2.UIFigure)
            end

        end

        % Button pushed function: Playback_Fcn
        function Playback_FcnButtonPushed(app, event)
            
            if app.Playback_Fcn.Tooltip == "Média"
                app.Playback_Fcn.Tooltip = "Mediana";
            else
                app.Playback_Fcn.Tooltip = "Média";
            end
            drawnow nocallbacks

            Playback_traceCheckBox1ValueChanged(app)

        end

        % Button pushed function: Report_Fiscaliza_2
        function Report_Fiscaliza_2ButtonPushed(app, event)
            
            if isempty(app.auxWin7)
                app.auxWin7 = winReport(app);
            else
                figure(app.auxWin7.UIFigure)
            end

        end

        % Value changed function: Report_BandLim1, Report_BandLim2
        function Report_BandLimValueChanged(app, event)
            
            switch event.Source
                case app.Report_BandLim1
                    if app.Report_BandLim1.Value > app.Report_BandLim2.Value
                        app.Report_BandLim2.Value = app.Report_BandLim1.Value;
                    end

                case app.Report_BandLim2
                    if app.Report_BandLim2.Value < app.Report_BandLim1.Value
                        app.Report_BandLim1.Value = app.Report_BandLim2.Value;
                    end
            end

        end

        % Button pushed function: Report_DetectionButton
        function Report_DetectionButtonPushed(app, event)
            
            winPeakDetection1(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'appAnálise';
            app.MainApp.Icon = 'Anatel.png';
            app.MainApp.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.Grid1 = uigridlayout(app.MainApp);
            app.TabGroup = uitabgroup(app.Grid1);
            app.Tab1_Home = uitab(app.TabGroup);
            app.Home_Grid1 = uigridlayout(app.Tab1_Home);
            app.Home_subTitle2 = uilabel(app.Home_Grid1);
            app.Home_subTitle2.Text = 'v. 1.20';
            app.Home_ProjectLogo = uiimage(app.Home_Grid1);
            app.Home_ProjectLogo.ImageSource = 'Puzzle.png';
            app.Home_mainTitle = uilabel(app.Home_Grid1);
            app.Home_mainTitle.Text = 'appAnálise';
            app.Tab2_File = uitab(app.TabGroup);
            app.File_Grid1 = uigridlayout(app.Tab2_File);
            app.File_GridA = uigridlayout(app.File_Grid1);
            app.File_Label1 = uilabel(app.File_GridA);
            app.File_Label1.Text = 'ARQUIVO(S)';
            app.File_Tree1 = uitree(app.File_GridA);
            app.File_Tree1.Multiselect = 'on';
            app.File_Tree1.SelectionChangedFcn = createCallbackFcn(app, @File_Tree1SelectionChanged, true);
            app.File_Button1 = uibutton(app.File_GridA, 'push');
            app.File_Button1.ButtonPushedFcn = createCallbackFcn(app, @File_Button1Pushed, true);
            app.File_Button1.Icon = 'White_OpenFile.png';
            app.File_Button1.Text = 'Arquivo local';
            app.File_Label2 = uilabel(app.File_GridA);
            app.File_Label2.Text = 'Informações gerais';
            app.File_Panel1 = uipanel(app.File_GridA);
            app.File_GridA1 = uigridlayout(app.File_Panel1);
            app.File_FileTypeLabel = uilabel(app.File_GridA1);
            app.File_FileTypeLabel.Enable = 'off';
            app.File_FileTypeLabel.Text = 'Tipo do arquivo:';
            app.File_FileType = uitextarea(app.File_GridA1);
            app.File_FileType.Editable = 'off';
            app.File_FileType.Enable = 'off';
            app.File_FilesListLabel = uilabel(app.File_GridA1);
            app.File_FilesListLabel.Enable = 'off';
            app.File_FilesListLabel.Text = 'Arquivo(s) relacionado(s):';
            app.File_FilesList = uitextarea(app.File_GridA1);
            app.File_FilesList.Editable = 'off';
            app.File_FilesList.Enable = 'off';
            app.udpLogger_Label3 = uilabel(app.File_GridA);
            app.udpLogger_Label3.Text = 'Fluxo(s) de dados';
            app.File_Tree2 = uitree(app.File_GridA);
            app.File_Tree2.Multiselect = 'on';
            app.File_Tree2.SelectionChangedFcn = createCallbackFcn(app, @File_Tree2SelectionChanged, true);
            app.File_Tree2.Enable = 'off';
            app.File_Info = uilabel(app.File_GridA);
            app.File_Info.Text = '';
            app.File_Button1_2 = uibutton(app.File_GridA, 'push');
            app.File_Button1_2.ButtonPushedFcn = createCallbackFcn(app, @File_Button1_2Pushed, true);
            app.File_Button1_2.Icon = 'URL.png';
            app.File_Button1_2.Enable = 'off';
            app.File_Button1_2.Text = 'Repositório';
            app.File_GridB = uigridlayout(app.File_Grid1);
            app.File_Button2 = uibutton(app.File_GridB, 'push');
            app.File_Button2.ButtonPushedFcn = createCallbackFcn(app, @File_Button2Pushed, true);
            app.File_Button2.Icon = 'White_Play.png';
            app.File_Button2.Enable = 'off';
            app.File_Button2.Text = 'Leitura espectro';
            app.File_Label4 = uilabel(app.File_GridB);
            app.File_Label4.Text = 'Informações complementares relacionadas ao fluxo selecionado';
            app.File_GridB1 = uigridlayout(app.File_GridB);
            app.File_Number = uieditfield(app.File_GridB1, 'numeric');
            app.File_Number.Limits = [0 Inf];
            app.File_Number.ValueDisplayFormat = '%.0f';
            app.File_Number.Editable = 'off';
            app.File_NumberLabel = uilabel(app.File_GridB1);
            app.File_NumberLabel.Text = {'Quantidade de '; 'arquivos:'};
            app.File_Memory = uieditfield(app.File_GridB1, 'numeric');
            app.File_Memory.Limits = [0 Inf];
            app.File_Memory.ValueDisplayFormat = '%.3f';
            app.File_Memory.Editable = 'off';
            app.File_MemoryLabel = uilabel(app.File_GridB1);
            app.File_MemoryLabel.Text = 'Estimativa de uso da memória após leitura do espectro (MB):';
            app.Panel_4 = uipanel(app.File_GridB);
            app.File_GridB2 = uigridlayout(app.Panel_4);
            app.File_Description = uieditfield(app.File_GridB2, 'text');
            app.File_Description.Editable = 'off';
            app.File_Description.Enable = 'off';
            app.File_DescriptionLabel = uilabel(app.File_GridB2);
            app.File_DescriptionLabel.Enable = 'off';
            app.File_DescriptionLabel.Text = 'Descrição:';
            app.File_Resolution = uieditfield(app.File_GridB2, 'numeric');
            app.File_Resolution.Limits = [-1 Inf];
            app.File_Resolution.ValueDisplayFormat = '%.3f';
            app.File_Resolution.Editable = 'off';
            app.File_Resolution.Enable = 'off';
            app.File_Resolution.Value = -1;
            app.File_ResolutionLabel = uilabel(app.File_GridB2);
            app.File_ResolutionLabel.Enable = 'off';
            app.File_ResolutionLabel.Text = 'Resolução (kHz):';
            app.File_Operation = uieditfield(app.File_GridB2, 'text');
            app.File_Operation.Editable = 'off';
            app.File_Operation.Enable = 'off';
            app.File_OperationLabel = uilabel(app.File_GridB2);
            app.File_OperationLabel.Enable = 'off';
            app.File_OperationLabel.Text = 'Operação:';
            app.File_ObservationTime = uieditfield(app.File_GridB2, 'text');
            app.File_ObservationTime.Editable = 'off';
            app.File_ObservationTime.Enable = 'off';
            app.File_ObservationTime.Tooltip = {''};
            app.File_ObservationTimeLabel = uilabel(app.File_GridB2);
            app.File_ObservationTimeLabel.Enable = 'off';
            app.File_ObservationTimeLabel.Text = 'Período de observação:';
            app.File_City = uieditfield(app.File_GridB2, 'text');
            app.File_City.Editable = 'off';
            app.File_City.Enable = 'off';
            app.File_City.Tooltip = {''};
            app.File_CityLabel = uilabel(app.File_GridB2);
            app.File_CityLabel.Enable = 'off';
            app.File_CityLabel.Text = 'Local da monitoração:';
            app.File_DataPoints = uieditfield(app.File_GridB2, 'numeric');
            app.File_DataPoints.Limits = [1 Inf];
            app.File_DataPoints.ValueDisplayFormat = '%.0f';
            app.File_DataPoints.Editable = 'off';
            app.File_DataPoints.Enable = 'off';
            app.File_DataPoints.Value = 501;
            app.File_DataPointsLabel = uilabel(app.File_GridB2);
            app.File_DataPointsLabel.Enable = 'off';
            app.File_DataPointsLabel.Text = 'Pontos por traço:';
            app.File_EstimatedMemory = uieditfield(app.File_GridB2, 'numeric');
            app.File_EstimatedMemory.Limits = [0 Inf];
            app.File_EstimatedMemory.ValueDisplayFormat = '%.3f';
            app.File_EstimatedMemory.Editable = 'off';
            app.File_EstimatedMemory.Enable = 'off';
            app.EstimativadeusodamemriaMBLabel = uilabel(app.File_GridB2);
            app.EstimativadeusodamemriaMBLabel.Enable = 'off';
            app.EstimativadeusodamemriaMBLabel.Text = 'Estimativa de uso da memória (MB):';
            app.File_Samples = uieditfield(app.File_GridB2, 'numeric');
            app.File_Samples.Limits = [1 Inf];
            app.File_Samples.ValueDisplayFormat = '%.0f';
            app.File_Samples.Editable = 'off';
            app.File_Samples.Enable = 'off';
            app.File_Samples.Value = 1;
            app.File_SamplesLabel = uilabel(app.File_GridB2);
            app.File_SamplesLabel.Enable = 'off';
            app.File_SamplesLabel.Text = 'Número de traços:';
            app.File_LevelUnitLabel = uilabel(app.File_GridB2);
            app.File_LevelUnitLabel.Enable = 'off';
            app.File_LevelUnitLabel.Text = 'Unidade:';
            app.File_LevelUnit = uieditfield(app.File_GridB2, 'text');
            app.File_LevelUnit.Editable = 'off';
            app.File_LevelUnit.Enable = 'off';
            app.Tab3_Playback = uitab(app.TabGroup);
            app.GridLayout5 = uigridlayout(app.Tab3_Playback);
            app.Grid3_Playback = uigridlayout(app.GridLayout5);
            app.Playback_Panel0 = uipanel(app.Grid3_Playback);
            app.Playback_Grid0 = uigridlayout(app.Playback_Panel0);
            app.Playback_Grid2 = uigridlayout(app.Playback_Grid0);
            app.Playback_Grid3 = uigridlayout(app.Playback_Grid2);
            app.Playback_Slider = uislider(app.Playback_Grid3);
            app.Playback_Slider.MajorTicks = [0 25 50 75 100];
            app.Playback_Slider.MajorTickLabels = {'0', '', '', '', '1'};
            app.Playback_Slider.ValueChangingFcn = createCallbackFcn(app, @Playback_SliderValueChanging, true);
            app.Playback_Slider.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.Playback_Slider.Enable = 'off';
            app.Panel_12 = uipanel(app.Playback_Grid2);
            app.GridLayout15 = uigridlayout(app.Panel_12);
            app.Playback_ColorMap = uidropdown(app.GridLayout15);
            app.Playback_ColorMap.Items = {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'};
            app.Playback_ColorMap.ValueChangedFcn = createCallbackFcn(app, @Playback_ColorMapValueChanged, true);
            app.Playback_ColorMap.Enable = 'off';
            app.Playback_ColorMap.Value = 'jet';
            app.EstiloLabel = uilabel(app.GridLayout15);
            app.EstiloLabel.Enable = 'off';
            app.EstiloLabel.Text = 'Estilo de cores:';
            app.Playback_DecimateFactor = uidropdown(app.GridLayout15);
            app.Playback_DecimateFactor.Items = {'Automático', '1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.Playback_DecimateFactor.ValueChangedFcn = createCallbackFcn(app, @Playback_DecimateFactorValueChanged, true);
            app.Playback_DecimateFactor.Enable = 'off';
            app.Playback_DecimateFactor.Value = 'Automático';
            app.EstiloLabel_2 = uilabel(app.GridLayout15);
            app.EstiloLabel_2.Enable = 'off';
            app.EstiloLabel_2.Text = 'Fator de decimação:';
            app.Playback_wfLine = uidropdown(app.GridLayout15);
            app.Playback_wfLine.Items = {'Ativada', 'Desativada'};
            app.Playback_wfLine.ValueChangedFcn = createCallbackFcn(app, @Playback_wfLineValueChanged, true);
            app.Playback_wfLine.Enable = 'off';
            app.Playback_wfLine.Value = 'Ativada';
            app.Playback_wfLineLabel = uilabel(app.GridLayout15);
            app.Playback_wfLineLabel.Enable = 'off';
            app.Playback_wfLineLabel.Text = 'Timestamp:';
            app.Playback_AxesHoles = uidropdown(app.GridLayout15);
            app.Playback_AxesHoles.Items = {'row', 'both'};
            app.Playback_AxesHoles.ValueChangedFcn = createCallbackFcn(app, @Playback_AxesHolesValueChanged, true);
            app.Playback_AxesHoles.Enable = 'off';
            app.Playback_AxesHoles.Value = 'both';
            app.Playback_wfLineLabel_2 = uilabel(app.GridLayout15);
            app.Playback_wfLineLabel_2.Enable = 'off';
            app.Playback_wfLineLabel_2.Text = 'Direção:';
            app.Panel_14 = uipanel(app.Playback_Grid2);
            app.GridLayout23 = uigridlayout(app.Panel_14);
            app.Playback_occTypeLabel = uilabel(app.GridLayout23);
            app.Playback_occTypeLabel.Enable = 'off';
            app.Playback_occTypeLabel.Text = 'Threshold:';
            app.Playback_occType = uidropdown(app.GridLayout23);
            app.Playback_occType.Items = {'Linear', 'Piso de ruído (Offset)'};
            app.Playback_occType.ValueChangedFcn = createCallbackFcn(app, @Playback_occTypeValueChanged, true);
            app.Playback_occType.Enable = 'off';
            app.Playback_occType.Value = 'Linear';
            app.Playback_occValueLabel = uilabel(app.GridLayout23);
            app.Playback_occValueLabel.Enable = 'off';
            app.Playback_occValueLabel.Text = 'Valor (dBm):';
            app.Playback_occValue = uispinner(app.GridLayout23);
            app.Playback_occValue.RoundFractionalValues = 'on';
            app.Playback_occValue.ValueDisplayFormat = '%d';
            app.Playback_occValue.ValueChangedFcn = createCallbackFcn(app, @Playback_occValueChanged, true);
            app.Playback_occValue.Enable = 'off';
            app.Playback_occValue.Value = -80;
            app.Playback_occOffset = uispinner(app.GridLayout23);
            app.Playback_occOffset.Limits = [0 12];
            app.Playback_occOffset.ValueChangedFcn = createCallbackFcn(app, @Playback_occValueChanged, true);
            app.Playback_occOffset.Enable = 'off';
            app.Playback_occOffset.Value = 12;
            app.Playback_occOffsetLabel = uilabel(app.GridLayout23);
            app.Playback_occOffsetLabel.Enable = 'off';
            app.Playback_occOffsetLabel.Text = 'OffSet:';
            app.Playback_occTime = uispinner(app.GridLayout23);
            app.Playback_occTime.Limits = [2 Inf];
            app.Playback_occTime.ValueDisplayFormat = '%d';
            app.Playback_occTime.ValueChangedFcn = createCallbackFcn(app, @Playback_occValueChanged, true);
            app.Playback_occTime.Tag = 'EXPORT';
            app.Playback_occTime.Enable = 'off';
            app.Playback_occTime.Tooltip = {''};
            app.Playback_occTime.Value = 10;
            app.Playback_occTimeLabel = uilabel(app.GridLayout23);
            app.Playback_occTimeLabel.Enable = 'off';
            app.Playback_occTimeLabel.Text = 'Fator integração:';
            app.Playback_occConfLevel = uidropdown(app.GridLayout23);
            app.Playback_occConfLevel.Items = {'68', '95', '99'};
            app.Playback_occConfLevel.ValueChangedFcn = createCallbackFcn(app, @Playback_occValueChanged, true);
            app.Playback_occConfLevel.Enable = 'off';
            app.Playback_occConfLevel.Value = '95';
            app.Playback_occConfLevelLabel = uilabel(app.GridLayout23);
            app.Playback_occConfLevelLabel.Enable = 'off';
            app.Playback_occConfLevelLabel.Text = 'Fator ceifamento:';
            app.Playback_occLabel = uilabel(app.GridLayout23);
            app.Playback_occLabel.Enable = 'off';
            app.Playback_occLabel.Text = 'Relacionados à estimativa do piso de ruído:';
            app.Playback_occPanel = uipanel(app.GridLayout23);
            app.Playback_occGrid2 = uigridlayout(app.Playback_occPanel);
            app.Playback_uSamples = uispinner(app.Playback_occGrid2);
            app.Playback_uSamples.Limits = [10 90];
            app.Playback_uSamples.RoundFractionalValues = 'on';
            app.Playback_uSamples.ValueDisplayFormat = '%.0f';
            app.Playback_uSamples.ValueChangedFcn = createCallbackFcn(app, @Playback_occValueChanged, true);
            app.Playback_uSamples.Enable = 'off';
            app.Playback_uSamples.Value = 20;
            app.Playback_occFcn = uidropdown(app.Playback_occGrid2);
            app.Playback_occFcn.Items = {'Mediana', 'Média'};
            app.Playback_occFcn.ValueChangedFcn = createCallbackFcn(app, @Playback_occValueChanged, true);
            app.Playback_occFcn.Enable = 'off';
            app.Playback_occFcn.Value = 'Média';
            app.Playback_uSamplesLabel = uilabel(app.Playback_occGrid2);
            app.Playback_uSamplesLabel.Enable = 'off';
            app.Playback_uSamplesLabel.Text = {'Amostras '; 'úteis (%):'};
            app.Playback_occFcnLabel = uilabel(app.Playback_occGrid2);
            app.Playback_occFcnLabel.Enable = 'off';
            app.Playback_occFcnLabel.Text = {'Função'; 'estatística:'};
            app.Playback_tSamples = uispinner(app.Playback_occGrid2);
            app.Playback_tSamples.Limits = [0 10];
            app.Playback_tSamples.RoundFractionalValues = 'on';
            app.Playback_tSamples.ValueDisplayFormat = '%.0f';
            app.Playback_tSamples.ValueChangedFcn = createCallbackFcn(app, @Playback_occValueChanged, true);
            app.Playback_tSamples.Enable = 'off';
            app.Playback_tSamplesLabel = uilabel(app.Playback_occGrid2);
            app.Playback_tSamplesLabel.Enable = 'off';
            app.Playback_tSamplesLabel.Text = {'Amostras '; 'descartadas (%):'};
            app.Playback_peakLabel0_2 = uilabel(app.Playback_Grid2);
            app.Playback_peakLabel0_2.Text = 'Waterfall';
            app.Playback_peakLabel0_3 = uilabel(app.Playback_Grid2);
            app.Playback_peakLabel0_3.Text = 'Ocupação';
            app.Playback_Grid1 = uigridlayout(app.Playback_Grid0);
            app.Playback_traceLabel0 = uilabel(app.Playback_Grid1);
            app.Playback_traceLabel0.Text = 'Limites';
            app.Playback_peakRadioGroup = uibuttongroup(app.Playback_Grid1);
            app.Playback_peakRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @Playback_peakRadioGroupSelectionChanged, true);
            app.Playback_peakRadio2 = uiradiobutton(app.Playback_peakRadioGroup);
            app.Playback_peakRadio2.Text = 'Automática';
            app.Playback_peakRadio1 = uiradiobutton(app.Playback_peakRadioGroup);
            app.Playback_peakRadio1.Text = 'ROI';
            app.Playback_peakRadio1.Value = true;
            app.Playback_peakRadio3 = uiradiobutton(app.Playback_peakRadioGroup);
            app.Playback_peakRadio3.Text = 'DataTips';
            app.Playback_Panel2 = uipanel(app.Playback_Grid1);
            app.Playback_peakGrid = uigridlayout(app.Playback_Panel2);
            app.Playback_peakBWLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakBWLabel.Text = {'Largura mínima '; 'ocupada (kHz):'};
            app.Playback_peakDistanceLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakDistanceLabel.Text = {'Distância mínima '; 'entre picos (kHz):'};
            app.Playback_peakProeminenceLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakProeminenceLabel.Text = {'Proeminência '; 'mínima (dB):'};
            app.Playback_peakTHRLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakTHRLabel.Text = {'Threshold'; '(dBμV/m):'};
            app.Playback_peakBW = uispinner(app.Playback_peakGrid);
            app.Playback_peakBW.Step = 10;
            app.Playback_peakBW.Limits = [0 Inf];
            app.Playback_peakBW.RoundFractionalValues = 'on';
            app.Playback_peakBW.ValueDisplayFormat = '%.0f';
            app.Playback_peakBW.Value = 10;
            app.Playback_peakDistance = uispinner(app.Playback_peakGrid);
            app.Playback_peakDistance.Step = 25;
            app.Playback_peakDistance.Limits = [0 Inf];
            app.Playback_peakDistance.RoundFractionalValues = 'on';
            app.Playback_peakDistance.ValueDisplayFormat = '%.0f';
            app.Playback_peakDistance.Value = 25;
            app.Playback_peakProeminence = uispinner(app.Playback_peakGrid);
            app.Playback_peakProeminence.Step = 10;
            app.Playback_peakProeminence.Limits = [1 Inf];
            app.Playback_peakProeminence.RoundFractionalValues = 'on';
            app.Playback_peakProeminence.ValueDisplayFormat = '%.0f';
            app.Playback_peakProeminence.Value = 30;
            app.Playback_peakTHR = uispinner(app.Playback_peakGrid);
            app.Playback_peakTHR.Step = 10;
            app.Playback_peakTHR.RoundFractionalValues = 'on';
            app.Playback_peakTHR.ValueDisplayFormat = '%.0f';
            app.Playback_peakTHR.Value = -Inf;
            app.Playback_peakNumbers = uispinner(app.Playback_peakGrid);
            app.Playback_peakNumbers.Limits = [1 100];
            app.Playback_peakNumbers.RoundFractionalValues = 'on';
            app.Playback_peakNumbers.ValueDisplayFormat = '%.0f';
            app.Playback_peakNumbers.Value = 10;
            app.Playback_peakNumbersLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakNumbersLabel.Text = 'Número de picos:';
            app.Playback_peakTraceLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakTraceLabel.Text = 'Tipo de traço:';
            app.Playback_peakTrace = uidropdown(app.Playback_peakGrid);
            app.Playback_peakTrace.Items = {'Mediana', 'Média', 'MaxHold'};
            app.Playback_peakTrace.Value = 'Média';
            app.Playback_addMarker = uiimage(app.Playback_Grid1);
            app.Playback_addMarker.ImageClickedFcn = createCallbackFcn(app, @Playback_peakAddPushed, true);
            app.Playback_addMarker.Enable = 'off';
            app.Playback_addMarker.ImageSource = 'General_Add.png';
            app.Playback_peakTree = uitree(app.Playback_Grid1);
            app.Playback_peakTree.Multiselect = 'on';
            app.Playback_peakTree.SelectionChangedFcn = createCallbackFcn(app, @Playback_peakTreeSelectionChanged, true);
            app.Playback_Panel1_2 = uipanel(app.Playback_Grid1);
            app.Playback_traceGrid_2 = uigridlayout(app.Playback_Panel1_2);
            app.Playback_xLim1 = uispinner(app.Playback_traceGrid_2);
            app.Playback_xLim1.Limits = [0 Inf];
            app.Playback_xLim1.ValueDisplayFormat = '%.3f';
            app.Playback_xLim1.ValueChangedFcn = createCallbackFcn(app, @Playback_xLimitsValueChanged, true);
            app.Playback_xLim1.Tag = 'FreqStart';
            app.Playback_xLim1.Enable = 'off';
            app.Playback_xLim1.Tooltip = {''};
            app.xSpinnerLabel = uilabel(app.Playback_traceGrid_2);
            app.xSpinnerLabel.Enable = 'off';
            app.xSpinnerLabel.Text = 'x';
            app.Playback_xLim2 = uispinner(app.Playback_traceGrid_2);
            app.Playback_xLim2.Limits = [0 Inf];
            app.Playback_xLim2.ValueDisplayFormat = '%.3f';
            app.Playback_xLim2.ValueChangedFcn = createCallbackFcn(app, @Playback_xLimitsValueChanged, true);
            app.Playback_xLim2.Tag = 'FreqStop';
            app.Playback_xLim2.Enable = 'off';
            app.Playback_xLim2.Tooltip = {''};
            app.ySpinnerLabel = uilabel(app.Playback_traceGrid_2);
            app.ySpinnerLabel.Enable = 'off';
            app.ySpinnerLabel.Text = 'y';
            app.Playback_yLim1 = uispinner(app.Playback_traceGrid_2);
            app.Playback_yLim1.Step = 5;
            app.Playback_yLim1.ValueDisplayFormat = '%.1f';
            app.Playback_yLim1.ValueChangedFcn = createCallbackFcn(app, @Playback_yLimitsValueChanged, true);
            app.Playback_yLim1.Tag = 'MinLevel';
            app.Playback_yLim1.Enable = 'off';
            app.Playback_yLim1.Tooltip = {''};
            app.Playback_yLim2 = uispinner(app.Playback_traceGrid_2);
            app.Playback_yLim2.Step = 5;
            app.Playback_yLim2.ValueDisplayFormat = '%.1f';
            app.Playback_yLim2.ValueChangedFcn = createCallbackFcn(app, @Playback_yLimitsValueChanged, true);
            app.Playback_yLim2.Tag = 'MaxLevel';
            app.Playback_yLim2.Enable = 'off';
            app.Playback_yLim2.Tooltip = {''};
            app.Playback_peakLabel0 = uilabel(app.Playback_Grid1);
            app.Playback_peakLabel0.Text = 'Identificação de emissões';
            app.PeakCF = uieditfield(app.Playback_Grid1, 'numeric');
            app.PeakCF.ValueDisplayFormat = '%.3f';
            app.PeakCF.ValueChangedFcn = createCallbackFcn(app, @Playback_peakValueChanged, true);
            app.PeakCF.Enable = 'off';
            app.PeakCFLabel = uilabel(app.Playback_Grid1);
            app.PeakCFLabel.Enable = 'off';
            app.PeakCFLabel.Text = 'Frequência central (MHz):';
            app.PeakBW = uieditfield(app.Playback_Grid1, 'numeric');
            app.PeakBW.ValueDisplayFormat = '%.3f';
            app.PeakBW.ValueChangedFcn = createCallbackFcn(app, @Playback_peakValueChanged, true);
            app.PeakBW.Enable = 'off';
            app.PeakBWLabel = uilabel(app.Playback_Grid1);
            app.PeakBWLabel.Enable = 'off';
            app.PeakBWLabel.Text = 'Largura banda (kHz):';
            app.Playback_mainLabel = uilabel(app.Grid3_Playback);
            app.Playback_mainLabel.Text = 'PLAYBACK';
            app.Playback_Button5 = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Button5.ButtonPushedFcn = createCallbackFcn(app, @Playback_ButtonPlayPushed, true);
            app.Playback_Button5.Icon = 'White_Play.png';
            app.Playback_Button5.Enable = 'off';
            app.Playback_Button5.Text = 'Iniciar escrita';
            app.Playback_Button4 = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Button4.ButtonPushedFcn = createCallbackFcn(app, @Playback_Button4Pushed, true);
            app.Playback_Button4.Icon = 'Playback_Icon4B.png';
            app.Playback_Button4.Enable = 'off';
            app.Playback_Button4.Tooltip = {'Loop playback'};
            app.Playback_Button4.Text = '';
            app.Playback_Button1 = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Button1.ButtonPushedFcn = createCallbackFcn(app, @Playback_Button1Pushed, true);
            app.Playback_Button1.Icon = 'Playback_Icon1.png';
            app.Playback_Button1.Tooltip = {'Visualização padrão'};
            app.Playback_Button1.Text = '';
            app.Playback_Separator2_3 = uiimage(app.Grid3_Playback);
            app.Playback_Separator2_3.ImageSource = 'Line.png';
            app.Playback_Button1_2 = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Button1_2.ButtonPushedFcn = createCallbackFcn(app, @Playback_Button1_2Pushed, true);
            app.Playback_Button1_2.Icon = 'Pan.png';
            app.Playback_Button1_2.Tooltip = {'Interação pan'};
            app.Playback_Button1_2.Text = '';
            app.Playback_Layout = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Layout.ButtonPushedFcn = createCallbackFcn(app, @LayoutEspectroOCCWaterfallDropDownValueChanged, true);
            app.Playback_Layout.Icon = 'newPlot_Factor1.png';
            app.Playback_Layout.Tooltip = {'Layout dos eixos'};
            app.Playback_Layout.Text = '';
            app.Playback_Fcn = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Fcn.ButtonPushedFcn = createCallbackFcn(app, @Playback_FcnButtonPushed, true);
            app.Playback_Fcn.Icon = '102533.png';
            app.Playback_Fcn.Tooltip = {'Média'};
            app.Playback_Fcn.Text = '';
            app.Playback_traceCheckBox2 = uibutton(app.Grid3_Playback, 'state');
            app.Playback_traceCheckBox2.ValueChangedFcn = createCallbackFcn(app, @Playback_traceCheckBox2ValueChanged, true);
            app.Playback_traceCheckBox2.Tooltip = {'MinHold'};
            app.Playback_traceCheckBox2.Icon = '1200px-BellcurveMinHold.png';
            app.Playback_traceCheckBox2.Text = '';
            app.Playback_traceCheckBox1 = uibutton(app.Grid3_Playback, 'state');
            app.Playback_traceCheckBox1.ValueChangedFcn = createCallbackFcn(app, @Playback_traceCheckBox1ValueChanged, true);
            app.Playback_traceCheckBox1.Tooltip = {'Tendência central'};
            app.Playback_traceCheckBox1.Icon = '1200px-BellcurveMean.png';
            app.Playback_traceCheckBox1.Text = '';
            app.Playback_traceCheckBox3 = uibutton(app.Grid3_Playback, 'state');
            app.Playback_traceCheckBox3.ValueChangedFcn = createCallbackFcn(app, @Playback_traceCheckBox3ValueChanged, true);
            app.Playback_traceCheckBox3.Tooltip = {'MaxHold'};
            app.Playback_traceCheckBox3.Icon = '1200px-Bellcurve3.png';
            app.Playback_traceCheckBox3.Text = '';
            app.Playback_wfCheckBox = uibutton(app.Grid3_Playback, 'state');
            app.Playback_wfCheckBox.ValueChangedFcn = createCallbackFcn(app, @Playback_wfCheckBoxValueChanged, true);
            app.Playback_wfCheckBox.Tooltip = {'Waterfall'};
            app.Playback_wfCheckBox.Icon = 'Waterfall7.png';
            app.Playback_wfCheckBox.Text = '';
            app.Playback_occCheckBox = uibutton(app.Grid3_Playback, 'state');
            app.Playback_occCheckBox.ValueChangedFcn = createCallbackFcn(app, @Playback_occCheckBoxValueChanged, true);
            app.Playback_occCheckBox.Tooltip = {'Waterfall'};
            app.Playback_occCheckBox.Icon = 'occ4.png';
            app.Playback_occCheckBox.Text = '';
            app.Playback_Separator2_6 = uiimage(app.Grid3_Playback);
            app.Playback_Separator2_6.ImageSource = 'Line.png';
            app.Playback_Separator2_4 = uiimage(app.Grid3_Playback);
            app.Playback_Separator2_4.ImageSource = 'Line.png';
            app.mainInfo_Grid = uigridlayout(app.GridLayout5);
            app.mainInfo_Expand = uiimage(app.mainInfo_Grid);
            app.mainInfo_Expand.ImageClickedFcn = createCallbackFcn(app, @mainInfo_ExpandCollapseTreeNodes, true);
            app.mainInfo_Expand.ImageSource = 'General_Expand.png';
            app.mainInfo_Label2 = uilabel(app.mainInfo_Grid);
            app.mainInfo_Label2.Text = '0 alerta(s)';
            app.mainInfo_Warning = uiimage(app.mainInfo_Grid);
            app.mainInfo_Warning.Visible = 'off';
            app.mainInfo_Warning.Tooltip = {'Feita alteração na relação de arquivos ou fluxo(s) de dados'};
            app.mainInfo_Warning.ImageSource = 'Warn_Red.png';
            app.mainInfo_Label1 = uilabel(app.mainInfo_Grid);
            app.mainInfo_Label1.Text = 'FLUXO(S) DE DADOS';
            app.mainInfo_DeleteAll = uibutton(app.mainInfo_Grid, 'push');
            app.mainInfo_DeleteAll.ButtonPushedFcn = createCallbackFcn(app, @mainInfo_DeleteAllButtonPushed, true);
            app.mainInfo_DeleteAll.Icon = 'General_DeleteAll.png';
            app.mainInfo_DeleteAll.Tooltip = {'Excluir specData, lista de exceções etc'};
            app.mainInfo_DeleteAll.Text = '';
            app.mainInfo_Tree = uitree(app.mainInfo_Grid);
            app.mainInfo_Tree.SelectionChangedFcn = createCallbackFcn(app, @mainInfo_TreeSelectionChanged, true);
            app.mainInfo_DeleteAll_2 = uibutton(app.mainInfo_Grid, 'push');
            app.mainInfo_DeleteAll_2.ButtonPushedFcn = createCallbackFcn(app, @mainInfo_DeleteAuxiliarWin_ButtonPushed, true);
            app.mainInfo_DeleteAll_2.Icon = 'Report_Icon3.png';
            app.mainInfo_DeleteAll_2.Tooltip = {'Excluir janelas auxiliares'};
            app.mainInfo_DeleteAll_2.Text = '';
            app.Button_Mask = uibutton(app.mainInfo_Grid, 'push');
            app.Button_Mask.ButtonPushedFcn = createCallbackFcn(app, @Button_MaskValueChanged, true);
            app.Button_Mask.Icon = 'Playback_Icon3.png';
            app.Button_Mask.Enable = 'off';
            app.Button_Mask.Tooltip = {'Editar máscara espectral'};
            app.Button_Mask.Text = '';
            app.Button_DriveTest = uibutton(app.mainInfo_Grid, 'push');
            app.Button_DriveTest.ButtonPushedFcn = createCallbackFcn(app, @Button_DriveTestPushed, true);
            app.Button_DriveTest.Icon = 'heatmap.PNG';
            app.Button_DriveTest.Enable = 'off';
            app.Button_DriveTest.Tooltip = {'Drive-test'};
            app.Button_DriveTest.Text = '';
            app.mainInfo_TextLabel = uilabel(app.mainInfo_Grid);
            app.mainInfo_TextLabel.Text = 'Metadados:';
            app.mainInfo_Text = uitextarea(app.mainInfo_Grid);
            app.mainInfo_Text.Editable = 'off';
            app.Report_EditPlots = uibutton(app.mainInfo_Grid, 'state');
            app.Report_EditPlots.ValueChangedFcn = createCallbackFcn(app, @Report_EditPlotsValueChanged, true);
            app.Report_EditPlots.Tooltip = {'Edições avançadas (Matlab)'};
            app.Report_EditPlots.Icon = 'Report_Icon4.png';
            app.Report_EditPlots.Text = '';
            app.Playback_Separator2_2 = uiimage(app.mainInfo_Grid);
            app.Playback_Separator2_2.ImageSource = 'Line.png';
            app.Playback_Separator2_5 = uiimage(app.mainInfo_Grid);
            app.Playback_Separator2_5.ImageSource = 'Line.png';
            app.Tab4_Report = uitab(app.TabGroup);
            app.GridLayout6 = uigridlayout(app.Tab4_Report);
            app.Grid3_Report = uigridlayout(app.GridLayout6);
            app.Report_playButton = uibutton(app.Grid3_Report, 'push');
            app.Report_playButton.ButtonPushedFcn = createCallbackFcn(app, @Report_playButtonPushed, true);
            app.Report_playButton.Icon = 'White_Play.png';
            app.Report_playButton.Enable = 'off';
            app.Report_playButton.Text = 'Gerar relatório';
            app.Report_previewButton = uibutton(app.Grid3_Report, 'push');
            app.Report_previewButton.ButtonPushedFcn = createCallbackFcn(app, @Report_playButtonPushed, true);
            app.Report_previewButton.Icon = 'White_Spectrum.png';
            app.Report_previewButton.Enable = 'off';
            app.Report_previewButton.Text = 'Gerar prévia';
            app.Report_Panel0 = uipanel(app.Grid3_Report);
            app.Report_Grid0 = uigridlayout(app.Report_Panel0);
            app.Report_Grid1 = uigridlayout(app.Report_Grid0);
            app.Report_Tree = uitree(app.Report_Grid1, 'checkbox');
            app.Report_Tree.SelectionChangedFcn = createCallbackFcn(app, @Report_TreeSelectionChanged, true);
            app.GridLayout8 = uigridlayout(app.Report_Grid1);
            app.Report_ImageWarn = uiimage(app.GridLayout8);
            app.Report_ImageWarn.Tooltip = {'Pendente salvar o projeto'};
            app.Report_ImageWarn.ImageSource = 'Warn_Red.png';
            app.Report_Sublabel1 = uilabel(app.GridLayout8);
            app.Report_Sublabel1.Text = 'Aspectos gerais';
            app.Report_Sublabel4 = uilabel(app.Report_Grid1);
            app.Report_Sublabel4.Text = 'Período de observação a processar';
            app.Panel_9 = uipanel(app.Report_Grid1);
            app.Report_Grid4 = uigridlayout(app.Panel_9);
            app.Report_DatePicker1 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.Report_DatePicker1.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner1 = uispinner(app.Report_Grid4);
            app.Report_Spinner1.Limits = [0 23];
            app.Report_Spinner1.RoundFractionalValues = 'on';
            app.Report_Spinner1.ValueDisplayFormat = '%.0f';
            app.Report_Spinner1.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner2 = uispinner(app.Report_Grid4);
            app.Report_Spinner2.Step = 10;
            app.Report_Spinner2.Limits = [0 59];
            app.Report_Spinner2.RoundFractionalValues = 'on';
            app.Report_Spinner2.ValueDisplayFormat = '%.0f';
            app.Report_Spinner2.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_DatePicker2 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.Report_DatePicker2.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner3 = uispinner(app.Report_Grid4);
            app.Report_Spinner3.Limits = [0 23];
            app.Report_Spinner3.RoundFractionalValues = 'on';
            app.Report_Spinner3.ValueDisplayFormat = '%.0f';
            app.Report_Spinner3.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4 = uispinner(app.Report_Grid4);
            app.Report_Spinner4.Step = 10;
            app.Report_Spinner4.Limits = [0 59];
            app.Report_Spinner4.RoundFractionalValues = 'on';
            app.Report_Spinner4.ValueDisplayFormat = '%.0f';
            app.Report_Spinner4.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner4.Value = 59;
            app.Panel_10 = uipanel(app.Report_Grid1);
            app.GridLayout7 = uigridlayout(app.Panel_10);
            app.ProjectName = uieditfield(app.GridLayout7, 'text');
            app.ProjectName.ValueChangedFcn = createCallbackFcn(app, @Report_ProjectNameValueChanged, true);
            app.ProjectName.Value = 'Projeto 001';
            app.ProjectNameLabel = uilabel(app.GridLayout7);
            app.ProjectNameLabel.Text = 'Projeto:';
            app.ProjectFilename = uieditfield(app.GridLayout7, 'text');
            app.ProjectFilename.Editable = 'off';
            app.Report_SaveProject = uibutton(app.GridLayout7, 'push');
            app.Report_SaveProject.ButtonPushedFcn = createCallbackFcn(app, @Report_SaveProjectValueChanged, true);
            app.Report_SaveProject.Icon = 'Report_Icon2.png';
            app.Report_SaveProject.Tooltip = {'Salvar projeto'};
            app.Report_SaveProject.Text = '';
            app.Panel_11 = uipanel(app.GridLayout7);
            app.GridLayout21 = uigridlayout(app.Panel_11);
            app.Report_Type = uidropdown(app.GridLayout21);
            app.Report_Type.Items = {};
            app.Report_Type.Value = {};
            app.Report_TypeLabel = uilabel(app.GridLayout21);
            app.Report_TypeLabel.Text = 'Modelo:';
            app.Report_Version = uidropdown(app.GridLayout21);
            app.Report_Version.Items = {'Preliminar', 'Definitiva'};
            app.Report_Version.Value = 'Preliminar';
            app.Report_VersionLabel = uilabel(app.GridLayout21);
            app.Report_VersionLabel.Text = 'Versão:';
            app.RelatrioLabel = uilabel(app.GridLayout7);
            app.RelatrioLabel.Text = 'Relatório:';
            app.Report_IssueLabel = uilabel(app.GridLayout7);
            app.Report_IssueLabel.Text = 'Inspeção:';
            app.Report_Issue = uieditfield(app.GridLayout7, 'numeric');
            app.Report_Issue.Limits = [-1 Inf];
            app.Report_Issue.RoundFractionalValues = 'on';
            app.Report_Issue.ValueDisplayFormat = '%d';
            app.Report_Issue.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Issue.Value = -1;
            app.GridLayout22 = uigridlayout(app.Report_Grid1);
            app.Report_Sublabel3 = uilabel(app.GridLayout22);
            app.Report_Sublabel3.Text = 'Fluxo(s) de dados a processar';
            app.Report_addButton = uiimage(app.GridLayout22);
            app.Report_addButton.ImageClickedFcn = createCallbackFcn(app, @Report_addButtonPushed, true);
            app.Report_addButton.Enable = 'off';
            app.Report_addButton.Tooltip = {''};
            app.Report_addButton.ImageSource = 'General_Add.png';
            app.GridLayout17 = uigridlayout(app.Report_Grid0);
            app.Report_Sublabel5_2 = uilabel(app.GridLayout17);
            app.Report_Sublabel5_2.Text = 'Ocupação';
            app.Panel_6 = uipanel(app.GridLayout17);
            app.GridLayout18 = uigridlayout(app.Panel_6);
            app.Report_occMethodLabel = uilabel(app.GridLayout18);
            app.Report_occMethodLabel.Text = 'Método:';
            app.Report_occMethod = uieditfield(app.GridLayout18, 'text');
            app.Report_occMethod.Editable = 'off';
            app.Report_occButton = uibutton(app.GridLayout18, 'push');
            app.Report_occButton.Icon = 'mainMenu_Edit.png';
            app.Report_occButton.Enable = 'off';
            app.Report_occButton.Tooltip = {''};
            app.Report_occButton.Text = '';
            app.Report_occParameters = uitextarea(app.GridLayout18);
            app.Report_occParameters.Editable = 'off';
            app.Panel_7 = uipanel(app.GridLayout17);
            app.GridLayout16 = uigridlayout(app.Panel_7);
            app.Report_BandPanel = uipanel(app.GridLayout16);
            app.Report_BandGrid = uigridlayout(app.Report_BandPanel);
            app.Report_BandLim1 = uispinner(app.Report_BandGrid);
            app.Report_BandLim1.Step = 0.001;
            app.Report_BandLim1.Limits = [-1 -0.999];
            app.Report_BandLim1.ValueDisplayFormat = '%.3f';
            app.Report_BandLim1.ValueChangedFcn = createCallbackFcn(app, @Report_BandLimValueChanged, true);
            app.Report_BandLim1.Tag = 'FreqStart';
            app.Report_BandLim1.Enable = 'off';
            app.Report_BandLim1.Tooltip = {''};
            app.Report_BandLim1.Value = -1;
            app.Report_BandSliderLabel = uilabel(app.Report_BandGrid);
            app.Report_BandSliderLabel.Enable = 'off';
            app.Report_BandSliderLabel.Text = 'x';
            app.Report_BandLim2 = uispinner(app.Report_BandGrid);
            app.Report_BandLim2.Step = 0.001;
            app.Report_BandLim2.Limits = [-1 -0.999];
            app.Report_BandLim2.ValueDisplayFormat = '%.3f';
            app.Report_BandLim2.ValueChangedFcn = createCallbackFcn(app, @Report_BandLimValueChanged, true);
            app.Report_BandLim2.Tag = 'FreqStop';
            app.Report_BandLim2.Enable = 'off';
            app.Report_BandLim2.Tooltip = {''};
            app.Report_BandLim2.Value = -1;
            app.Report_DetectionParameters = uitextarea(app.GridLayout16);
            app.Report_DetectionParameters.Editable = 'off';
            app.Report_PlaybackOnly = uicheckbox(app.GridLayout16);
            app.Report_PlaybackOnly.ValueChangedFcn = createCallbackFcn(app, @Report_Report_PlaybackOnlyValueChanged, true);
            app.Report_PlaybackOnly.Enable = 'off';
            app.Report_PlaybackOnly.Text = 'Restringir emissões às identificadas no modo PLAYBACK.';
            app.Report_BandPanelLabel = uilabel(app.GridLayout16);
            app.Report_BandPanelLabel.Text = 'Faixa sob análise:';
            app.Report_DetectionLabel = uilabel(app.GridLayout16);
            app.Report_DetectionLabel.Text = 'Algoritmo:';
            app.Report_Detection = uieditfield(app.GridLayout16, 'text');
            app.Report_Detection.Editable = 'off';
            app.Report_DetectionButton = uibutton(app.GridLayout16, 'push');
            app.Report_DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @Report_DetectionButtonPushed, true);
            app.Report_DetectionButton.Icon = 'mainMenu_Edit.png';
            app.Report_DetectionButton.Enable = 'off';
            app.Report_DetectionButton.Tooltip = {''};
            app.Report_DetectionButton.Text = '';
            app.Report_Sublabel5 = uilabel(app.GridLayout17);
            app.Report_Sublabel5.Text = 'Detecção de emissões';
            app.Panel_8 = uipanel(app.GridLayout17);
            app.GridLayout20 = uigridlayout(app.Panel_8);
            app.Report_ClassificationLabel = uilabel(app.GridLayout20);
            app.Report_ClassificationLabel.Text = 'Algoritmo:';
            app.Report_Classification = uieditfield(app.GridLayout20, 'text');
            app.Report_Classification.Editable = 'off';
            app.Report_ClassificationButton = uibutton(app.GridLayout20, 'push');
            app.Report_ClassificationButton.Icon = 'mainMenu_Edit.png';
            app.Report_ClassificationButton.Enable = 'off';
            app.Report_ClassificationButton.Tooltip = {''};
            app.Report_ClassificationButton.Text = '';
            app.Report_ClassificationParameters = uitextarea(app.GridLayout20);
            app.Report_ClassificationParameters.Editable = 'off';
            app.Report_Sublabel6 = uilabel(app.GridLayout17);
            app.Report_Sublabel6.Text = 'Classificação de emissões';
            app.Report_Label0 = uilabel(app.Grid3_Report);
            app.Report_Label0.Text = 'RELATÓRIO';
            app.Report_Separator1 = uiimage(app.Grid3_Report);
            app.Report_Separator1.ImageSource = 'Line.png';
            app.Report_NewProject = uibutton(app.Grid3_Report, 'push');
            app.Report_NewProject.ButtonPushedFcn = createCallbackFcn(app, @Report_NewProjectButtonPushed, true);
            app.Report_NewProject.Icon = 'Report_Icon1.png';
            app.Report_NewProject.Tooltip = {'Novo projeto'};
            app.Report_NewProject.Text = '';
            app.Report_tableView = uibutton(app.Grid3_Report, 'push');
            app.Report_tableView.ButtonPushedFcn = createCallbackFcn(app, @Report_tableViewValueChanged, true);
            app.Report_tableView.Icon = 'Report_Icon5.png';
            app.Report_tableView.Tooltip = {'Análise de emissões'};
            app.Report_tableView.Text = '';
            app.Tab5_Edit = uitab(app.TabGroup);
            app.Edit_Grid1 = uigridlayout(app.Tab5_Edit);
            app.Grid3_Info = uigridlayout(app.Edit_Grid1);
            app.Info_Panel2 = uipanel(app.Grid3_Info);
            app.Info_Grid3 = uigridlayout(app.Info_Panel2);
            app.Info_Label1 = uilabel(app.Info_Grid3);
            app.Info_Label1.Text = 'GPS';
            app.Info_Label2 = uilabel(app.Info_Grid3);
            app.Info_Label2.Text = 'Edição do status do local da monitoração registrado no(s) arquivo(s) relacionado(s) ao fluxo selecionado';
            app.Info_Panel2B = uipanel(app.Info_Grid3);
            app.Info_Grid2_2 = uigridlayout(app.Info_Panel2B);
            app.Info_CheckBox = uicheckbox(app.Info_Grid2_2);
            app.Info_CheckBox.Text = 'Transportar esses valores para outros fluxos de dados do instrumento, caso existentes.';
            app.Info_CityLabel = uilabel(app.Info_Grid2_2);
            app.Info_CityLabel.Text = 'Localidade:';
            app.Info_City = uieditfield(app.Info_Grid2_2, 'text');
            app.Info_City.ValueChangedFcn = createCallbackFcn(app, @Info_CityValueChanged, true);
            app.Info_Latitude = uieditfield(app.Info_Grid2_2, 'numeric');
            app.Info_Latitude.Limits = [-90 90];
            app.Info_Latitude.ValueDisplayFormat = '%.6f';
            app.Info_Latitude.ValueChangedFcn = createCallbackFcn(app, @Info_LongitudeValueChanged, true);
            app.Info_Latitude.Value = -1;
            app.Info_LatitudeLabel = uilabel(app.Info_Grid2_2);
            app.Info_LatitudeLabel.Text = 'Latitude:';
            app.Info_LongitudeLabel = uilabel(app.Info_Grid2_2);
            app.Info_LongitudeLabel.Text = 'Longitude:';
            app.Info_Longitude = uieditfield(app.Info_Grid2_2, 'numeric');
            app.Info_Longitude.Limits = [-180 180];
            app.Info_Longitude.ValueDisplayFormat = '%.6f';
            app.Info_Longitude.ValueChangedFcn = createCallbackFcn(app, @Info_LongitudeValueChanged, true);
            app.Info_Longitude.Value = -1;
            app.GridLayout14 = uigridlayout(app.Info_Grid3);
            app.Info_Table = uitable(app.GridLayout14);
            app.Info_Table.ColumnName = {''; ''; ''; ''; ''; ''};
            app.Info_Table.RowName = {};
            app.Info_Table.ColumnEditable = [false false true false false false];
            app.Info_Table.CellEditCallback = createCallbackFcn(app, @Info_TableCellEdit, true);
            app.Info_Column1 = uilabel(app.GridLayout14);
            app.Info_Column1.Text = {'  #'; '  '};
            app.Info_Column2 = uilabel(app.GridLayout14);
            app.Info_Column2.Text = {'  Arquivo'; '  '};
            app.Info_Column3 = uilabel(app.GridLayout14);
            app.Info_Column3.Text = {'  Status *'; '  '};
            app.Info_Column4 = uilabel(app.GridLayout14);
            app.Info_Column4.Text = {'  Latitude'; '  '; ''};
            app.Info_Column5 = uilabel(app.GridLayout14);
            app.Info_Column5.Text = {'  Longitude'; '  '};
            app.Info_Column6 = uilabel(app.GridLayout14);
            app.Info_Column6.Text = {'  Fator'; '  Ponderação'};
            app.Info_Label2_2 = uilabel(app.Info_Grid3);
            app.Info_Label2_2.Text = 'Edição manual do local da monitoração';
            app.OcupaoLabel = uilabel(app.Info_Grid3);
            app.OcupaoLabel.Text = 'Ocupação';
            app.Info_OCC = uidropdown(app.Info_Grid3);
            app.Info_OCC.Items = {'Ocupação a ser aferida'};
            app.Info_OCC.ValueChangedFcn = createCallbackFcn(app, @Info_OCCValueChanged, true);
            app.Info_OCC.Value = 'Ocupação a ser aferida';
            app.Info_MeanValues = uilabel(app.Info_Grid3);
            app.Info_MeanValues.Text = {'Latitude: -12.123456'; 'Longitude: -38.102020'};
            app.Info_TableStatusInfo = uilabel(app.Info_Grid3);
            app.Info_TableStatusInfo.Text = '* Manual (-1), automática inválida (0) ou válida (1).';
            app.Info_Label1_3 = uilabel(app.Info_Grid3);
            app.Info_Label1_3.Text = 'Conversão da unidade de medida: dBm/dBμV → dBμV/m';
            app.AntenaDropDown = uidropdown(app.Info_Grid3);
            app.AntenaDropDown.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (Argus)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (690 MHz - 6 GHz)'};
            app.AntenaDropDown.ValueChangedFcn = createCallbackFcn(app, @Info_OCCValueChanged, true);
            app.AntenaDropDown.Value = '';
            app.AntenaDropDownLabel = uilabel(app.Info_Grid3);
            app.AntenaDropDownLabel.Text = 'Antena:';
            app.Info_Button = uibutton(app.Grid3_Info, 'push');
            app.Info_Button.ButtonPushedFcn = createCallbackFcn(app, @Info_ButtonPushed, true);
            app.Info_Button.Icon = 'White_Edit.png';
            app.Info_Button.Text = 'Confirma edição';
            app.Info_Label0 = uilabel(app.Grid3_Info);
            app.Info_Label0.Text = 'EDITAR FLUXO(S) DE DADOS';
            app.Info_RefreshGPS = uibutton(app.Grid3_Info, 'push');
            app.Info_RefreshGPS.ButtonPushedFcn = createCallbackFcn(app, @Info_RefreshGPSButtonPushed, true);
            app.Info_RefreshGPS.Icon = 'General_Refresh.png';
            app.Info_RefreshGPS.Enable = 'off';
            app.Info_RefreshGPS.Tooltip = {'GPS automático'};
            app.Info_RefreshGPS.Text = '';
            app.Tab6_Save = uitab(app.TabGroup);
            app.Save_Grid1 = uigridlayout(app.Tab6_Save);
            app.Save_Grid2 = uigridlayout(app.Save_Grid1);
            app.Save_Panel1 = uipanel(app.Save_Grid2);
            app.Save_Grid3 = uigridlayout(app.Save_Panel1);
            app.Save_Label2 = uilabel(app.Save_Grid3);
            app.Save_Label2.Text = 'Informações gerais';
            app.Save_ObservationTime = uicheckbox(app.Save_Grid3);
            app.Save_ObservationTime.ValueChangedFcn = createCallbackFcn(app, @Save_ObservationTimeValueChanged, true);
            app.Save_ObservationTime.Text = 'Período de observação';
            app.Save_Panel3 = uipanel(app.Save_Grid3);
            app.Save_Grid5 = uigridlayout(app.Save_Panel3);
            app.Save_DatePicker1 = uidatepicker(app.Save_Grid5);
            app.Save_DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.Save_DatePicker1.Enable = 'off';
            app.Save_Spinner1 = uispinner(app.Save_Grid5);
            app.Save_Spinner1.Limits = [0 23];
            app.Save_Spinner1.RoundFractionalValues = 'on';
            app.Save_Spinner1.ValueDisplayFormat = '%.0f';
            app.Save_Spinner1.Enable = 'off';
            app.Save_Spinner2 = uispinner(app.Save_Grid5);
            app.Save_Spinner2.Step = 10;
            app.Save_Spinner2.Limits = [0 59];
            app.Save_Spinner2.RoundFractionalValues = 'on';
            app.Save_Spinner2.ValueDisplayFormat = '%.0f';
            app.Save_Spinner2.Enable = 'off';
            app.Save_DatePicker2 = uidatepicker(app.Save_Grid5);
            app.Save_DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.Save_DatePicker2.Enable = 'off';
            app.Save_Spinner3 = uispinner(app.Save_Grid5);
            app.Save_Spinner3.Limits = [0 23];
            app.Save_Spinner3.RoundFractionalValues = 'on';
            app.Save_Spinner3.ValueDisplayFormat = '%.0f';
            app.Save_Spinner3.Enable = 'off';
            app.Save_Spinner3.Value = 23;
            app.Save_Spinner4 = uispinner(app.Save_Grid5);
            app.Save_Spinner4.Step = 10;
            app.Save_Spinner4.Limits = [0 59];
            app.Save_Spinner4.RoundFractionalValues = 'on';
            app.Save_Spinner4.ValueDisplayFormat = '%.0f';
            app.Save_Spinner4.Enable = 'off';
            app.Save_Spinner4.Value = 59;
            app.Save_Note = uilabel(app.Save_Grid3);
            app.Save_Note.Text = {'MAT v.7.3 (HDF5)'; '• É escrito arquivo binário da v.7.3 de formato proprietário do Matlab, contendo as variáveis ''Type'' ("Spectral data" ou "Project data"), ''prj_Version'' (1), ''prj_Source'' ("appAnalise"), ''RelatedFiles'' (arquivos nos formatos RF Look Bin, CRFS Bin, Argus CSV ou SM1809 que armazenavam a informação), ''metaData'', ''specData'' e ''Info''.'; ''; ''; 'CRFS Bin v.5'; '• É escrito,  a partir da variável ''specData'', arquivo binário da v.5 de formato proprietário da CRFS, formando os blocos dos tipos 21 (Informações gerais), 40 (GPS), 67 (Espectro) e 69 (Ocupação).'; ''; '• Limitação: Um arquivo nesse formato aceita dados gerados apenas por um único sensor RFeye.'; ''; ''; 'SM1809 (UIT)'; '• É escrito,  a partir da variável ''specData'', arquivo textual definido na Recomendação ITU-R SM.1809, publicada em 2007, sendo inclusos no seu cabeçalho todos os campos obrigatórios (FileType, LocationName, Latitude, Longitude, FreqStart, FreqStop, AntennaType, FilterBandwidth, LevelUnits, Date, DataPoints, ScanTime e Detector) e um dos opcionais citados na recomendação (MultiScan). Foram inseridos outros campos opcionais (Node, ThreadID, TraceMode, Samples, TaskName, Description e ObservationTime) que possibilitam a reconstrução da variável ''specData''. Quando se tratar de um arquivo que registra uma monitoração multifaixa, alguns dos parâmetros do seu cabeçalho apresentam valores específicos para cada faixa (FreqStart, FreqStop, FilterBandwidth, LevelUnits, DataPoints, ScanTime, Detector, TraceMode, ThreadID e Description).'; ''; '• Limitações: Um arquivo nesse formato aceita dados gerados apenas por um único sensor, além de não aceitar fluxos de ocupação. Por fim, o número de amostras constantes em arquivo que registra uma monitoração multifaixa será definido pelo menor número de amostras dos fluxos selecionados, sendo a referência temporal dada pelo primeiro dos fluxos.'};
            app.Save_Button = uibutton(app.Save_Grid2, 'push');
            app.Save_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_ButtonPushed, true);
            app.Save_Button.Icon = 'White_Save.png';
            app.Save_Button.Text = 'Salvar arquivo';
            app.Save_Label1 = uilabel(app.Save_Grid2);
            app.Save_Label1.Text = 'EXPORTAR FLUXO(S) DE DADOS';
            app.ToolBar_Panel = uipanel(app.Grid1);
            app.GridLayout = uigridlayout(app.ToolBar_Panel);
            app.ToolBar = uigridlayout(app.GridLayout);
            app.ToolBar_Button3 = uibutton(app.ToolBar, 'push');
            app.ToolBar_Button3.ButtonPushedFcn = createCallbackFcn(app, @ToolBar_Button3Pushed, true);
            app.ToolBar_Button3.Icon = 'Toolbar_Icon3.png';
            app.ToolBar_Button3.Tooltip = {'Informações gerais'};
            app.ToolBar_Button3.Text = '';
            app.ToolBar_Button2 = uibutton(app.ToolBar, 'push');
            app.ToolBar_Button2.ButtonPushedFcn = createCallbackFcn(app, @ToolBar_Button2Pushed, true);
            app.ToolBar_Button2.Icon = 'Toolbar_Icon2.png';
            app.ToolBar_Button2.Tooltip = {'Configurações'};
            app.ToolBar_Button2.Text = '';
            app.ToolBar_Button1 = uibutton(app.ToolBar, 'push');
            app.ToolBar_Button1.ButtonPushedFcn = createCallbackFcn(app, @ToolBar_Button1Pushed, true);
            app.ToolBar_Button1.Icon = 'Toolbar_Icon1.png';
            app.ToolBar_Button1.Tooltip = {'Visualização padrão'};
            app.ToolBar_Button1.Text = '';
            app.menu_Panel = uipanel(app.Grid1);
            app.GridLayout2 = uigridlayout(app.menu_Panel);
            app.MainMenu = uigridlayout(app.GridLayout2);
            app.menu_Grid2 = uigridlayout(app.MainMenu);
            app.menu_userPath = uidropdown(app.menu_Grid2);
            app.menu_userPath.Items = {'Path'};
            app.menu_userPath.ValueChangedFcn = createCallbackFcn(app, @menu_userPathValueChanged, true);
            app.menu_userPath.Value = 'Path';
            app.menu_searchPath = uiimage(app.menu_Grid2);
            app.menu_searchPath.ImageClickedFcn = createCallbackFcn(app, @General_searchPathImageClicked, true);
            app.menu_searchPath.ImageSource = 'Toolbar_Zoom.png';
            app.menu_Grid1 = uigridlayout(app.MainMenu);
            app.Button_Separator2 = uiimage(app.menu_Grid1);
            app.Button_Separator2.ImageSource = 'Line.png';
            app.Button_Playback = uibutton(app.menu_Grid1, 'state');
            app.Button_Playback.ValueChangedFcn = createCallbackFcn(app, @mainMenu_Playback, true);
            app.Button_Playback.Tag = 'PLAYBACK';
            app.Button_Playback.Enable = 'off';
            app.Button_Playback.Tooltip = {'Playback'};
            app.Button_Playback.Icon = '1200px-BellcurveMean_Edited2.png';
            app.Button_Playback.Text = '';
            app.Button_Report = uibutton(app.menu_Grid1, 'state');

            app.Button_Report.ValueChangedFcn = createCallbackFcn(app, @mainMenu_Report, true);
            app.Button_Report.Tag = 'REPORT';
            app.Button_Report.Enable = 'off';
            app.Button_Report.Tooltip = {'Relatório'};
            app.Button_Report.Icon = '186-1864026_free-icons-png-results-icon-png-black.png';
            app.Button_Report.Text = '';
            app.Button_SaveFile = uibutton(app.menu_Grid1, 'state');
            app.Button_SaveFile.ValueChangedFcn = createCallbackFcn(app, @mainMenu_SaveFile, true);
            app.Button_SaveFile.Tag = 'EXPORT';
            app.Button_SaveFile.Enable = 'off';
            app.Button_SaveFile.Tooltip = {'Exportar fluxo(s) de dados'};
            app.Button_SaveFile.Icon = 'mainMenu_Save.png';
            app.Button_SaveFile.Text = '';
            app.Button_Home = uibutton(app.menu_Grid1, 'state');
            app.Button_Home.ValueChangedFcn = createCallbackFcn(app, @mainMenu_Home, true);
            app.Button_Home.Tag = 'HOME';
            app.Button_Home.Tooltip = {''};
            app.Button_Home.Icon = 'mainMenu_Home.png';
            app.Button_Home.Text = '';
            app.Button_Home.Value = true;
            app.Button_File = uibutton(app.menu_Grid1, 'state');
            app.Button_File.ValueChangedFcn = createCallbackFcn(app, @mainMenu_File, true);
            app.Button_File.Tag = 'FILE';
            app.Button_File.Tooltip = {'Arquivo(s)'};
            app.Button_File.Icon = 'mainMenu_File.png';
            app.Button_File.Text = '';
            app.Button_Separator3_2 = uiimage(app.menu_Grid1);
            app.Button_Separator3_2.ImageSource = 'Line.png';
            app.Button_Separator3 = uiimage(app.menu_Grid1);
            app.Button_Separator3.ImageSource = 'Line.png';
            app.Button_EditInfo = uibutton(app.menu_Grid1, 'state');
            app.Button_EditInfo.ValueChangedFcn = createCallbackFcn(app, @mainMenu_EditInfo, true);
            app.Button_EditInfo.Tag = 'MISC';
            app.Button_EditInfo.Enable = 'off';
            app.Button_EditInfo.Tooltip = {'Editar fluxo(s) de dados'};
            app.Button_EditInfo.Icon = 'png-transparent-command-key-computer-icons-cmd-exe-apple-apple-text-number-fruit-nut-thumbnail.png';
            app.Button_EditInfo.Text = '';
            app.Report_Fiscaliza = uibutton(app.menu_Grid1, 'push');
            app.Report_Fiscaliza.ButtonPushedFcn = createCallbackFcn(app, @Report_FiscalizaCredential, true);
            app.Report_Fiscaliza.Icon = 'Report_Icon6.png';
            app.Report_Fiscaliza.Tooltip = {'Fiscaliza'};
            app.Report_Fiscaliza.Text = '';
            app.Report_Fiscaliza_2 = uibutton(app.menu_Grid1, 'push');
            app.Report_Fiscaliza_2.ButtonPushedFcn = createCallbackFcn(app, @Report_Fiscaliza_2ButtonPushed, true);
            app.Report_Fiscaliza_2.Icon = 'mainMenu_Report.png';
            app.Report_Fiscaliza_2.Tooltip = {'Design do relatório'};
            app.Report_Fiscaliza_2.Text = '';
            app.ContextMenu_File1 = uicontextmenu(app.MainApp);
            app.File_ContextMenu1 = uimenu(app.ContextMenu_File1);
            app.File_ContextMenu1.MenuSelectedFcn = createCallbackFcn(app, @File_Tree1Delete, true);
            app.File_ContextMenu1.Text = 'Excluir';
            app.ContextMenu_Playback = uicontextmenu(app.MainApp);
            app.Playback_ContextMenu1 = uimenu(app.ContextMenu_Playback);
            app.Playback_ContextMenu1.MenuSelectedFcn = createCallbackFcn(app, @Playback_peakDeletePushed, true);
            app.Playback_ContextMenu1.Text = 'Excluir';
            app.EditarMenu = uimenu(app.ContextMenu_Playback);
            app.EditarMenu.Text = 'Editar';
            app.TruncarfrequnciaMenu = uimenu(app.EditarMenu);
            app.TruncarfrequnciaMenu.MenuSelectedFcn = createCallbackFcn(app, @Playback_TruncatedCheckBoxValueChanged, true);
            app.TruncarfrequnciaMenu.Text = 'Truncar frequência';
            app.NotruncarMenu = uimenu(app.EditarMenu);
            app.NotruncarMenu.MenuSelectedFcn = createCallbackFcn(app, @Playback_TruncatedCheckBoxValueChanged, true);
            app.NotruncarMenu.Text = 'Não truncar';
            app.ContextMenu_File2 = uicontextmenu(app.MainApp);
            app.File_ContextMenu2 = uimenu(app.ContextMenu_File2);
            app.File_ContextMenu2.MenuSelectedFcn = createCallbackFcn(app, @File_Tree2Delete, true);
            app.File_ContextMenu2.Text = 'Excluir';
            app.ContextMenu_Report = uicontextmenu(app.MainApp);
            app.Report_ContextMenu1 = uimenu(app.ContextMenu_Report);
            app.Report_ContextMenu1.MenuSelectedFcn = createCallbackFcn(app, @Report_deleteButtonPushed, true);
            app.Report_ContextMenu1.Text = 'Excluir';
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end