classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout4         matlab.ui.container.GridLayout
        Grid1               matlab.ui.container.GridLayout
        Button4             matlab.ui.control.Button
        Panel_2             matlab.ui.container.Panel
        Grid2_2             matlab.ui.container.GridLayout
        CheckBox3_2         matlab.ui.control.CheckBox
        CheckBox2_2         matlab.ui.control.CheckBox
        CheckBox1_3         matlab.ui.control.CheckBox
        Variables_Label1_3  matlab.ui.control.Label
        Variables_Label1_2  matlab.ui.control.Label
        SpinnerLabel        matlab.ui.control.Label
        Spinner             matlab.ui.control.Spinner
        CheckBox6_2         matlab.ui.control.CheckBox
        CheckBox5_2         matlab.ui.control.CheckBox
        CheckBox4_2         matlab.ui.control.CheckBox
        CheckBox1_2         matlab.ui.control.CheckBox
        Button2             matlab.ui.control.Button
        Subtitle            matlab.ui.control.Label
        Title               matlab.ui.control.Label
        GridLayout3         matlab.ui.container.GridLayout
        Panel               matlab.ui.container.Panel
        Grid2               matlab.ui.container.GridLayout
        Variables_Label1    matlab.ui.control.Label
        Variables_Label2    matlab.ui.control.Label
        Variables_Label3    matlab.ui.control.Label
        CheckBox7           matlab.ui.control.CheckBox
        CheckBox6           matlab.ui.control.CheckBox
        CheckBox5           matlab.ui.control.CheckBox
        CheckBox4           matlab.ui.control.CheckBox
        CheckBox3           matlab.ui.control.CheckBox
        CheckBox2           matlab.ui.control.CheckBox
        CheckBox1           matlab.ui.control.CheckBox
        GridLayout2         matlab.ui.container.GridLayout
        ImageResolution     matlab.ui.control.DropDown
        ResoluoDPILabel     matlab.ui.control.Label
        GPUType             matlab.ui.control.DropDown
        TipodeunidadedeprocessamentogrficoDropDownLabel  matlab.ui.control.Label
        GPUInfo             matlab.ui.control.TextArea
        Info                matlab.ui.control.TextArea
        Button1             matlab.ui.control.Button
        Button3             matlab.ui.control.Button
        Toolbar1            matlab.ui.control.Button
        Toolbar2            matlab.ui.control.Button
        Toolbar3            matlab.ui.control.Button
        GridLayout5         matlab.ui.container.GridLayout
        Tree                matlab.ui.container.Tree
        Node1               matlab.ui.container.TreeNode
        Node2               matlab.ui.container.TreeNode
        Node3               matlab.ui.container.TreeNode
        Node4               matlab.ui.container.TreeNode
        Logo                matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        CallingApp
        
        newVersion
        PublicLinks
        
    end
    
    methods (Access = private)
        
        function Fcn1_VersionInfo(app)
            
            [Version, msgConsole] = Misc_appVersions(app.CallingApp.RootFolder);
            app.Info.Value = sprintf('Informações acerca das versões instaladas dos módulos do appAnálise:\n%s\n\n%s', ...
                                      jsonencode(Version, 'PrettyPrint', true), msgConsole);
            
            app.Button1.Enable  = 1;
            app.Toolbar2.Enable = 0;
            app.Toolbar3.Enable = 0;
            
        end
        
        
        function Fcn2_PublicLinks(app)
            
            fileID = fopen(fullfile(app.CallingApp.RootFolder, 'DataBase', 'PublicLinks.cfg'), 'rt');
            app.PublicLinks = jsondecode(fread(fileID, [1, Inf], '*char'));
            fclose(fileID);            
            
        end
        
        
        function Fcn3_Graphics(app)

            graphRender = opengl('data');
            app.GPUInfo.Value = jsonencode(rmfield(graphRender, 'Extensions'), 'PrettyPrint', true);
            
            switch graphRender.HardwareSupportLevel
                case 'basic'; app.GPUType.Value = 'Hardware Basic';
                case 'full';  app.GPUType.Value = 'Hardware Full';
                case 'none';  app.GPUType.Value = 'Software';
            end

            app.ImageResolution.Value = num2str(app.CallingApp.ImageResolution);

            app.Button4.Enable = 0;
            
        end
        
        
        function Fcn4_MergeType(app)
            
            app.CheckBox1_2.Value = 0;
            app.CheckBox4_2.Value = 0;
            app.CheckBox5_2.Value = 0;
            app.CheckBox6_2.Value = 0;
            
            if ismember(app.CheckBox1_2.Text, app.CallingApp.MergeType.OptionalFieldsToRemove); app.CheckBox1_2.Value = 1;
            end
            CheckBox1_2ValueChanged(app)
            
            if ismember(app.CheckBox4_2.Text, app.CallingApp.MergeType.OptionalFieldsToRemove); app.CheckBox4_2.Value = 1;
            end
            
            if ismember(app.CheckBox5_2.Text, app.CallingApp.MergeType.OptionalFieldsToRemove); app.CheckBox5_2.Value = 1;
            end
            
            if ismember(app.CheckBox6_2.Text, app.CallingApp.MergeType.OptionalFieldsToRemove); app.CheckBox6_2.Value = 1;
            end
            
            switch app.CallingApp.MergeType.DataTypeStatus
                case 'keep';   app.CheckBox2_2.Value = 0;
                case 'remove'; app.CheckBox2_2.Value = 1;
            end
            
            switch app.CallingApp.MergeType.AntennaNameStatus
                case 'keep';   app.CheckBox3_2.Value = 0;
                case 'remove'; app.CheckBox3_2.Value = 1;
            end
            
            app.Spinner.Value = app.CallingApp.MergeType.DistanceValue;
            
            app.Button2.Enable = 0;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            if isdeployed
                set(app.Grid2.Children, 'Enable', 0)
            end
            
            expand(app.Tree, 'all')
            app.Tree.SelectedNodes = app.Tree.Children(1);
            TreeSelectionChanged(app)
            
            Fcn1_VersionInfo(app)
            Fcn2_PublicLinks(app)
            Fcn3_Graphics(app)
            Fcn4_MergeType(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin5 = [];
            delete(app)
            
        end

        % Value changed function: CheckBox1, CheckBox2, CheckBox3, 
        % CheckBox4, CheckBox5, CheckBox6, CheckBox7
        function CheckBoxes(app, event)
            
            if app.CheckBox1.Value || app.CheckBox2.Value || app.CheckBox3.Value || app.CheckBox4.Value || ...
               app.CheckBox5.Value || app.CheckBox6.Value || app.CheckBox7.Value
                app.Button3.Enable = 1;
            else
                app.Button3.Enable = 0;
            end
            
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            
            try
                FileVersion = jsondecode(webread(app.PublicLinks.FileVersion));
                app.newVersion = struct('appAnalise', FileVersion.appAnalise.Version, ...
                                        'fiscaliza',  FileVersion.fiscaliza.Version,  ...
                                        'anateldb',   FileVersion.anateldb.ReleaseDate);
                
                if isequal(app.CallingApp.Version, app.newVersion)
                    uialert(app.UIFigure, sprintf('Módulos atualizados:\n• appAnalise, fiscaliza, anateldb'), 'appAnálise', 'Icon', 'success')
                    
                else
                    app.Info.Value{end+1} = sprintf('Informações acerca das versões disponíveis dos módulos do appAnálise:\n%s\n\n%s\n\n', ...
                                                     jsonencode(app.newVersion, 'PrettyPrint', true));
                                    
                    str1 = {};
                    str2 = {};
                    if strcmp(app.CallingApp.Version.appAnalise, app.newVersion.appAnalise); str1(end+1) = {'appAnalise'};
                    else;                                                                    str2(end+1) = {'appAnalise'};
                    end                    
                    if strcmp(app.CallingApp.Version.fiscaliza,  app.newVersion.fiscaliza);  str1(end+1) = {'fiscaliza'};
                    else;                                                                    str2(end+1) = {'fiscaliza'}; app.Toolbar2.Enable = 1;
                    end                    
                    if strcmp(app.CallingApp.Version.anateldb,   app.newVersion.anateldb);   str1(end+1) = {'anateldb'};
                    else;                                                                    str2(end+1) = {'anateldb'};  app.Toolbar3.Enable = 1;
                    end
                    
                    if ~isempty(str1) && ~isempty(str2); msg = sprintf('Módulo(s) atualizado(s):\n• %s\n\nMódulo(s) desatualizado(s):\n• %s', strjoin(str1, ', '), strjoin(str2, ', '));
                    else;                                msg = sprintf('Módulo(s) desatualizado(s):\n• %s', strjoin(str2, ', '));
                    end                    
                    uialert(app.UIFigure, msg, 'appAnálise', 'Icon', 'warning')
                    
                end
                app.Button1.Enable = 0;
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
            end
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            OptionalFieldsToRemove = {};
            if app.CheckBox1_2.Value; OptionalFieldsToRemove = [OptionalFieldsToRemove, app.CheckBox1_2.Text];
            end
            
            if app.CheckBox4_2.Value; OptionalFieldsToRemove = [OptionalFieldsToRemove, app.CheckBox4_2.Text];
            end
            
            if app.CheckBox5_2.Value; OptionalFieldsToRemove = [OptionalFieldsToRemove, app.CheckBox5_2.Text];
            end
            
            if app.CheckBox6_2.Value; OptionalFieldsToRemove = [OptionalFieldsToRemove, app.CheckBox6_2.Text];
            end
            
            MergeType.OptionalFieldsToRemove = OptionalFieldsToRemove;
            
            switch app.CheckBox1_3.Value
                case 0; MergeType.ThreadIDStatus = 'keep';
                case 1; MergeType.ThreadIDStatus = 'remove';
            end
            
            switch app.CheckBox2_2.Value
                case 0; MergeType.DataTypeStatus = 'keep';
                case 1; MergeType.DataTypeStatus = 'remove';
            end
            
            switch app.CheckBox3_2.Value
                case 0; MergeType.AntennaNameStatus = 'keep';
                case 1; MergeType.AntennaNameStatus = 'remove';
            end
            
            MergeType.DistanceValue = app.Spinner.Value;
            
            fileID = fopen(fullfile(app.CallingApp.RootFolder, 'DataBase', 'MergeType.cfg'), 'wt');
            fwrite(fileID, jsonencode(MergeType, 'PrettyPrint', true), 'char');
            fclose(fileID);
            
            app.CallingApp.Misc_MergeType
            Fcn4_MergeType(app)            
            
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            
            if app.CheckBox1.Value
                assignin('base', 'metaData', app.CallingApp.metaData)
                openvar('metaData')
            end
            
            if app.CheckBox2.Value
                assignin('base', 'Samples', app.CallingApp.Samples)
                openvar('Samples')
            end
            
            if app.CheckBox3.Value
                assignin('base', 'specData', app.CallingApp.specData)
                openvar('specData')
            end
            
            if app.CheckBox4.Value
                assignin('base', 'traceInfo', app.CallingApp.traceInfo)
                openvar('traceInfo')
            end
            
            if app.CheckBox5.Value
                assignin('base', 'peaksTable', app.CallingApp.peaksTable)
                openvar('peaksTable')
            end
            
            if app.CheckBox6.Value
                assignin('base', 'exceptionList', app.CallingApp.exceptionList)
                openvar('exceptionList')
            end
            
            if app.CheckBox7.Value
                assignin('base', 'reportInfo', app.CallingApp.reportInfo)
                openvar('reportInfo')
            end
            
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            
            switch app.GPUType.Value
                case 'Software';       opengl software
                case 'Hardware Full';  opengl hardware
                case 'Hardware Basic'; opengl hardwarebasic
            end

            app.CallingApp.ImageResolution = str2double(app.ImageResolution.Value);
            
            Fcn3_Graphics(app)
            
        end

        % Button pushed function: Toolbar1
        function Toolbar1Pushed(app, event)
            
            Python = pyenv;
            
            [file, pathfile] = uigetfile({'python*.exe', 'python*.exe'}, 'Selecione o arquivo executável do Python', Python.Executable);
            figure(app.UIFigure)
            
            if file
                try
                    pyenv('Version', fullfile(pathfile, file));
                    uialert(app.UIFigure, 'Alteração realizada com sucesso!', 'appAnálise', 'Icon', 'success')
                    
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Verificando as versões dos módulos do appAnálise...');
                    Fcn1_VersionInfo(app)
                
                catch ME
                    uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
                end
            end
            
        end

        % Button pushed function: Toolbar2
        function Toolbar2Pushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando atualizar a biblioteca Fiscaliza...');
            
            Python   = pyenv;
            [~, msg] = system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), 'install fiscaliza -U'));
            
            app.Info.Value{end+1} = sprintf('%s\n\n', msg);
            
            [~, fiscalizaVersion] = system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), 'list | findstr fiscaliza'));
            if ~isempty(fiscalizaVersion); app.CallingApp.Version.fiscaliza = strtrim(extractAfter(fiscalizaVersion, 'fiscaliza'));
            end
            
        end

        % Button pushed function: Toolbar3
        function Toolbar3Pushed(app, event)
            
            try
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando atualizar a base de dados offline da Agência...');
                
                websave(fullfile(app.CallingApp.RootFolder, 'DataBase', 'AnatelDB.xlsx'), app.PublicLinks.AnatelDB);
                
                delete(fullfile(app.CallingApp.RootFolder, 'DataBase', 'AnatelDB.mat'))
                clear global AnatelDB
                clear global AnatelDB_ExtractDate
                drawnow nocallbacks
                
                Misc_DataBaseRead(app.CallingApp.RootFolder)
                
                global AnatelDB_ExtractDate
                app.CallingApp.Version.anateldb = AnatelDB_ExtractDate;
                
                Fcn1_VersionInfo(app)
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
            end
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            NodeData = app.Tree.SelectedNodes.NodeData;
            
            if ~isempty(NodeData)
                switch NodeData
                    case 1; app.Grid1.ColumnWidth = {24, 24, 24, '1x', 110,    0,   0,    0,   0,    0,   0};
                    case 2; app.Grid1.ColumnWidth = { 0,  0,  0,   0,    0, '1x', 110,    0,   0,    0,   0};
                    case 3; app.Grid1.ColumnWidth = { 0,  0,  0,   0,    0,    0,   0, '1x', 110,    0,   0};
                    case 4; app.Grid1.ColumnWidth = { 0,  0,  0,   0,    0,    0,   0,    0,   0, '1x', 110};
                end
                
            else
                app.Tree.SelectedNodes = app.Tree.Children(1);
                TreeSelectionChanged(app)
            end
            
        end

        % Value changed function: GPUType, ImageResolution
        function GPUTypeValueChanged(app, event)
            
            app.Button4.Enable = 1;
            
        end

        % Value changed function: CheckBox1_2
        function CheckBox1_2ValueChanged(app, event)
            
            app.CheckBox1_3.Value = app.CheckBox1_2.Value;
            MergeTypeValueChanged(app)
            
        end

        % Value changed function: CheckBox2_2, CheckBox3_2, CheckBox4_2, 
        % CheckBox5_2, CheckBox6_2, Spinner
        function MergeTypeValueChanged(app, event)
            
            app.Button2.Enable = 1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Configurações';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout4 = uigridlayout(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Logo = uiimage(app.GridLayout5);
            app.Logo.ImageSource = 'Puzzle.png';
            app.Tree = uitree(app.GridLayout5);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node1 = uitreenode(app.Tree);
            app.Node1.NodeData = 1;
            app.Node1.Text = 'Geral';
            app.Node2 = uitreenode(app.Tree);
            app.Node2.NodeData = 2;
            app.Node2.Text = 'Variáveis';
            app.Node3 = uitreenode(app.Tree);
            app.Node3.NodeData = 3;
            app.Node3.Text = 'Junção dos fluxos de dados';
            app.Node4 = uitreenode(app.Tree);
            app.Node4.NodeData = 4;
            app.Node4.Text = 'Unidade gráfica';
            app.Grid1 = uigridlayout(app.GridLayout4);
            app.Toolbar3 = uibutton(app.Grid1, 'push');
            app.Toolbar3.ButtonPushedFcn = createCallbackFcn(app, @Toolbar3Pushed, true);
            app.Toolbar3.Icon = 'Settings_Sheet.png';
            app.Toolbar3.Tooltip = {'Atualizar base de dados offline'};
            app.Toolbar3.Text = '';
            app.Toolbar2 = uibutton(app.Grid1, 'push');
            app.Toolbar2.ButtonPushedFcn = createCallbackFcn(app, @Toolbar2Pushed, true);
            app.Toolbar2.Icon = 'Report_Icon6.png';
            app.Toolbar2.Tooltip = {'Atualizar biblioteca Fiscaliza'};
            app.Toolbar2.Text = '';
            app.Toolbar1 = uibutton(app.Grid1, 'push');
            app.Toolbar1.ButtonPushedFcn = createCallbackFcn(app, @Toolbar1Pushed, true);
            app.Toolbar1.Icon = 'Settings_Python.png';
            app.Toolbar1.Tooltip = {'Editar ambiente Python'};
            app.Toolbar1.Text = '';
            app.Button3 = uibutton(app.Grid1, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Icon = 'White_Play.png';
            app.Button3.Enable = 'off';
            app.Button3.Text = 'Explorar dados';
            app.Button1 = uibutton(app.Grid1, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Icon = 'White_Reload.png';
            app.Button1.Text = 'Verificar versões';
            app.Info = uitextarea(app.Grid1);
            app.GridLayout2 = uigridlayout(app.Grid1);
            app.GPUInfo = uitextarea(app.GridLayout2);
            app.TipodeunidadedeprocessamentogrficoDropDownLabel = uilabel(app.GridLayout2);
            app.TipodeunidadedeprocessamentogrficoDropDownLabel.Text = 'Tipo de unidade de processamento gráfico:';
            app.GPUType = uidropdown(app.GridLayout2);
            app.GPUType.Items = {'Software', 'Hardware Full', 'Hardware Basic'};
            app.GPUType.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.GPUType.Value = 'Hardware Basic';
            app.ResoluoDPILabel = uilabel(app.GridLayout2);
            app.ResoluoDPILabel.Text = 'Resolução da imagem incluída no relatório (DPI):';
            app.ImageResolution = uidropdown(app.GridLayout2);
            app.ImageResolution.Items = {'100', '120', '150', '200'};
            app.ImageResolution.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.ImageResolution.Value = '120';
            app.GridLayout3 = uigridlayout(app.Grid1);
            app.Panel = uipanel(app.GridLayout3);
            app.Grid2 = uigridlayout(app.Panel);
            app.CheckBox1 = uicheckbox(app.Grid2);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox1.Text = 'app.metaData';
            app.CheckBox2 = uicheckbox(app.Grid2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox2.Text = 'app.Samples';
            app.CheckBox3 = uicheckbox(app.Grid2);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox3.Text = 'app.specData';
            app.CheckBox4 = uicheckbox(app.Grid2);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox4.Text = 'app.traceInfo';
            app.CheckBox5 = uicheckbox(app.Grid2);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox5.Text = 'app.peaksTable';
            app.CheckBox6 = uicheckbox(app.Grid2);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox6.Text = 'app.exceptionList';
            app.CheckBox7 = uicheckbox(app.Grid2);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox7.Text = 'app.reportInfo';
            app.Variables_Label3 = uilabel(app.Grid2);
            app.Variables_Label3.Text = 'Relacionadas ao "Relatório":';
            app.Variables_Label2 = uilabel(app.Grid2);
            app.Variables_Label2.Text = 'Relacionadas ao "Playback":';
            app.Variables_Label1 = uilabel(app.Grid2);
            app.Variables_Label1.Text = 'Relacionadas à leitura dos arquivos:';
            app.Title = uilabel(app.Grid1);
            app.Title.Text = 'Configurações';
            app.Subtitle = uilabel(app.Grid1);
            app.Subtitle.Text = 'Ajustes';
            app.Button2 = uibutton(app.Grid1, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'White_Edit.png';
            app.Button2.Enable = 'off';
            app.Button2.Text = 'Alterar';
            app.Panel_2 = uipanel(app.Grid1);
            app.Grid2_2 = uigridlayout(app.Panel_2);
            app.CheckBox1_2 = uicheckbox(app.Grid2_2);
            app.CheckBox1_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1_2ValueChanged, true);
            app.CheckBox1_2.Text = 'ThreadID';
            app.CheckBox4_2 = uicheckbox(app.Grid2_2);
            app.CheckBox4_2.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.CheckBox4_2.Text = 'FileFormat';
            app.CheckBox5_2 = uicheckbox(app.Grid2_2);
            app.CheckBox5_2.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.CheckBox5_2.Text = 'TaskName';
            app.CheckBox6_2 = uicheckbox(app.Grid2_2);
            app.CheckBox6_2.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.CheckBox6_2.Text = 'Description';
            app.Spinner = uispinner(app.Grid2_2);
            app.Spinner.Step = 50;
            app.Spinner.Limits = [50 Inf];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.Spinner.Value = 100;
            app.SpinnerLabel = uilabel(app.Grid2_2);
            app.SpinnerLabel.Text = 'Distância mínima registrada nos diferentes arquivos gerados por um mesmo sensor (metros):';
            app.Variables_Label1_2 = uilabel(app.Grid2_2);
            app.Variables_Label1_2.Text = 'Campos da variável app.metaData que serão ignorados na junção dos fluxos de dados:';
            app.Variables_Label1_3 = uilabel(app.Grid2_2);
            app.Variables_Label1_3.Text = 'Campos da estrutura MetaData, da variável app.metaData, que serão ignorados na junção dos fluxos de dados:';
            app.CheckBox1_3 = uicheckbox(app.Grid2_2);
            app.CheckBox1_3.Enable = 'off';
            app.CheckBox1_3.Text = 'ThreadID';
            app.CheckBox2_2 = uicheckbox(app.Grid2_2);
            app.CheckBox2_2.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.CheckBox2_2.Text = 'DataType';
            app.CheckBox3_2 = uicheckbox(app.Grid2_2);
            app.CheckBox3_2.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.CheckBox3_2.Text = 'AntennaName';
            app.Button4 = uibutton(app.Grid1, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Icon = 'White_Edit.png';
            app.Button4.Enable = 'off';
            app.Button4.Text = 'Alterar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end