classdef winTemplate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Grid1              matlab.ui.container.GridLayout
        GridLayout         matlab.ui.container.GridLayout
        Image_up           matlab.ui.control.Image
        Image_down         matlab.ui.control.Image
        Grid1B             matlab.ui.container.GridLayout
        Button_refresh     matlab.ui.control.Button
        Button_details     matlab.ui.control.Button
        Image              matlab.ui.control.Image
        Button_info        matlab.ui.control.Button
        Button_preview     matlab.ui.control.Button
        GridLayout9        matlab.ui.container.GridLayout
        Type               matlab.ui.control.DropDown
        TypeLabel          matlab.ui.control.Label
        Panel_5            matlab.ui.container.Panel
        GridLayout7        matlab.ui.container.GridLayout
        Panel_3            matlab.ui.container.Panel
        GridLayout11       matlab.ui.container.GridLayout
        AuxiliarText       matlab.ui.control.TextArea
        AuxiliarTextLabel  matlab.ui.control.Label
        Editable           matlab.ui.control.DropDown
        EditableLabel      matlab.ui.control.Label
        MainText           matlab.ui.control.TextArea
        MainTextLabel      matlab.ui.control.Label
        DataLabel          matlab.ui.control.Label
        Recurrence         matlab.ui.control.CheckBox
        GridLayout6        matlab.ui.container.GridLayout
        Warn               matlab.ui.control.Image
        ModelLabel         matlab.ui.control.Label
        Sublabel2          matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        GridLayout2_2      matlab.ui.container.GridLayout
        Sublabel1          matlab.ui.control.Label
        GridLayout5        matlab.ui.container.GridLayout
        GridLayout2        matlab.ui.container.GridLayout
        HTML               matlab.ui.control.HTML
        GridLayout3        matlab.ui.container.GridLayout
        Tree               matlab.ui.container.Tree
        Model              matlab.ui.control.DropDown
        Logo               matlab.ui.control.Image
        Title              matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        Template

        i = ["OBJETIVO",    "LT_home.png";      ...
             "INTRODUÇÃO",  "LT_info.png";      ...
             "METODOLOGIA", "LT_settings.png";  ...
             "RESULTADOS",  "LT_Detection.png"; ...
             "ANÁLISE",     "LT_Filter.png";    ...
             "CONCLUSÃO",   "LT_Pin.png";       ...
             "GENERIC",     "LT_report.png"]
        s = uistyle('FontColor', '#808080')
        
    end
    
    methods (Access = private)

        function Fcn_ReadModels(app)

            app.Model.Items = app.CallingApp.Report_Type.Items;

        end


        function Fcn_ReadTemplate(app)

            idx = find(strcmp(app.Model.Items, app.Model.Value), 1);

            app.Template       = jsondecode(fileread(fullfile(app.RootFolder, 'Template', app.CallingApp.General.Models.Template{idx})));
            app.Sublabel1.Text = upper(app.CallingApp.General.Models.Description{idx});
            app.Sublabel2.Text = sprintf('v. %s (%s)\n%s', app.CallingApp.General.Models.Version{idx}, ...
                                                           app.CallingApp.General.Models.Release{idx}, ...
                                                           app.CallingApp.General.Models.Author{idx});  
            Fcn_TreeBuilding(app)

        end


        function Fcn_TreeBuilding(app)

            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end
         
            for ii = 1:numel(app.Template)
                idx = find(app.i(:,1) == app.Template(ii).Data.String);
                if isempty(idx); idx = height(app.i);
                end
                
                Parent = uitreenode(app.Tree, 'Text', sprintf('ITEM %.0f: %s', ii, app.Template(ii).Data.String), 'NodeData', ii, 'Icon', app.i(idx,2));

                if isempty(app.Template(ii).Data.Children)
                    addStyle(app.Tree, app.s, 'node', Parent)
                else
                    for jj = 1:numel(app.Template(ii).Data.Children)
                        Children = uitreenode(Parent, 'Text', app.Template(ii).Data.Children(jj).Type, 'NodeData', jj);

                        if Children.Text == "List"
                            for kk = 1:numel(app.Template(ii).Data.Children(jj).Data)
                                uitreenode(Children, 'Text', sprintf('Element %.0f', kk), 'NodeData', kk);
                            end
                        end
                    end
                end
            end
            expand(app.Tree, 'all')

            if ~isempty(app.Tree.Children)
                app.Tree.SelectedNodes = app.Tree.Children(1);
                TreeSelectionChanged(app)
            end

        end


        function Fcn_Layout_UpDown(app)

            Parent = app.Tree.SelectedNodes.Parent;
            
            NN   = numel(Parent.Children);
            idx1 = find([Parent.Children.NodeData] == Parent.Children(1).NodeData);
            idx2 = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);

            if NN == 1
                app.Image_up.Enable   = 0;
                app.Image_down.Enable = 0;

            elseif idx2 == idx1
                app.Image_up.Enable   = 0;
                app.Image_down.Enable = 1;

            elseif idx2 - idx1 + 1 == NN
                app.Image_up.Enable   = 1;
                app.Image_down.Enable = 0;

            else
                app.Image_up.Enable   = 1;
                app.Image_down.Enable = 1;
            end

        end
        

        function Fcn_NodeData(app)

            for ii = 1:numel(app.Tree.Children)
                idx = app.Tree.Children(ii).UserData;

                set(app.Tree.Children(ii), 'Text', sprintf('ITEM %.0f: %s', ii, app.Template(ii).Data.String), ...
                                           'NodeData', ii)

                for jj = 1:numel(app.Tree.Children(ii).Children)
                    app.Tree.Children(ii).Children(jj).NodeData = ii+jj;
                end
            end
            app.Warn.Visible = 1;

            TreeSelectionChanged(app)

        end

        
        function Fcn_updateValues(app)

            try
                if app.Tree.SelectedNodes.Parent == app.Tree
                    idx1 = app.Tree.SelectedNodes.NodeData;
    
                    app.Type.Items       = {app.Template(idx1).Type};
                    app.Recurrence.Value = app.Template(idx1).Recurrence;
                    app.Editable.Items   = {app.Template(idx1).Data.Editable};
                    app.MainText.Value   = app.Template(idx1).Data.String;

                    app.AuxiliarText.Value = jsonencode(app.Template(idx1).Data.Children, 'PrettyPrint', true);
    
                else
                    if app.Tree.SelectedNodes.Parent.Parent == app.Tree
                        idx1 = app.Tree.SelectedNodes.Parent.NodeData;
                        idx2 = app.Tree.SelectedNodes.NodeData;
                        idx3 = [];

                    else
                        idx1 = app.Tree.SelectedNodes.Parent.Parent.NodeData;
                        idx2 = app.Tree.SelectedNodes.Parent.NodeData;
                        idx3 = app.Tree.SelectedNodes.NodeData;
                    end
    
                    app.Type.Items       = {app.Template(idx1).Data.Children(idx2).Type};
                    app.Recurrence.Value = 0;

                    if isempty(idx3)
                        switch app.Type.Value
                            case 'List'
                                app.Editable.Items     = {};
                                app.MainText.Value     = '';
                                app.AuxiliarText.Value = jsonencode(app.Template(idx1).Data.Children(idx2).Data, 'PrettyPrint', true);

                            case {'Image', 'Table'}
                                app.Editable.Items = {};
                                app.MainText.Value = jsonencode(rmfield(app.Template(idx1).Data.Children(idx2).Data, 'Settings'), 'PrettyPrint', true);

                                if app.Type.Value == "Image"
                                    app.AuxiliarText.Value = '';
                                else
                                    app.AuxiliarText.Value = jsonencode(app.Template(idx1).Data.Children(idx2).Data.Settings, 'PrettyPrint', true);
                                end

                            otherwise
                                app.Editable.Items     = {app.Template(idx1).Data.Children(idx2).Data.Editable};
                                app.MainText.Value     = app.Template(idx1).Data.Children(idx2).Data.String;
                                app.AuxiliarText.Value = jsonencode(app.Template(idx1).Data.Children(idx2).Data.Settings, 'PrettyPrint', true);
                        end

                    else
                        app.Editable.Items     = {app.Template(idx1).Data.Children(idx2).Data(idx3).Editable};
                        app.MainText.Value     = app.Template(idx1).Data.Children(idx2).Data(idx3).String;
                        app.AuxiliarText.Value = jsonencode(app.Template(idx1).Data.Children(idx2).Data(idx3).Settings, 'PrettyPrint', true);
                    end
                end

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

        end


        function Fcn_reportPreview(app)

            try                
                CheckedRows = find([app.CallingApp.specData.reportFlag]);
                
                idx = Misc_FindOCCData(app.CallingApp.specData(CheckedRows));                
                ProjectRows = sort([CheckedRows, idx]);
                
                % OCC Map (Step 1 of 2)
                OCCLength = numel(idx);
                if OCCLength
                    OCCMap = zeros(OCCLength, 2);
                    
                    jj = 0;
                    for ii = 1:numel(ProjectRows)
                        if ~isempty(app.CallingApp.specData(ProjectRows(ii)).reportOCC.Index)
                            jj=jj+1;
                            OCCMap(jj,:) = [ProjectRows(ii), app.CallingApp.specData(ProjectRows(ii)).reportOCC.Index];
                            app.CallingApp.specData(ProjectRows(ii)).reportOCC.Index = find(ProjectRows == OCCMap(jj,2));
                        end
                    end
                end

                Date1 = app.CallingApp.Report_DatePicker1.Value + hours(app.CallingApp.Report_Spinner1.Value) + minutes(app.CallingApp.Report_Spinner2.Value);
                Date2 = app.CallingApp.Report_DatePicker2.Value + hours(app.CallingApp.Report_Spinner3.Value) + minutes(app.CallingApp.Report_Spinner4.Value);
                
                if Date1 > Date2
                    error('Os valores inseridos para aplicação do filtro de TimeStamp devem ser ajustados!')
                end
    
                idx = find(strcmp(app.Model.Items, app.Model.Value), 1);
                reportInfo = struct('appVersion', app.CallingApp.General.ver,    ...
                                    'Issue',      -1,                            ...
                                    'General',    struct('Mode',       "Report", ...
                                                         'Version',    app.CallingApp.Report_Version.Value, ...
                                                         'Image',      struct('Visibility', 'off', 'Format', 'jpeg', 'Resolution', 120), ...
                                                         'RootFolder', app.RootFolder,            ...
                                                         'UserPath',   app.CallingApp.menu_userPath.Value),  ...
                                    'Model',      struct('Name',       app.Model.Value,     ...
                                                         'Type',       app.CallingApp.General.Models(idx,:), ...
                                                         'Template',   fileread(fullfile(app.RootFolder, 'Template', app.CallingApp.General.Models.Template{idx}))), ...
                                    'Attachments', app.CallingApp.projData,  ...
                                    'TimeStamp',  [Date1, Date2], ...
                                    'Filename',   '');

                htmlReport = ReportGenerator(app.CallingApp.specData(ProjectRows), reportInfo, []);

                % OCC Map (Step 2 of 2)
                if OCCLength
                    for jj = 1:height(OCCMap)
                        app.specData(OCCMap(jj,1)).reportOCC.Index = OCCMap(jj,2);
                    end
                end

                app.HTML.HTMLSource = htmlReport;
                app.Warn.Visible    = 0;

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...");

            Fcn_ReadModels(app)
            Fcn_ReadTemplate(app)
            Button_detailsPushed(app)

            delete(d)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin7 = [];
            delete(app)
            
        end

        % Value changed function: Model
        function ModelValueChanged(app, event)
            
            Fcn_ReadTemplate(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            if ~isempty(app.Tree.SelectedNodes)
                Fcn_updateValues(app)
            end

        end

        % Image clicked function: Image_down, Image_up
        function Image_orderClicked(app, event)
            
            Parent = app.Tree.SelectedNodes.Parent;
            
            idx = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);
            switch event.Source
                case app.Image_down
                    Parent.Children(idx:idx+1) = flip(Parent.Children(idx:idx+1));
                    app.Tree.SelectedNodes       = Parent.Children(idx+1);

                case app.Image_up
                    Parent.Children(idx-1:idx) = flip(Parent.Children(idx-1:idx));
                    app.Tree.SelectedNodes       = Parent.Children(idx-1);
            end

            Fcn_NodeData(app)
            
        end

        % Button pushed function: Button_info
        function Button_infoPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winTemplate>', '</winTemplate>')));

            Misc_uialertCreation(app, 'info', msg)

        end

        % Button pushed function: Button_details
        function Button_detailsPushed(app, event)
            
            if app.Grid1.ColumnWidth{3} == 266
                app.Grid1.ColumnWidth = {92, 169,   0, '1x', 259};
            else
                app.Grid1.ColumnWidth = {92, 169, 266, '1x', 259};
            end

        end

        % Button pushed function: Button_refresh
        function Button_refreshPushed(app, event)
            
            app.CallingApp.General.Models    = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Template', 'html_General.cfg'))));
            app.CallingApp.Report_Type.Items = app.CallingApp.General.Models.Name;

            Fcn_ReadModels(app)
            Fcn_ReadTemplate(app)

        end

        % Button pushed function: Button_preview
        function Button_previewPushed(app, event)
            
            if ~isempty(app.CallingApp.Report_Tree.Children)
                d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento criação do modelo do relatório...");
                Fcn_reportPreview(app)
    
                delete(d)

            else
                Misc_uialertCreation(app, 'warning', 'Funcionalidades disponível apenas se incluído ao menos um fluxo de dados a processar no modo "RELATÓRIO".')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Relatório</b>'; '<font>Criação de modelo (Item, subitem, parágrafo, nota de rodapé, lista, tabela e imagem)</font>'; ' '};
            app.Logo = uiimage(app.Grid1);
            app.Logo.ImageSource = 'LT_template.png';
            app.GridLayout3 = uigridlayout(app.Grid1);
            app.Model = uidropdown(app.GridLayout3);
            app.Model.Items = {'PMEC 2022', 'PMRD 2022', 'PMEF 2022', 'Rádio pirata', 'Drive-test'};
            app.Model.ValueChangedFcn = createCallbackFcn(app, @ModelValueChanged, true);
            app.Model.Value = 'PMEC 2022';
            app.Tree = uitree(app.GridLayout3);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GridLayout5 = uigridlayout(app.Grid1);
            app.GridLayout2 = uigridlayout(app.GridLayout5);
            app.HTML = uihtml(app.GridLayout2);
            app.Panel = uipanel(app.Grid1);
            app.GridLayout2_2 = uigridlayout(app.Panel);
            app.Sublabel1 = uilabel(app.GridLayout2_2);
            app.Sublabel1.Interpreter = 'html';
            app.Sublabel1.Text = 'PLANO DE MONITORAÇAO DO ESPECTRO DE RADIOFREQUÊNCIAS';
            app.Sublabel2 = uilabel(app.Grid1);
            app.Sublabel2.Text = {'v. 1.00 (12/03/2022)'; 'Centralizador PMEC 2022'};
            app.ModelLabel = uilabel(app.Grid1);
            app.ModelLabel.Text = 'MODELO:';
            app.GridLayout6 = uigridlayout(app.Grid1);
            app.Warn = uiimage(app.GridLayout6);
            app.Warn.Visible = 'off';
            app.Warn.ImageSource = 'LT_redWarn.png';
            app.GridLayout9 = uigridlayout(app.Grid1);
            app.Panel_5 = uipanel(app.GridLayout9);
            app.GridLayout7 = uigridlayout(app.Panel_5);
            app.Recurrence = uicheckbox(app.GridLayout7);
            app.Recurrence.Enable = 'off';
            app.Recurrence.Text = 'Recorrência';
            app.DataLabel = uilabel(app.GridLayout7);
            app.DataLabel.Text = 'Data:';
            app.Panel_3 = uipanel(app.GridLayout7);
            app.GridLayout11 = uigridlayout(app.Panel_3);
            app.MainTextLabel = uilabel(app.GridLayout11);
            app.MainTextLabel.Text = 'Informação principal:';
            app.MainText = uitextarea(app.GridLayout11);
            app.MainText.Editable = 'off';
            app.EditableLabel = uilabel(app.GridLayout11);
            app.EditableLabel.Text = 'Editável:';
            app.Editable = uidropdown(app.GridLayout11);
            app.Editable.Items = {'true', 'false'};
            app.Editable.Value = 'true';
            app.AuxiliarTextLabel = uilabel(app.GridLayout11);
            app.AuxiliarTextLabel.Text = 'Informação complementar:';
            app.AuxiliarText = uitextarea(app.GridLayout11);
            app.AuxiliarText.Editable = 'off';
            app.TypeLabel = uilabel(app.GridLayout9);
            app.TypeLabel.Text = 'Tipo:';
            app.Type = uidropdown(app.GridLayout9);
            app.Type.Items = {'Item', 'Subitem', 'Paragraph', 'Footnote', 'List', 'Image', 'Table'};
            app.Type.Value = 'Item';
            app.Grid1B = uigridlayout(app.Grid1);
            app.Button_preview = uibutton(app.Grid1B, 'push');
            app.Button_preview.ButtonPushedFcn = createCallbackFcn(app, @Button_previewPushed, true);
            app.Button_preview.Icon = 'DT_zoom.png';
            app.Button_preview.Text = 'Pré-visualizar';
            app.Button_info = uibutton(app.Grid1B, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @Button_infoPushed, true);
            app.Button_info.Icon = 'LT_info.png';
            app.Button_info.Text = '';
            app.Image = uiimage(app.Grid1B);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'LT_LineV.png';
            app.Button_details = uibutton(app.Grid1B, 'push');
            app.Button_details.ButtonPushedFcn = createCallbackFcn(app, @Button_detailsPushed, true);
            app.Button_details.Icon = 'LT_list.png';
            app.Button_details.Text = '';
            app.Button_refresh = uibutton(app.Grid1B, 'push');
            app.Button_refresh.ButtonPushedFcn = createCallbackFcn(app, @Button_refreshPushed, true);
            app.Button_refresh.Icon = 'LT_refresh.png';
            app.Button_refresh.Text = '';
            app.GridLayout = uigridlayout(app.Grid1);
            app.Image_down = uiimage(app.GridLayout);
            app.Image_down.ImageClickedFcn = createCallbackFcn(app, @Image_orderClicked, true);
            app.Image_down.Enable = 'off';
            app.Image_down.ImageSource = 'LT_downArrow_Zoom.png';
            app.Image_up = uiimage(app.GridLayout);
            app.Image_up.ImageClickedFcn = createCallbackFcn(app, @Image_orderClicked, true);
            app.Image_up.Enable = 'off';
            app.Image_up.ImageSource = 'LT_upArrow_Zoom.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTemplate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end